classdef BBB_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout32                    matlab.ui.container.GridLayout
        ImportButton                    matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        GridLayout27                    matlab.ui.container.GridLayout
        DisconnectButton                matlab.ui.control.Button
        ConnectButton                   matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SettingTab                      matlab.ui.container.Tab
        GridLayout25                    matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        BasicSettingTab                 matlab.ui.container.Tab
        GridLayout20                    matlab.ui.container.GridLayout
        GridLayout19                    matlab.ui.container.GridLayout
        GridLayout18                    matlab.ui.container.GridLayout
        GridLayout16                    matlab.ui.container.GridLayout
        SensorSettingPanel              matlab.ui.container.Panel
        GridLayout29_2                  matlab.ui.container.GridLayout
        GridLayout172                   matlab.ui.container.GridLayout
        MotorTemperatureSensorLamp      matlab.ui.control.Lamp
        MotorTemperatureSensorLampLabel  matlab.ui.control.Label
        HallSensorLamp                  matlab.ui.control.Lamp
        HallSensorLampLabel             matlab.ui.control.Label
        AbsoluteEncoderCH2Lamp          matlab.ui.control.Lamp
        AbsoluteEncoderCH2LampLabel     matlab.ui.control.Label
        AbsoluteEncoderCH1Lamp          matlab.ui.control.Lamp
        AbsoluteEncoderCH1LampLabel     matlab.ui.control.Label
        IncrementalEncoderLamp          matlab.ui.control.Lamp
        IncrementalEncoderLampLabel     matlab.ui.control.Label
        SensorRequirementLabel          matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        MotorSetting_ApplyButton_2      matlab.ui.control.Button
        OtherSensorsLabel               matlab.ui.control.Label
        ControllerFeedbackSensorsLabel  matlab.ui.control.Label
        AngleHomingSensorsLabel         matlab.ui.control.Label
        IMU3AxisDropDown                matlab.ui.control.DropDown
        IMU3AxisDropDownLabel           matlab.ui.control.Label
        IMU6AxisDropDown                matlab.ui.control.DropDown
        IMU6AxisDropDownLabel           matlab.ui.control.Label
        MechanicalAngleHomingSensorDropDown  matlab.ui.control.DropDown
        MechanicalAngleHomingSensorDropDownLabel  matlab.ui.control.Label
        ElectricalAngleHomingSensorDropDown  matlab.ui.control.DropDown
        ElectricalAngleHomingSensorDropDownLabel  matlab.ui.control.Label
        MotorTemperatureSensorDropDown  matlab.ui.control.DropDown
        MotorTemperatureSensorDropDownLabel  matlab.ui.control.Label
        PositionFeedbackSensorDropDown  matlab.ui.control.DropDown
        PositionFeedbackSensorDropDownLabel  matlab.ui.control.Label
        CommutationSensorDropDown       matlab.ui.control.DropDown
        CommutationSensorDropDownLabel  matlab.ui.control.Label
        MotorPropertiesPanel            matlab.ui.container.Panel
        GridLayout17_4                  matlab.ui.container.GridLayout
        VelocityConstantVradsEditField  matlab.ui.control.NumericEditField
        VelocityConstantVradsEditFieldLabel  matlab.ui.control.Label
        TorqueConstantNmAEditField      matlab.ui.control.NumericEditField
        TorqueConstantNmAEditFieldLabel  matlab.ui.control.Label
        MaxVelocity                     matlab.ui.control.NumericEditField
        MaximumVelocityrpmBeforegoingthoughgearEditFieldLabel  matlab.ui.control.Label
        ContinuousCurrentLimitEdit      matlab.ui.control.NumericEditField
        ContinuousCurrentALabel         matlab.ui.control.Label
        PeakCurrentLimitEdit            matlab.ui.control.NumericEditField
        PeakCurrentALabel               matlab.ui.control.Label
        GearRatioOutputInputLabel       matlab.ui.control.Label
        MotorSetting_ApplyButton        matlab.ui.control.Button
        GearRatio                       matlab.ui.control.NumericEditField
        EncResolution                   matlab.ui.control.NumericEditField
        PolePair                        matlab.ui.control.NumericEditField
        IncrementalEncoderResolutionLabel  matlab.ui.control.Label
        NumberofPolePairLabel           matlab.ui.control.Label
        NamePanel                       matlab.ui.container.Panel
        GridLayout29                    matlab.ui.container.GridLayout
        MotorName_ApplyButton           matlab.ui.control.Button
        MotorModelNameLabel             matlab.ui.control.Label
        MotorName                       matlab.ui.control.EditField
        HallSensorTab                   matlab.ui.container.Tab
        GridLayout174                   matlab.ui.container.GridLayout
        HallSensorSignalPanel           matlab.ui.container.Panel
        GridLayout64_10                 matlab.ui.container.GridLayout
        HallCLabel                      matlab.ui.control.Label
        HallBLabel                      matlab.ui.control.Label
        HallALabel                      matlab.ui.control.Label
        HallSensorAEditField            matlab.ui.control.NumericEditField
        HallSensorBEditField            matlab.ui.control.NumericEditField
        HallSensorA                     matlab.ui.control.Label
        HallSensorB                     matlab.ui.control.Label
        HallSensorReadStopButton        matlab.ui.control.Button
        HallSensorReadStartButton       matlab.ui.control.Button
        HallSensorC                     matlab.ui.control.Label
        HallSensorLogicEditField        matlab.ui.control.NumericEditField
        HALLLOGICLabel                  matlab.ui.control.Label
        HallSensorCEditField            matlab.ui.control.NumericEditField
        HallPatternPlot                 matlab.ui.control.UIAxes
        HallSensorPlot                  matlab.ui.control.UIAxes
        IncrementalEncoderTab           matlab.ui.container.Tab
        GridLayout173                   matlab.ui.container.GridLayout
        IncrementalEncoderPanel_2       matlab.ui.container.Panel
        GridLayout64_9                  matlab.ui.container.GridLayout
        StopIncEncReadButton            matlab.ui.control.Button
        StartIncEncReadButton           matlab.ui.control.Button
        IncEncSetOriginButton           matlab.ui.control.Button
        IncEncCounterLabel              matlab.ui.control.Label
        IncEncAnglerDegEditField        matlab.ui.control.NumericEditField
        AngleDegLabel                   matlab.ui.control.Label
        IncEncCounterEditField          matlab.ui.control.NumericEditField
        IncEncPlot                      matlab.ui.control.UIAxes
        AbsoluteEncoderTab              matlab.ui.container.Tab
        GridLayout168                   matlab.ui.container.GridLayout
        AbsoluteEncoderCH2DirectionOffsetSettingPanel  matlab.ui.container.Panel
        GridLayout171                   matlab.ui.container.GridLayout
        AbsoluteEncoderCH2SetButton     matlab.ui.control.Button
        AbsoluteEncoderCH2DirectionChangeButton  matlab.ui.control.Button
        AbsoluteEncoderCH2StopReadButton  matlab.ui.control.Button
        AbsoluteEncoderCH2StartReadButton  matlab.ui.control.Button
        AbsoluteEncoderCH2DesiredAngleDeg  matlab.ui.control.NumericEditField
        AngledegLabel_9                 matlab.ui.control.Label
        AbsoluteEncoderCH2CurrentAngleDeg  matlab.ui.control.NumericEditField
        AngledegLabel_8                 matlab.ui.control.Label
        AbsoluteEncoderCH2Plot          matlab.ui.control.UIAxes
        AbsoluteEncoderCH1DirectionOffsetSettingPanel  matlab.ui.container.Panel
        GridLayout170                   matlab.ui.container.GridLayout
        AbsoluteEncoderCH1DesiredAngleDeg  matlab.ui.control.NumericEditField
        AngledegLabel_7                 matlab.ui.control.Label
        AbsoluteEncoderCH1CurrentAngleDeg  matlab.ui.control.NumericEditField
        AngledegLabel_6                 matlab.ui.control.Label
        AbsoluteEncoderCH1DirectionChangeButton  matlab.ui.control.Button
        AbsoluteEncoderCH1StopReadButton  matlab.ui.control.Button
        AbsoluteEncoderCH1StartReadButton  matlab.ui.control.Button
        AbsoluteEncoderCH1SetButton     matlab.ui.control.Button
        AbsoluteEncoderCH1Plot          matlab.ui.control.UIAxes
        AbsoluteEncoderChannelSettingPanel  matlab.ui.container.Panel
        GridLayout169                   matlab.ui.container.GridLayout
        AbsoluteEncoderSettingApply     matlab.ui.control.Button
        MountingLocationDefinitionLabel  matlab.ui.control.Label
        AbsoluteEncoderChannelDefinitionLabel  matlab.ui.control.Label
        AbsoluteEncoderMountingLocationDefinitionImage  matlab.ui.control.Image
        AbsoluteEncoderChannelDefinitionImage  matlab.ui.control.Image
        AbsoluteEncoderCH2Resolution    matlab.ui.control.NumericEditField
        AbsoluteEncoderCH1Resolution    matlab.ui.control.NumericEditField
        ResolutionLabel                 matlab.ui.control.Label
        MountingLocationLabel           matlab.ui.control.Label
        AbsoluteEncoderChannelLabel     matlab.ui.control.Label
        AbsoluteEncoderCH2DropDown      matlab.ui.control.DropDown
        AbsoluteEncoderCH2DropDownLabel  matlab.ui.control.Label
        AbsoluteEncoderCH1DropDown      matlab.ui.control.DropDown
        AbsoluteEncoderCH1DropDownLabel  matlab.ui.control.Label
        CommutationSettingTab           matlab.ui.container.Tab
        CommutationSettingPanel         matlab.ui.container.Panel
        GridLayout31                    matlab.ui.container.GridLayout
        StartCommutationSettingButton   matlab.ui.control.Button
        FocedVoltage_Edit               matlab.ui.control.NumericEditField
        ForcedVoltageVLabel             matlab.ui.control.Label
        direction_set_hall_step_plot    matlab.ui.control.UIAxes
        direction_set_step_plot         matlab.ui.control.UIAxes
        direction_set_enc_plot          matlab.ui.control.UIAxes
        ELECTab                         matlab.ui.container.Tab
        GridLayout25_2                  matlab.ui.container.GridLayout
        TabGroup2_2                     matlab.ui.container.TabGroup
        ElecSysIDTab                    matlab.ui.container.Tab
        GridLayout20_2                  matlab.ui.container.GridLayout
        GridLayout19_2                  matlab.ui.container.GridLayout
        GridLayout18_2                  matlab.ui.container.GridLayout
        GridLayout33                    matlab.ui.container.GridLayout
        ElectricalSystemIDPanel         matlab.ui.container.Panel
        GridLayout38                    matlab.ui.container.GridLayout
        WaitforPlotLabel_3              matlab.ui.control.Label
        ElectricalSystemIDStartButton   matlab.ui.control.Button
        InductanceHEditField            matlab.ui.control.NumericEditField
        InductanceHEditFieldLabel       matlab.ui.control.Label
        ResistanceOhmEditField          matlab.ui.control.NumericEditField
        ResistanceOhmEditFieldLabel     matlab.ui.control.Label
        InputVoltageVEditField          matlab.ui.control.NumericEditField
        InputVoltageVEditFieldLabel     matlab.ui.control.Label
        ElecBode                        matlab.ui.control.UIAxes
        BEMFConstantinDiscretizedModelPanel  matlab.ui.container.Panel
        GridLayout53                    matlab.ui.container.GridLayout
        VelocityControllerGainSlider    matlab.ui.control.Slider
        VelocityControllerGainLabel     matlab.ui.control.Label
        BemfLabel                       matlab.ui.control.Label
        AngularVelocity                 matlab.ui.control.NumericEditField
        AngularVelocityradsMultipliedbygearratioEditFieldLabel  matlab.ui.control.Label
        BEMFConstantIDStartButton       matlab.ui.control.Button
        CurrentControllerDesignTab      matlab.ui.container.Tab
        CurrentControllerFrequencyResponsePanel  matlab.ui.container.Panel
        GridLayout99_2                  matlab.ui.container.GridLayout
        CurrentControllerBodePhase      matlab.ui.control.UIAxes
        CurrentControllerBodeMag        matlab.ui.control.UIAxes
        CurrentControllerDesignVerificationPanel  matlab.ui.container.Panel
        GridLayout99                    matlab.ui.container.GridLayout
        MeasurementLabel                matlab.ui.control.Label
        ReferenceLabel_3                matlab.ui.control.Label
        DesiredBW2                      matlab.ui.control.NumericEditField
        DesiredCurrnetControllerBandwidthHzEditFieldLabel  matlab.ui.control.Label
        CurrentControllerBandwidthCheckButton  matlab.ui.control.Button
        WaitforPlotLabel_4              matlab.ui.control.Label
        BW_PLOT                         matlab.ui.control.UIAxes
        MECHTab                         matlab.ui.container.Tab
        GridLayout25_4                  matlab.ui.container.GridLayout
        TabGroup2_4                     matlab.ui.container.TabGroup
        OverviewTab                     matlab.ui.container.Tab
        GridLayout118                   matlab.ui.container.GridLayout
        MidlevelControllerSaturationEditField  matlab.ui.control.NumericEditField
        MidlevelControllerSaturationEditFieldLabel  matlab.ui.control.Label
        ApplyButton_2                   matlab.ui.control.Button
        DisturbanceObserverCheckBox     matlab.ui.control.CheckBox
        PDControllerCheckBox            matlab.ui.control.CheckBox
        MechanicalImpedanceCompensatorCheckBox  matlab.ui.control.CheckBox
        ImpedanceControllerCheckBox     matlab.ui.control.CheckBox
        FeedforwardControllerCheckBox   matlab.ui.control.CheckBox
        Image9                          matlab.ui.control.Image
        AdvFrictionIDTab                matlab.ui.container.Tab
        GridLayout155                   matlab.ui.container.GridLayout
        FrictionCompensatorEvaluationPanel  matlab.ui.container.Panel
        GridLayout158_2                 matlab.ui.container.GridLayout
        IDStartwithCompFFButton         matlab.ui.control.Button
        PlotButton_3                    matlab.ui.control.Button
        IDStopButton_2                  matlab.ui.control.Button
        IDStartwithCompFBButton         matlab.ui.control.Button
        adv_friction_verify_plot_2      matlab.ui.control.UIAxes
        FrictionCompensatorVerificationPanel  matlab.ui.container.Panel
        GridLayout158                   matlab.ui.container.GridLayout
        FrictionCompensatorStopButton   matlab.ui.control.Button
        FrictionCompensatorStartButton  matlab.ui.control.Button
        adv_friction_verify_plot        matlab.ui.control.UIAxes
        FrictionIDResultPanel           matlab.ui.container.Panel
        GridLayout157                   matlab.ui.container.GridLayout
        FitModelDropDownLabel_2         matlab.ui.control.Label
        Fs_Field                        matlab.ui.control.NumericEditField
        Fc_Field                        matlab.ui.control.NumericEditField
        GainEditField                   matlab.ui.control.NumericEditField
        GainEditFieldLabel              matlab.ui.control.Label
        FitModelDropDown                matlab.ui.control.DropDown
        FitModelDropDownLabel           matlab.ui.control.Label
        DesiredDampingAsradEditField    matlab.ui.control.NumericEditField
        DesiredDampingAsradEditFieldLabel  matlab.ui.control.Label
        ApplyButton_5                   matlab.ui.control.Button
        EpsilonDeadZoneradsEditField    matlab.ui.control.NumericEditField
        EpsilonDeadZoneradsEditFieldLabel  matlab.ui.control.Label
        adv_friction_id_plot            matlab.ui.control.UIAxes
        FrictionIDSettingPanel          matlab.ui.container.Panel
        GridLayout156                   matlab.ui.container.GridLayout
        VelocityRefradsEditField        matlab.ui.control.NumericEditField
        VelocityRefradsEditFieldLabel   matlab.ui.control.Label
        ControlStopButton               matlab.ui.control.Button
        ControlStartButton              matlab.ui.control.Button
        IgainSliderLabel                matlab.ui.control.Label
        Igain_Slider                    matlab.ui.control.Slider
        ApplyButton_4                   matlab.ui.control.Button
        IDStopButton                    matlab.ui.control.Button
        IDStartButton                   matlab.ui.control.Button
        Pgain_Slider                    matlab.ui.control.Slider
        PgainLabel                      matlab.ui.control.Label
        CutTimesecEditField             matlab.ui.control.NumericEditField
        CutTimesecEditFieldLabel        matlab.ui.control.Label
        Vel2StepNumberEditField         matlab.ui.control.NumericEditField
        Vel2StepNumberEditFieldLabel    matlab.ui.control.Label
        MaxVel2radsEditField            matlab.ui.control.NumericEditField
        MaxVel2radsEditFieldLabel       matlab.ui.control.Label
        TimeperStepsecEditField         matlab.ui.control.NumericEditField
        TimeperStepsecEditFieldLabel    matlab.ui.control.Label
        Vel1StepNumberEditField         matlab.ui.control.NumericEditField
        Vel1StepNumberEditFieldLabel    matlab.ui.control.Label
        MaxVel1radsEditField            matlab.ui.control.NumericEditField
        MaxVel1radsEditFieldLabel       matlab.ui.control.Label
        friction_id_vel_ctrl_eval       matlab.ui.control.UIAxes
        SystemIDTab                     matlab.ui.container.Tab
        GridLayout90                    matlab.ui.container.GridLayout
        SystemIDResultPanel             matlab.ui.container.Panel
        GridLayout149                   matlab.ui.container.GridLayout
        Bode_Phase                      matlab.ui.control.UIAxes
        Bode_Magnitude                  matlab.ui.control.UIAxes
        Image8                          matlab.ui.control.Image
        VerificationPlotPanel_2         matlab.ui.container.Panel
        GridLayout92                    matlab.ui.container.GridLayout
        CurrentReferenceDropDown        matlab.ui.control.DropDown
        CurrentReferenceDropDownLabel   matlab.ui.control.Label
        Cur_Rec_Freq                    matlab.ui.control.NumericEditField
        FrequencyHzLabel                matlab.ui.control.Label
        Cur_Rec_Amp                     matlab.ui.control.NumericEditField
        MagnitudeALabel                 matlab.ui.control.Label
        sys_id_verification_StopButton  matlab.ui.control.Button
        sys_id_verification_StartButton  matlab.ui.control.Button
        system_id_verify_plot           matlab.ui.control.UIAxes
        FrequencySamplePropertiesSettingPanel  matlab.ui.container.Panel
        GridLayout91                    matlab.ui.container.GridLayout
        ApplyButton_6                   matlab.ui.control.StateButton
        custom_B                        matlab.ui.control.NumericEditField
        custom_J                        matlab.ui.control.NumericEditField
        MinimumFrequencyHzf_minEditField_2Label_2  matlab.ui.control.Label
        DampingBunitAsLabel             matlab.ui.control.Label
        InertiaJunitAs2Label            matlab.ui.control.Label
        AnalysisButton                  matlab.ui.control.Button
        StopIDButton                    matlab.ui.control.Button
        StartIDButton                   matlab.ui.control.Button
        FileSaveNametxtEditField        matlab.ui.control.EditField
        FileSaveNametxtEditFieldLabel   matlab.ui.control.Label
        MinimumFrequencyHzf_minEditField  matlab.ui.control.NumericEditField
        MinimumFrequencyHzf_minEditField_2Label  matlab.ui.control.Label
        MaximumFrequencyHzf_maxEditField  matlab.ui.control.NumericEditField
        MaximumFrequencyHzf_maxEditField_2Label  matlab.ui.control.Label
        NumberofFrequencySamplesN_samplesEditField  matlab.ui.control.NumericEditField
        NumberofFrequencySamplesN_samplesEditField_2Label  matlab.ui.control.Label
        NumberofIterationsN_iterEditField  matlab.ui.control.NumericEditField
        NumberofIterationsN_iterEditField_2Label  matlab.ui.control.Label
        InputMagnitudeAMEditField       matlab.ui.control.NumericEditField
        InputMagnitudeAMEditField_2Label  matlab.ui.control.Label
        InputOffsetAoffsetEditField     matlab.ui.control.NumericEditField
        InputOffsetAoffsetEditField_2Label  matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.StateButton
        VelocityEstimatorTab            matlab.ui.container.Tab
        GridLayout146                   matlab.ui.container.GridLayout
        Panel4                          matlab.ui.container.Panel
        GridLayout147                   matlab.ui.container.GridLayout
        VE_result_plot                  matlab.ui.control.UIAxes
        Panel3                          matlab.ui.container.Panel
        GridLayout148                   matlab.ui.container.GridLayout
        bofLeadLagEditField             matlab.ui.control.NumericEditField
        bofLeadLagEditFieldLabel        matlab.ui.control.Label
        aofLeadLagEditField             matlab.ui.control.NumericEditField
        aofLeadLagEditFieldLabel        matlab.ui.control.Label
        VE_StopButton                   matlab.ui.control.Button
        VE_StartButton                  matlab.ui.control.Button
        MaskingSizeEditField            matlab.ui.control.NumericEditField
        MaskingSizeEditFieldLabel       matlab.ui.control.Label
        VE_apply                        matlab.ui.control.Button
        EstimatorTypeDropDown           matlab.ui.control.DropDown
        EstimatorTypeDropDownLabel      matlab.ui.control.Label
        IRCTab                          matlab.ui.container.Tab
        GridLayout100                   matlab.ui.container.GridLayout
        ImpedanceReductionControllerVerificationPanel  matlab.ui.container.Panel
        GridLayout104                   matlab.ui.container.GridLayout
        GridLayout175                   matlab.ui.container.GridLayout
        IRC_Verification_Plot           matlab.ui.control.UIAxes
        GridLayout106                   matlab.ui.container.GridLayout
        FrictionCompensatorONCheckBox   matlab.ui.control.CheckBox
        IRCStopButton                   matlab.ui.control.Button
        IRCStartButton                  matlab.ui.control.Button
        FileNametxtEditField            matlab.ui.control.EditField
        FileNametxtEditFieldLabel       matlab.ui.control.Label
        ImpedanceReductionControllerDesignPanel  matlab.ui.container.Panel
        GridLayout103                   matlab.ui.container.GridLayout
        GridLayout117                   matlab.ui.container.GridLayout
        IRCFilterMainFilterSubFilterLabel  matlab.ui.control.Label
        IRCSaturationAEditField         matlab.ui.control.NumericEditField
        IRCSaturationAEditFieldLabel    matlab.ui.control.Label
        SubFilter1storderlowpassPanel   matlab.ui.container.Panel
        GridLayout122                   matlab.ui.container.GridLayout
        UsesubfilterCheckBox            matlab.ui.control.CheckBox
        CutoffHzEditField_2             matlab.ui.control.NumericEditField
        CutoffHzEditField_2Label        matlab.ui.control.Label
        MainFilterPanel                 matlab.ui.container.Panel
        GridLayout121                   matlab.ui.container.GridLayout
        CutoffHzEditField               matlab.ui.control.NumericEditField
        CutoffHzEditFieldLabel          matlab.ui.control.Label
        LowPassFilterTypeDropDown       matlab.ui.control.DropDown
        LowPassFilterTypeDropDownLabel  matlab.ui.control.Label
        OrderDropDown                   matlab.ui.control.DropDown
        OrderDropDownLabel              matlab.ui.control.Label
        IRC_apply_button                matlab.ui.control.Button
        ConversionPlotButton            matlab.ui.control.Button
        ApproximationDropDown           matlab.ui.control.DropDown
        ApproximationDropDownLabel      matlab.ui.control.Label
        InertiaJunitAs2Label_2          matlab.ui.control.Label
        DampingBunitAsLabel_2           matlab.ui.control.Label
        QvalueEditField                 matlab.ui.control.NumericEditField
        QvalueEditFieldLabel            matlab.ui.control.Label
        GridLayout112                   matlab.ui.container.GridLayout
        CsIRCControllerLabel            matlab.ui.control.Label
        FsIRCFilterLabel                matlab.ui.control.Label
        GsPlantInverseLabel             matlab.ui.control.Label
        IRC_Bode_Mag                    matlab.ui.control.UIAxes
        IRC_Bode_Phase                  matlab.ui.control.UIAxes
        ImpedanceControllerTab          matlab.ui.container.Tab
        GridLayout123                   matlab.ui.container.GridLayout
        ImpedanceParameterExplanationPanel  matlab.ui.container.Panel
        GridLayout125                   matlab.ui.container.GridLayout
        Image13                         matlab.ui.control.Image
        ImpedanceControllerVerificationPanel  matlab.ui.container.Panel
        GridLayout127                   matlab.ui.container.GridLayout
        ActualLabel_3                   matlab.ui.control.Label
        ReferenceLabel_2                matlab.ui.control.Label
        TabGroup9                       matlab.ui.container.TabGroup
        SinusoidalTab_3                 matlab.ui.container.Tab
        GridLayout129                   matlab.ui.container.GridLayout
        FrictionCompensatorONCheckBox_2  matlab.ui.control.CheckBox
        imp_verify_sine_stop            matlab.ui.control.Button
        imp_verify_sine_start           matlab.ui.control.Button
        IMP_Sine_Frequency              matlab.ui.control.NumericEditField
        FrequencyHzEditField_7Label     matlab.ui.control.Label
        IMP_Sine_Magnitude              matlab.ui.control.NumericEditField
        MagnituderadEditFieldLabel      matlab.ui.control.Label
        imp_verify_input_plot           matlab.ui.control.UIAxes
        imp_verify_position_plot        matlab.ui.control.UIAxes
        ImpedanceControllerGainSettingPanel  matlab.ui.container.Panel
        GridLayout124                   matlab.ui.container.GridLayout
        ImpedanceControlModeDropDown    matlab.ui.control.DropDown
        ImpedanceControlModeDropDownLabel  matlab.ui.control.Label
        ImpedanceControllerApplyButton_2  matlab.ui.control.Button
        ActualKd100ADegsEditField       matlab.ui.control.NumericEditField
        ActualKd100ADegsEditFieldLabel  matlab.ui.control.Label
        ActualKp100ADegEditField        matlab.ui.control.NumericEditField
        ActualKp100ADegEditFieldLabel   matlab.ui.control.Label
        TransitionTimemsEditField       matlab.ui.control.NumericEditField
        TransitionTimemsEditFieldLabel  matlab.ui.control.Label
        ImpedanceRatioinCorridor0255EditField  matlab.ui.control.NumericEditField
        ImpedanceRatioinCorridor0255EditFieldLabel  matlab.ui.control.Label
        CorridorHalfWidthDegEditField   matlab.ui.control.NumericEditField
        CorridorHalfWidthDegEditFieldLabel  matlab.ui.control.Label
        ImpedanceControllerApplyButton  matlab.ui.control.Button
        VirtualDampingEditField         matlab.ui.control.NumericEditField
        VirtualDampingLabel             matlab.ui.control.Label
        VirtualStiffnessEditField       matlab.ui.control.NumericEditField
        VirtualStiffnessLabel           matlab.ui.control.Label
        DOBTab                          matlab.ui.container.Tab
        GridLayout89                    matlab.ui.container.GridLayout
        DObVerificationPanel            matlab.ui.container.Panel
        GridLayout133                   matlab.ui.container.GridLayout
        DgainDOb                        matlab.ui.control.NumericEditField
        DgainImpedanceControllerEditFieldLabel  matlab.ui.control.Label
        PgainDOb                        matlab.ui.control.NumericEditField
        PgainImpedanceControllerEditFieldLabel  matlab.ui.control.Label
        DoB_test_AmplitudedegEditField  matlab.ui.control.NumericEditField
        AmplituderadLabel               matlab.ui.control.Label
        DoB_test_FrequencyHzEditField   matlab.ui.control.NumericEditField
        FrequencyHzEditField_3Label_3   matlab.ui.control.Label
        DoB_test_StopButton             matlab.ui.control.Button
        DoB_test_StartButton            matlab.ui.control.Button
        FileSaveNametxtEditField_2      matlab.ui.control.EditField
        FileSaveNametxtEditField_2Label  matlab.ui.control.Label
        DoB_test_plot1                  matlab.ui.control.UIAxes
        DoB_test_plot2                  matlab.ui.control.UIAxes
        DObDesignPanel                  matlab.ui.container.Panel
        GridLayout131                   matlab.ui.container.GridLayout
        ReferenceDropDown_2             matlab.ui.control.DropDown
        ReferenceDropDown_2Label        matlab.ui.control.Label
        EstimationLabel_2               matlab.ui.control.Label
        ActualLabel_2                   matlab.ui.control.Label
        dob_verify_freq                 matlab.ui.control.NumericEditField
        FrequencyHzLabel_2              matlab.ui.control.Label
        dob_verify_amp                  matlab.ui.control.NumericEditField
        MagnitudeALabel_2               matlab.ui.control.Label
        dob_verification_stop           matlab.ui.control.Button
        dob_verification_start          matlab.ui.control.Button
        SaturationAEditField_3          matlab.ui.control.NumericEditField
        SaturationAEditField_3Label     matlab.ui.control.Label
        InertiaJunitAs2Label_3          matlab.ui.control.Label
        DampingBunitAsLabel_3           matlab.ui.control.Label
        DoB_Qfilter_ApplyButton         matlab.ui.control.Button
        QFilterCutoffHzEditField        matlab.ui.control.NumericEditField
        InputOffsetAoffsetEditField_2Label_2  matlab.ui.control.Label
        GinvQ_phase_plot                matlab.ui.control.UIAxes
        GinvQ_mag_plot                  matlab.ui.control.UIAxes
        dob_evaluation_plot             matlab.ui.control.UIAxes
        PDControllerTab                 matlab.ui.container.Tab
        GridLayout58                    matlab.ui.container.GridLayout
        PositionControlPanel            matlab.ui.container.Panel
        GridLayout68                    matlab.ui.container.GridLayout
        TabGroup5                       matlab.ui.container.TabGroup
        StepTab_2                       matlab.ui.container.Tab
        GridLayout75                    matlab.ui.container.GridLayout
        pos_ref                         matlab.ui.control.NumericEditField
        ReferenceLabel                  matlab.ui.control.Label
        pos_step_stop                   matlab.ui.control.Button
        pos_step_start                  matlab.ui.control.Button
        SinusoidalTab_2                 matlab.ui.container.Tab
        GridLayout74                    matlab.ui.container.GridLayout
        FrequencyHzEditField_6          matlab.ui.control.NumericEditField
        FrequencyHzEditField_6Label     matlab.ui.control.Label
        AmplituderadEditField_4         matlab.ui.control.NumericEditField
        AmplituderadEditField_4Label    matlab.ui.control.Label
        pos_sine_stop                   matlab.ui.control.Button
        pos_sine_start                  matlab.ui.control.Button
        RectangleTab_2                  matlab.ui.container.Tab
        GridLayout74_2                  matlab.ui.container.GridLayout
        Pos_Rec_Freq                    matlab.ui.control.NumericEditField
        FrequencyHzEditField_4Label     matlab.ui.control.Label
        Pos_Rec_Amp                     matlab.ui.control.NumericEditField
        AmplituderadEditField_4Label_2  matlab.ui.control.Label
        pos_rec_stop                    matlab.ui.control.Button
        pos_rec_start                   matlab.ui.control.Button
        pos_ctrl_plot                   matlab.ui.control.UIAxes
        VelocityControlPanel            matlab.ui.container.Panel
        GridLayout67                    matlab.ui.container.GridLayout
        TabGroup4                       matlab.ui.container.TabGroup
        StepTab                         matlab.ui.container.Tab
        GridLayout72                    matlab.ui.container.GridLayout
        vel_step_start                  matlab.ui.control.Button
        vel_step_stop                   matlab.ui.control.Button
        vel_ref                         matlab.ui.control.NumericEditField
        StepReferenceLabel              matlab.ui.control.Label
        SinusoidalTab                   matlab.ui.container.Tab
        GridLayout73                    matlab.ui.container.GridLayout
        FrequencyHzEditField            matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        AmplituderadEditField           matlab.ui.control.NumericEditField
        AmplituderadEditFieldLabel      matlab.ui.control.Label
        vel_sine_stop                   matlab.ui.control.Button
        vel_sine_start                  matlab.ui.control.Button
        RectangleTab                    matlab.ui.container.Tab
        GridLayout73_2                  matlab.ui.container.GridLayout
        RecFrequency                    matlab.ui.control.NumericEditField
        FrequencyHzEditField_3Label_5   matlab.ui.control.Label
        RecAmplitude                    matlab.ui.control.NumericEditField
        AmplituderadEditField_3Label_3  matlab.ui.control.Label
        vel_rec_stop                    matlab.ui.control.Button
        vel_rec_start                   matlab.ui.control.Button
        vel_ctrl_plot                   matlab.ui.control.UIAxes
        GainTunnerPanel                 matlab.ui.container.Panel
        GridLayout60                    matlab.ui.container.GridLayout
        GridLayout62                    matlab.ui.container.GridLayout
        PositionControllerPDControllerExpertPanel  matlab.ui.container.Panel
        GridLayout65_2                  matlab.ui.container.GridLayout
        SetGainButton                   matlab.ui.control.Button
        DGainEditField                  matlab.ui.control.NumericEditField
        DGainEditFieldLabel             matlab.ui.control.Label
        PGainEditField                  matlab.ui.control.NumericEditField
        PGainEditFieldLabel             matlab.ui.control.Label
        PositionControllerPDControllerPanel  matlab.ui.container.Panel
        GridLayout65                    matlab.ui.container.GridLayout
        Pos_D_Gain                      matlab.ui.control.Label
        Pos_P_Gain                      matlab.ui.control.Label
        VelocityControllerPIControllerPanel  matlab.ui.container.Panel
        GridLayout64_6                  matlab.ui.container.GridLayout
        Vel_I_Gain                      matlab.ui.control.Label
        Vel_P_Gain                      matlab.ui.control.Label
        GridLayout63                    matlab.ui.container.GridLayout
        Pos_Input_Penalty               matlab.ui.control.NumericEditField
        PositionInputPenaltyEditFieldLabel  matlab.ui.control.Label
        VelocityBandwidthHzEditField    matlab.ui.control.NumericEditField
        VelocityBandwidthHzEditFieldLabel  matlab.ui.control.Label
        GainTune                        matlab.ui.control.Button
        FrequencyResponseofClosedloopSystemPanel_2  matlab.ui.container.Panel
        GridLayout130                   matlab.ui.container.GridLayout
        closed_loop_phase2              matlab.ui.control.UIAxes
        closed_loop_mag2                matlab.ui.control.UIAxes
        FeedforwardTab                  matlab.ui.container.Tab
        GridLayout141                   matlab.ui.container.GridLayout
        FeedforwardforPDControllerPanel  matlab.ui.container.Panel
        GridLayout142                   matlab.ui.container.GridLayout
        GridLayout144                   matlab.ui.container.GridLayout
        FF_StopButton                   matlab.ui.control.Button
        FF_StartButton                  matlab.ui.control.Button
        SineMagnitudeEditField          matlab.ui.control.NumericEditField
        SineMagnitudeEditFieldLabel     matlab.ui.control.Label
        SineFrequencyEditField          matlab.ui.control.NumericEditField
        SineFrequencyEditFieldLabel     matlab.ui.control.Label
        ff_plot                         matlab.ui.control.UIAxes
        FeedforwardControllerPanel      matlab.ui.container.Panel
        GridLayout143                   matlab.ui.container.GridLayout
        GridLayout150                   matlab.ui.container.GridLayout
        ff_phase_plot                   matlab.ui.control.UIAxes
        ff_mag_plot                     matlab.ui.control.UIAxes
        GridLayout145                   matlab.ui.container.GridLayout
        FeedforwardDesignOnforImpedanceControllerButton  matlab.ui.control.Button
        DampingBunitAsLabel_5           matlab.ui.control.Label
        InertiaJunitAs2Label_5          matlab.ui.control.Label
        VerificationTab                 matlab.ui.container.Tab
        GridLayout118_2                 matlab.ui.container.GridLayout
        Panel5                          matlab.ui.container.Panel
        GridLayout154                   matlab.ui.container.GridLayout
        PDControllerCheckBox_2          matlab.ui.control.CheckBox
        ImpedanceControllerCheckBox_2   matlab.ui.control.CheckBox
        DisturbanceObserverCheckBox_2   matlab.ui.control.CheckBox
        MechanicalImpedanceCompensatorCheckBox_2  matlab.ui.control.CheckBox
        FeedforwardControllerCheckBox_2  matlab.ui.control.CheckBox
        Image9_2                        matlab.ui.control.Image
        MidlevelControllerSaturationEditField_2  matlab.ui.control.NumericEditField
        MidlevelControllerSaturationEditField_2Label  matlab.ui.control.Label
        ApplyButton_3                   matlab.ui.control.Button
        FrequencyHzEditField_7          matlab.ui.control.NumericEditField
        FrequencyHzEditField_7Label_2   matlab.ui.control.Label
        AmplituderadEditField_5         matlab.ui.control.NumericEditField
        AmplituderadEditField_5Label    matlab.ui.control.Label
        Plot2Panel                      matlab.ui.container.Panel
        GridLayout153                   matlab.ui.container.GridLayout
        DisturbanceCheckBox_2           matlab.ui.control.CheckBox
        MidCtrlInputCheckBox_2          matlab.ui.control.CheckBox
        ErrorCheckBox_2                 matlab.ui.control.CheckBox
        VelocityCheckBox_2              matlab.ui.control.CheckBox
        PositionCheckBox_2              matlab.ui.control.CheckBox
        ReferenceCheckBox_2             matlab.ui.control.CheckBox
        PlotButton_2                    matlab.ui.control.Button
        total_plot2                     matlab.ui.control.UIAxes
        TotalVerification_StopButton    matlab.ui.control.Button
        TotalVerification_StartButton   matlab.ui.control.Button
        Plot1Panel                      matlab.ui.container.Panel
        GridLayout152                   matlab.ui.container.GridLayout
        DisturbanceCheckBox             matlab.ui.control.CheckBox
        MidCtrlInputCheckBox            matlab.ui.control.CheckBox
        ErrorCheckBox                   matlab.ui.control.CheckBox
        VelocityCheckBox                matlab.ui.control.CheckBox
        PositionCheckBox                matlab.ui.control.CheckBox
        ReferenceCheckBox               matlab.ui.control.CheckBox
        PlotButton                      matlab.ui.control.Button
        total_plot1                     matlab.ui.control.UIAxes
        AdditionalFunctionTab           matlab.ui.container.Tab
        GridLayout119                   matlab.ui.container.GridLayout
        TabGroup7                       matlab.ui.container.TabGroup
        VSDTab                          matlab.ui.container.Tab
        GridLayout137                   matlab.ui.container.GridLayout
        VirtualSpringDamperConceptPanel  matlab.ui.container.Panel
        GridLayout140                   matlab.ui.container.GridLayout
        Image10                         matlab.ui.control.Image
        VerificationPlotPanel           matlab.ui.container.Panel
        GridLayout138                   matlab.ui.container.GridLayout
        vsd_input_plot                  matlab.ui.control.UIAxes
        vsd_abs_position_plot           matlab.ui.control.UIAxes
        VirtualSpringDamperParametersPanel  matlab.ui.container.Panel
        GridLayout139                   matlab.ui.container.GridLayout
        UpperStiffRangedegEditField     matlab.ui.control.NumericEditField
        UpperStiffRangedegEditFieldLabel  matlab.ui.control.Label
        LowerStiffRangedegEditField     matlab.ui.control.NumericEditField
        LowerStiffRangedegEditFieldLabel  matlab.ui.control.Label
        VSD_SaturationSetBtn            matlab.ui.control.Button
        SaturationAEditField_2          matlab.ui.control.NumericEditField
        SaturationAEditField_2Label     matlab.ui.control.Label
        VSD_StopBtn                     matlab.ui.control.Button
        VSD_StartBtn                    matlab.ui.control.Button
        VSD_UpperLimit_SetBtn           matlab.ui.control.Button
        VSD_LowerLimit_SetBtn           matlab.ui.control.Button
        VSD_applyBtn                    matlab.ui.control.Button
        UpperLimitdegEditField          matlab.ui.control.NumericEditField
        UpperLimitdegEditFieldLabel     matlab.ui.control.Label
        LowerLimitdegEditField          matlab.ui.control.NumericEditField
        LowerLimitdegEditFieldLabel     matlab.ui.control.Label
        UpperDampedRangedegEditField    matlab.ui.control.NumericEditField
        UpperDampedRangedegEditFieldLabel  matlab.ui.control.Label
        LowerDampedRangedegEditField    matlab.ui.control.NumericEditField
        LowerDampedRangedegEditFieldLabel  matlab.ui.control.Label
        UpperDamperAsdegEditField       matlab.ui.control.NumericEditField
        UpperDamperAsdegEditFieldLabel  matlab.ui.control.Label
        LowerDamperAsdegEditField       matlab.ui.control.NumericEditField
        LowerDamperAsdegEditFieldLabel  matlab.ui.control.Label
        UpperStiffnessAdegEditField     matlab.ui.control.NumericEditField
        UpperStiffnessAdegEditFieldLabel  matlab.ui.control.Label
        LowerStiffnessAdegEditField     matlab.ui.control.NumericEditField
        LowerStiffnessAdegEditFieldLabel  matlab.ui.control.Label
        BacklashTab                     matlab.ui.container.Tab
        GridLayout120                   matlab.ui.container.GridLayout
        PositionSineReferenceSettingPanel  matlab.ui.container.Panel
        GridLayout83_3                  matlab.ui.container.GridLayout
        BacklashTest_StopButton         matlab.ui.control.Button
        BacklashTest_StartButton        matlab.ui.control.Button
        MaxBacklashdegEditField         matlab.ui.control.NumericEditField
        MaxBacklashdegLabel             matlab.ui.control.Label
        Backlash_test_plot              matlab.ui.control.UIAxes
        Image7                          matlab.ui.control.Image
        PFITestTab                      matlab.ui.container.Tab
        GridLayout163                   matlab.ui.container.GridLayout
        GridLayout164                   matlab.ui.container.GridLayout
        SetPVectorPanel                 matlab.ui.container.Panel
        GridLayout165_3                 matlab.ui.container.GridLayout
        YddegEditFieldLabel_2           matlab.ui.control.Label
        P_Vector_L6                     matlab.ui.control.NumericEditField
        P_Vector_L5                     matlab.ui.control.NumericEditField
        P_Vector_L4                     matlab.ui.control.NumericEditField
        P_Vector_L3                     matlab.ui.control.NumericEditField
        P_Vector_L2                     matlab.ui.control.NumericEditField
        P_Vector_L1                     matlab.ui.control.NumericEditField
        P_Vector_Ev_Controller_ON       matlab.ui.control.CheckBox
        ModeIDXEditFieldLabel_13        matlab.ui.control.Label
        ModeIDXEditFieldLabel_12        matlab.ui.control.Label
        ModeIDXEditFieldLabel_11        matlab.ui.control.Label
        P_Vector_Checkbox6              matlab.ui.control.CheckBox
        P_Vector_Checkbox5              matlab.ui.control.CheckBox
        P_Vector_Checkbox4              matlab.ui.control.CheckBox
        P_Vector_Checkbox3              matlab.ui.control.CheckBox
        P_Vector_Sd6                    matlab.ui.control.NumericEditField
        P_Vector_S06                    matlab.ui.control.NumericEditField
        P_Vector_Yd6                    matlab.ui.control.NumericEditField
        P_Vector_Sd5                    matlab.ui.control.NumericEditField
        P_Vector_S05                    matlab.ui.control.NumericEditField
        P_Vector_Yd5                    matlab.ui.control.NumericEditField
        P_Vector_Sd4                    matlab.ui.control.NumericEditField
        P_Vector_S04                    matlab.ui.control.NumericEditField
        P_Vector_Yd4                    matlab.ui.control.NumericEditField
        ModeIDXEditFieldLabel_10        matlab.ui.control.Label
        P_Vector_Checkbox2              matlab.ui.control.CheckBox
        P_Vector_Sd3                    matlab.ui.control.NumericEditField
        P_Vector_S03                    matlab.ui.control.NumericEditField
        P_Vector_Yd3                    matlab.ui.control.NumericEditField
        P_Vector_Sd2                    matlab.ui.control.NumericEditField
        P_Vector_S02                    matlab.ui.control.NumericEditField
        P_Vector_Yd2                    matlab.ui.control.NumericEditField
        P_Vector_Checkbox1              matlab.ui.control.CheckBox
        ModeIDXEditFieldLabel_9         matlab.ui.control.Label
        ModeIDXEditFieldLabel_8         matlab.ui.control.Label
        P_Vector_Send                   matlab.ui.control.StateButton
        P_Vector_Ev_Stop                matlab.ui.control.Button
        P_Vector_Ev_Start               matlab.ui.control.Button
        P_Vector_Sd1                    matlab.ui.control.NumericEditField
        SdEditFieldLabel                matlab.ui.control.Label
        P_Vector_S01                    matlab.ui.control.NumericEditField
        S0EditFieldLabel                matlab.ui.control.Label
        P_Vector_Yd1                    matlab.ui.control.NumericEditField
        YddegEditFieldLabel             matlab.ui.control.Label
        PvectorPlot                     matlab.ui.control.UIAxes
        SetFVectorPanel                 matlab.ui.container.Panel
        GridLayout165_2                 matlab.ui.container.GridLayout
        F_Vector_Ev_Controller_ON       matlab.ui.control.CheckBox
        ModeIDXEditFieldLabel_7         matlab.ui.control.Label
        ModeIDXEditFieldLabel_6         matlab.ui.control.Label
        ModeIDXEditFieldLabel_5         matlab.ui.control.Label
        CheckBox_6                      matlab.ui.control.CheckBox
        CheckBox_5                      matlab.ui.control.CheckBox
        CheckBox_4                      matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        DelayEditField_6                matlab.ui.control.NumericEditField
        MaxTorqueAEditField_6           matlab.ui.control.NumericEditField
        ModeIDXEditField_6              matlab.ui.control.NumericEditField
        DelayEditField_5                matlab.ui.control.NumericEditField
        MaxTorqueAEditField_5           matlab.ui.control.NumericEditField
        ModeIDXEditField_5              matlab.ui.control.NumericEditField
        DelayEditField_4                matlab.ui.control.NumericEditField
        MaxTorqueAEditField_4           matlab.ui.control.NumericEditField
        ModeIDXEditField_4              matlab.ui.control.NumericEditField
        ModeIDXEditFieldLabel_4         matlab.ui.control.Label
        CheckBox_2                      matlab.ui.control.CheckBox
        DelayEditField_3                matlab.ui.control.NumericEditField
        MaxTorqueAEditField_3           matlab.ui.control.NumericEditField
        ModeIDXEditField_3              matlab.ui.control.NumericEditField
        DelayEditField_2                matlab.ui.control.NumericEditField
        MaxTorqueAEditField_2           matlab.ui.control.NumericEditField
        ModeIDXEditField_2              matlab.ui.control.NumericEditField
        CheckBox                        matlab.ui.control.CheckBox
        ModeIDXEditFieldLabel_3         matlab.ui.control.Label
        ModeIDXEditFieldLabel_2         matlab.ui.control.Label
        F_Vector_Send                   matlab.ui.control.StateButton
        F_Vector_Ev_Stop                matlab.ui.control.Button
        F_Vector_Ev_Start               matlab.ui.control.Button
        DelaymsEditField                matlab.ui.control.NumericEditField
        DelaymsEditFieldLabel           matlab.ui.control.Label
        MaxTorqueAEditField             matlab.ui.control.NumericEditField
        MaxTorqueALabel                 matlab.ui.control.Label
        ModeIDXEditField                matlab.ui.control.NumericEditField
        ModeIDXEditFieldLabel           matlab.ui.control.Label
        FvectorPlot                     matlab.ui.control.UIAxes
        SetIVectorPanel                 matlab.ui.container.Panel
        GridLayout165                   matlab.ui.container.GridLayout
        LmsEditField6                   matlab.ui.control.NumericEditField
        lambdaEditField6                matlab.ui.control.NumericEditField
        KdEditField6                    matlab.ui.control.NumericEditField
        KpEditField6                    matlab.ui.control.NumericEditField
        HalfWidthdegEditField6          matlab.ui.control.NumericEditField
        LmsEditField5                   matlab.ui.control.NumericEditField
        lambdaEditField5                matlab.ui.control.NumericEditField
        KdEditField5                    matlab.ui.control.NumericEditField
        KpEditField5                    matlab.ui.control.NumericEditField
        HalfWidthdegEditField5          matlab.ui.control.NumericEditField
        LmsEditField4                   matlab.ui.control.NumericEditField
        lambdaEditField4                matlab.ui.control.NumericEditField
        KdEditField4                    matlab.ui.control.NumericEditField
        KpEditField4                    matlab.ui.control.NumericEditField
        HalfWidthdegEditField4          matlab.ui.control.NumericEditField
        LmsEditField3                   matlab.ui.control.NumericEditField
        lambdaEditField3                matlab.ui.control.NumericEditField
        KdEditField3                    matlab.ui.control.NumericEditField
        KpEditField3                    matlab.ui.control.NumericEditField
        HalfWidthdegEditField3          matlab.ui.control.NumericEditField
        LmsEditField2                   matlab.ui.control.NumericEditField
        lambdaEditField2                matlab.ui.control.NumericEditField
        KdEditField2                    matlab.ui.control.NumericEditField
        KpEditField2                    matlab.ui.control.NumericEditField
        HalfWidthdegEditField2          matlab.ui.control.NumericEditField
        I_Vector_Checkbox6              matlab.ui.control.CheckBox
        I_Vector_Checkbox5              matlab.ui.control.CheckBox
        I_Vector_Checkbox4              matlab.ui.control.CheckBox
        I_Vector_Checkbox3              matlab.ui.control.CheckBox
        I_Vector_Checkbox2              matlab.ui.control.CheckBox
        I_Vector_Checkbox1              matlab.ui.control.CheckBox
        ModeIDXEditFieldLabel_19        matlab.ui.control.Label
        ModeIDXEditFieldLabel_18        matlab.ui.control.Label
        ModeIDXEditFieldLabel_17        matlab.ui.control.Label
        ModeIDXEditFieldLabel_16        matlab.ui.control.Label
        ModeIDXEditFieldLabel_15        matlab.ui.control.Label
        ModeIDXEditFieldLabel_14        matlab.ui.control.Label
        Impedance_Ctrl_Stop             matlab.ui.control.Button
        Impedance_Ctrl_Start            matlab.ui.control.Button
        Impedance_Setting               matlab.ui.control.StateButton
        LmsEditField1                   matlab.ui.control.NumericEditField
        LmsEditFieldLabel               matlab.ui.control.Label
        lambdaEditField1                matlab.ui.control.NumericEditField
        lambdaEditFieldLabel            matlab.ui.control.Label
        KdEditField1                    matlab.ui.control.NumericEditField
        KdLabel                         matlab.ui.control.Label
        KpEditField1                    matlab.ui.control.NumericEditField
        KpLabel                         matlab.ui.control.Label
        HalfWidthdegEditField1          matlab.ui.control.NumericEditField
        CorridorHalfWidthdegLabel       matlab.ui.control.Label
        IvectorPlot                     matlab.ui.control.UIAxes
        WIDMTab                         matlab.ui.container.Tab
        GridLayout159                   matlab.ui.container.GridLayout
        GridLayout160                   matlab.ui.container.GridLayout
        GetGaitPhasePanel               matlab.ui.container.Panel
        GridLayout162                   matlab.ui.container.GridLayout
        DEG_TVCFCheckBox                matlab.ui.control.CheckBox
        DEG_GYR_FCheckBox               matlab.ui.control.CheckBox
        DEG_ACC_FCheckBox               matlab.ui.control.CheckBox
        DEG_GYRCheckBox                 matlab.ui.control.CheckBox
        DEG_ACCCheckBox                 matlab.ui.control.CheckBox
        GaitPhase_Stop                  matlab.ui.control.Button
        GaitPhase_Start                 matlab.ui.control.Button
        Degree_Plot                     matlab.ui.control.UIAxes
        GaitPhase_Plot                  matlab.ui.control.UIAxes
        GetACCGYROPanel                 matlab.ui.container.Panel
        GridLayout161                   matlab.ui.container.GridLayout
        Get_ACCGYR_Stop                 matlab.ui.control.Button
        Get_ACCGYR_Start                matlab.ui.control.Button
        GYR_Plot                        matlab.ui.control.UIAxes
        ACC_Plot                        matlab.ui.control.UIAxes
        QuaternionTab                   matlab.ui.container.Tab
        GridLayout160_2                 matlab.ui.container.GridLayout
        GetACCGYROMAGPanel              matlab.ui.container.Panel
        GridLayout161_2                 matlab.ui.container.GridLayout
        FileSaveNametxtEditField_3      matlab.ui.control.EditField
        FileSaveNametxtEditField_3Label  matlab.ui.control.Label
        ApplyButton_7                   matlab.ui.control.Button
        Get_ACCGYR_Stop_2               matlab.ui.control.Button
        Get_ACCGYR_Start_2              matlab.ui.control.Button
        Plot_Quaternion_Mag             matlab.ui.control.UIAxes
        Plot_Quaternion                 matlab.ui.control.UIAxes
        Plot_Quaternion_Gyro            matlab.ui.control.UIAxes
        Plot_Quaternion_Accel           matlab.ui.control.UIAxes
        ElecAngleDebuggingTab           matlab.ui.container.Tab
        HallSensorvsIncEncoderLabel     matlab.ui.control.Label
        pos_sine_stop_3                 matlab.ui.control.Button
        pos_sine_start_3                matlab.ui.control.Button
        FrequencyHzEditField_8          matlab.ui.control.NumericEditField
        FrequencyHzEditField_8Label     matlab.ui.control.Label
        AmplituderadEditField_6         matlab.ui.control.NumericEditField
        AmplituderadEditField_6Label    matlab.ui.control.Label
        pos_sine_stop_2                 matlab.ui.control.Button
        pos_sine_start_2                matlab.ui.control.Button
        LinkLUTConverterTab             matlab.ui.container.Tab
        pos_sine_stop_4                 matlab.ui.control.Button
        pos_sine_start_4                matlab.ui.control.Button
        Link_Conversion_Plot            matlab.ui.control.UIAxes
        MagnetometerCalibrationTab      matlab.ui.container.Tab
        GridLayout166                   matlab.ui.container.GridLayout
        SetMagnetometerMatrixPanel      matlab.ui.container.Panel
        GridLayout167                   matlab.ui.container.GridLayout
        mag_calib_apply                 matlab.ui.control.StateButton
        B1                              matlab.ui.control.NumericEditField
        B2                              matlab.ui.control.NumericEditField
        B3                              matlab.ui.control.NumericEditField
        B_matrix                        matlab.ui.control.Label
        A31                             matlab.ui.control.NumericEditField
        A33                             matlab.ui.control.NumericEditField
        A32                             matlab.ui.control.NumericEditField
        A23                             matlab.ui.control.NumericEditField
        A22                             matlab.ui.control.NumericEditField
        A13                             matlab.ui.control.NumericEditField
        A21                             matlab.ui.control.NumericEditField
        A12                             matlab.ui.control.NumericEditField
        A11                             matlab.ui.control.NumericEditField
        A11Label                        matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        CommunicationPort               matlab.ui.control.DropDown
        PORTDropDown_2Label             matlab.ui.control.Label
        GridLayout5                     matlab.ui.container.GridLayout
        Image12                         matlab.ui.control.Image
        Image11                         matlab.ui.control.Image
        system_id_verify_plot_2         matlab.ui.control.UIAxes
        adv_friction_verify_plot_3      matlab.ui.control.UIAxes
    end

 
    %% PUBLIC PROPERTIES (MOTOR)
    properties (Access = public)

        %% MSG FNC CODE
        EMCY            = uint16(0x000);
        SYNC            = uint16(0x100);
        SDO             = uint16(0x200);
        PDO             = uint16(0x300);
        TIMEOUT         = uint16(0x400);
        GUI_SYNC        = uint16(0x500);

        %% MSG FNC CODE
        EMCY_SEND            = uint8(0x00);
        SYNC_SEND            = uint8(0x01);
        SDO_SEND             = uint8(0x02);
        PDO_SEND             = uint8(0x03);
        TIMEOUT_SEND         = uint8(0x04);
        GUI_SYNC_SEND        = uint8(0x05);

        %% Node ID
        NODE_ID_ALL	= uint8(0x00);
        NODE_ID_CM	= uint8(0x10);
        NODE_ID_LH	= uint8(0x20);
        NODE_ID_RH	= uint8(0x30);
        NODE_ID_LK	= uint8(0x40);
        NODE_ID_RK	= uint8(0x50);
        NODE_ID_LA	= uint8(0x60);
        NODE_ID_RA	= uint8(0x70);

        %% TASK ID
        TASK_ID_LOWLEVEL    = 0x00;
        TASK_ID_MIDLEVEL    = 0x01;
    	TASK_ID_MSG         = 0x02;
    	TASK_ID_IMU         = 0x03;
    	TASK_ID_SYSMNGT     = 0x04;
    	TASK_ID_EXTDEV      = 0x05;

        %% SDO ID
        %--------------------LOW LEVEL SDO----------------------%
    	SDO_ID_LOWLEVEL_GET_STATE                   =0x00;
	    SDO_ID_LOWLEVEL_SET_STATE                   =0x01;
	    SDO_ID_LOWLEVEL_GET_ROUTINE                 =0x02;
	    SDO_ID_LOWLEVEL_SET_ROUTINE                 =0x03;
	    SDO_ID_LOWLEVEL_NAME                        =0x04;
	    SDO_ID_LOWLEVEL_POLE_PAIR                   =0x05;
	    SDO_ID_LOWLEVEL_ENCODER_RESOLUTION          =0x06;
        SDO_ID_LOWLEVEL_GEAR_RATIO                  =0x07;
        SDO_ID_LOWLEVEL_TORQUE_CONSTANT             =0x08;
        SDO_ID_LOWLEVEL_VELOCITY_CONSTANT           =0x09;
	    SDO_ID_LOWLEVEL_PEAK_CURRENT_LIMIT          =0x0A;
	    SDO_ID_LOWLEVEL_CONTINUOUS_CURRENT_LIMIT    =0x0B;
        SDO_ID_LOWLEVEL_MAX_VELOCITY                =0x0C;
        SDO_ID_LOWLEVEL_COMMUTATION_DUTY            =0x0D;
	    SDO_ID_LOWLEVEL_USER_DIRECTION              =0x0E;
        SDO_ID_LOWLEVEL_ELEC_SYSTEM_ID_MAG          =0x0F;
	    SDO_ID_LOWLEVEL_TERMINAL_RESISTANCE         =0x10;
	    SDO_ID_LOWLEVEL_TERMINAL_INDUCTANCE         =0x11;
        SDO_ID_LOWLEVEL_BEMF_ID_VELOCITY            =0x12;
        SDO_ID_LOWLEVEL_BEMF_ID_GAIN_PCTG           =0x13;
        SDO_ID_LOWLEVEL_CURRENT_CTRL_BW_RAD         =0x14;
	    SDO_ID_LOWLEVEL_INERTIA                     =0x15;
	    SDO_ID_LOWLEVEL_DAMPING_COEF                =0x16;
	    SDO_ID_LOWLEVEL_MECH_MODEL_A                =0x17;
	    SDO_ID_LOWLEVEL_MECH_MODEL_B                =0x18;
        SDO_ID_LOWLEVEL_FRICTION_ID_INFO            =0x19;
        SDO_ID_LOWLEVEL_FRICTION_LUT_INFO           =0x1A;
        SDO_ID_LOWLEVEL_SET_COMMUTATION_SENSOR      =0x1B;
        SDO_ID_LOWLEVEL_SET_POS_FEEDBACK_SENSOR     =0x1C;
        SDO_ID_LOWLEVEL_SET_E_ANGLE_HOMING_SENSOR   =0x1D;
        SDO_ID_LOWLEVEL_SET_M_ANGLE_HOMING_SENSOR   =0x1E;
        SDO_ID_LOWLEVEL_SET_SENSOR_USAGE            =0x1F;

        %---------------------MID LEVEL SDO----------------------%
    	SDO_ID_MIDLEVEL_GET_STATE                    =0x00;
	    SDO_ID_MIDLEVEL_SET_STATE                    =0x01;
	    SDO_ID_MIDLEVEL_GET_ROUTINE                  =0x02;
	    SDO_ID_MIDLEVEL_SET_ROUTINE                  =0x03;
        SDO_ID_MIDLEVEL_IRC_NUMERATOR_LENGTH         =0x04;
        SDO_ID_MIDLEVEL_IRC_DENOMINATOR_LENGTH       =0x05;
        SDO_ID_MIDLEVEL_IRC_NUMERATOR                =0x06;
        SDO_ID_MIDLEVEL_IRC_DENOMINATOR              =0x07;
        SDO_ID_MIDLEVEL_IRC_SATURATION               =0x08;
	    SDO_ID_MIDLEVEL_IMP_VIRTUAL_STIFFNESS        =0x09;
	    SDO_ID_MIDLEVEL_IMP_VIRTUAL_DAMPER           =0x0A;
        SDO_ID_MIDLEVEL_CURRENT_PERIODIC_SIG_INFO    =0x0B;
        SDO_ID_MIDLEVEL_VELOCITY_PERIODIC_SIG_INFO   =0x0C;
        SDO_ID_MIDLEVEL_POSITION_PERIODIC_SIG_INFO   =0x0D;
        SDO_ID_MIDLEVEL_DOB_Q_BW                     =0x0E;
        SDO_ID_MIDLEVEL_DOB_GQ_NUM                   =0x0F;
        SDO_ID_MIDLEVEL_DOB_GQ_DEN                   =0x10;
        SDO_ID_MIDLEVEL_DOB_Q_NUM                    =0x11;
        SDO_ID_MIDLEVEL_DOB_Q_DEN                    =0x12;
        SDO_ID_MIDLEVEL_DOB_SATURATION               =0x13;
	    SDO_ID_MIDLEVEL_VELOCITY_BW                  =0x14;
	    SDO_ID_MIDLEVEL_VELOCITY_P_GAIN              =0x15;
	    SDO_ID_MIDLEVEL_VELOCITY_I_GAIN              =0x16;
	    SDO_ID_MIDLEVEL_POSITION_INPUT_PENALTY       =0x17;
	    SDO_ID_MIDLEVEL_POSITION_P_GAIN              =0x18;
	    SDO_ID_MIDLEVEL_POSITION_D_GAIN              =0x19;
        SDO_ID_MIDLEVEL_MID_CTRL_SATURATION          =0x1A;
        SDO_ID_MIDLEVEL_INCENCODER_SET_OFFSET        =0x1B;
        SDO_ID_MIDLEVEL_ABSENCODER1_SET_OFFSET       =0x1C;
        SDO_ID_MIDLEVEL_ABSENCODER1_CHANGE_DIRECTION =0x1D;
        SDO_ID_MIDLEVEL_ABSENCODER2_SET_OFFSET       =0x1E;
        SDO_ID_MIDLEVEL_ABSENCODER2_CHANGE_DIRECTION =0x1F;
        SDO_ID_MIDLEVEL_VSD_STIFFNESS                =0x20;
        SDO_ID_MIDLEVEL_VSD_DAMPER                   =0x21;
        SDO_ID_MIDLEVEL_VSD_DAMPED_RANGE             =0x22;
        SDO_ID_MIDLEVEL_VSD_STIFF_RANGE              =0x23;
        SDO_ID_MIDLEVEL_SET_VSD_UPPER_LIMIT          =0x24;
        SDO_ID_MIDLEVEL_SET_VSD_LOWER_LIMIT          =0x25;
        SDO_ID_MIDLEVEL_VSD_SATURATION               =0x26;

        SDO_ID_MIDLEVEL_FEEDFORWARD_NUM              =0x27;
        SDO_ID_MIDLEVEL_FEEDFORWARD_DEN              =0x28;
       
	    SDO_ID_MIDLEVEL_VELOCITY_ESTIMATOR           =0x29;
        SDO_ID_MIDLEVEL_VELOCITY_ESTIMATOR_LEAD_LAG  =0x2A;
	    SDO_ID_MIDLEVEL_ENCODER_RESOLUTION           =0x2B;
	    SDO_ID_MIDLEVEL_SYSTEM_ID_SBS_INFO           =0x2C;
	    SDO_ID_MIDLEVEL_SYSTEM_ID_VERIFICATION_MAG   =0x2D;
    
	    SDO_ID_MIDLEVEL_P_VECTOR_YD                  =0x2E;
	    SDO_ID_MIDLEVEL_P_VECTOR_L                   =0x2F;
	    SDO_ID_MIDLEVEL_P_VECTOR_S0                  =0x30;
	    SDO_ID_MIDLEVEL_P_VECTOR_SD                  =0x31;
	    SDO_ID_MIDLEVEL_F_VECTOR_MODE_IDX            =0x32;
	    SDO_ID_MIDLEVEL_F_VECTOR_TMAX                =0x33;
        SDO_ID_MIDLEVEL_F_VECTOR_DELAY               =0x34;
        SDO_ID_MIDLEVEL_I_VECTOR_EPSILON             =0x35;
        SDO_ID_MIDLEVEL_I_VECTOR_KP                  =0x36; 
        SDO_ID_MIDLEVEL_I_VECTOR_KD                  =0x37;
        SDO_ID_MIDLEVEL_I_VECTOR_LAMBDA              =0x38;
        SDO_ID_MIDLEVEL_I_VECTOR_DURATION            =0x39;
        SDO_ID_MIDLEVEL_I_VECTOR_KP_MAX              =0x3A;
        SDO_ID_MIDLEVEL_I_VECTOR_KD_MAX              =0x3B;
        SDO_ID_MIDLEVEL_I_VECTOR_OPTION              =0x3C;
        SDO_ID_MIDLEVEL_DESIRED_MECH_ANGLE           =0x3D;
        SDO_ID_MIDLEVEL_ABSENCODER1_LOCATION         =0x3E;
        SDO_ID_MIDLEVEL_ABSENCODER2_LOCATION         =0x3F;

        %---------------------MSG SDO----------------------%
        SDO_ID_MSG_GET_STATE 		=0x00;
        SDO_ID_MSG_SET_STATE		=0x01;
        SDO_ID_MSG_GET_ROUTINE		=0x02;
        SDO_ID_MSG_SET_ROUTINE		=0x03;
        SDO_ID_MSG_PDO_LIST 	    =0x04;
        SDO_ID_MSG_MS_ENUM 	        =0x05;
        SDO_ID_MSG_GUI_COMM_ONOFF   =0x06;
        SDO_ID_MSG_GUI_COMM_COMMAND =0x07;


        %---------------------IMU SDO----------------------%
        SDO_ID_IMU_GET_STATE        =0x00;
	    SDO_ID_IMU_SET_STATE        =0x01;
	    SDO_ID_IMU_GET_ROUTINE      =0x02;
	    SDO_ID_IMU_SET_ROUTINE      =0x03;
	    SDO_ID_IMU_FOR_TEST         =0x04; 

    	SDO_ID_EXT_GET_STATE                    =0x00;
	    SDO_ID_EXT_SET_STATE                    =0x01;
	    SDO_ID_EXT_GET_ROUTINE                  =0x02;
	    SDO_ID_EXT_SET_ROUTINE                  =0x03;
        SDO_ID_EXT_DC_SET_LENGTH	            =0x04;     
        SDO_ID_EXT_DC_SET_DIRECT                =0x05;

        %% PDO ID
	    PDO_ID_LOWLEVEL_3PHASES_CURRENT_RAW       =0x00;
	    PDO_ID_LOWLEVEL_3PHASES_CURRENT_KF        =0x01;
        PDO_ID_LOWLEVEL_3PHASES_VOLTAGE_RAW       =0x02;
	    PDO_ID_LOWLEVEL_3PHASES_VOLTAGE_KF        =0x03;
	    PDO_ID_LOWLEVEL_POSITION                  =0x04;
	    PDO_ID_LOWLEVEL_VELOCITY                  =0x05;
	    PDO_ID_LOWLEVEL_CLARKE_OUT                =0x06;
	    PDO_ID_LOWLEVEL_PARK_OUT                  =0x07;
	    PDO_ID_LOWLEVEL_VOLTAGE_IN                =0x08;
	    PDO_ID_LOWLEVEL_ELEC_ANGLE                =0x09;
	    PDO_ID_LOWLEVEL_PRBS_DATA                 =0x0A;

	    PDO_ID_LOWLEVEL_TOTAL_CURRENT_INPUT       =0x0B;
	    PDO_ID_LOWLEVEL_CURRENT_OUTPUT            =0x0C;
	    PDO_ID_LOWLEVEL_AUXILIARY_INPUT           =0x0D;
        PDO_ID_LOWLEVEL_F_VECTOR_INPUT            =0x0E;  
	    PDO_ID_LOWLEVEL_LOW_ID_PROCESS_INPUT      =0x0F;
	    PDO_ID_LOWLEVEL_FRICTION_COMPENSATOR_INPUT=0x10;
	    PDO_ID_LOWLEVEL_MID_ID_PROCESS_INPUT      =0x11;
	    PDO_ID_LOWLEVEL_IRC_INPUT                 =0x12;
	    PDO_ID_LOWLEVEL_MID_CTRL_INPUT            =0x13;
	    PDO_ID_LOWLEVEL_ANALYZER_INPUT            =0x14;
        PDO_ID_LOWLEVEL_COMMUTATION_STEP          =0x15;
        PDO_ID_LOWLEVEL_FRICTION_ID_REF           =0x16;
        PDO_ID_LOWLEVEL_HALL_SENSOR_SIG           =0x17;
        PDO_ID_LOWLEVEL_HALL_SENSOR_LOGIC         =0x18;

        PDO_ID_MIDLEVEL_LOOP_CNT                    =0x00;
        PDO_ID_MIDLEVEL_REF_POSITION                =0x01;
        PDO_ID_MIDLEVEL_REF_VELOCITY                =0x02;
        PDO_ID_MIDLEVEL_ACTUAL_POSITION             =0x03;
        PDO_ID_MIDLEVEL_ACTUAL_VELOCITY_RAW         =0x04;
        PDO_ID_MIDLEVEL_SYSTEM_ID_SBS_FREQ          =0x05;
        PDO_ID_MIDLEVEL_IMP_CTRL_INPUT              =0x06;
	    PDO_ID_MIDLEVEL_POS_PID_CTRL_INPUT          =0x07;
	    PDO_ID_MIDLEVEL_VEL_PID_CTRL_INPUT          =0x08;
        PDO_ID_MIDLEVEL_VSD_INPUT                   =0x09
	    PDO_ID_MIDLEVEL_UNIT_TRAJECTORY_BUFF_COUNT  =0x0A;
	    PDO_ID_MIDLEVEL_TORQUE_TRAJECTORY_INPUT     =0x0B;
        PDO_ID_MIDLEVEL_ABSENCODER1_POSITION        =0x0C;
        PDO_ID_MIDLEVEL_ABSENCODER2_POSITION        =0x0D;
        PDO_ID_MIDLEVEL_DOB_DISTURABNCE             =0x0E;
        PDO_ID_MIDLEVEL_DOB_INPUT                   =0x0F;
	    PDO_ID_MIDLEVEL_FF_INPUT                    =0x10;
	    PDO_ID_MIDLEVEL_VELOCITY_ESTIMATED          =0x11;
         b
	    PDO_ID_MSG_TEST1                            =0x00;
	    PDO_ID_MSG_TEST2                            =0x01;
	    PDO_ID_MSG_TEST3                            =0x02;
	    PDO_ID_MSG_TEST4                            =0x03;
	    PDO_ID_MSG_TEST5                            =0x04;
	    PDO_ID_MSG_TEST6                            =0x05;
	    PDO_ID_MSG_TEST7                            =0x06;
	    PDO_ID_MSG_TEST8                            =0x07;
	    PDO_ID_MSG_TEST9                            =0x08;
	    PDO_ID_MSG_TEST10   =0x09;

        % IMU_CTRL_TASK
        PDO_ID_IMU_ACC_X             =0x00;  
	    PDO_ID_IMU_ACC_Y             =0x01; 
	    PDO_ID_IMU_ACC_Z             =0x02; 
	    PDO_ID_IMU_GYR_X             =0x03; 
	    PDO_ID_IMU_GYR_Y             =0x04; 
	    PDO_ID_IMU_GYR_Z             =0x05;  
	    PDO_ID_IMU_MAG_X             =0x06; 
	    PDO_ID_IMU_MAG_Y             =0x07; 
	    PDO_ID_IMU_MAG_Z             =0x08; 
	    PDO_ID_IMU_ACC_XYZ           =0x09; 
	    PDO_ID_IMU_GYR_XYZ           =0x0A; 
	    PDO_ID_IMU_MAG_XYZ           =0x0B; 
        PDO_ID_IMU_ACC_GYR_XYZ       =0x0C; 
        PDO_ID_QUATERNION            =0x0D; 
	    PDO_ID_IMU_WALKING_STATE     =0x0E; 
	    PDO_ID_IMU_GAIT_PHASE        =0x0F; 
	    PDO_ID_IMU_TENSION_P         =0x10; 
	    PDO_ID_IMU_TENSION_D         =0x11; 
        PDO_ID_IMU_DEG_ACC           =0x12; 
	    PDO_ID_IMU_DEG_GYR           =0x13; 
	    PDO_ID_IMU_DEG_ACC_FILTERED  =0x14; 
	    PDO_ID_IMU_DEG_GYR_FILTERED  =0x15; 
	    PDO_ID_IMU_DEG_TVCF          =0x16;

        % CORE_TASK
        PDO_ID_CORE_LTC_VOLT =0x00;
        PDO_ID_CORE_LTC_CURR =0x01;
        PDO_ID_CORE_LTC_TEMP =0x02;

        PDO_ID_EXT_FSR          =0x00;
        PDO_ID_EXT_LP           =0x01;
        PDO_ID_EXT_DC_LENGTH    =0x02;
        PDO_ID_EXT_DC_DIRECTION =0x03;


        %% Routine ID
        ROUTINE_ID_LOWLEVEL_DIRECTION_SET                  =0x00;
        ROUTINE_ID_LOWLEVEL_ELECTRICAL_SYS_ID              =0x01;
        ROUTINE_ID_LOWLEVEL_ELECTRICAL_BEMF_ID             =0x02;
        ROUTINE_ID_LOWLEVEL_CURRENT_CTRL	               =0x03;
        ROUTINE_ID_LOWLEVEL_CURRENT_CTRL_BANDWIDTH_CHECK   =0x04;
        ROUTINE_ID_LOWLEVEL_ADVANCED_FRICTION_ID           =0x05;
        ROUTINE_ID_LOWLEVEL_ADV_FRICTION_COMPENSATION      =0x06;
        ROUTINE_ID_LOWLEVEL_ADV_FRICTION_ID_VEL_CTRL_EVAL  =0x07;
        ROUTINE_ID_LOWLEVEL_ADVANCED_FRICTION_COMPENSATION_FF        =0x08;
        ROUTINE_ID_LOWLEVEL_HALL_INCENC_RELIABILITY_EVAL   = 0x09;


        ROUTINE_ID_MIDLEVEL_SYS_ID_SBS                 =0x00;
	    ROUTINE_ID_MIDLEVEL_IRC                        =0x01;   
        ROUTINE_ID_MIDLEVEL_IMPEDANCE_CONTROL          =0x02;
	    ROUTINE_ID_MIDLEVEL_POSITION_REC_REF           =0x03;
	    ROUTINE_ID_MIDLEVEL_POSITION_SINE_REF          =0x04;
        ROUTINE_ID_MIDLEVEL_POSITION_CTRL              =0x05;
        ROUTINE_ID_MIDLEVEL_VELOCITY_CTRL	           =0x06;
        ROUTINE_ID_MIDLEVEL_VELOCITY_SINE_REF          =0x07;
        ROUTINE_ID_MIDLEVEL_VELOCITY_REC_REF           =0x08;
        ROUTINE_ID_MIDLEVEL_CURRENT_SINE_REF           =0x09;
        ROUTINE_ID_MIDLEVEL_CURRENT_REC_REF            =0x0A;
        ROUTINE_ID_MIDLEVEL_CURRENT_TANH_REF           =0x0B;
        ROUTINE_ID_MIDLEVEL_GET_HALL_SENSOR_VALUE      =0x0C;
        ROUTINE_ID_MIDLEVEL_GET_INCENCODER_VALUE       =0x0D;
        ROUTINE_ID_MIDLEVEL_GET_ABSENCODER1_VALUE      =0x0E;
        ROUTINE_ID_MIDLEVEL_GET_ABSENCODER2_VALUE      =0x0F;
        ROUTINE_ID_MIDLEVEL_COMPRESSIONAL_VSD          =0x10;
        ROUTINE_ID_MIDLEVEL_BACKLASH_TEST              =0x11;
        ROUTINE_ID_MIDLEVEL_DISTURBANCE_OBS            =0x12;
	    ROUTINE_ID_MIDLEVEL_P_VECTOR_DECODER           =0x13;
	    ROUTINE_ID_MIDLEVEL_F_VECTOR_DECODER           =0x14;
	    ROUTINE_ID_MIDLEVEL_FEEDFORWARD_FILTER         =0x15;
        ROUTINE_ID_MIDLEVEL_SYS_ID_SBS_VERIFY          =0x16;

        ROUTINE_ID_MSG_PDO_SEND                        =0x00;
        ROUTINE_ID_MSG_PDO_DUMMY_TEST                  =0x01;

        ROUTINE_ID_IMU_6AXIS_GETVALUE                  =0x00;
    	ROUTINE_ID_IMU_3AXIS_GETVALUE                  =0x01;
    	ROUTINE_ID_IMU_RPY_CAL                         =0x02;
    	ROUTINE_ID_IMU_WALKING_STATE                   =0x03; 
    	ROUTINE_ID_IMU_MAGNET_GETVALUE                 =0x04; 
    	ROUTINE_ID_IMU_TOTAL_FUNCTION                  =0x05; 
    	ROUTINE_ID_IMU_QT_INT                          =0x06; 

        ROUTINE_ID_EXT_FOOT_ROUTINE                    =0x00;
        ROUTINE_ID_DC_LENGTH_CMD_ROUTINE	           =0x01;
        ROUTINE_ID_DC_DIRECT_CMD_ROUTINE               =0x02;
        ROUTINE_ID_DC_SWITCH_ROUTINE                   =0x03;

        %% SDO FNC
        SDO_IDLE  =2;
        SDO_REQU  =1;
        SDO_SUCC  =0;
        SDO_FAIL =255;

        %% STATE
        State_Off     = 0;
        State_Standby = 1;
        State_Enable  = 2;
        State_Error   = 3;

        %% Main Sequence Enum
	    IDLE                        = 0;
	    UPLOAD_PROPERTIES           = 1;
	    SAVE_PROPERTIES             = 2;
        DOWNLOAD_PROPERTIES         = 3;
	    ELECTRICAL_SYSTEM_ID        = 4;
        BEMF_ID                     = 5;
        CURRENT_BANDWIDTH_CHECK     = 6;
	    AUTO_TUNING                 = 7;
        ADV_FRICTION_ID             = 8;

        %% UI UPDATE
        FIRST_USE					             =0x01;
        SECOND_USE					             =0x02;
        MEMORY_UI_MOTOR_PROPERTIES	             =0x03;
        MEMORY_UI_SENSOR_SETTING                 =0x04;
        MEMORY_UI_ELECTRICAL_PROPERTIES	         =0x05;
        MEMORY_UI_MECHANICAL_PROPERTIES	         =0x06;
        MEMORY_UI_CONTROL_PARAMETERS1	         =0x07;
        MEMORY_UI_CONTROL_PARAMETERS2	         =0x08;
        MEMORY_UI_ADDITIONAL_FUNCTION_PARAMETERS =0x09;

        E_SYS_BATCH					             =0x0A;
        E_SYS_BEMF                               =0x0B;
        BEMF_ID_OVER_CURRENT                     =0x0C;
        BW_CHECK                                 =0x0D;
        FRICTION_ID_RAW_DATA                     =0x0E;
        FRICTION_ID_AVERAGED_DATA                =0x0F;
        FRICTION_ID_DONE                         =0x10;
        FRICTION_COMPENSATOR_VERIFICATION        =0x11;

        MECH_SYS_ID_SBS_RAW_DATA                 =0x12;
        MECH_SYS_ID_SBS_DONE                     =0x13;

        IRC_VERIFICATION                         =0x14;

        GET_IMPEDANCE_SINE_CTRL                  =0x15;
        GET_IMPEDANCE_REC_CTRL                   =0x16;

        GAIN_TUNER                  =0x17;
        GET_VELOCITY_CTRL           =0x18;
        GET_POSITION_CTRL           =0x19;
        GET_HALLSENSOR              =0x1A;
        GET_INCENCODER              =0x1B;
        GET_ABSENCODER1             =0x1C;
        GET_ABSENCODER2             =0x1D;
        GET_VSD_UPPER_LIMIT         =0x1E;
        GET_VSD_LOWER_LIMIT         =0x1F;

        VSD_VERIFICATION_DATA       =0x20;
        GET_BACKLASH_TEST           =0x21;
        GET_DOB_DATA                =0x22;

        GET_DIRECTION_SET_DATA      =0x23;
        GET_DIRECTION_SET_DONE      =0x24;

        SAVE_DONE                   =0x25;
        GET_VE_TEST_DATA            =0x26;
        GET_SYSTEM_ID_VERIFY        =0x27;
        VE_KF_SETTING_ERROR         =0x28;
        GET_FF_CTRL                 =0x29;
        GET_TOTAL_CTRL              =0x2A;
        ADV_FRICTION_ID_DATA        =0x2B;
        ADV_FRICTION_ID_DONE        =0x2C;

        GET_GAIT_PHASE              =0x2D;
        GET_ACC_GYRO                =0x2E;
        GET_QUATERNION              =0x2F;

        FRICTION_ID_VEL_CTRL_EVAL   =0x30;
        GET_SBS_ID_VERIFY           =0x31;
        GET_DOB_VERIFY              =0x32;
        GET_ELEC_ANGLE_EST_EV       =0x33;

        GET_HALL_VS_INCENC_DATA     =0x34;
        GET_P_VECTOR_EVAL           =0x35;
        GET_F_VECTOR_EVAL           =0x36;
        GET_I_VECTOR_EVAL           =0x37;
        GET_LINK_LUT                =0x38;

        %% Mode of Operation 
        CURRENT_CTRL_MODE = 0x00;
        VELOCITY_CTRL_MODE = 0x01;
        POSITION_CTRL_MODE = 0x02;

        %% Feedback Sensor
        INCREMENTAL_ENCODER = 0x00;
        HALL_SENSOR         = 0x01;

        %% Current CTRL Type
        FOC                 = 0x00;
        TRAPEZOIDAL         = 0x01;

        %% Input Method
    	M_COMMUNICATION =0x00;
	    M_PWM           =0x01;
	    M_ANALOG        =0x02;
        M_GUI           =0x03;

    end

    %% PUBLIC PROPERTIES
    properties (Access = public)
        device %serial_port
        feedback_sensor
        current_ctrl_type
        mode_of_operation
        input_method
        value %TypeDropDown
        state
        Timer1 = timer
        Timer2 = timer
        UI_ModelName
        DataLoad
        fig = [];
        ax  = [];
        incremental_encoder_usage
        absolute_encoder1_usage
        absolute_encoder2_usage
        hall_sensor_usage
        motor_temp_sensor_usage
        imu_6axis_usage
        imu_3axis_usage
        
    end

    %% PRIVATE PROPERTIES
    properties (Access = private)
        DialogApp 
        tap_activate = 0 % Description
        elecModel_a
        elecModel_b
        E_verify_index
        E_verify_ref = zeros(6895)
        E_verify_act = zeros(6895)
        node_id % Description
        file_backlash
        file_friction_id_raw
        file_friction_id_avr
        file_friction_id_vel_ctrl
        filename
        JB_user_set = 0

        plot_cnt;

         % Description
        file_friction_verification % Description
        file_mech_system_id
        file_irc_verification
        inertia_value % Description
        damp_coef_value % Description
        mech_a_value % Description
        mech_b_value % Description
        irc_filter % Description
        irc_s_domain
        irc_z_domain% Description
        irc_num_length
        irc_den_length
        irc_num
        irc_den% Description
        file_imp_sine_verification
        file_imp_rec_verification% Description
        file_ctrl_verification% Description
        file_hall_sensor
        file_inc_encoder % Description
        file_abs_encoder % Description
        file_vsd_verification % Description
        file_dob_verification
        file_direction_set % Description
        file_WIDM_gait_phase% Description
        file_WIDM_acc_gyr% Description
        file_quaternion
        file_current_ctrl_bandwidth_verification

        FF_OFF = 0;
        FF_FOR_IMP_CTRL = 1; % Description
        FF_FOR_PD_CTRL = 2;
        file_ve % Description
        file_sys_id_verify % Description
        file_ff_verification % Description
        file_total_verification % Description
        file_adv_friction_id % Description
        friction_lut % Description

        friction_compensator_evluation % Description

        file_dob_evaluation
        file_pfi_evaluation
    end
    
    %% FUNCTIONS
    methods (Access = private)
            
       function edit_commBoard(app, n_text)

                currString= app.TextArea.Value;

                if(length(currString) < 100)
                    currString{end+1}=n_text;
                else
                    for i = 1:99
                        currString{i} = currString{i+1};
                    end
                    currString{100}=n_text;
                end
            
                app.TextArea.Value = currString;

        end
        
        

        function readData(app, buf, ~)
            
            temp_buf = buf;
            data = readline(temp_buf);
            temp_data_uint8 = double(char(data));
            real_packet_length = floor(length(temp_data_uint8)/2);
            data_uint8 = zeros(1, real_packet_length);

            for i = 1:real_packet_length
                data_uint8(i) = temp_data_uint8(2*i-1);
            end

            fnc_code = data_uint8(1)*256;

            if fnc_code == app.SDO

                n_sdo = data_uint8(3);
                flag = 0;

                for i = 1:n_sdo
                    if(data_uint8(i*4+2) == 255)
                        edit_commBoard(app, 'Fail');
                        flag = 1;
                    end
                end

                if(flag == 0)
                    edit_commBoard(app, 'Success');
                end


            elseif fnc_code == app.GUI_SYNC
                
                isUsed = data_uint8(2);

                if(isUsed == app.FIRST_USE)
                    app.UI_ModelName  = 'No data saved. Please set your motor.';
                    app.DataLoad = app.UI_ModelName;

                    pause(1);
                    app.DialogApp.OKAY2Button.Enable = 'on';
                    app.DialogApp.OKAY2Button.Visible = 'on';  

                %%
                elseif(isUsed == app.SECOND_USE)
                    app.UI_ModelName = char(data_uint8(4:4+data_uint8(3)-1));
                    app.DataLoad = strcat('Model Name :  ', num2str(app.UI_ModelName));
  

                %%
                elseif(isUsed == app.MEMORY_UI_MOTOR_PROPERTIES)
                    
                    app.MotorName.Value = app.UI_ModelName;
                    
                    t_pp = double(typecast(uint8(data_uint8(3)),'uint8'));
                    t_er = double(typecast(uint8(data_uint8(7:8)),'uint16'));
                    t_gr = double(typecast(uint8(data_uint8(11:14)),'single'));
                    t_tc = double(typecast(uint8(data_uint8(15:18)),'single'));
                    t_vc = double(typecast(uint8(data_uint8(19:22)),'single'));
                    t_pcl = double(typecast(uint8(data_uint8(23:26)),'single'));
                    t_ccl = double(typecast(uint8(data_uint8(27:30)),'single'));
                    t_maxVel = double(typecast(uint8(data_uint8(31:34)),'single'));

                    t_pp_l = get(app.PolePair, 'Limits');
                    t_er_l = get(app.EncResolution, 'Limits');
                    t_gr_l = get(app.GearRatio, 'Limits');
                    t_tc_l = get(app.TorqueConstantNmAEditField, 'Limits');
                    t_vc_l = get(app.VelocityConstantVradsEditField, 'Limits');
                    t_pcl_l = get(app.PeakCurrentLimitEdit, 'Limits');
                    t_ccl_l = get(app.ContinuousCurrentLimitEdit, 'Limits');
                    t_maxVel_l = get(app.MaxVelocity, 'Limits');

                    t_pp(isnan(t_pp)) = t_pp_l(1);
                    t_er(isnan(t_er)) = t_er_l(1);
                    t_gr(isnan(t_gr)) = t_gr_l(1);
                    t_tc(isnan(t_tc)) = t_tc_l(1);
                    t_vc(isnan(t_vc)) = t_vc_l(1);
                    t_pcl(isnan(t_pcl)) = t_pcl_l(1);
                    t_ccl(isnan(t_ccl)) = t_ccl_l(1);
                    t_maxVel(isnan(t_maxVel)) = t_maxVel_l(1);

                    app.PolePair.Value = t_pp;
                    app.EncResolution.Value = t_er;
                    app.GearRatio.Value = t_gr;
                    app.TorqueConstantNmAEditField.Value = t_tc;
                    app.VelocityConstantVradsEditField.Value = t_vc;
                    app.PeakCurrentLimitEdit.Value = t_pcl;
                    app.ContinuousCurrentLimitEdit.Value = t_ccl;
                    app.MaxVelocity.Value = t_maxVel;

                    edit_commBoard(app, 'Motor Properties Loaded');
                    
                %%
                elseif(isUsed == app.MEMORY_UI_SENSOR_SETTING)

                    t_e_angle_homing_sensor = double(typecast(uint8(data_uint8(3)),'uint8'));
                    t_m_angle_homing_sensor = double(typecast(uint8(data_uint8(7)),'uint8'));
                    t_commutation_sensor    = double(typecast(uint8(data_uint8(11)),'uint8'));
                    t_pos_feedback_sensor   = double(typecast(uint8(data_uint8(15)),'uint8'));
                    t_temp_sensor_usage     = double(typecast(uint8(data_uint8(19)),'uint8'));
                    t_imu_6axis_usage       = double(typecast(uint8(data_uint8(23)),'uint8'));
                    t_imu_3axis_usage       = double(typecast(uint8(data_uint8(27)),'uint8'));
                    t_absenc1_location      = double(typecast(uint8(data_uint8(31)),'uint8'));
                    t_absenc2_location      = double(typecast(uint8(data_uint8(35)),'uint8'));

                    t_commutation_sensor(isnan(t_commutation_sensor)) = 0;
                    t_pos_feedback_sensor(isnan(t_pos_feedback_sensor)) = 0;
                    t_e_angle_homing_sensor(isnan(t_e_angle_homing_sensor)) = 0;
                    t_m_angle_homing_sensor(isnan(t_m_angle_homing_sensor)) = 0;
                    t_temp_sensor_usage(isnan(t_temp_sensor_usage)) = 0;
                    t_imu_6axis_usage(isnan(t_imu_6axis_usage)) = 0;
                    t_imu_3axis_usage(isnan(t_imu_3axis_usage)) = 0;
                    t_absenc1_location(isnan(t_absenc1_location)) = 0;
                    t_absenc2_location(isnan(t_absenc2_location)) = 0;

                    app.incremental_encoder_usage = 0;
                    app.absolute_encoder1_usage   = 0;
                    app.absolute_encoder2_usage   = 0;
                    app.hall_sensor_usage         = 0;
                    app.motor_temp_sensor_usage   = 0;


                    if (t_commutation_sensor == 0)
                        app.CommutationSensorDropDown.Value = "Incremental Encoder (default)";
                        app.incremental_encoder_usage = 1;
                    elseif (t_commutation_sensor == 1)
                        app.CommutationSensorDropDown.Value = "Hall Sensor";
                        app.hall_sensor_usage = 1;
                    elseif (t_commutation_sensor == 2)
                        app.CommutationSensorDropDown.Value = "Incremental Encoder + Hall Sensor";
                        app.incremental_encoder_usage = 1;
                        app.hall_sensor_usage = 1;
                    end

                    if (t_pos_feedback_sensor == 0)
                        app.PositionFeedbackSensorDropDown.Value = "No Sensor (default)";
                    elseif (t_pos_feedback_sensor == 1)
                        app.PositionFeedbackSensorDropDown.Value = "Incremental Encoder";
                        app.incremental_encoder_usage = 1;
                    elseif (t_pos_feedback_sensor == 2)
                        app.PositionFeedbackSensorDropDown.Value = "Absolute Encoder (CH1)";
                        app.absolute_encoder1_usage = 1;
                    elseif (t_pos_feedback_sensor == 3)
                        app.PositionFeedbackSensorDropDown.Value = "Absolute Encoder (CH2)";
                        app.absolute_encoder2_usage = 1;
                    end

                    if (t_e_angle_homing_sensor == 0)
                        app.ElectricalAngleHomingSensorDropDown.Value = "No Sensor (default)";
                    elseif (t_e_angle_homing_sensor == 1)
                        app.ElectricalAngleHomingSensorDropDown.Value = "Hall Sensor";
                        app.hall_sensor_usage = 1;
                    elseif (t_e_angle_homing_sensor == 2)
                        app.ElectricalAngleHomingSensorDropDown.Value = "Absolute Encoder (CH1)";
                        app.absolute_encoder1_usage = 1;
                    elseif (t_e_angle_homing_sensor == 3)
                        app.ElectricalAngleHomingSensorDropDown.Value = "Absolute Encoder (CH2)";
                        app.absolute_encoder2_usage = 1;
                    end

                    if (t_m_angle_homing_sensor == 0)
                        app.MechanicalAngleHomingSensorDropDown.Value = "Set Zero (default)";
                    elseif (t_m_angle_homing_sensor == 1)
                        app.MechanicalAngleHomingSensorDropDown.Value = "Absolute Encoder (CH1)";
                        app.absolute_encoder1_usage = 1;
                    elseif (t_m_angle_homing_sensor == 2)
                        app.MechanicalAngleHomingSensorDropDown.Value = "Absolute Encoder (CH2)";
                        app.absolute_encoder2_usage = 1;
                    elseif (t_m_angle_homing_sensor == 3)
                        app.MechanicalAngleHomingSensorDropDown.Value = "Self Setting";
                    elseif (t_m_angle_homing_sensor == 4)
                        app.MechanicalAngleHomingSensorDropDown.Value = "Master Setting";
                    end

                    if (t_temp_sensor_usage == 0)
                        app.MotorTemperatureSensorDropDown.Value = "Not Used (default)";
                        app.motor_temp_sensor_usage = 0;
                    elseif (t_temp_sensor_usage == 1)
                        app.MotorTemperatureSensorDropDown.Value = "Used";
                        app.motor_temp_sensor_usage = 1;                        
                    end

                    if (t_imu_6axis_usage == 0)
                        app.IMU6AxisDropDown.Value = "Not Used (default)";
                    elseif (t_imu_6axis_usage == 1)
                        app.IMU6AxisDropDown.Value = "Used";
                    end

                    if (t_imu_3axis_usage == 0)
                        app.IMU3AxisDropDown.Value = "Not Used (default)";
                    elseif (t_imu_3axis_usage == 1)
                        app.IMU3AxisDropDown.Value = "Used";
                    end

                    if (t_absenc1_location == 0)
                        app.AbsoluteEncoderCH1DropDown.Value = "None (defaut)";
                    elseif(t_absenc1_location == 1)
                        app.AbsoluteEncoderCH1DropDown.Value = "Actuator Input";
                    elseif(t_absenc1_location == 2)
                        app.AbsoluteEncoderCH1DropDown.Value = "Actuator Output";
                    elseif(t_absenc1_location == 3)
                        app.AbsoluteEncoderCH1DropDown.Value = "Joint1";
                    elseif(t_absenc1_location == 4)
                        app.AbsoluteEncoderCH1DropDown.Value = "Joint2";      
                    end

                    if (t_absenc2_location == 0)
                        app.AbsoluteEncoderCH2DropDown.Value = "None (defaut)";
                    elseif(t_absenc2_location == 1)
                        app.AbsoluteEncoderCH2DropDown.Value = "Actuator Input";
                    elseif(t_absenc2_location == 2)
                        app.AbsoluteEncoderCH2DropDown.Value = "Actuator Output";
                    elseif(t_absenc2_location == 3)
                        app.AbsoluteEncoderCH2DropDown.Value = "Joint1";
                    elseif(t_absenc2_location == 4)
                        app.AbsoluteEncoderCH2DropDown.Value = "Joint2";
                    end

                    if (app.incremental_encoder_usage == 1)
                        app.IncrementalEncoderLamp.Color = [0, 1, 0];
                    else
                        app.IncrementalEncoderLamp.Color = [0.8, 0.8, 0.8];                           
                    end 
                    
                    if (app.absolute_encoder1_usage == 1)
                        app.AbsoluteEncoderCH1Lamp.Color = [0, 1, 0];
                    else
                        app.AbsoluteEncoderCH1Lamp.Color = [0.8, 0.8, 0.8];
                        
                    end 
            
                    if (app.absolute_encoder2_usage == 1)
                        app.AbsoluteEncoderCH2Lamp.Color = [0, 1, 0];
                    else
                        app.AbsoluteEncoderCH2Lamp.Color = [0.8, 0.8, 0.8];                          
                    end

                    if (app.hall_sensor_usage == 1)
                        app.HallSensorLamp.Color = [0, 1, 0];
                    else
                        app.HallSensorLamp.Color = [0.8, 0.8, 0.8];    
                    end
 
                    if (t_temp_sensor_usage == 1)
                        app.MotorTemperatureSensorLamp.Color = [0, 1, 0];
                    else
                        app.MotorTemperatureSensorLamp.Color = [0.8, 0.8, 0.8];                        
                    end
                

                    edit_commBoard(app, 'Sensor Settings Loaded');
                %%
                elseif(isUsed == app.MEMORY_UI_ELECTRICAL_PROPERTIES)

                    t_r = double(typecast(uint8(data_uint8(3:6)),'single'));
                    t_l = double(typecast(uint8(data_uint8(7:10)),'single'));
                    t_bemf = double(typecast(uint8(data_uint8(11:14)),'single'));
                    t_bw = double(typecast(uint8(data_uint8(15:18)),'single'));

                    t_r(isnan(t_r)) = 0;
                    t_l(isnan(t_l)) = 0;
                    t_bw(isnan(t_bw)) = 1000*2*pi;
                    
                    if(t_bw == 0)
                        t_bw = 1000*2*pi;
                    end

                    app.ResistanceOhmEditField.Value = t_r;
                    app.InductanceHEditField.Value = t_l;
                    app.BemfLabel.Text = strcat('BEMF Constant = ', num2str(t_bemf));
                    app.DesiredBW2.Value = t_bw/2/pi;
                    
                    edit_commBoard(app, 'Electrical Properties Loaded');

                %%
                elseif(isUsed == app.MEMORY_UI_MECHANICAL_PROPERTIES)
                    
                    t_J = double(typecast(uint8(data_uint8(3:6)),'single'));
                    t_B = double(typecast(uint8(data_uint8(7:10)),'single'));
                    t_a = double(typecast(uint8(data_uint8(11:14)),'single'));
                    t_b = double(typecast(uint8(data_uint8(15:18)),'single'));
                    t_mid_sat = double(typecast(uint8(data_uint8(19:22)),'single'));

                    t_mid_sat_l = get(app.MidlevelControllerSaturationEditField, 'Limits');

                    t_J(isnan(t_J)) = 0;
                    t_B(isnan(t_B)) = 0;
                    t_a(isnan(t_a)) = 0;
                    t_b(isnan(t_b)) = 0;
                     t_mid_sat(isnan(t_mid_sat)) = t_mid_sat_l(1);

                    app.inertia_value = t_J;
                    app.damp_coef_value = t_B;
                    app.mech_a_value = t_a;
                    app.mech_b_value = t_b;

                    app.InertiaJunitAs2Label.Text = strcat("Inertia (J) (unit: As^2) = ", num2str(t_J));
                    app.DampingBunitAsLabel.Text = strcat("Damping (B) (unit: As) = ", num2str(t_B));
                    app.InertiaJunitAs2Label_2.Text = strcat("Inertia (J) (unit: As^2) = ", num2str(t_J));
                    app.DampingBunitAsLabel_2.Text = strcat("Damping (B) (unit: As) = ", num2str(t_B));
                    app.InertiaJunitAs2Label_3.Text = strcat("Inertia (J) (unit: As^2) = ", num2str(t_J));
                    app.DampingBunitAsLabel_3.Text = strcat("Damping (B) (unit: As) = ", num2str(t_B));
                    app.InertiaJunitAs2Label_5.Text = strcat("Inertia (J) (unit: As^2) = ", num2str(t_J));
                    app.DampingBunitAsLabel_5.Text = strcat("Damping (B) (unit: As) = ", num2str(t_B));

                      app.MidlevelControllerSaturationEditField.Value = t_mid_sat;
                    app.MidlevelControllerSaturationEditField_2.Value = t_mid_sat;


                    edit_commBoard(app, 'Mechanical Properties Loaded');

                %%
                elseif(isUsed == app.MEMORY_UI_CONTROL_PARAMETERS1)
                                       
                    t_vel_p = double(typecast(uint8(data_uint8(3:6)),'single'));
                    t_vel_i = double(typecast(uint8(data_uint8(7:10)),'single'));
                    t_vel_hz = double(typecast(uint8(data_uint8(11:14)),'single'));
                    t_pos_p = double(typecast(uint8(data_uint8(15:18)),'single'));
                    t_pos_d = double(typecast(uint8(data_uint8(19:22)),'single'));
                    t_pos_r = double(typecast(uint8(data_uint8(23:26)),'single'));
                    t_dob_q = double(typecast(uint8(data_uint8(27:30)),'single'));
                    t_dob_sat = double(typecast(uint8(data_uint8(31:34)),'single'));
                    t_ve_type = double(typecast(uint8(data_uint8(35)),'uint8'));
                    t_ve_masking = double(typecast(uint8(data_uint8(39)),'uint8'));
                    t_irc_sat = double(typecast(uint8(data_uint8(43:46)),'single'));
                    t_ic_Kp_max = double(typecast(uint8(data_uint8(47:50)),'single'));
                    t_ic_Kd_max = double(typecast(uint8(data_uint8(51:54)),'single'));
                    t_ic_option = double(typecast(uint8(data_uint8(55)),'uint8'));

                    t_irc_sat_l = get(app.IRCSaturationAEditField, 'Limits');

                    t_vel_p(isnan(t_vel_p)) = 0;
                    t_vel_i(isnan(t_vel_i)) = 0;
                    t_vel_hz(isnan(t_vel_hz)) = 0;
                    t_pos_p(isnan(t_pos_p)) = 0;
                    t_pos_d(isnan(t_pos_d)) = 0;
                    t_pos_r(isnan(t_pos_r)) = 10;
                    t_dob_q(isnan(t_dob_q)) = 0;
                    t_dob_sat(isnan(t_dob_sat)) = 0;
                    t_irc_sat(isnan(t_irc_sat)) = 0;
                    t_ic_Kp_max(isnan(t_ic_Kp_max)) = 0;
                    t_ic_Kd_max(isnan(t_ic_Kd_max)) = 0;
                    t_ic_option(isnan(t_ic_option)) = 0;

                    if(t_ve_type == 255)    
                        t_ve_type = 0;
                    end
                    if(t_ve_masking == 255)    
                        t_ve_masking = 0;
                    end

                    if(t_pos_r == 0)    
                        t_pos_r = 10;
                    end

                    if(t_ve_masking == 0)    
                        t_ve_masking = 1;
                    end

                    if(t_irc_sat == 0)    
                        t_irc_sat = t_irc_sat_l(1);
                    end

                    t_dob_q = t_dob_q/(2*pi);

                    app.VelocityBandwidthHzEditField.Value = t_vel_hz;
                    app.Pos_Input_Penalty.Value = t_pos_r;

                    app.Vel_P_Gain.Text = strcat('P gain = ', num2str(t_vel_p));
                    app.Vel_I_Gain.Text = strcat('I gain = ', num2str(t_vel_i));
                    app.Pos_P_Gain.Text = strcat('P gain = ', num2str(t_pos_p));
                    app.Pos_D_Gain.Text = strcat('D gain = ', num2str(t_pos_d));

                    app.PGainEditField.Value = t_pos_p;
                    app.DGainEditField.Value = t_pos_d;

                    app.QFilterCutoffHzEditField.Value = t_dob_q;
                    app.SaturationAEditField_3.Value = t_dob_sat;

                    app.EstimatorTypeDropDown.Value = num2str(t_ve_type);
                    app.MaskingSizeEditField.Value = t_ve_masking;

                    app.IRCSaturationAEditField.Value = t_irc_sat;

                    app.ActualKp100ADegEditField.Value = t_ic_Kp_max * pi/180;
                    app.ActualKd100ADegsEditField.Value = t_ic_Kd_max * pi/180;

                    if (t_ic_option == 0)
                        app.ImpedanceControlModeDropDown.Value = "Variable Parameter";
                    elseif (t_ic_option == 1)
                        app.ImpedanceControlModeDropDown.Value = "Constant Parameter";
                    end                           

                    edit_commBoard(app, 'Control Parameters 1 Loaded');


                %%
                elseif(isUsed == app.MEMORY_UI_CONTROL_PARAMETERS2)

                    t_epsilon  = double(typecast(uint8(data_uint8(3)),'uint8'));
                    t_Kp       = double(typecast(uint8(data_uint8(7)),'uint8'));
                    t_Kd       = double(typecast(uint8(data_uint8(11)),'uint8'));
                    t_lambda   = double(typecast(uint8(data_uint8(15)),'uint8'));
                    t_duration = double(typecast(uint8(data_uint8(19:20)),'uint16'));

                    t_epsilon(isnan(t_epsilon)) = 0;  
                    t_Kp(isnan(t_Kp)) = 0;       
                    t_Kd(isnan(t_epsilon)) = 0;  
                    t_lambda(isnan(t_lambda)) = 0;       
                    t_duration(isnan(t_duration)) = 0;  
   
                    app.CorridorHalfWidthDegEditField.Value               = t_epsilon * 0.1;
                    app.VirtualStiffnessEditField.Value                   = t_Kp * 100/255;                   
                    app.VirtualDampingEditField.Value                     = t_Kd * 100/255; 
                    app.ImpedanceRatioinCorridor0255EditField.Value       = t_lambda * 0.01;
                    app.TransitionTimemsEditField.Value                   = t_duration;

                    edit_commBoard(app, 'Control Parameters 2 Loaded');
                %%
                elseif(isUsed == app.MEMORY_UI_ADDITIONAL_FUNCTION_PARAMETERS)

                    t_l_lim   = double(typecast(uint8(data_uint8(3:6)),'single'));
                    t_u_lim   = double(typecast(uint8(data_uint8(7:10)),'single'));
                    t_l_d_ran = double(typecast(uint8(data_uint8(11:14)),'single'));
                    t_u_d_ran = double(typecast(uint8(data_uint8(15:18)),'single'));
                    t_l_s_ran = double(typecast(uint8(data_uint8(19:22)),'single'));
                    t_u_s_ran = double(typecast(uint8(data_uint8(23:26)),'single'));
                    t_l_damp  = double(typecast(uint8(data_uint8(27:30)),'single'));
                    t_u_damp  = double(typecast(uint8(data_uint8(31:34)),'single'));
                    t_l_stiff = double(typecast(uint8(data_uint8(35:38)),'single'));
                    t_u_stiff = double(typecast(uint8(data_uint8(39:42)),'single'));
                    t_vsd_sat = double(typecast(uint8(data_uint8(43:46)),'single'));

                    t_l_lim(isnan(t_l_lim)) = 0;
                    t_u_lim(isnan(t_u_lim)) = 0;
                    t_l_d_ran(isnan(t_l_d_ran)) = 0;
                    t_u_d_ran(isnan(t_u_d_ran)) = 0;
                    t_l_s_ran(isnan(t_l_s_ran)) = 0;
                    t_u_s_ran(isnan(t_u_s_ran)) = 0;
                    t_l_damp(isnan(t_l_damp)) = 0;
                    t_u_damp(isnan(t_u_damp)) = 0;
                    t_l_stiff(isnan(t_l_stiff)) = 0;
                    t_u_stiff(isnan(t_u_stiff)) = 0;
                    t_vsd_sat(isnan(t_vsd_sat)) = 0;

                    app.LowerLimitdegEditField.Value = t_l_lim;
                    app.UpperLimitdegEditField.Value = t_u_lim;
                    app.LowerDampedRangedegEditField.Value = t_l_d_ran;
                    app.UpperDampedRangedegEditField.Value = t_u_d_ran;
                    app.LowerStiffRangedegEditField.Value = t_l_s_ran;
                    app.UpperStiffRangedegEditField.Value = t_u_s_ran;
                    app.LowerDamperAsdegEditField.Value = t_l_damp;
                    app.UpperDamperAsdegEditField.Value = t_u_damp;
                    app.LowerStiffnessAdegEditField.Value = t_l_stiff;
                    app.UpperStiffnessAdegEditField.Value = t_u_stiff;
                    app.SaturationAEditField_2.Value = t_vsd_sat;
                    
                    edit_commBoard(app, 'Additional Function Parameters Loaded');

                %%
                elseif(isUsed == app.E_SYS_BATCH)

                    app.BEMFConstantinDiscretizedModelPanel.Enable = "on";

                    app.elecModel_a = double(typecast(uint8(data_uint8(3:6)),'single'));
                    app.elecModel_b = double(typecast(uint8(data_uint8(7:10)),'single'));

                    T = 0.000040;            
                    s = tf('s');
        
                    freq_ = 2*pi*100:2*pi*10000;      % unit: rad/s
                    sys_mag_ = zeros(size(freq_));
                    
                    for i = 1:length(freq_)
                        t_s = 1i*freq_(i);
                        t_z = exp(-t_s*T);
                        sys_mag_(i) = abs((app.elecModel_b*(t_z)^-1)/(1-app.elecModel_a*(t_z)^-1))*((29/65536)/(48/4800));
                    end
        
                    cla(app.ElecBode) 
                    semilogx(app.ElecBode, freq_, 20*log10(sys_mag_));
                    hold(app.ElecBode,'on')
        
                    type = fittype('abs( 1/(L*(x*j) + R) )');
                    Initial = [0.00031, 0.92];
                    F=fitoptions('method','NonlinearLeastSquares','MaxIter',2000,'MaxFunEvals',600,'StartPoint',Initial);
                    [X,R,O]=fit(freq_', sys_mag_', type, F);
        
                    for i = 1:length(freq_)
                        sys_fit_mag_(i) = 1/(abs(X.L)*freq_(i)*sqrt(-1) + abs(X.R));
                    end
                    
                    semilogx(app.ElecBode, freq_, 20*log10(abs(sys_fit_mag_)));
                    app.ElecBode.XScale = "log";
                 
                    app.ElecBode.XGrid = "on";
                    app.ElecBode.YGrid = "on";
                    hold(app.ElecBode,'off')
                
                    app.ResistanceOhmEditField.Value = abs(X.R);
                    app.InductanceHEditField.Value = abs(X.L);
        
                    SendData = [app.SDO_SEND,...                  %SDO
                        (app.node_id+16), ...                      %identifier
                        2,...
                        app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_TERMINAL_RESISTANCE, app.SDO_REQU, 1, single(abs(X.R))), ...
                        app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_TERMINAL_INDUCTANCE, app.SDO_REQU, 1, single(abs(X.L))), ...
                        ];
                        
                    write(app.device,SendData,"uint8");

                   
                    app.WaitforPlotLabel_3.Visible = "off";

                %%
                elseif(isUsed == app.E_SYS_BEMF)
                    
                    t_c = double(typecast(uint8(data_uint8(3:6)),'single'));
                    t_c(isnan(t_c)) = 0;

                    app.BemfLabel.Text = strcat('c = ', num2str(t_c));

                    app.BEMFConstantIDStartButton.Enable = "on";

                %%
                elseif(isUsed == app.BEMF_ID_OVER_CURRENT)
                    
                    f = msgbox("Error: Please lower the gain","Error","error");

                %%
                elseif(isUsed == app.BW_CHECK)

%                     temp = double(typecast(uint8(data_uint8(3:6)),'single')) + 1;
%                     app.E_verify_index = temp;
% 
%                     app.E_verify_ref(temp*7-6) = double(typecast(uint8(data_uint8(7:10)),'single'));
%                     app.E_verify_act(temp*7-6) = double(typecast(uint8(data_uint8(11:14)),'single'));
% 
%                     app.E_verify_ref(temp*7-5) = double(typecast(uint8(data_uint8(15:18)),'single'));
%                     app.E_verify_act(temp*7-5) = double(typecast(uint8(data_uint8(19:22)),'single'));
% 
%                     app.E_verify_ref(temp*7-4) = double(typecast(uint8(data_uint8(23:26)),'single'));
%                     app.E_verify_act(temp*7-4) = double(typecast(uint8(data_uint8(27:30)),'single'));
% 
%                     app.E_verify_ref(temp*7-3) = double(typecast(uint8(data_uint8(31:34)),'single'));
%                     app.E_verify_act(temp*7-3) = double(typecast(uint8(data_uint8(35:38)),'single'));
% 
%                     app.E_verify_ref(temp*7-2) = double(typecast(uint8(data_uint8(39:42)),'single'));
%                     app.E_verify_act(temp*7-2) = double(typecast(uint8(data_uint8(43:46)),'single'));
% 
%                     app.E_verify_ref(temp*7-1) = double(typecast(uint8(data_uint8(47:50)),'single'));
%                     app.E_verify_act(temp*7-1) = double(typecast(uint8(data_uint8(51:54)),'single'));
% 
%                     app.E_verify_ref(temp*7) = double(typecast(uint8(data_uint8(55:58)),'single'));
%                     app.E_verify_act(temp*7) = double(typecast(uint8(data_uint8(59:62)),'single'));
% 
%                     if(temp >= 985)
% 
%                         edit_commBoard(app, 'Done');
% 
%                         cla(app.BW_PLOT); 
% 
%                         hold(app.BW_PLOT,'on')
%                         plot(app.BW_PLOT, app.E_verify_act(1:temp*7))
%                         plot(app.BW_PLOT, app.E_verify_ref(3:temp*7+2))
%                         hold(app.BW_PLOT,'off')
% 
%                         app.WaitforPlotLabel_5.Visible = "off";
%                         app.CurrentControllerBandwidthCheckButton.Enable = "on";
% 
%                         flush(app.device);
%                     end

                    %app.CurrentControllerBandwidthCheckButton.Enable = "on";

                    t_cnt  = double(typecast(uint8(data_uint8(3:6)),'single'));
                    t_ref1  = double(typecast(uint8(data_uint8(7:10)),'single'));
                    t_act1  = double(typecast(uint8(data_uint8(11:14)),'single')); 

                    t_ref2  = double(typecast(uint8(data_uint8(15:18)),'single'));
                    t_act2  = double(typecast(uint8(data_uint8(19:22)),'single')); 
                    
                    t_ref3  = double(typecast(uint8(data_uint8(23:26)),'single'));
                    t_act3  = double(typecast(uint8(data_uint8(27:30)),'single')); 
                    
                    t_ref4  = double(typecast(uint8(data_uint8(31:34)),'single'));
                    t_act4  = double(typecast(uint8(data_uint8(35:38)),'single')); 
                    
                    t_ref5  = double(typecast(uint8(data_uint8(39:42)),'single'));
                    t_act5  = double(typecast(uint8(data_uint8(43:46)),'single')); 
                    
                    t_ref6  = double(typecast(uint8(data_uint8(47:50)),'single'));
                    t_act6  = double(typecast(uint8(data_uint8(51:54)),'single')); 
                    
                    t_ref7  = double(typecast(uint8(data_uint8(55:58)),'single'));
                    t_act7  = double(typecast(uint8(data_uint8(59:62)),'single')); 


                    t_cnt(isnan(t_cnt))   = 0;

                    fprintf(app.file_current_ctrl_bandwidth_verification,'%.3f  %.3f\n', t_ref1, t_act1);
                    fprintf(app.file_current_ctrl_bandwidth_verification,'%.3f  %.3f\n', t_ref2, t_act2);
                    fprintf(app.file_current_ctrl_bandwidth_verification,'%.3f  %.3f\n', t_ref3, t_act3);
                    fprintf(app.file_current_ctrl_bandwidth_verification,'%.3f  %.3f\n', t_ref4, t_act4);
                    fprintf(app.file_current_ctrl_bandwidth_verification,'%.3f  %.3f\n', t_ref5, t_act5);
                    fprintf(app.file_current_ctrl_bandwidth_verification,'%.3f  %.3f\n', t_ref6, t_act6);
                    fprintf(app.file_current_ctrl_bandwidth_verification,'%.3f  %.3f\n', t_ref7, t_act7);

                    if (t_cnt == 984)

                        try
                            fclose(app.file_current_ctrl_bandwidth_verification);
                            edit_commBoard(app, 'Done');
                        catch                            
                        end

                        get_current_controll_frequency_response("ELEC_CurrCtrl_BWTest_TimeDomain.txt", app.DesiredBW2.Value);

                        % Plot Time Domain 
                        data = load("ELEC_CurrCtrl_BWTest_TimeDomain.txt");
                        cla(app.BW_PLOT); 

                        len = length(data(1:end-2, 2));

                        t = 0:(1/25000):(1/25000)*(len-1);

                        hold(app.BW_PLOT,'on');
                        plot(app.BW_PLOT, t, data(1:end-2,2), 'r'); %reference      
                        plot(app.BW_PLOT, t, data(3:end,1), 'k'); %measurement                         
                        hold(app.BW_PLOT,'off');
                        app.BW_PLOT.XGrid = "on";
                        app.BW_PLOT.YGrid = "on";         
                        app.BW_PLOT.Title.FontWeight = 'bold';
                        app.BW_PLOT.Title.FontSize = 14;

                        legend(app.BW_PLOT, {'Reference', 'Measurement'});
                        
                        app.WaitforPlotLabel_4.Visible = "off";
            

                        % Plot Frequency Response

                        data2 = load("ELEC_CurrCtrl_BWTest_FrequencyDomain.txt");

                        cla(app.CurrentControllerBodeMag); 
                        plot(app.CurrentControllerBodeMag, data2(:,1), data2(:,2), 'k'); 
                        app.CurrentControllerBodeMag.XGrid = "on";
                        app.CurrentControllerBodeMag.YGrid = "on"; 
                        app.CurrentControllerBodeMag.XScale = "log";
                        app.CurrentControllerBodeMag.XLim = [1000, 5000];
                        app.CurrentControllerBodeMag.Title.FontWeight = 'bold';
                        app.CurrentControllerBodeMag.Title.FontSize = 14;

                        cla(app.CurrentControllerBodePhase); 
                        plot(app.CurrentControllerBodePhase, data2(:,1), data2(:,3), 'k'); 
                        app.CurrentControllerBodePhase.XGrid = "on";
                        app.CurrentControllerBodePhase.YGrid = "on"; 
                        app.CurrentControllerBodePhase.XScale = "log";
                        app.CurrentControllerBodePhase.XLim = [1000, 5000];
                        app.CurrentControllerBodePhase.Title.FontWeight = 'bold';
                        app.CurrentControllerBodePhase.Title.FontSize = 14;

                        flush(app.device);
                        
                    end


                %%
                elseif(isUsed == app.FRICTION_ID_RAW_DATA)
                    
                    t_vel  = double(typecast(uint8(data_uint8(3:6)),'single'));
                    t_cur  = double(typecast(uint8(data_uint8(7:10)),'single'));

                    t_vel(isnan(t_vel)) = 0;
                    t_cur(isnan(t_cur)) = 0;

                    fprintf(app.file_friction_id_raw, '%.3f  %.3f \n', t_vel, t_cur);

                %%
                elseif(isUsed == app.FRICTION_ID_AVERAGED_DATA)
                    
                    t_vel  = double(typecast(uint8(data_uint8(3:6)),'single'));
                    t_cur  = double(typecast(uint8(data_uint8(7:10)),'single'));

                    t_vel(isnan(t_vel)) = 0;
                    t_cur(isnan(t_cur)) = 0;

                    fprintf(app.file_friction_id_avr, '%.3f  %.3f \n', t_vel, t_cur);

                %%
                elseif(isUsed == app.FRICTION_ID_DONE)

                    fclose(app.file_friction_id_raw);
                    fclose(app.file_friction_id_avr);

                    edit_commBoard(app, 'Friction ID Done');

                %%
                elseif(isUsed == app.FRICTION_COMPENSATOR_VERIFICATION)

                    t_vel  = double(typecast(uint8(data_uint8(6:9)),'single'));
                    t_input  = double(typecast(uint8(data_uint8(12:15)),'single'));

                    t_vel(isnan(t_vel)) = 0;
                    t_input(isnan(t_input)) = 0;

                    fprintf(app.file_friction_verification, '%.3f  %.3f \n', t_vel, t_input);


                %%
                elseif(isUsed == app.MECH_SYS_ID_SBS_RAW_DATA)

                    t_vel  = double(typecast(uint8(data_uint8(6:9)),'single'));
                    t_fre  = double(typecast(uint8(data_uint8(12:15)),'single'));
                    t_cur  = double(typecast(uint8(data_uint8(18:21)),'single'));
                    t_cnt  = double(typecast(uint8(data_uint8(24:27)),'uint32'));

                    t_vel(isnan(t_vel))   = 0;
                    t_fre(isnan(t_fre))   = 0;
                    t_cur(isnan(t_cur))   = 0;
                    t_cnt(isnan(t_cnt))   = 0;

                    fprintf(app.file_mech_system_id,'%d   %.3f  %.3f  %.3f\n', t_cnt, t_fre, t_cur, t_vel);

                %%
                elseif(isUsed == app.MECH_SYS_ID_SBS_DONE)

                    edit_commBoard(app, 'System ID Done');
                    app.AnalysisButton.Enable = "on";

                    try
                        fclose(app.file_mech_system_id);
                    catch
                    end
                    
                    flush(app.device);

                %%
                elseif(isUsed == app.IRC_VERIFICATION)

                    t_cur_ref  = double(typecast(uint8(data_uint8(6:9)),'single'));
                    t_cur_act  = double(typecast(uint8(data_uint8(12:15)),'single'));
                    t_irc_input  = double(typecast(uint8(data_uint8(18:21)),'single'));

                    t_cur_ref(isnan(t_cur_ref))   = 0;
                    t_cur_act(isnan(t_cur_act))   = 0;
                    t_irc_input(isnan(t_irc_input))   = 0;

                    fprintf(app.file_irc_verification,'%.3f  %.3f  %.3f\n', t_cur_ref, t_cur_act, t_irc_input);

                  
                %%
                elseif(isUsed == app.GET_IMPEDANCE_SINE_CTRL)

                    t_cnt  = double(typecast(uint8(data_uint8(6:9)),'uint32'));
                    t_ref  = double(typecast(uint8(data_uint8(12:15)),'single'));
                    t_act  = double(typecast(uint8(data_uint8(18:21)),'single'));
                    t_in_act  = double(typecast(uint8(data_uint8(24:27)),'single'));

                    t_cnt(isnan(t_cnt))   = 0;
                    t_ref(isnan(t_ref))   = 0;
                    t_act(isnan(t_act))   = 0;
                    t_in_act(isnan(t_in_act))   = 0;

                    fprintf(app.file_imp_sine_verification,'%d  %.3f  %.3f  %.3f\n', t_cnt, t_ref, t_act, t_in_act);
                  
                %%
                elseif(isUsed == app.GET_IMPEDANCE_REC_CTRL)

                    t_cnt  = double(typecast(uint8(data_uint8(6:9)),'uint32'));
                    t_ref  = double(typecast(uint8(data_uint8(12:15)),'single'));
                    t_act  = double(typecast(uint8(data_uint8(18:21)),'single'));
                    t_in_act  = double(typecast(uint8(data_uint8(24:27)),'single'));

                    t_cnt(isnan(t_cnt))   = 0;
                    t_ref(isnan(t_ref))   = 0;
                    t_act(isnan(t_act))   = 0;
                    t_in_act(isnan(t_in_act))   = 0;

                    fprintf(app.file_imp_rec_verification,'%d  %.3f  %.3f  %.3f\n', t_cnt, t_ref, t_act, t_in_act);

                %%  
                elseif(isUsed == app.GAIN_TUNER)

                    velP = double(typecast(uint8(data_uint8(3:6)),'single'));
                    velI = double(typecast(uint8(data_uint8(7:10)),'single'));
                    posP = double(typecast(uint8(data_uint8(11:14)),'single'));
                    posD = double(typecast(uint8(data_uint8(15:18)),'single'));

                    app.Vel_P_Gain.Text = strcat('P Gain = ', num2str(velP));
                    app.Vel_I_Gain.Text = strcat('I Gain = ', num2str(velI));
                    app.Pos_P_Gain.Text = strcat('P Gain = ', num2str(posP));
                    app.Pos_D_Gain.Text = strcat('D Gain = ', num2str(posD));

                    s = tf('s');
        
                    t_vel_system = 1/(app.inertia_value*s + app.damp_coef_value);
                    t_pos_system = 1/(app.inertia_value*s^2 + app.damp_coef_value*s);
                    
                    t_vel_controller = velP + velI/s;
                    t_pos_controller = posP + posD*s;
        
                    t_vel_cl_systemp = (t_vel_controller*t_vel_system)/(1+t_vel_controller*t_vel_system);
                    t_pos_cl_systemp = (t_pos_controller*t_pos_system)/(1+t_pos_controller*t_pos_system);
        
                    w = 0:1:500*2*pi;
                    [vel_sys_inv_mag, vel_sys_inv_phase] = bode(t_vel_cl_systemp, w);
                    [pos_sys_inv_mag, pos_sys_inv_phase] = bode(t_pos_cl_systemp, w);
        
                    vel_sys_inv_mag = 20*log10(squeeze(vel_sys_inv_mag));
                    pos_sys_inv_mag = 20*log10(squeeze(pos_sys_inv_mag));
        
                     cla(app.closed_loop_mag2); 
                     cla(app.closed_loop_phase2); 
        
                     hold(app.closed_loop_mag2,'on');
                     plot(app.closed_loop_mag2, w, (vel_sys_inv_mag), "r");
                     plot(app.closed_loop_mag2, w, (pos_sys_inv_mag), "b");
                     app.closed_loop_mag2.XScale = "log";
                     app.closed_loop_mag2.XGrid = "on";
                     app.closed_loop_mag2.YGrid = "on";
                     hold(app.closed_loop_mag2,'off');
        
                     hold(app.closed_loop_phase2,'on');
                     plot(app.closed_loop_phase2, w, squeeze(vel_sys_inv_phase), "r");
                     plot(app.closed_loop_phase2, w, squeeze(pos_sys_inv_phase), "b");
                     app.closed_loop_phase2.XScale = "log";
                     app.closed_loop_phase2.XGrid = "on";
                     app.closed_loop_phase2.YGrid = "on";
                     hold(app.closed_loop_phase2,'off');

                %%
                elseif(isUsed == app.GET_VELOCITY_CTRL)
                    
                    t_cnt = double(typecast(uint8(data_uint8(6:9)),'uint32'));
                    t_ref = double(typecast(uint8(data_uint8(12:15)),'single'));
                    t_act = double(typecast(uint8(data_uint8(18:21)),'single'));
                    t_cnt(isnan(t_cnt)) = 0;
                    t_ref(isnan(t_ref)) = 0;
                    t_act(isnan(t_act)) = 0;

                    fprintf(app.file_ctrl_verification,'%d  %.3f  %.3f  \n', t_cnt, t_ref, t_act);


                %%
                elseif(isUsed == app.GET_POSITION_CTRL)
                    
                    t_cnt = double(typecast(uint8(data_uint8(6:9)),'uint32'));
                    t_ref = double(typecast(uint8(data_uint8(12:15)),'single'));
                    t_act = double(typecast(uint8(data_uint8(18:21)),'single'));
                    t_cnt(isnan(t_cnt)) = 0;
                    t_ref(isnan(t_ref)) = 0;
                    t_act(isnan(t_act)) = 0;

                    fprintf(app.file_ctrl_verification,'%d  %.3f  %.3f  \n', t_cnt, t_ref, t_act);

                %%
                elseif(isUsed == app.GET_HALLSENSOR)
                    
                    t_time = double(typecast(uint8(data_uint8(3:6)),'uint32'));
                    t_H1 = double(typecast(uint8(data_uint8(7:10)),'uint32'));
                    t_H2 = double(typecast(uint8(data_uint8(11:14)),'uint32'));
                    t_H3 = double(typecast(uint8(data_uint8(15:18)),'uint32'));
                    t_logic = double(typecast(uint8(data_uint8(19:22)),'uint32'));

                    t_time(isnan(t_time)) = 0;
                    t_H1(isnan(t_H1)) = 0;
                    t_H2(isnan(t_H2)) = 0;
                    t_H3(isnan(t_H3)) = 0;
                    t_logic(isnan(t_logic)) = 0;

                    fprintf(app.file_hall_sensor,'%d %d %d %d %d\n', t_time, t_H1, t_H2, t_H3, t_logic);

                    if(mod(uint32(floor(t_time)), 4) == 0)
                        app.HallSensorAEditField.Value = t_H1;
                        app.HallSensorBEditField.Value = t_H2;
                        app.HallSensorCEditField.Value = t_H3;
                        app.HallSensorLogicEditField.Value   = t_logic;
                    end

                %%
                elseif(isUsed == app.GET_INCENCODER)
                    
                    t_time = double(typecast(uint8(data_uint8(3:6)),'uint32'));
                    t_count = double(typecast(uint8(data_uint8(7:10)),'int32'));
                    t_rad = double(typecast(uint8(data_uint8(11:14)),'single'));

                    t_time(isnan(t_time)) = 0;
                    t_count(isnan(t_count)) = 0;
                    t_rad(isnan(t_rad)) = 0;

                    fprintf(app.file_inc_encoder,'%d  %d  %.3f  \n', t_time, t_count, t_rad);

                    if(mod(uint32(floor(t_time)), 4) == 0)
                        app.IncEncCounterEditField.Value = t_count;
                        app.IncEncAnglerDegEditField.Value = t_rad*180/pi;
                    end

                %%
                elseif(isUsed == app.GET_ABSENCODER1)
                    
                    t_time = double(typecast(uint8(data_uint8(3:6)),'uint32'));
                    t_deg = double(typecast(uint8(data_uint8(7:10)),'single'));
                    t_offset = double(typecast(uint8(data_uint8(11:14)),'single'));

                    t_time(isnan(t_time)) = 0;
                    t_deg(isnan(t_deg)) = 0;
                    t_offset(isnan(t_offset)) = 0;

                    fprintf(app.file_abs_encoder,'%d  %.3f  %.3f  \n', t_time, t_deg, t_offset);

                    if(mod(uint32(floor(t_time)), 4) == 0)
                        app.AbsoluteEncoderCH1CurrentAngleDeg.Value = t_deg;
                    end

                %%
                elseif(isUsed == app.GET_ABSENCODER2)
                    
                    t_time = double(typecast(uint8(data_uint8(3:6)),'uint32'));
                    t_deg = double(typecast(uint8(data_uint8(7:10)),'single'));
                    t_offset = double(typecast(uint8(data_uint8(11:14)),'single'));

                    t_time(isnan(t_time)) = 0;
                    t_deg(isnan(t_deg)) = 0;
                    t_offset(isnan(t_offset)) = 0;

                    fprintf(app.file_abs_encoder,'%d  %.3f  %.3f  \n', t_time, t_deg, t_offset);

                    if(mod(uint32(floor(t_time)), 4) == 0)
                        app.AbsoluteEncoderCH2CurrentAngleDeg.Value = t_deg;
                    end
                %%
                elseif(isUsed == app.GET_VSD_UPPER_LIMIT)

                    t_val = double(typecast(uint8(data_uint8(3:6)),'single'));

                    t_val(isnan(t_val)) = 0;

                    app.UpperLimitdegEditField.Value = t_val;

                %%
                elseif(isUsed == app.GET_VSD_LOWER_LIMIT)
                    
                    t_val = double(typecast(uint8(data_uint8(3:6)),'single'));

                    t_val(isnan(t_val)) = 0;

                    app.LowerLimitdegEditField.Value = t_val;

                %%
                elseif(isUsed == app.VSD_VERIFICATION_DATA)
                    
                    t_cnt  = double(typecast(uint8(data_uint8(6:9)),'uint32'));
                    t_pos  = double(typecast(uint8(data_uint8(12:15)),'single'));
                    t_vsd_in  = double(typecast(uint8(data_uint8(18:21)),'single'));

                    t_cnt(isnan(t_cnt))   = 0;
                    t_pos(isnan(t_pos))   = 0;
                    t_vsd_in(isnan(t_vsd_in))   = 0;

                    fprintf(app.file_vsd_verification,'%d  %.3f  %.3f\n', t_cnt, t_pos, t_vsd_in);

                %%
                elseif(isUsed == app.GET_BACKLASH_TEST)

                    t_cnt  = double(typecast(uint8(data_uint8(6:9)),'uint32'));
                    t_inc_pos  = double(typecast(uint8(data_uint8(12:15)),'single'));
                    t_abs_pos  = double(typecast(uint8(data_uint8(18:21)),'single'));

                    t_cnt(isnan(t_cnt))   = 0;
                    t_inc_pos(isnan(t_inc_pos))   = 0;
                    t_abs_pos(isnan(t_abs_pos))   = 0;

                    if(t_abs_pos < 0)
                        t_abs_pos = t_abs_pos + 360;
                    end

                    t_inc_pos = rad2deg(t_inc_pos);

                    fprintf(app.file_backlash,'%d  %.6f  %.6f\n', t_cnt, t_inc_pos, t_abs_pos);

                %%
                elseif(isUsed == app.GET_DOB_DATA)

                    t_cnt  = double(typecast(uint8(data_uint8(6:9)),'uint32'));
                    t_ref_pos  = double(typecast(uint8(data_uint8(12:15)),'single'));
                    t_act_pos  = double(typecast(uint8(data_uint8(18:21)),'single'));
                    t_disturb  = double(typecast(uint8(data_uint8(24:27)),'single'));
                    t_cur_act  = double(typecast(uint8(data_uint8(30:33)),'single'));

                    t_cnt(isnan(t_cnt))   = 0;
                    t_ref_pos(isnan(t_ref_pos))   = 0;
                    t_act_pos(isnan(t_act_pos))   = 0;
                    t_disturb(isnan(t_disturb))   = 0;
                    t_cur_act(isnan(t_cur_act))   = 0;

                    fprintf(app.file_dob_verification,'%d  %.6f  %.6f  %.6f  %.6f\n', t_cnt, t_ref_pos, t_act_pos, t_disturb, t_cur_act);

                %%
                elseif(isUsed == app.GET_DIRECTION_SET_DATA)

                    t_pos         = double(typecast(uint8(data_uint8(6:9)),'single'));
                    t_step        = double(typecast(uint8(data_uint8(12)),'uint8'));
                    t_hall_logic  = double(typecast(uint8(data_uint8(15)),'uint8'));


                    t_pos(isnan(t_pos))               = 0;
                    t_step(isnan(t_step))             = 0;
                    t_hall_logic(isnan(t_hall_logic)) = 0;

                    fprintf(app.file_direction_set,'%.6f  %d  %d\n', t_pos, t_step, t_hall_logic);

                %%
                elseif(isUsed == app.GET_DIRECTION_SET_DONE)

                    edit_commBoard(app, 'Direction Set');

                    try
                        fclose(app.file_direction_set);
                    catch
                    end

                    flush(app.device);

                    data = load('Setting_CommutationSet.txt');
                    
                    cla(app.direction_set_step_plot); 
                    cla(app.direction_set_enc_plot); 
                    cla(app.direction_set_hall_step_plot); 
                
                    hold(app.direction_set_step_plot,'on')
                    plot(app.direction_set_step_plot, data(:,2), 'b');
                    app.direction_set_step_plot.XGrid = "on";
                    app.direction_set_step_plot.YGrid = "on";
                    hold(app.direction_set_step_plot,'off')

                    hold(app.direction_set_enc_plot,'on')
                    plot(app.direction_set_enc_plot, data(:,1), 'b');
                    app.direction_set_enc_plot.XGrid = "on";
                    app.direction_set_enc_plot.YGrid = "on";
                    hold(app.direction_set_enc_plot,'off');

                    
                    hold(app.direction_set_hall_step_plot,'on')
                    plot(app.direction_set_hall_step_plot, data(:,3), 'b');
                    app.direction_set_hall_step_plot.XGrid = "on";
                    app.direction_set_hall_step_plot.YGrid = "on";
                    hold(app.direction_set_hall_step_plot,'off')

                %%
                elseif(isUsed == app.SAVE_DONE)

                    f = msgbox("Save Success");

                %%
                elseif(isUsed == app.GET_VE_TEST_DATA)

                    t_cnt  = double(typecast(uint8(data_uint8(6:9)),'uint32'));
                    t_act_vel  = double(typecast(uint8(data_uint8(12:15)),'single'));
                    t_est_vel  = double(typecast(uint8(data_uint8(18:21)),'single'));

                    t_cnt(isnan(t_cnt))   = 0;
                    t_act_vel(isnan(t_act_vel))   = 0;
                    t_est_vel(isnan(t_est_vel))   = 0;

                    
                    fprintf(app.file_ve,'%d  %.6f  %.6f\n', t_cnt, t_act_vel, t_est_vel);

                %%
                elseif(isUsed == app.GET_SYSTEM_ID_VERIFY)

                    t_cnt  = double(typecast(uint8(data_uint8(3:6)),'uint32'));
                    t_input  = double(typecast(uint8(data_uint8(7:10)),'single'));
                    t_output  = double(typecast(uint8(data_uint8(11:14)),'single'));

                    t_cnt(isnan(t_cnt))   = 0;
                    t_input(isnan(t_input))   = 0;
                    t_output(isnan(t_output))   = 0;

                    fprintf(app.file_sys_id_verify,'%d  %.6f  %.6f\n', t_cnt, t_input, t_output);

                %%
                elseif(isUsed == app.VE_KF_SETTING_ERROR)
                    f = msgbox("Error: You need to identify your system first.","Error","error");

                %%
                elseif(isUsed == app.GET_FF_CTRL)

                    t_cnt  = double(typecast(uint8(data_uint8(6:9)),'uint32'));
                    t_ref_pos  = double(typecast(uint8(data_uint8(12:15)),'single'));
                    t_act_pos  = double(typecast(uint8(data_uint8(18:21)),'single'));
                    t_input  = double(typecast(uint8(data_uint8(24:27)),'single'));

                    t_cnt(isnan(t_cnt))   = 0;
                    t_ref_pos(isnan(t_ref_pos))   = 0;
                    t_act_pos(isnan(t_act_pos))   = 0;
                    t_input(isnan(t_input))   = 0;

                    fprintf(app.file_ff_verification,'%d  %.6f  %.6f  %.6f\n', t_cnt, t_ref_pos, t_act_pos, t_input);

                %%
                elseif(isUsed == app.GET_TOTAL_CTRL)

                    t_cnt  = double(typecast(uint8(data_uint8(6:9)),'uint32'));
                    t_ref_pos  = double(typecast(uint8(data_uint8(12:15)),'single'));
                    t_act_pos  = double(typecast(uint8(data_uint8(18:21)),'single'));
                    t_vel  = double(typecast(uint8(data_uint8(24:27)),'single'));
                    t_input  = double(typecast(uint8(data_uint8(30:33)),'single'));
                    t_dist  = double(typecast(uint8(data_uint8(36:39)),'single'));

                    t_cnt(isnan(t_cnt))   = 0;
                    t_ref_pos(isnan(t_ref_pos))   = 0;
                    t_act_pos(isnan(t_act_pos))   = 0;
                    t_vel(isnan(t_vel))   = 0;
                    t_input(isnan(t_input))   = 0;
                    t_dist(isnan(t_dist))   = 0;

                    fprintf(app.file_total_verification,'%d  %.8f  %.8f  %.8f  %.8f  %.8f\n', t_cnt, t_ref_pos, t_act_pos, t_vel, t_input, t_dist);

                %%
                elseif(isUsed == app.ADV_FRICTION_ID_DATA)
                    
                    t_cnt  = double(typecast(uint8(data_uint8(3:6)),'single'));
                    t_vel  = double(typecast(uint8(data_uint8(7:10)),'single'));
                    t_cur  = double(typecast(uint8(data_uint8(11:14)),'single'));

                    t_cnt(isnan(t_cnt))   = 0;
                    t_vel(isnan(t_vel))   = 0;
                    t_cur(isnan(t_cur))   = 0;

                    fprintf(app.file_adv_friction_id,'%.6f  %.6f  %.6f\n', t_cnt, t_vel, t_cur);

                %%
                elseif(isUsed == app.ADV_FRICTION_ID_DONE)
                    
                    try
                        fclose(app.file_adv_friction_id);
                        edit_commBoard(app, 'Friction ID Done');
                    catch
                        f = msgbox("Warning: Friction ID was not implemented.");
                    end
                    
                    if (app.friction_compensator_evluation == 0)
                        try
                          
                            data = load('MECH_AdvFrictionID_FrictionModelID.txt');    
                            cla(app.adv_friction_id_plot); 
        
                            hold(app.adv_friction_id_plot,'on')
                            plot(app.adv_friction_id_plot, data(:,2), data(:,3), 'k.');
                            app.adv_friction_id_plot.XGrid = "on";
                            app.adv_friction_id_plot.YGrid = "on";
                            hold(app.adv_friction_id_plot,'off')
                        catch
                            f = msgbox("Error: File Error.","Error","error");
                        end
                    end


                %%
                elseif(isUsed == app.GET_GAIT_PHASE)
                    
                    t_gait_phase  = double(typecast(uint8(data_uint8(6:9)),'single'));
                    t_deg_acc     = double(typecast(uint8(data_uint8(12:15)),'single'));
                    t_deg_gyr     = double(typecast(uint8(data_uint8(18:21)),'single'));
                    t_deg_acc_f   = double(typecast(uint8(data_uint8(24:27)),'single'));
                    t_deg_gyr_f   = double(typecast(uint8(data_uint8(30:33)),'single'));      
                    t_deg_tvcf    = double(typecast(uint8(data_uint8(36:39)),'single'));

                    t_gait_phase(isnan(t_gait_phase))   = 0;
                    t_deg_acc(isnan(t_deg_acc))   = 0;
                    t_deg_gyr(isnan(t_deg_gyr))   = 0;
                    t_deg_acc_f(isnan(t_deg_acc_f))   = 0;
                    t_deg_gyr_f(isnan(t_deg_gyr_f))   = 0;
                    t_deg_tvcf(isnan(t_deg_tvcf))   = 0;

                    fprintf(app.file_WIDM_gait_phase,'%.3f  %.3f  %.3f  %.3f  %.3f  %.3f\n', t_gait_phase, t_deg_acc, t_deg_gyr, t_deg_acc_f, t_deg_gyr_f, t_deg_tvcf);

                %%
                elseif(isUsed == app.GET_ACC_GYRO)
                    
                    t_acc_x = double(typecast(uint8(data_uint8(6:9)),'single'));
                    t_acc_y = double(typecast(uint8(data_uint8(12:15)),'single'));
                    t_acc_z = double(typecast(uint8(data_uint8(18:21)),'single'));
                    t_gyr_x = double(typecast(uint8(data_uint8(24:27)),'single'));
                    t_gyr_y = double(typecast(uint8(data_uint8(30:33)),'single'));      
                    t_gyr_z = double(typecast(uint8(data_uint8(36:39)),'single'));

                    t_acc_x(isnan(t_acc_x))   = 0;
                    t_acc_y(isnan(t_acc_y))   = 0;
                    t_acc_z(isnan(t_acc_z))   = 0;
                    t_gyr_x(isnan(t_gyr_x))   = 0;
                    t_gyr_y(isnan(t_gyr_y))   = 0;
                    t_gyr_z(isnan(t_gyr_z))   = 0;

                    fprintf(app.file_WIDM_acc_gyr,'%.3f  %.3f  %.3f  %.3f  %.3f  %.3f\n', t_acc_x, t_acc_y, t_acc_z, t_gyr_x, t_gyr_y, t_gyr_z);

                %%
                elseif(isUsed == app.GET_QUATERNION)
                    
                    t_acc_x = double(typecast(uint8(data_uint8(6:9)),'single'));
                    t_acc_y = double(typecast(uint8(data_uint8(10:13)),'single'));
                    t_acc_z = double(typecast(uint8(data_uint8(14:17)),'single'));
                    t_gyr_x = double(typecast(uint8(data_uint8(18:21)),'single'));
                    t_gyr_y = double(typecast(uint8(data_uint8(22:25)),'single'));      
                    t_gyr_z = double(typecast(uint8(data_uint8(26:29)),'single'));

                    t_mag_x = double(typecast(uint8(data_uint8(32:35)),'single'));
                    t_mag_y = double(typecast(uint8(data_uint8(36:39)),'single'));
                    t_mag_z = double(typecast(uint8(data_uint8(40:43)),'single'));

                    t_quat_x = double(typecast(uint8(data_uint8(46:47)),'int16'))/32768;
                    t_quat_y = double(typecast(uint8(data_uint8(48:49)),'int16'))/32768;
                    t_quat_z = double(typecast(uint8(data_uint8(50:51)),'int16'))/32768;     
                    t_quat_w = double(typecast(uint8(data_uint8(52:53)),'int16'))/32768;

                    t_acc_x(isnan(t_acc_x))   = 0;
                    t_acc_y(isnan(t_acc_y))   = 0;
                    t_acc_z(isnan(t_acc_z))   = 0;
                    t_gyr_x(isnan(t_gyr_x))   = 0;
                    t_gyr_y(isnan(t_gyr_y))   = 0;
                    t_gyr_z(isnan(t_gyr_z))   = 0;
                    t_mag_x(isnan(t_mag_x))   = 0;
                    t_mag_y(isnan(t_mag_y))   = 0;
                    t_mag_z(isnan(t_mag_z))   = 0;

                    t_quat_x(isnan(t_quat_x))   = 0;
                    t_quat_y(isnan(t_quat_y))   = 0;
                    t_quat_z(isnan(t_quat_z))   = 0;
                    t_quat_w(isnan(t_quat_w))   = 0;

                    fprintf(app.file_quaternion,'%.3f  %.3f  %.3f  %.3f %.3f  %.3f  %.3f  %.3f  %.3f  %.3f  %.3f  %.3f  %.3f\n', t_acc_x, t_acc_y, t_acc_z, t_gyr_x, t_gyr_y, t_gyr_z, t_mag_x, t_mag_y, t_mag_z, t_quat_x, t_quat_y, t_quat_z, t_quat_w);

                    
                %%
                elseif(isUsed == app.FRICTION_ID_VEL_CTRL_EVAL)

                    t_vel_act  = double(typecast(uint8(data_uint8(6:9)),'single'));
                    t_vel_filt = double(typecast(uint8(data_uint8(12:15)),'single'));
                    t_cnt      = double(typecast(uint8(data_uint8(18:21)),'uint32'));
                    t_vel_ref  = app.VelocityRefradsEditField.Value;

                    t_vel_act(isnan(t_vel_act))   = 0;
                    t_vel_filt(isnan(t_vel_filt))   = 0;
                    t_cnt(isnan(t_cnt))   = 0;
                    t_vel_ref(isnan(t_vel_ref))   = 0;

                    fprintf(app.file_friction_id_vel_ctrl,'%d  %.3f  %.3f  %.3f\n', t_cnt, t_vel_ref, t_vel_act, t_vel_filt);

                %%
                elseif(isUsed == app.GET_SBS_ID_VERIFY)

                    t_cnt            = double(typecast(uint8(data_uint8(6:9)),'uint32'));
                    t_id_input       = double(typecast(uint8(data_uint8(12:15)),'single'));
                    t_friction_input = double(typecast(uint8(data_uint8(18:21)),'single'));
                    t_vel            = double(typecast(uint8(data_uint8(24:27)),'single'));

                    t_cnt(isnan(t_cnt))                         = 0;
                    t_id_input(isnan(t_id_input))               = 0;
                    t_friction_input(isnan(t_friction_input))   = 0;
                    t_vel(isnan(t_vel))                         = 0;

                    fprintf(app.file_sys_id_verify ,'%d  %.3f  %.3f  %.3f\n', t_cnt, t_id_input, t_friction_input, t_vel);

                %%
                elseif(isUsed == app.GET_DOB_VERIFY)

                    t_cnt            = double(typecast(uint8(data_uint8(6:9)),'uint32'));
                    t_dob_input      = double(typecast(uint8(data_uint8(12:15)),'single'));
                    t_id_input       = double(typecast(uint8(data_uint8(18:21)),'single'));
                    t_friction_input = double(typecast(uint8(data_uint8(24:27)),'single'));
                    t_vel            = double(typecast(uint8(data_uint8(30:33)),'single'));

                    t_dob_input(isnan(t_dob_input))             = 0;
                    t_cnt(isnan(t_cnt))                         = 0;
                    t_id_input(isnan(t_id_input))               = 0;
                    t_friction_input(isnan(t_friction_input))   = 0;
                    t_vel(isnan(t_vel))                         = 0;

                    fprintf(app.file_dob_evaluation,'%d  %.3f  %.3f %.3f %.3f\n', t_cnt, t_dob_input, t_id_input, t_friction_input, t_vel);

                   %%
                elseif(isUsed == app.GET_ELEC_ANGLE_EST_EV)


                    t_cnt             = double(typecast(uint8(data_uint8(6:9)),  'uint32'));
                    t_pos_ref         = double(typecast(uint8(data_uint8(12:15)),'single'));
                    t_pos_act         = double(typecast(uint8(data_uint8(18:21)),'single'));
                    t_hall            = double(typecast(uint8(data_uint8(24)),    'uint8'));
                    t_elec_angle      = double(typecast(uint8(data_uint8(27:28)), 'uint16'));
                    t_hall_elec_angle = double(typecast(uint8(data_uint8(31:32)), 'uint16'));

                    t_I_U_act         = double(typecast(uint8(data_uint8(35:38)), 'int32'));
                    t_I_V_act         = double(typecast(uint8(data_uint8(39:42)), 'int32'));                    
                    t_I_W_act         = double(typecast(uint8(data_uint8(43:46)), 'int32'));
 
                    t_I_U_KF          = double(typecast(uint8(data_uint8(49:52)), 'single'));
                    t_I_V_KF          = double(typecast(uint8(data_uint8(53:56)), 'single'));                    
                    t_I_W_KF          = double(typecast(uint8(data_uint8(57:60)), 'single'));

                    t_e_homing_done   = double(typecast(uint8(data_uint8(63)), 'uint8'));

                    t_cnt(isnan(t_cnt))                 = 0;
                    t_pos_ref(isnan(t_pos_ref))         = 0;
                    t_pos_act(isnan(t_pos_act))         = 0;
                    t_hall(isnan(t_hall))               = 0;
                    t_elec_angle(isnan(t_elec_angle))           = 0;
                    t_hall_elec_angle(isnan(t_hall_elec_angle)) = 0;
                    t_I_U_act(isnan(t_I_U_act))   = 0;
                    t_I_V_act(isnan(t_I_V_act))   = 0;
                    t_I_W_act(isnan(t_I_W_act))   = 0;
                    t_I_U_KF(isnan(t_I_U_KF))   = 0;
                    t_I_V_KF(isnan(t_I_V_KF))   = 0;
                    t_I_W_KF(isnan(t_I_W_KF))   = 0;
                    t_e_homing_done(isnan(t_e_homing_done)) = 0;
 
                    fprintf(app.file_ctrl_verification,'%d  %.3f  %.3f  %d  %d  %d  %d  %d  %d  %.3f  %.3f  %.3f %d\n', t_cnt, t_pos_ref, t_pos_act, t_hall, t_elec_angle, t_hall_elec_angle, t_I_U_act, t_I_V_act, t_I_W_act, t_I_U_KF, t_I_V_KF, t_I_W_KF, t_e_homing_done);
                
                    %%     
                elseif(isUsed == app.GET_HALL_VS_INCENC_DATA)

                    t_cnt             = double(typecast(uint8(data_uint8(6:9)),   'int32'));
                    t_incenc_cnt      = double(typecast(uint8(data_uint8(10:13)), 'int32'));
                    t_abs_cnt         = double(typecast(uint8(data_uint8(14:17)), 'int32'));
                    t_IT_incenc_cnt   = double(typecast(uint8(data_uint8(18:21)), 'int32'));
                    t_IT_hall_cnt     = double(typecast(uint8(data_uint8(22:25)), 'int32'));

                    t_cnt(isnan(t_cnt))                             = 0;
                    t_incenc_cnt(isnan(t_incenc_cnt))               = 0;
                    t_abs_cnt(isnan(t_abs_cnt))                     = 0;
                    t_IT_incenc_cnt(isnan(t_IT_incenc_cnt))         = 0;
                    t_IT_hall_cnt(isnan(t_IT_hall_cnt))             = 0;       
 
                    fprintf(app.file_ctrl_verification,'%d  %d   %d   %d    %d\n', t_cnt, t_incenc_cnt, t_abs_cnt, t_IT_incenc_cnt, t_IT_hall_cnt);   

                    %%
                elseif(isUsed == app.GET_F_VECTOR_EVAL)

                    t_cnt               = double(typecast(uint8(data_uint8(6:9)),   'int32'));
                    t_cur_ref           = double(typecast(uint8(data_uint8(12:15)), 'single'));
                    t_cur_act           = double(typecast(uint8(data_uint8(18:21)), 'single'));

                    t_cnt(isnan(t_cnt))                       = 0;
                    t_cur_ref(isnan(t_cur_ref))               = 0;
                    t_cur_act(isnan(t_cur_act))               = 0;

                    fprintf(app.file_pfi_evaluation,'%d  %.4f  %.4f \n', t_cnt, t_cur_ref, t_cur_act);                              

                    %%
                    elseif(isUsed == app.GET_P_VECTOR_EVAL)

                    t_cnt               = double(typecast(uint8(data_uint8(6:9)),   'int32'));
                    t_pos_ref           = double(typecast(uint8(data_uint8(12:15)), 'single')* 180/pi) ;
                    t_pos_act           = double(typecast(uint8(data_uint8(18:21)), 'single')* 180/pi) ;

                    t_cnt(isnan(t_cnt))                       = 0;
                    t_pos_ref(isnan(t_pos_ref))               = 0;
                    t_pos_act(isnan(t_pos_act))               = 0;

                    fprintf(app.file_pfi_evaluation,'%d  %.4f %.4f\n', t_cnt, t_pos_ref, t_pos_act);     

                    %%
                    elseif(isUsed == app.GET_I_VECTOR_EVAL)

                    t_cnt               = double(typecast(uint8(data_uint8(6:9)),   'int32'));
                    t_pos_ref           = double(typecast(uint8(data_uint8(12:15)), 'single')* 180/pi);
                    t_pos_act           = double(typecast(uint8(data_uint8(18:21)), 'single')* 180/pi);
                    t_input             = double(typecast(uint8(data_uint8(24:27)), 'single'));

                    t_cnt(isnan(t_cnt))                       = 0;
                    t_pos_ref(isnan(t_pos_ref))               = 0;
                    t_pos_act(isnan(t_pos_act))               = 0;
                    t_input(isnan(t_input))               = 0;

                    fprintf(app.file_pfi_evaluation,'%d %.4f %.4f %.4f \n', t_cnt, t_pos_ref, t_pos_act, t_input);   
                    
                    %%
                    elseif(isUsed == app.GET_LINK_LUT)

                    t_cnt               = double(typecast(uint8(data_uint8(6:9)),   'int32'));
                    t_pos_inc           = double(typecast(uint8(data_uint8(12:15)), 'single'))* 180/pi;
                    t_pos_abs           = double(typecast(uint8(data_uint8(18:21)), 'single'));

                    t_cnt(isnan(t_cnt))                       = 0;
                    t_pos_inc(isnan(t_pos_inc))               = 0;
                    t_pos_abs(isnan(t_pos_abs))               = 0;

                    fprintf(app.file_abs_encoder,'%d %.4f %.4f\n', t_cnt, t_pos_inc, t_pos_abs);                  
                end
            end
        end      


        %%
        function results = pack_sdoUnit(app, task_id, sdo_id, status, length, value)

            temp = [task_id, sdo_id, status, length];

            for i = 1:length
                temp = [temp, typecast(value(i),'uint8')];
            end
            
            results = temp;

        end

        
        function results = pack_pdoUnit(app, task_id, pdo_id, value)

            temp = [task_id, pdo_id, typecast(value,'uint8')];
            
            results = temp;

        end

    end
    
    methods (Access = public)
    
        %%
         function ContactSerial(app,src,~) 
            app.TextArea.Value = "Sucess";
         end

        %%
         function PropertySetChosen(app,num)

            app.TabGroup.Visible = 'on';
            app.tap_activate = 1;

            switch num
                case 1
                    app.TabGroup.SelectedTab = app.SettingTab;
                case 2
                    app.TabGroup.SelectedTab = app.SettingTab;
            end
         end

         function SetNodeID(app,num)

            app.node_id = num;

         end

         %%
         function FigCreate(app)
             app.fig = figure;
             app.ax = axes(app.fig);
         
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,"center");

            app.AdvFrictionIDTab.Parent = [];
            app.SystemIDTab.Parent = [];
            app.VelocityEstimatorTab.Parent = [];
            app.IRCTab.Parent = [];
            app.DOBTab.Parent = [];
            app.PDControllerTab.Parent = [];
            app.ImpedanceControllerTab.Parent = [];
            app.FeedforwardTab.Parent = [];
            app.VerificationTab.Parent = [];

        end

        % Drop down opening function: CommunicationPort
        function CommunicationPortOpening(app, event)
            app.CommunicationPort.Items = serialportlist;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            
            app.TextArea.Value = "Please wait";

            try
                app.device = serialport(app.CommunicationPort.Value, 115200);
                configureTerminator(app.device, "CR/LF");
                app.device.UserData = struct("Data","","Count",67);
                configureCallback(app.device,"terminator",@app.readData);
            catch
                app.TextArea.Value = "Check the Serial Port";
                return;
            end

            app.DialogApp = DataLoad(app,app.device);
            app.TextArea.Value = "Connected";
            app.tap_activate = 0;
            
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.TextArea.Value = "Please Wait";

            app.device = [];

            app.ConnectButton.Enable = "on";
            app.TextArea.Value = "Disconnected";
            app.TabGroup.Visible = 'off';
            
        end

        % Button pushed function: MotorName_ApplyButton
        function MotorName_ApplyButtonPushed(app, event)
            
            if(length(app.MotorName.Value) > 30)
                f = msgbox("Warning: Please name within 30 characters");
            else
                SendData = [app.SDO_SEND,...                    %SDO
                            (app.node_id+16), ...                      %identifier
                            1,...
                            app.TASK_ID_LOWLEVEL, ...
                            app.SDO_ID_LOWLEVEL_NAME, ...
                            app.SDO_REQU, ...
                            1, ...
                            length(app.MotorName.Value), ...
                            app.MotorName.Value, ...
                            ];
    
                    write(app.device,SendData,"uint8");

            end

        end

        % Button pushed function: MotorSetting_ApplyButton
        function MotorSetting_ApplyButtonPushed(app, event)
            
            SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...                      %identifier
                5,...
                app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_POLE_PAIR,                   app.SDO_REQU, 1, uint8(app.PolePair.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_ENCODER_RESOLUTION,          app.SDO_REQU, 1, uint16(app.EncResolution.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL, app.SDO_ID_MIDLEVEL_ENCODER_RESOLUTION,          app.SDO_REQU, 1, uint16(app.EncResolution.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_GEAR_RATIO,                  app.SDO_REQU, 1, single(app.GearRatio.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_TORQUE_CONSTANT,             app.SDO_REQU, 1, single(app.TorqueConstantNmAEditField.Value)), ...
                ];

                write(app.device,SendData,"uint8");           


            SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...                      %identifier
                4,...
                app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_VELOCITY_CONSTANT,           app.SDO_REQU, 1, single(app.VelocityConstantVradsEditField.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_PEAK_CURRENT_LIMIT,          app.SDO_REQU, 1, single(app.PeakCurrentLimitEdit.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_CONTINUOUS_CURRENT_LIMIT,    app.SDO_REQU, 1, single(app.ContinuousCurrentLimitEdit.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_MAX_VELOCITY,                app.SDO_REQU, 1, single(app.MaxVelocity.Value)), ...
                ];           

                write(app.device,SendData,"uint8");

        end

        % Button pushed function: StartCommutationSettingButton
        function StartCommutationSettingButtonPushed(app, event)
         
            app.file_direction_set = fopen('Setting_CommutationSet.txt','w');

             SendData = [app.SDO_SEND,...                  %SDO
                 (app.node_id+16), ...                      %identifier
                 3,...
                 app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_COMMUTATION_DUTY,   app.SDO_REQU, 1, uint16(app.FocedVoltage_Edit.Value*100)), ...
                 app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_SET_ROUTINE,        app.SDO_REQU, 1, uint8(app.ROUTINE_ID_LOWLEVEL_DIRECTION_SET)), ...
                 app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_USER_DIRECTION,     app.SDO_REQU, 1, uint8(0)),...
                 ];

                write(app.device,SendData,"uint8");

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 3, ...
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_POSITION), ...
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_COMMUTATION_STEP), ...
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_HALL_SENSOR_LOGIC), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_DIRECTION_SET_DATA)), ...
                    ];

                write(app.device,SendData,"uint8");

             SendData = [app.SDO_SEND,...                  %SDO
                 (app.node_id+16), ...                      %identifier
                 2,...
                 app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_SET_STATE,          app.SDO_REQU, 1, uint8(app.State_Enable)),...
                 app.pack_sdoUnit(app.TASK_ID_MSG,      app.SDO_ID_MSG_SET_STATE,          app.SDO_REQU, 1, uint8(app.State_Enable)),...
                 ];

             write(app.device,SendData,"uint8");

            app.DialogApp = Commutation_DirectionSet(app,app.device);
             
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            if(app.tap_activate == 0)
                app.TabGroup.SelectedTab = event.OldValue;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
                SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...                      %identifier
                    1,...
                    app.TASK_ID_MSG, ...
                    app.SDO_ID_MSG_MS_ENUM, ...
                    app.SDO_REQU, ...
                    1, ...
                    app.SAVE_PROPERTIES, ...
                    ];
            
                write(app.device,SendData,"uint8");
        end

        % Button pushed function: BEMFConstantIDStartButton
        function BEMFConstantIDStartButtonPushed(app, event)
                
                app.BEMFConstantIDStartButton.Enable = "on";
                
                SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...                      %identifier
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_BEMF_ID_VELOCITY, app.SDO_REQU, 1, single(app.AngularVelocity.Value)), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_BEMF_ID_GAIN_PCTG, app.SDO_REQU, 1, uint8(app.VelocityControllerGainSlider.Value)), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_SET_ROUTINE,      app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_ELECTRICAL_BEMF_ID), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_SET_STATE,        app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];
            
                write(app.device,SendData,"uint8");

                SendData = [2,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ]; 

                write(app.device,SendData,"uint8");

                app.DesiredBW2.Value = 1000;

        end

        % Button pushed function: ElectricalSystemIDStartButton
        function ElectricalSystemIDStartButtonPushed(app, event)
            
            app.WaitforPlotLabel_3.Visible = "on";

            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_ELEC_SYSTEM_ID_MAG,  app.SDO_REQU, 1, single(app.InputVoltageVEditField.Value)), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_SET_STATE,           app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_SET_ROUTINE,         app.SDO_REQU, 1, uint8(app.ROUTINE_ID_LOWLEVEL_ELECTRICAL_SYS_ID)), ...
                    ]; 

                write(app.device,SendData,"uint8");
            
                
            SendData = [2,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ]; 

                write(app.device,SendData,"uint8");

               
        end

        % Button pushed function: CurrentControllerBandwidthCheckButton
        function CurrentControllerBandwidthCheckButtonPushed(app, event)
            
            app.file_current_ctrl_bandwidth_verification = fopen('ELEC_CurrCtrl_BWTest_TimeDomain.txt','w'); 

            app.WaitforPlotLabel_4.Visible = "on";
                  
            temp_bw = app.DesiredBW2.Value*2*pi;

            SendData = [2,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                   app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_CURRENT_CTRL_BW_RAD,   app.SDO_REQU, 1, single(temp_bw)), ...
                   app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL_BANDWIDTH_CHECK)]), ...
                   app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

            SendData = [2,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");
        end

        % Button pushed function: GainTune
        function GainTuneButtonPushed(app, event)
             
            SendData = [2,...                  %SDO
                    (app.node_id+16), ...
                    2,...
                   app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_VELOCITY_BW,             app.SDO_REQU, 1, single(app.VelocityBandwidthHzEditField.Value)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_POSITION_INPUT_PENALTY, app.SDO_REQU, 1, single(app.Pos_Input_Penalty.Value)), ...
                    ];

                write(app.device,SendData,"uint8");

            SendData = [2,...                  %SDO
                    (app.node_id+16), ...                      %identifier
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MSG, app.SDO_ID_MSG_MS_ENUM, app.SDO_REQU, 1, uint8(app.AUTO_TUNING)), ...
                    ];
            
                write(app.device,SendData,"uint8");

        end

        % Button pushed function: SetGainButton
        function SetGainButtonPushed(app, event)
            SendData = [2,...                  %SDO
                    (app.node_id+16), ...
                    2,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_POSITION_P_GAIN, app.SDO_REQU, 1, single(app.PGainEditField.Value)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_POSITION_D_GAIN, app.SDO_REQU, 1, single(app.DGainEditField.Value)), ...
                    ];

                write(app.device,SendData,"uint8");

        end

        % Button pushed function: vel_step_stop
        function vel_step_startPushed(app, event)

            app.file_ctrl_verification = fopen('control.txt','w');

                    % routine & state set
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_ADV_FRICTION_COMPENSATION), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MIDLEVEL_VELOCITY_CTRL)), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 3, ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_LOOP_CNT), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_REF_VELOCITY), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_ACTUAL_VELOCITY_RAW), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_VELOCITY_CTRL)), ...
                    ];

                write(app.device,SendData,"uint8");

            SendData = [3,...                  %PDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_pdoUnit(app.TASK_ID_MIDLEVEL, app.PDO_ID_MIDLEVEL_REF_VELOCITY, single(app.vel_ref.Value)), ...
                    ];

                write(app.device,SendData,"uint8");

             SendData = [2,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");


        end

        % Button pushed function: vel_step_start
        function vel_step_stopPushed(app, event)
            
                    % State Standby
                 SendData = [app.SDO_SEND,...                  %SDO
                        (app.node_id+16), ...
                        3,...
                        app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                        app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                        app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                        ];

                write(app.device,SendData,"uint8");

                fclose(app.file_ctrl_verification);
                flush(app.device);

                data = load('control.txt');

                t_t = 0.001:0.001:length(data(:,1))/1000;
    
                cla(app.vel_ctrl_plot); 
            
                hold(app.vel_ctrl_plot,'on')
                plot(app.vel_ctrl_plot, t_t, data(:,2), 'b');
                plot(app.vel_ctrl_plot, t_t, data(:,3), 'r');
                app.vel_ctrl_plot.XGrid = "on";
                app.vel_ctrl_plot.YGrid = "on";
                hold(app.vel_ctrl_plot,'off')


        end

        % Button pushed function: pos_step_start
        function pos_step_startPushed(app, event)
            
            app.file_ctrl_verification = fopen('control.txt','w');

                    % routine & state set
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_ADV_FRICTION_COMPENSATION), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MIDLEVEL_POSITION_CTRL)), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 3, ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_LOOP_CNT), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_REF_POSITION), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_ACTUAL_POSITION), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_POSITION_CTRL)), ...
                    ];

                write(app.device,SendData,"uint8");


            SendData = [app.PDO_SEND,...                  %PDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_pdoUnit(app.TASK_ID_MIDLEVEL, app.PDO_ID_MIDLEVEL_REF_POSITION, single(app.pos_ref.Value)), ...
                    ];

                write(app.device,SendData,"uint8");

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");
        end

        % Button pushed function: pos_step_stop
        function pos_step_stopPushed(app, event)
             
                    % State Standby
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                fclose(app.file_ctrl_verification);
                flush(app.device);

                data = load('control.txt');

                t_t = 0.001:0.001:length(data(:,1))/1000;
    
                cla(app.pos_ctrl_plot); 
            
                hold(app.pos_ctrl_plot,'on')
                plot(app.pos_ctrl_plot, t_t, data(:,2), 'b');
                plot(app.pos_ctrl_plot, t_t, data(:,3), 'r');
                app.pos_ctrl_plot.XGrid = "on";
                app.pos_ctrl_plot.YGrid = "on";
                hold(app.pos_ctrl_plot,'off')

        end

        % Button pushed function: vel_sine_start
        function vel_sine_startPushed(app, event)
            
            app.file_ctrl_verification = fopen('control.txt','w');

            SendData = [app.SDO_SEND,...                  %PDO
                    (app.node_id+16), ...
                    1,...
                    app.TASK_ID_MIDLEVEL, app.SDO_ID_MIDLEVEL_VELOCITY_PERIODIC_SIG_INFO, app.SDO_REQU, 2, ...
                    typecast(single(app.AmplituderadEditField.Value), 'uint8'), ...
                    typecast(single(app.FrequencyHzEditField.Value), 'uint8'), ...
                    ];

                write(app.device,SendData,"uint8");

                    % routine & state set
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_ADV_FRICTION_COMPENSATION), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_MIDLEVEL_VELOCITY_SINE_REF), uint8(app.ROUTINE_ID_MIDLEVEL_VELOCITY_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 3, ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_LOOP_CNT), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_REF_VELOCITY), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_ACTUAL_VELOCITY_RAW), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_VELOCITY_CTRL)), ...
                    ];

                write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");


        end

        % Button pushed function: vel_sine_stop
        function vel_sine_stopPushed(app, event)
             
                    % State Standby
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                fclose(app.file_ctrl_verification);
                flush(app.device);

                data = load('control.txt');
                t_t = 0.001:0.001:length(data(:,1))/1000;
    
                cla(app.vel_ctrl_plot); 
            
                hold(app.vel_ctrl_plot,'on')
                plot(app.vel_ctrl_plot, t_t, data(:,2), 'b');
                plot(app.vel_ctrl_plot, t_t, data(:,3), 'r');
                app.vel_ctrl_plot.XGrid = "on";
                app.vel_ctrl_plot.YGrid = "on";
                hold(app.vel_ctrl_plot,'off')

        end

        % Button pushed function: pos_sine_start
        function pos_sine_startButtonPushed(app, event)
                    

            app.file_ctrl_verification = fopen('control.txt','w');

            SendData = [app.SDO_SEND,...                  %PDO
                    (app.node_id+16), ...
                    1,...
                    app.TASK_ID_MIDLEVEL, app.SDO_ID_MIDLEVEL_POSITION_PERIODIC_SIG_INFO, app.SDO_REQU, 2, ...
                    typecast(single(app.AmplituderadEditField_4.Value), 'uint8'), ...
                    typecast(single(app.FrequencyHzEditField_6.Value), 'uint8'), ...
                    ];

                write(app.device,SendData,"uint8");

                    % routine & state set
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_ADV_FRICTION_COMPENSATION), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_MIDLEVEL_POSITION_SINE_REF), uint8(app.ROUTINE_ID_MIDLEVEL_POSITION_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 3, ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_LOOP_CNT), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_REF_POSITION), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_ACTUAL_POSITION), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_POSITION_CTRL)), ...
                    ];

                write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");

        end

        % Button pushed function: pos_sine_stop
        function pos_sine_stopPushed(app, event)
            
                    % State Standby
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                fclose(app.file_ctrl_verification);
                flush(app.device);

                data = load('control.txt');

                t_t = 0.001:0.001:length(data(:,1))/1000;
    
                cla(app.pos_ctrl_plot); 
            
                hold(app.pos_ctrl_plot,'on')
                plot(app.pos_ctrl_plot, t_t, data(:,2), 'b');
                plot(app.pos_ctrl_plot, t_t, data(:,3), 'r');
                app.pos_ctrl_plot.XGrid = "on";
                app.pos_ctrl_plot.YGrid = "on";
                hold(app.pos_ctrl_plot,'off')
                
        end

        % Button pushed function: vel_rec_start
        function vel_rec_startButtonPushed(app, event)
             

            app.file_ctrl_verification = fopen('control.txt','w');

            SendData = [app.SDO_SEND,...                  %PDO
                    (app.node_id+16), ...
                    1,...
                    app.TASK_ID_MIDLEVEL, app.SDO_ID_MIDLEVEL_VELOCITY_PERIODIC_SIG_INFO, app.SDO_REQU, 2, ...
                    typecast(single(app.RecAmplitude.Value), 'uint8'), ...
                    typecast(single(app.RecFrequency.Value), 'uint8'), ...
                    ];

                write(app.device,SendData,"uint8");

                    % routine & state set
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_ADV_FRICTION_COMPENSATION), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_MIDLEVEL_VELOCITY_REC_REF), uint8(app.ROUTINE_ID_MIDLEVEL_VELOCITY_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 3, ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_LOOP_CNT), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_REF_VELOCITY), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_ACTUAL_VELOCITY_RAW), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_VELOCITY_CTRL)), ...
                    ];

                write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");


        end

        % Button pushed function: vel_rec_stop
        function vel_rec_stopButtonPushed(app, event)
            
                    % State Standby
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                try
                    fclose(app.file_ctrl_verification);
                catch
                end

                flush(app.device);

                data = load('control.txt');
                t_t = 0.001:0.001:length(data(:,1))/1000;
    
                cla(app.vel_ctrl_plot); 
            
                hold(app.vel_ctrl_plot,'on')
                plot(app.vel_ctrl_plot, t_t, data(:,2), 'b');
                plot(app.vel_ctrl_plot, t_t, data(:,3), 'r');
                app.vel_ctrl_plot.XGrid = "on";
                app.vel_ctrl_plot.YGrid = "on";
                hold(app.vel_ctrl_plot,'off')

        end

        % Button pushed function: pos_rec_start
        function pos_rec_startButtonPushed(app, event)
            
            app.file_ctrl_verification = fopen('control.txt','w');

            SendData = [app.SDO_SEND,...                  %PDO
                    (app.node_id+16), ...
                    1,...
                    app.TASK_ID_MIDLEVEL, app.SDO_ID_MIDLEVEL_POSITION_PERIODIC_SIG_INFO, app.SDO_REQU, 2, ...
                    typecast(single(app.Pos_Rec_Amp.Value), 'uint8'), ...
                    typecast(single(app.Pos_Rec_Freq.Value), 'uint8'), ...
                    ];

                write(app.device,SendData,"uint8");

                    % routine & state set
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_ADV_FRICTION_COMPENSATION), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_MIDLEVEL_POSITION_REC_REF), uint8(app.ROUTINE_ID_MIDLEVEL_POSITION_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 3, ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_LOOP_CNT), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_REF_POSITION), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_ACTUAL_POSITION), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_POSITION_CTRL)), ...
                    ];

                write(app.device,SendData,"uint8");


                    % State Enable
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");

        end

        % Button pushed function: pos_rec_stop
        function pos_rec_stopButtonPushed(app, event)
            
                    % State Standby
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                try
                    fclose(app.file_ctrl_verification);
                catch
                end

                flush(app.device);

                data = load('control.txt');

                t_t = 0.001:0.001:length(data(:,1))/1000;
    
                cla(app.pos_ctrl_plot); 
            
                hold(app.pos_ctrl_plot,'on')
                plot(app.pos_ctrl_plot, t_t, data(:,2), 'b');
                plot(app.pos_ctrl_plot, t_t, data(:,3), 'r');
                app.pos_ctrl_plot.XGrid = "on";
                app.pos_ctrl_plot.YGrid = "on";
                hold(app.pos_ctrl_plot,'off')

        end

        % Callback function
        function FrictionIDStartButtonPushed(app, event)
            
            t_filename = sprintf('%s', app.FrictionFileName.Value);
            filename_friction_raw = strcat(t_filename, '_raw.txt');
            filename_friction_avr = strcat(t_filename, '_avr.txt');

            app.file_friction_id_raw = fopen(filename_friction_raw,'w');
            app.file_friction_id_avr = fopen(filename_friction_avr,'w');
            
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    2,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_FRICTION_ID), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                    % State Enable
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");

        end

        % Callback function
        function FrictionIDStopButtonPushed(app, event)
            
                    % State Enable
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                try
                    fclose(app.file_friction_id_raw);
                    fclose(app.t_filename_avr);
                catch
                end

                flush(app.device);

        end

        % Callback function
        function FrictionID_PararmeterApplyBtnPushed(app, event)
            
            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_FRICTION_ID_MAX_VEL,        app.SDO_REQU, 1, single(app.FrictionID_MaxVelocity.Value)), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_FRICTION_ID_STEP_NUM,       app.SDO_REQU, 1, uint8(app.StepNumberEditField.Value)), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_FRICTION_ID_GAIN_PCTG,      app.SDO_REQU, 1, uint8(app.VelocityControllerGainSlider_2.Value)), ...
                    ];

                write(app.device,SendData,"uint8");


        end

        % Callback function
        function FrictionID_DataPlotPushed(app, event)
            
            t_filename = sprintf('%s', app.FrictionFileName.Value);
            filename_friction_raw = strcat(t_filename, '_raw.txt');
            filename_friction_avr = strcat(t_filename, '_avr.txt');

            cla(app.friction_id_plot); 

            if(app.RawDataCheckBox.Value == 1)
                data1 = load(filename_friction_raw);
                hold(app.friction_id_plot,'on')
                plot(app.friction_id_plot, data1(:,1), data1(:,2), '.');
            end

            if(app.AveragedDataCheckBox.Value == 1)
                data2 = load(filename_friction_avr);
                hold(app.friction_id_plot,'on')
                plot(app.friction_id_plot, data2(:,1), data2(:,2));
            end
            
            hold(app.friction_id_plot,'off')

        end

        % Callback function
        function FrictionID_CurveFittingButtonPushed(app, event)
            
            t_filename = sprintf('%s', app.FrictionFileName.Value);
            filename_friction_avr = strcat(t_filename, '_avr.txt');

            switch app.ModelDropDown.Value
                case 'Basic'
                    t_arg = 1;
                    t_result = do_friction_model_fitting(filename_friction_avr, t_arg);
                    t_vel = -30:0.001:30;
                    t_cur = sign(t_vel).*t_result(1) + t_vel.*t_result(2);
        
                    hold(app.friction_id_plot,'on')
                    plot(app.friction_id_plot, t_vel, t_cur, 'k.');


                     SendData = [app.SDO_SEND,...                  %SDO
                            (app.node_id+16), ...
                            4,...
                            app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                            app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_FRICTION_MODEL_TYPE, app.SDO_REQU, 1, uint8(t_arg)), ...
                            app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_MU_C, app.SDO_REQU, 1, single(t_result(1))), ...
                            app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_MU_V, app.SDO_REQU, 1, single(t_result(2))), ...
                            ];
        
                        write(app.device,SendData,"uint8");

                case 'Stribeck'
                    t_arg = 2;
                case 'Karno'
                    t_arg = 3;
                otherwise 
                    return;
            end

            app.FrictionParameter_label.Text = strcat("Coulomb: ", num2str(t_result(1)), ", Viscous: ", num2str(t_result(2)));
            

        end

        % Callback function
        function FrictionCompensationStartButtonPushed(app, event)
                        
            app.file_friction_verification = fopen('friction_verification.txt','w');

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 2, ...
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_VELOCITY), ...
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_FRICTION_COMPENSATOR_INPUT), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.FRICTION_COMPENSATOR_VERIFICATION)), ...
                    ];

                write(app.device,SendData,"uint8");

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    2,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_ADV_FRICTION_COMPENSATION), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                    % State Enable
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    2,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");
        end

        % Callback function
        function FrictionCompensationStopButtonPushed(app, event)
            
                    % State Enable
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    2,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

            try
                fclose(app.file_friction_verification);
            catch
            end

            flush(app.device);
            data = load('friction_verification.txt');

            cla(app.FrictionVerificationPlot); 
        
            hold(app.FrictionVerificationPlot,'on')
            plot(app.FrictionVerificationPlot, data(:,1), 'b');
            plot(app.FrictionVerificationPlot, data(:,2), 'r');
            app.FrictionVerificationPlot.XGrid = "on";
            app.FrictionVerificationPlot.YGrid = "on";
            hold(app.FrictionVerificationPlot,'off')

        end

        % Callback function
        function IncEncSetOriginButtonPushed(app, event)
            
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_INCENCODER_SET_OFFSET, app.SDO_REQU, 1, uint8(0)), ...
                    ];

                write(app.device,SendData,"uint8");

        end

        % Callback function
        function StartIncEncReadButtonPushed(app, event)
                       
                app.file_inc_encoder = fopen('inc_encoder_values.txt','w');

             SendData = [app.SDO_SEND,...                 %SDO
                    (app.node_id+16), ...
                    2,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MIDLEVEL_GET_INCENCODER_VALUE)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");

        end

        % Callback function
        function StopIncEncReadButtonPushed(app, event)
            
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");
                
                flush(app.device);

                try
                    fclose(app.file_inc_encoder);
                catch
                end

                data = load('inc_encoder_values.txt');
    
                t_t = 0.001:0.001:length(data(:,1))/1000;
    
                cla(app.inc_enc_plot); 
            
                hold(app.inc_enc_plot,'on')
                plot(app.inc_enc_plot, t_t, data(:,2), 'k');
                app.inc_enc_plot.XGrid = "on";
                app.inc_enc_plot.YGrid = "on";
                hold(app.inc_enc_plot,'off')


        end

        % Callback function
        function AbsEncSetOffsetButtonPushed(app, event)
                    SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_ABSENCODER_SET_OFFSET, app.SDO_REQU, 1, single(app.AbsEncOffsetEditField.Value)), ...
                    ];

                write(app.device,SendData,"uint8");

        end

        % Callback function
        function AbsEncStartReadButtonPushed(app, event)
                         
            app.file_abs_encoder = fopen('abs_encoder_values.txt','w');

            SendData = [app.SDO_SEND,...                 %SDO
                    (app.node_id+16), ...
                    2,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MIDLEVEL_GET_ABSENCODER1_VALUE)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

             write(app.device,SendData,"uint8");

        end

        % Callback function
        function AbsEncStopReadButtonPushed(app, event)
                        
            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");
                flush(app.device);

                try
                    fclose(app.file_abs_encoder);
                catch
                end

                data = load('abs_encoder_values.txt');
    
                t_t = 0.001:0.001:length(data(:,1))/1000;
    
                cla(app.abs_enc_plot); 
            
                hold(app.abs_enc_plot,'on')
                plot(app.abs_enc_plot, t_t, data(:,2), 'k');
                app.abs_enc_plot.XGrid = "on";
                app.abs_enc_plot.YGrid = "on";
                hold(app.abs_enc_plot,'off')

        end

        % Button pushed function: BacklashTest_StartButton
        function BacklashTest_StartButtonPushed(app, event)

            app.file_backlash = fopen('AddFunction_BacklashTest.txt','w');

            if (app.AbsoluteEncoderCH1DropDown.Value == "Actuator Output")


                 SendData = [app.SDO_SEND,...                  %SDO
                        (app.node_id+16), ...
                        5,...
                        app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                        app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                        app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 3, ...
                                    app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_LOOP_CNT), ...
                                    app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_ACTUAL_POSITION), ...
                                    app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_ABSENCODER1_POSITION), ...
                        app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                        app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_BACKLASH_TEST)), ...
                        ];
    
                 write(app.device,SendData,"uint8");


            elseif (app.AbsoluteEncoderCH2DropDown.Value == "Actuator Output")

                
                SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 3, ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_LOOP_CNT), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_ACTUAL_POSITION), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_ABSENCODER2_POSITION), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_BACKLASH_TEST)), ...
                    ];

                write(app.device,SendData,"uint8");

            else

                f = msgbox("Error: Please install absolute encoder at actuator output.","Error","error");

            end


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");

                app.BacklashTest_StartButton.Enable = 0;
        end

        % Button pushed function: BacklashTest_StopButton
        function BacklashTest_StopButtonPushed(app, event)
                                                      % State Standby
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                try
                    fclose(app.file_backlash);
                catch
                end
                flush(app.device);

                data = load('AddFunction_BacklashTest.txt');

                t_t = 0.001:0.001:length(data(:,1))/1000;
    
                backlash_est = (data(:,2) - data(:,3));
                backlash_est = backlash_est - backlash_est(1);

                cla(app.Backlash_test_plot); 
            
                hold(app.Backlash_test_plot,'on')
                plot(app.Backlash_test_plot, t_t, backlash_est, 'r');
                app.Backlash_test_plot.XGrid = "on";
                app.Backlash_test_plot.YGrid = "on";
                hold(app.Backlash_test_plot,'off')

                app.MaxBacklashdegEditField.Value = (abs(min(backlash_est)) + abs(max(backlash_est)))/2;
                app.BacklashTest_StartButton.Enable = 1;
        end

        % Button pushed function: DoB_Qfilter_ApplyButton
        function DoB_Qfilter_ApplyButtonPushed(app, event)
           
            t_radPsec = 2*pi*(app.QFilterCutoffHzEditField.Value);

            try
                results = Position_DOb_Calculator(app.inertia_value, app.damp_coef_value, t_radPsec);
            catch
                f = msgbox("Error: You need to identify your system first.","Error","error");
                return;
            end

            GQ_num_length = uint8(results(1)); 
            GQ_den_length = uint8(results(2)); 
            Q_num_length = uint8(results(3));
            Q_den_length = uint8(results(4));

            if((GQ_num_length > GQ_den_length) || (Q_num_length > Q_den_length))
                f = msgbox("Error: The orders of model are abnormal","Error","error");
                return;
            end

            if((GQ_num_length > 6) || (GQ_den_length > 6) || (Q_num_length > 4) || (Q_den_length > 4))
                f = msgbox("Error: Too many coefficients","Error","error");
                return;
            end

            index = 5;
            GQ_num = results(index:index+GQ_den_length-1);
            index = index+GQ_den_length;
            GQ_den = results(index:index+GQ_den_length-1);

            %------------------------PLOT--------------------------%
            s = tf('s');
            G = 1/(app.inertia_value*s^2 + app.damp_coef_value*s);
            G_d = c2d(G, 0.001, 'zoh');

            GinvQ = tf(GQ_num, GQ_den, 0.001);
            w = 0:1:500*2*pi;
            [gq_mag, gq_phase] = bode(GinvQ, w);
            [g_mag, g_phase] = bode(G_d, w);

            gq_mag = 20*log10(squeeze(gq_mag));
            g_mag = 20*log10(squeeze(g_mag));
            gq_phase = squeeze(gq_phase);
            g_phase = squeeze(g_phase);

             cla(app.GinvQ_mag_plot); 
             cla(app.GinvQ_phase_plot); 

             hold(app.GinvQ_mag_plot,'on');
             plot(app.GinvQ_mag_plot, w, (gq_mag), "r");
             plot(app.GinvQ_mag_plot, w, (g_mag), "k");
             app.GinvQ_mag_plot.XScale = "log";
             app.GinvQ_mag_plot.XGrid = "on";
             app.GinvQ_mag_plot.YGrid = "on";
             hold(app.GinvQ_mag_plot,'off');

             hold(app.GinvQ_phase_plot,'on');
             plot(app.GinvQ_phase_plot, w, gq_phase, "r");
             plot(app.GinvQ_phase_plot, w, g_phase, "k");
             app.GinvQ_phase_plot.XScale = "log";
             app.GinvQ_phase_plot.XGrid = "on";
             app.GinvQ_phase_plot.YGrid = "on";
             hold(app.GinvQ_phase_plot,'off');

            %------------------------MESSAGE--------------------------%
            index = 5;
            GQ_num = [results(1), results(index:index+GQ_den_length-1)]
            index = index+GQ_den_length;
            GQ_den = [results(2), results(index:index+GQ_den_length-1)]
            index = index+GQ_den_length;
            Q_num = [results(3), results(index:index+Q_den_length-1)]
            index = index+Q_den_length;
            Q_den = [results(4), results(index:index+Q_den_length-1)]

            SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...                     %identifier
                2,...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL, app.SDO_ID_MIDLEVEL_DOB_Q_BW,        app.SDO_REQU, 1, single(t_radPsec)), ...    
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL, app.SDO_ID_MIDLEVEL_DOB_SATURATION,  app.SDO_REQU, 1, single(app.SaturationAEditField_3.Value)), ...
                ];

             write(app.device,SendData,"uint8");

            SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...                     %identifier
                1,...
                app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_DOB_GQ_NUM,   app.SDO_REQU, GQ_num_length+1, typecast(single(GQ_num),'uint8'), ...
                ];

             write(app.device,SendData,"uint8");

            SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...                     %identifier
                1,...
                app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_DOB_GQ_DEN,   app.SDO_REQU, GQ_den_length+1, typecast(single(GQ_den),'uint8'), ...
                ];

             write(app.device,SendData,"uint8");

            SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...                     %identifier
                2,... 
                app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_DOB_Q_NUM,    app.SDO_REQU, Q_num_length+1, typecast(single(Q_num),'uint8'), ...
                app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_DOB_Q_DEN,    app.SDO_REQU, Q_den_length+1, typecast(single(Q_den),'uint8'), ...
                ]

             write(app.device,SendData,"uint8");


        end

        % Button pushed function: DoB_test_StartButton
        function DoB_test_StartButtonPushed(app, event)
             
            t_filename = sprintf('%s', app.FileSaveNametxtEditField_2.Value);
            t_filename = strcat(t_filename, '.txt');

            app.file_dob_verification = fopen(t_filename,'w');

            SendData = [app.SDO_SEND,...                  %PDO
                    (app.node_id+16), ...
                    3,...
                    app.TASK_ID_MIDLEVEL, app.SDO_ID_MIDLEVEL_POSITION_PERIODIC_SIG_INFO, app.SDO_REQU, 2, ...
                    typecast(single(app.DoB_test_AmplitudedegEditField.Value), 'uint8'), ...
                    typecast(single(app.DoB_test_FrequencyHzEditField.Value), 'uint8'), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL, app.SDO_ID_MIDLEVEL_IMP_VIRTUAL_STIFFNESS, app.SDO_REQU, 1, single(app.PgainDOb.Value)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL, app.SDO_ID_MIDLEVEL_IMP_VIRTUAL_DAMPER, app.SDO_REQU, 1, single(app.DgainDOb.Value)), ...
                    ];

                write(app.device,SendData,"uint8");

                app.VirtualStiffnessEditField.Value = app.PgainDOb.Value;
                app.VirtualDampingEditField.Value = app.DgainDOb.Value;

                    % routine & state set
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_ADV_FRICTION_COMPENSATION), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 3, [uint8(app.ROUTINE_ID_MIDLEVEL_POSITION_SINE_REF), uint8(app.ROUTINE_ID_MIDLEVEL_POSITION_CTRL), uint8(app.ROUTINE_ID_MIDLEVEL_DISTURBANCE_OBS)]), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 5, ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_LOOP_CNT), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_REF_POSITION), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_ACTUAL_POSITION), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_DOB_DISTURABNCE), ...
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_MID_CTRL_INPUT), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_DOB_DATA)), ...
                    ];

                write(app.device,SendData,"uint8");

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");
                
                app.DoB_test_StartButton.Enable = 0;
        end

        % Button pushed function: DoB_test_StopButton
        function DoB_test_StopButtonPushed(app, event)
                                            % State Standby
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");
                
                fclose(app.file_dob_verification );

                flush(app.device);

                t_filename = sprintf('%s', app.FileSaveNametxtEditField_2.Value);
                t_filename = strcat(t_filename, '.txt');

                data = load(t_filename);

                t_t = 0.001:0.001:length(data(:,1))/1000;
    
                cla(app.DoB_test_plot1); 
                cla(app.DoB_test_plot2); 
            
                hold(app.DoB_test_plot1,'on')
                plot(app.DoB_test_plot1, t_t, data(:,2), 'b');
                plot(app.DoB_test_plot1, t_t, data(:,3), 'r');
                app.DoB_test_plot1.XGrid = "on";
                app.DoB_test_plot1.YGrid = "on";
                hold(app.DoB_test_plot1,'off')

                hold(app.DoB_test_plot2,'on')
                plot(app.DoB_test_plot2, t_t, data(:,4), 'b');
                plot(app.DoB_test_plot2, t_t, data(:,5), 'r');
                app.DoB_test_plot2.XGrid = "on";
                app.DoB_test_plot2.YGrid = "on";
                hold(app.DoB_test_plot2,'off')

                app.DoB_test_StartButton.Enable = 1;
        end

        % Value changed function: ApplyButton
        function ApplyButtonValueChanged(app, event)
                
            SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...                      %identifier
                1,...
                app.TASK_ID_MIDLEVEL, app.SDO_ID_MIDLEVEL_SYSTEM_ID_SBS_INFO, app.SDO_REQU, 6, ...
                typecast(single(app.MinimumFrequencyHzf_minEditField.Value),'uint8'), ...
                typecast(single(app.MaximumFrequencyHzf_maxEditField.Value),'uint8'), ...
                typecast(single(app.NumberofFrequencySamplesN_samplesEditField.Value),'uint8'), ...
                typecast(single(app.NumberofIterationsN_iterEditField.Value),'uint8'), ...
                typecast(single(app.InputMagnitudeAMEditField.Value),'uint8'), ...
                typecast(single(app.InputOffsetAoffsetEditField.Value),'uint8'), ...
                ];

                write(app.device,SendData,"uint8");
            
        end

        % Button pushed function: StartIDButton
        function StartIDButtonPushed(app, event)
            flush(app.device);

            app.AnalysisButton.Enable = "off";
            
            t_filename = sprintf('%s.txt', app.FileSaveNametxtEditField.Value);
            app.file_mech_system_id = fopen(t_filename,'w');            

            SendData = [app.SDO_SEND,...                  %SDO
                 (app.node_id+16), ...                      %identifier
                 4,...
                 app.pack_sdoUnit(app.TASK_ID_MSG, app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                 app.TASK_ID_MSG, app.SDO_ID_MSG_PDO_LIST, app.SDO_REQU, 4, ...                     
                     app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_ACTUAL_VELOCITY_RAW), ...
                     app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_SYSTEM_ID_SBS_FREQ), ...               %PDO_ID_MIDLEVEL_SYSTEM_ID_SBS_FREQ       
                     app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_MID_ID_PROCESS_INPUT), ...            % PDO_ID_LOWLEVEL_MID_ID_PROCESS_INPUT
                     app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_LOOP_CNT), ...                 
                 app.pack_sdoUnit(app.TASK_ID_MSG, app.SDO_ID_MSG_GUI_COMM_ONOFF,       app.SDO_REQU, 1, uint8(1)), ...
                 app.pack_sdoUnit(app.TASK_ID_MSG, app.SDO_ID_MSG_GUI_COMM_COMMAND,     app.SDO_REQU, 1, uint8(app.MECH_SYS_ID_SBS_RAW_DATA)), ...
                 ];
            
             write(app.device,SendData,"uint8");


            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    6,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ... 
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_ADV_FRICTION_COMPENSATION), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MIDLEVEL_SYS_ID_SBS)),...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];
            
                write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                 (app.node_id+16), ...                      %identifier
                 3,...
                 app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                 app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                 app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                 ];

                write(app.device,SendData,"uint8");

        end

        % Button pushed function: StopIDButton
        function StopIDButtonPushed(app, event)
                    
            app.AnalysisButton.Enable = "on";
            
            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...                      %identifier
                     3,...
                     app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                     app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                     app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");
                configureCallback(app.device,"terminator",@app.readData);

                try
                    fclose(app.file_mech_system_id);
                catch
                end

                flush(app.device);

        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
            
            t_filename = app.FileSaveNametxtEditField.Value;
            results = do_sys_id_analysis(t_filename);

            app.InertiaJunitAs2Label.Text = strcat("Inertia (J) (unit: As^2) = ", num2str(results(1)));
            app.DampingBunitAsLabel.Text = strcat("Damping (B) (unit: As) = ", num2str(results(2)));
            app.InertiaJunitAs2Label_2.Text = strcat("Inertia (J) (unit: As^2) = ", num2str(results(1)));
            app.DampingBunitAsLabel_2.Text = strcat("Damping (B) (unit: As) = ", num2str(results(2)));
            app.InertiaJunitAs2Label_3.Text = strcat("Inertia (J) (unit: As^2) = ", num2str(results(1)));
            app.DampingBunitAsLabel_3.Text = strcat("Damping (B) (unit: As) = ", num2str(results(2)));
            app.InertiaJunitAs2Label_5.Text = strcat("Inertia (J) (unit: As^2) = ", num2str(results(1)));
            app.DampingBunitAsLabel_5.Text = strcat("Damping (B) (unit: As) = ", num2str(results(2)));
            
            app.inertia_value = results(1);
            app.damp_coef_value = results(2);
            app.mech_a_value = results(3);
            app.mech_b_value = results(4);

            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_INERTIA,        app.SDO_REQU, 1, single(results(1))), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_DAMPING_COEF,   app.SDO_REQU, 1, single(results(2))), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_MECH_MODEL_A,   app.SDO_REQU, 1, single(results(3))), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_MECH_MODEL_B,   app.SDO_REQU, 1, single(results(4))), ...
                    ];
            
                write(app.device,SendData,"uint8");

             cla(app.Bode_Magnitude); 
             cla(app.Bode_Phase); 

             t_f_filename = sprintf('%s(frequency_domain).txt', t_filename);
             t_data = load(t_f_filename);

             t_frequency_samples = t_data(:,1);
             t_act_mag           = t_data(:,2);
             t_act_phase         = t_data(:,3);
             t_model_mag         = t_data(:,4);
             t_model_phase       = t_data(:,5);
             
             hold(app.Bode_Magnitude,'on');
             scatter(app.Bode_Magnitude, t_frequency_samples, t_act_mag, 20, "black", "filled");
             plot(app.Bode_Magnitude, t_frequency_samples, t_model_mag, "red");
             app.Bode_Magnitude.XScale = "log";
             app.Bode_Magnitude.XGrid = "on";
             app.Bode_Magnitude.YGrid = "on";
             hold(app.Bode_Magnitude,'off');
             app.Bode_Magnitude.Title.FontWeight = 'bold';
             app.Bode_Magnitude.Title.FontSize = 14;

             hold(app.Bode_Phase,'on');
             scatter(app.Bode_Phase, t_frequency_samples, t_act_phase, 20, "black", "filled");
             plot(app.Bode_Phase, t_frequency_samples, t_model_phase, "red");
             app.Bode_Phase.XScale = "log";
             app.Bode_Phase.XGrid = "on";
             app.Bode_Phase.YGrid = "on";
             app.Bode_Phase.Title.FontWeight = 'bold';
             app.Bode_Phase.Title.FontSize = 14;

             hold(app.Bode_Phase,'off');

        end

        % Button pushed function: IRCStartButton
        function IRCStartButtonPushed(app, event)
                        
            t_filename = sprintf('%s', app.FileNametxtEditField.Value);
            t_filename = strcat(t_filename, '.txt');

            app.file_irc_verification = fopen(t_filename,'w');

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 3, ...
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_TOTAL_CURRENT_INPUT), ...
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_CURRENT_OUTPUT), ...
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_IRC_INPUT), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.IRC_VERIFICATION)), ...
                    ];

             write(app.device,SendData,"uint8");

             if     (app.FrictionCompensatorONCheckBox.Value == 1)
                 
                 SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_ADV_FRICTION_COMPENSATION), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MIDLEVEL_IRC)), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];
                write(app.device,SendData,"uint8");


             elseif (app.FrictionCompensatorONCheckBox.Value == 0)

                                  SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MIDLEVEL_IRC)), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];
                write(app.device,SendData,"uint8");
             end         

             
             % State Enable
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

             write(app.device,SendData,"uint8");

             app.IRCStartButton.Enable = 0;
                
        end

        % Button pushed function: IRCStopButton
        function IRCStopButtonPushed(app, event)
            
            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

            try
                fclose(app.file_irc_verification);
            catch
            end

            flush(app.device);

            t_filename = sprintf('%s', app.FileNametxtEditField.Value);
            t_filename = strcat(t_filename, '.txt');

            data = load(t_filename);

            t_t = 0.001:0.001:length(data(:,1))/1000;

            cla(app.IRC_Verification_Plot); 
        
            hold(app.IRC_Verification_Plot,'on');
            plot(app.IRC_Verification_Plot, t_t, data(:,1), 'b');
            plot(app.IRC_Verification_Plot, t_t, data(:,2), 'r');
            plot(app.IRC_Verification_Plot, t_t, data(:,3), 'k');
            app.IRC_Verification_Plot.XGrid = "on";
            app.IRC_Verification_Plot.YGrid = "on";
            hold(app.IRC_Verification_Plot,'off');

            legend(app.IRC_Verification_Plot, {'Total Input', 'Actual Current', 'IRC Input'});
            app.IRC_Verification_Plot.Title.FontWeight = 'bold';
            app.IRC_Verification_Plot.Title.FontSize = 14;
            
            app.IRCStartButton.Enable = 1;
                
        end

        % Button pushed function: ConversionPlotButton
        function ConversionPlotButtonPushed(app, event)
                                    
            s = tf('s');

            t_system = app.inertia_value*s + app.damp_coef_value;
            wn1 = app.CutoffHzEditField.Value * 2* pi;
            
            if(app.UsesubfilterCheckBox.Value == 1)
                wn2 = app.CutoffHzEditField_2.Value * 2* pi;
                t_sub_filter = wn2/(s+wn2);
            else
                t_sub_filter = 1;
            end

            dc_gain = app.QvalueEditField.Value;
            order = str2double(app.OrderDropDown.Value);
            approx_method = app.ApproximationDropDown.Value;

            switch(app.LowPassFilterTypeDropDown.Value)
                case "Basic"
                    t_filt = wn1/(s+wn1);
                    app.irc_filter = 1;

                    for i=1:order
                        app.irc_filter = app.irc_filter*t_filt;
                    end

                    app.irc_filter = dc_gain*app.irc_filter*t_sub_filter;
                    app.irc_s_domain = t_system*app.irc_filter;

                    app.irc_z_domain = minreal(c2d(app.irc_s_domain, 0.001, approx_method));

                case "Butterworth"
                    if(order == 1)
                        app.irc_filter = wn1/(s+wn1);
                    elseif(order == 2)
                        app.irc_filter = wn1^2/(s^2+sqrt(2)*wn1*s+wn1^2);
                    elseif(order == 3)
                        app.irc_filter = wn1^3/((s^2+wn1*s+wn1^2)*(s+wn1));
                    end

                    app.irc_filter = dc_gain*app.irc_filter*t_sub_filter;
                    app.irc_s_domain = t_system*app.irc_filter;

                    app.irc_z_domain = minreal(c2d(app.irc_s_domain, 0.001, approx_method));

                case "Chebyshev"
                    [cheby_b, cheby_a] = cheby1(order, 1, wn1/(500*2*pi)); 
                    app.irc_filter = tf(cheby_b, cheby_a, 0.001);
                    app.irc_z_domain = c2d(dc_gain*t_system*t_sub_filter, 0.001, approx_method);
                    app.irc_z_domain = minreal(app.irc_z_domain * app.irc_filter);
                otherwise
            end

            w = 0:1:500*2*pi;
            [sys_inv_mag, sys_inv_phase] = bode(t_system, w);
            [filt_mag, filt_phase] = bode(app.irc_filter, w);
            [irc_mag, irc_phase] = bode(app.irc_z_domain, w);

            sys_inv_mag = 20*log10(squeeze(sys_inv_mag));
            filt_mag = 20*log10(squeeze(filt_mag));
            irc_mag = 20*log10(squeeze(irc_mag));

             cla(app.IRC_Bode_Mag); 
             cla(app.IRC_Bode_Phase); 

             hold(app.IRC_Bode_Mag,'on');
             plot(app.IRC_Bode_Mag, w, (sys_inv_mag), "k");
             plot(app.IRC_Bode_Mag, w, (filt_mag), "b");
             plot(app.IRC_Bode_Mag, w, (irc_mag), "r");
             app.IRC_Bode_Mag.XScale = "log";
             app.IRC_Bode_Mag.XGrid = "on";
             app.IRC_Bode_Mag.YGrid = "on";
             hold(app.IRC_Bode_Mag,'off');

             hold(app.IRC_Bode_Phase,'on');
             plot(app.IRC_Bode_Phase, w, squeeze(sys_inv_phase), "k");
             plot(app.IRC_Bode_Phase, w, squeeze(filt_phase), "b");
             plot(app.IRC_Bode_Phase, w, squeeze(irc_phase), "r");
             app.IRC_Bode_Phase.XScale = "log";
             app.IRC_Bode_Phase.XGrid = "on";
             app.IRC_Bode_Phase.YGrid = "on";
             hold(app.IRC_Bode_Phase,'off');

        end

        % Button pushed function: IRC_apply_button
        function IRC_apply_buttonPushed(app, event)
            
            [app.irc_num, app.irc_den] = tfdata(app.irc_z_domain, 'v');

            app.irc_num_length = length(app.irc_num);
            app.irc_den_length = length(app.irc_den);

            t_num = single(app.irc_num(1));
            t_den = single(app.irc_den(1));
            
            for i = 2:app.irc_num_length
               t_num = [t_num, single(app.irc_num(i))];
            end

            for i = 2:app.irc_den_length
               t_den = [t_den, single(app.irc_den(i))];
            end
% 
%             t_num
%             t_den

            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_IRC_NUMERATOR_LENGTH,   app.SDO_REQU, 1, uint8(app.irc_num_length)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_IRC_DENOMINATOR_LENGTH, app.SDO_REQU, 1, uint8(app.irc_den_length)), ...
                                     app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_IRC_NUMERATOR,          app.SDO_REQU, app.irc_num_length, typecast(t_num,'uint8'), ...
                                     app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_IRC_DENOMINATOR,        app.SDO_REQU, app.irc_den_length, typecast(t_den,'uint8'), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_IRC_SATURATION,         app.SDO_REQU, 1, single(app.IRCSaturationAEditField.Value)), ...
                    ];

                write(app.device,SendData,"uint8");

        end

        % Button pushed function: ImpedanceControllerApplyButton
        function ImpedanceControllerApplyButtonPushed(app, event)
             
            SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...
                5,...                
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_EPSILON,  app.SDO_REQU, 1, uint8(10*app.CorridorHalfWidthDegEditField.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_KP,       app.SDO_REQU, 1, uint8(2.55*app.VirtualStiffnessEditField.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_KD,       app.SDO_REQU, 1, uint8(2.55*app.VirtualDampingEditField.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_LAMBDA,   app.SDO_REQU, 1, uint8(100*app.ImpedanceRatioinCorridor0255EditField.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_DURATION, app.SDO_REQU, 1, uint16(app.TransitionTimemsEditField.Value)), ...
                ];
            write(app.device,SendData,"uint8");

        end

        % Callback function
        function DirectionChangeButtonPushed(app, event)
            
            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_ABSENCODER_CHANGE_DIRECTION, app.SDO_REQU, 1, uint8(0)), ...
                    ];

                write(app.device,SendData,"uint8");
        end

        % Value changed function: ImpedanceControllerCheckBox
        function ImpedanceControllerCheckBoxValueChanged(app, event)
            value = app.ImpedanceControllerCheckBox.Value;
            
            app.PDControllerCheckBox.Value = 0;
            app.DisturbanceObserverCheckBox.Value = 0;

        end

        % Value changed function: PDControllerCheckBox
        function PDControllerCheckBoxValueChanged(app, event)
            value = app.PDControllerCheckBox.Value;
            
            app.ImpedanceControllerCheckBox.Value = 0;

        end

        % Value changed function: MechanicalImpedanceCompensatorCheckBox
        function MechanicalImpedanceCompensatorCheckBoxValueChanged(app, event)
            value = app.MechanicalImpedanceCompensatorCheckBox.Value;
            
            app.DisturbanceObserverCheckBox.Value = 0;

        end

        % Value changed function: DisturbanceObserverCheckBox
        function DisturbanceObserverCheckBoxValueChanged(app, event)
            value = app.DisturbanceObserverCheckBox.Value;
            
            app.MechanicalImpedanceCompensatorCheckBox.Value = 0;
            app.ImpedanceControllerCheckBox.Value = 0;

        end

        % Button pushed function: ApplyButton_2
        function ApplyButton_2Pushed(app, event)
            
            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_MID_CTRL_SATURATION, app.SDO_REQU, 1, single(app.MidlevelControllerSaturationEditField.Value)), ...
                    ];

            write(app.device,SendData,"uint8");

            app.MidlevelControllerSaturationEditField_2.Value = app.MidlevelControllerSaturationEditField.Value;

            app.AdvFrictionIDTab.Parent = [];
            app.SystemIDTab.Parent = [];
            app.VelocityEstimatorTab.Parent = [];
            app.IRCTab.Parent = [];
            app.ImpedanceControllerTab.Parent = [];
            app.DOBTab.Parent = [];
            app.PDControllerTab.Parent = [];
            app.FeedforwardTab.Parent = [];
            app.VerificationTab.Parent = [];

            app.AdvFrictionIDTab.Parent = app.TabGroup2_4;
            app.SystemIDTab.Parent = app.TabGroup2_4;
            app.VelocityEstimatorTab.Parent = app.TabGroup2_4;

            if(app.MechanicalImpedanceCompensatorCheckBox.Value == 1)
                app.IRCTab.Parent = app.TabGroup2_4;
                app.MechanicalImpedanceCompensatorCheckBox_2.Value = 1;
                app.MechanicalImpedanceCompensatorCheckBox_2.Enable = "on";
            else
                app.IRCTab.Parent = [];
                app.MechanicalImpedanceCompensatorCheckBox_2.Value = 0;
                app.MechanicalImpedanceCompensatorCheckBox_2.Enable = "off";
            end

            if(app.ImpedanceControllerCheckBox.Value == 1)
                app.ImpedanceControllerTab.Parent = app.TabGroup2_4;
                app.ImpedanceControllerCheckBox_2.Value = 1;
                app.ImpedanceControllerCheckBox_2.Enable = "on";
            else
                app.ImpedanceControllerTab.Parent = [];
                app.ImpedanceControllerCheckBox_2.Value = 0;
                app.ImpedanceControllerCheckBox_2.Enable = "off";
            end

            if(app.DisturbanceObserverCheckBox.Value == 1)
                app.DOBTab.Parent = app.TabGroup2_4;
                app.DisturbanceObserverCheckBox_2.Value = 1;
                app.DisturbanceObserverCheckBox_2.Enable = "on";
            else
                app.DOBTab.Parent = [];
                app.DisturbanceObserverCheckBox_2.Value = 0;
                app.DisturbanceObserverCheckBox_2.Enable = "off";
            end

            if(app.PDControllerCheckBox.Value == 1)
                app.PDControllerTab.Parent = app.TabGroup2_4;
                app.PDControllerCheckBox_2.Value = 1;
                app.PDControllerCheckBox_2.Enable = "on";
            else
                app.PDControllerTab.Parent = [];
                app.PDControllerCheckBox_2.Value = 0;
                app.PDControllerCheckBox_2.Enable = "off";
            end
            
            if(app.FeedforwardControllerCheckBox.Value == 1)
                app.FeedforwardTab.Parent = app.TabGroup2_4;
                app.FeedforwardControllerCheckBox_2.Value = 1;
                app.FeedforwardControllerCheckBox_2.Enable = "on";
            else
                app.FeedforwardTab.Parent = [];
                app.FeedforwardControllerCheckBox_2.Value = 0;
                app.FeedforwardControllerCheckBox_2.Enable = "off";
            end

            app.VerificationTab.Parent = app.TabGroup2_4;

        end

        % Button pushed function: imp_verify_sine_start
        function imp_verify_sine_startPushed(app, event)
            
            app.file_imp_sine_verification = fopen('impedance_control.txt','w');

            SendData = [app.SDO_SEND,...                  %PDO
                    (app.node_id+16), ...
                    1,...
                    app.TASK_ID_MIDLEVEL, app.SDO_ID_MIDLEVEL_POSITION_PERIODIC_SIG_INFO, app.SDO_REQU, 2, ...
                    typecast(single(app.IMP_Sine_Magnitude.Value), 'uint8'), ...
                    typecast(single(app.IMP_Sine_Frequency.Value), 'uint8'), ...
                    ];

                write(app.device,SendData,"uint8");

             if (app.FrictionCompensatorONCheckBox_2.Value == 1)
                 
                 SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_ADV_FRICTION_COMPENSATION), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_MIDLEVEL_POSITION_SINE_REF), uint8(app.ROUTINE_ID_MIDLEVEL_IMPEDANCE_CONTROL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

             elseif (app.FrictionCompensatorONCheckBox_2.Value == 0)

                     SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_MIDLEVEL_POSITION_SINE_REF), uint8(app.ROUTINE_ID_MIDLEVEL_IMPEDANCE_CONTROL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");
             
             end


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 4, ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_LOOP_CNT), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_REF_POSITION), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_ACTUAL_POSITION), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_IMP_CTRL_INPUT), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_IMPEDANCE_SINE_CTRL)), ...
                    ];

                write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");

                app.imp_verify_sine_start.Enable = 0;
        end

        % Button pushed function: imp_verify_sine_stop
        function imp_verify_sine_stopPushed(app, event)
            
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

            try
                fclose(app.file_imp_sine_verification);
            catch
            end

            flush(app.device);
            
            data = load('impedance_control.txt');

            t_t = 0.001:0.001:length(data(:,1))/1000;

            cla(app.imp_verify_position_plot); 
        
            hold(app.imp_verify_position_plot,'on')
            plot(app.imp_verify_position_plot, t_t, data(:,2), 'r');
            plot(app.imp_verify_position_plot, t_t, data(:,3), 'k');
            app.imp_verify_position_plot.XGrid ="on";
            app.imp_verify_position_plot.YGrid ="on";
            hold(app.imp_verify_position_plot,'off')

        
            cla(app.imp_verify_input_plot); 
        
            hold(app.imp_verify_input_plot,'on')
            plot(app.imp_verify_input_plot, t_t, data(:,4), 'k');
            app.imp_verify_input_plot.XGrid ="on";
            app.imp_verify_input_plot.YGrid ="on";
            hold(app.imp_verify_input_plot,'off')

            app.imp_verify_sine_start.Enable = 1;

        end

        % Button pushed function: VSD_applyBtn
        function VSD_applyBtnButtonPushed(app, event)
            
            t_stiff = [single(app.LowerStiffnessAdegEditField.Value), single(app.UpperStiffnessAdegEditField.Value)];
            t_damp = [single(app.LowerDamperAsdegEditField.Value), single(app.UpperDamperAsdegEditField.Value)];
            t_damped_range = [single(app.LowerDampedRangedegEditField.Value), single(app.UpperDampedRangedegEditField.Value)];
            t_stiff_range = [single(app.LowerStiffRangedegEditField.Value), single(app.UpperStiffRangedegEditField.Value)];
            
            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_VSD_STIFFNESS, app.SDO_REQU, 2, typecast(t_stiff,'uint8'), ...
                    app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_VSD_DAMPER,    app.SDO_REQU, 2, typecast(t_damp,'uint8'), ...
                    app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_VSD_DAMPED_RANGE,     app.SDO_REQU, 2, typecast(t_damped_range,'uint8'), ...
                    app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_VSD_STIFF_RANGE,     app.SDO_REQU, 2, typecast(t_stiff_range,'uint8'), ...
                    ];

            write(app.device,SendData,"uint8");


        end

        % Button pushed function: VSD_LowerLimit_SetBtn
        function VSD_LowerLimit_SetBtnPushed(app, event)
            
            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_VSD_LOWER_LIMIT, app.SDO_REQU, 1, uint8(0)), ...
                    ];

            write(app.device,SendData,"uint8");

        end

        % Button pushed function: VSD_UpperLimit_SetBtn
        function VSD_UpperLimit_SetBtnButtonPushed(app, event)
            
            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_VSD_UPPER_LIMIT, app.SDO_REQU, 1, uint8(0)), ...
                    ];

            write(app.device,SendData,"uint8");

        end

        % Button pushed function: VSD_SaturationSetBtn
        function VSD_SaturationSetBtnButtonPushed(app, event)
            
            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_VSD_SATURATION, app.SDO_REQU, 1, single(app.SaturationAEditField_2.Value)), ...
                    ];

            write(app.device,SendData,"uint8");

        end

        % Button pushed function: VSD_StartBtn
        function VSD_StartBtnButtonPushed(app, event)
            
            app.file_vsd_verification = fopen('vsd_verification.txt','w');

                    % routine & state set
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_ADV_FRICTION_COMPENSATION), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MIDLEVEL_COMPRESSIONAL_VSD)), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 3, ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_LOOP_CNT), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_ABS_POSITION), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_VSD_INPUT), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.VSD_VERIFICATION_DATA)), ...
                    ];

                write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");

        end

        % Button pushed function: VSD_StopBtn
        function VSD_StopBtnButtonPushed(app, event)
            
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                try
                    fclose(app.file_vsd_verification);
                catch
                end
                
                flush(app.device);

                data = load('vsd_verification.txt');
                t_t = 0.001:0.001:length(data(:,1))/1000;
    
                cla(app.vsd_abs_position_plot); 
                cla(app.vsd_input_plot); 
            
                hold(app.vsd_abs_position_plot,'on')
                plot(app.vsd_abs_position_plot, t_t, data(:,2), 'b');
                app.vsd_abs_position_plot.XGrid = "on";
                app.vsd_abs_position_plot.YGrid = "on";
                hold(app.vsd_abs_position_plot,'off')
            
                hold(app.vsd_input_plot,'on')
                plot(app.vsd_input_plot, t_t, data(:,3), 'b');
                app.vsd_input_plot.XGrid = "on";
                app.vsd_input_plot.YGrid = "on";
                hold(app.vsd_input_plot,'off')

        end

        % Button pushed function: 
        % FeedforwardDesignOnforImpedanceControllerButton
        function FeedforwardDesignOnforImpedanceControllerButtonPushed(app, event)
           
            s = tf('s');
            Ts = 0.001;

            try
                sys = 1/(app.inertia_value*s^2 + app.damp_coef_value*s);
            catch
                f = msgbox("Error: Please identify your system first.","Error","error");
                return;
            end

            sys_d = minreal(c2d(sys, Ts, 'zoh'));

            [num, den] = tfdata(sys_d,'v');
            
            if (length(num)>length(den))
                f = msgbox("Error: Improper model","Error","error");
            end

            [Z,P,K] = tf2zpk(num,den);
            
            j = 0;      k = 0;
            Zu = [];    Zs = [];
            
            for i=1:length(Z)
                if(abs(Z(i)) >= 0.999)
                    j = j+1;
                    Zu(j) = Z(i);
                else
                    k = k+1;
                    Zs(k) = Z(i);
                end
            end
            
            edit_commBoard(app, strcat(num2str(j), ' unstable zero(s) detected.'));

            F1 = zpk(P, Zs, K^-1, Ts);
            F2temp = zpk(Zu, [], 1, Ts);
            Gain = dcgain(F2temp);
            
            if(Gain == inf)
                Gain = bode(F2temp, 2*pi);
                edit_commBoard(app, 'DC-gain is infinity. The magnitude is matched at 1Hz.');
            end
            
            [N,D] = tfdata(F2temp,'v');
            
            for p = 1:(j+1),
                Nu(j-p+2) = N(p);
            end
            Du = zeros(1,(j+1));
            Du(1) = 1;
            
            F2 = tf(Nu, Du, Ts);
            F = F1*F2/Gain^2;
            Gzpet = minreal(F);

            %-----------PLOT-----------%
            w = 0:1:500*2*pi;
            [sys_mag, sys_phase] = bode(sys_d, w);
            [sys_inv_mag, sys_inv_phase] = bode(Gzpet, w);
            [sys_overall, sys_overall_phase] = bode(minreal(sys_d*Gzpet), w);

            sys_mag = 20*log10(squeeze(sys_mag));
            sys_inv_mag = 20*log10(squeeze(sys_inv_mag));
            sys_overall = 20*log10(squeeze(sys_overall));

            cla(app.ff_mag_plot); 
            cla(app.ff_phase_plot); 
            
            hold(app.ff_mag_plot,'on');
            plot(app.ff_mag_plot, w, (sys_mag), "r");
            plot(app.ff_mag_plot, w, (sys_inv_mag), "b");
            plot(app.ff_mag_plot, w, (sys_overall), "k");
            app.ff_mag_plot.XScale = "log";
            app.ff_mag_plot.XGrid = "on";
            app.ff_mag_plot.YGrid = "on";
            hold(app.ff_mag_plot,'off');
            
            hold(app.ff_phase_plot,'on');
            plot(app.ff_phase_plot, w, squeeze(sys_phase), "r");
            plot(app.ff_phase_plot, w, squeeze(sys_inv_phase), "b");
            plot(app.ff_phase_plot, w, squeeze(sys_overall_phase), "k");
            app.ff_phase_plot.XScale = "log";
            app.ff_phase_plot.XGrid = "on";
            app.ff_phase_plot.YGrid = "on";
            hold(app.ff_phase_plot,'off');


            %------------Send ZPET Info------------%
            [z_num, z_den] = tfdata(Gzpet, 'v')
            
            for i = 1:length(z_den)
                if(z_den(i) == 0)
                    z_den_new = z_den(i+1:end);
                else
                    break;
                end
            end
            
            num_length = length(z_num);
            den_length = length(z_den_new);
            
            if((num_length > 5) || (den_length > 5))
                f = msgbox("Error: The number coefficients is over than 5.","Error","error");
            end
            
            t_num = [num_length, z_num]
            t_den = [den_length, z_den_new]
            
            SendData = [app.SDO_SEND,...                  %PDO
                (app.node_id+16), ...
                2,...
                app.TASK_ID_MIDLEVEL, app.SDO_ID_MIDLEVEL_FEEDFORWARD_NUM, app.SDO_REQU, (num_length+1), typecast(single(t_num),'uint8'), ...
                app.TASK_ID_MIDLEVEL, app.SDO_ID_MIDLEVEL_FEEDFORWARD_DEN, app.SDO_REQU, (den_length+1), typecast(single(t_den),'uint8'), ...
                ];
            
            write(app.device,SendData,"uint8");

        end

        % Callback function
        function FeedforwardDesignOnforPDControllerButtonPushed(app, event)
            
            % routine & state set
            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_GAIN_REQUEST_FOR_FEEDFORWARD, app.SDO_REQU, 1, uint8(app.FF_FOR_PD_CTRL)), ...
                    ];

            write(app.device,SendData,"uint8");

        end

        % Value changed function: EstimatorTypeDropDown
        function EstimatorTypeDropDownValueChanged(app, event)
            value = app.EstimatorTypeDropDown.Value;
            
            if(value == '4')    %'Moving Average'
                app.MaskingSizeEditField.Enable = "on";
                app.aofLeadLagEditField.Enable = "off";
                app.bofLeadLagEditField.Enable = "off";
            elseif(value == '6')
                app.MaskingSizeEditField.Enable = "off";
                app.aofLeadLagEditField.Enable = "on";
                app.bofLeadLagEditField.Enable = "on";
            end

        end

        % Button pushed function: VE_apply
        function VE_applyPushed(app, event)
                        
            value = app.EstimatorTypeDropDown.Value;
            
            switch(value)
                case '0'                %'Raw Value'
                    temp = [0,0];
                    temp2 = [0,0];
                case '1'                %'LPF 300'
                    temp = [1,0];
                    temp2 = [0,0];
                case '2'                %'LPF 400'
                    temp = [2,0];
                    temp2 = [0,0];
                case '3'                %'LPF 500'
                    temp = [3,0];
                    temp2 = [0,0];
                case '4'                %'Moving Average'
                    temp = [4,uint8(app.MaskingSizeEditField.Value)];
                    temp2 = [0,0];
                case '5'                %'Kalman Filter'
                    temp = [5,0];
                    temp2 = [0,0];
                case '6'                %'Lead Lag'
                    temp = [6,0];
                    temp2 = [single(app.aofLeadLagEditField.Value), single(app.aofLeadLagEditField.Value)];
                otherwise 
            end

            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    2,...
                    app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_VELOCITY_ESTIMATOR, app.SDO_REQU, 1, uint8(temp), ...
                    app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_VELOCITY_ESTIMATOR_LEAD_LAG, app.SDO_REQU, 2, typecast(single(temp2), 'uint8'), ...
                    ];

            write(app.device,SendData,"uint8");
            
        end

        % Button pushed function: VE_StartButton
        function VE_StartButtonPushed(app, event)
            
            app.file_ve = fopen('ve_test.txt','w');

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 3, ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_LOOP_CNT), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_ACTUAL_VELOCITY_RAW), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_VELOCITY_ESTIMATED), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_VE_TEST_DATA)), ...
                    ];

                write(app.device,SendData,"uint8");

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");

                app.VE_StartButton.Enable = 0;

        end

        % Button pushed function: VE_StopButton
        function VE_StopButtonPushed(app, event)
                         
            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                try
                    fclose(app.file_ve);
                catch
                end
                flush(app.device);

                data = load('ve_test.txt');

                t_t = 0.001:0.001:length(data(:,1))/1000;

                cla(app.VE_result_plot); 
            
                hold(app.VE_result_plot,'on')
                plot(app.VE_result_plot, t_t, data(:,2), 'r');
                plot(app.VE_result_plot, t_t, data(:,3), 'b');
                app.VE_result_plot.XGrid = "on";
                app.VE_result_plot.YGrid = "on";
                hold(app.VE_result_plot,'off')

                app.VE_StartButton.Enable = 1;

        end

        % Button pushed function: sys_id_verification_StartButton
        function sys_id_verification_StartButtonPushed(app, event)
                        
            app.file_sys_id_verify = fopen('sys_id_verification.txt','w');

            SendData = [app.SDO_SEND,...                  %PDO
                    (app.node_id+16), ...
                    1,...
                    app.TASK_ID_MIDLEVEL, app.SDO_ID_MIDLEVEL_CURRENT_PERIODIC_SIG_INFO, app.SDO_REQU, 2, ...
                    typecast(single(app.Cur_Rec_Amp.Value), 'uint8'), ...
                    typecast(single(app.Cur_Rec_Freq.Value), 'uint8'), ...
                    ];

                  write(app.device,SendData,"uint8");
            
                if(app.CurrentReferenceDropDown.Value == "SINE")

                    % routine & state set
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_ADV_FRICTION_COMPENSATION), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MIDLEVEL_CURRENT_SINE_REF)), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                elseif(app.CurrentReferenceDropDown.Value == "RECT")

                    % routine & state set
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_ADV_FRICTION_COMPENSATION), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MIDLEVEL_CURRENT_REC_REF)), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                elseif(app.CurrentReferenceDropDown.Value == "TANH")

                    % routine & state set
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_ADV_FRICTION_COMPENSATION), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MIDLEVEL_CURRENT_TANH_REF)), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                end

                write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 4, ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_LOOP_CNT), ...
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_MID_ID_PROCESS_INPUT), ...       % rectangular current reference
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_FRICTION_COMPENSATOR_INPUT), ...  % friction compensator reference
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_VELOCITY_ESTIMATED), ...         % actual output
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_SBS_ID_VERIFY)), ...
                    ];

                write(app.device,SendData,"uint8");


                    % State Enable
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");

        end

        % Button pushed function: sys_id_verification_StopButton
        function sys_id_verification_StopButtonPushed(app, event)
                
                    % State Standby
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                try
                    fclose(app.file_sys_id_verify);
                catch
                end

                flush(app.device);

                if (app.JB_user_set == 0)
                    do_sys_id_verification(app.inertia_value, app.damp_coef_value, 'sys_id_verification.txt');
                elseif (app.JB_user_set == 1)
                    do_sys_id_verification(app.custom_J.Value, app.custom_B.Value, 'sys_id_verification.txt');
                end

                data = load('sys_id_verification_with_sim.txt');

                t_t = 0.001:0.001:length(data(:,1))/1000;
    
                cla(app.system_id_verify_plot); 
            
                hold(app.system_id_verify_plot,'on')
                plot(app.system_id_verify_plot, t_t, data(:,4) * 180/pi, 'r');
                plot(app.system_id_verify_plot, t_t, data(:,5) * 180/pi, 'k');
                app.system_id_verify_plot.XGrid = "on";
                app.system_id_verify_plot.YGrid = "on";
                hold(app.system_id_verify_plot,'off');

                legend(app.system_id_verify_plot, {'Actual', 'Estimation'});
                app.system_id_verify_plot.Title.FontSize = 14;
                app.system_id_verify_plot.Title.FontWeight = 'bold';


        end

        % Button pushed function: FF_StartButton
        function FF_StartButtonPushed(app, event)
            
            app.file_ff_verification = fopen('ff_verify.txt','w');

            SendData = [app.SDO_SEND,...                  %PDO
                    (app.node_id+16), ...
                    1,...
                    app.TASK_ID_MIDLEVEL, app.SDO_ID_MIDLEVEL_POSITION_PERIODIC_SIG_INFO, app.SDO_REQU, 2, ...
                    typecast(single(app.SineMagnitudeEditField.Value), 'uint8'), ...
                    typecast(single(app.SineFrequencyEditField.Value), 'uint8'), ...
                    ];

                write(app.device,SendData,"uint8");

             % routine & state set
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_ADV_FRICTION_COMPENSATION), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_MIDLEVEL_POSITION_SINE_REF), uint8(app.ROUTINE_ID_MIDLEVEL_FEEDFORWARD_FILTER)]), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 4, ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_LOOP_CNT), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_REF_POSITION), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_ACTUAL_POSITION), ...
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_CURRENT_OUTPUT), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_FF_CTRL)), ...
                    ];

                write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");
        end

        % Button pushed function: FF_StopButton
        function FF_StopButtonPushed(app, event)
            
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

            try
                fclose(app.file_ff_verification);
            catch
            end

            flush(app.device);
            
            data = load('ff_verify.txt');

            t_t = 0.001:0.001:length(data(:,1))/1000;

            cla(app.ff_plot); 
        
            hold(app.ff_plot,'on')
            plot(app.ff_plot, t_t, data(:,2), 'k');
            plot(app.ff_plot, t_t, data(:,3), 'b');
            plot(app.ff_plot, t_t, data(:,2) - data(:,3), 'r');
            plot(app.ff_plot, t_t, data(:,4), 'g');
            app.ff_plot.XGrid ="on";
            app.ff_plot.YGrid ="on";
            hold(app.ff_plot,'off')

        end

        % Button pushed function: ApplyButton_3
        function ApplyButton_3Pushed(app, event)

            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_MID_CTRL_SATURATION, app.SDO_REQU, 1, single(app.MidlevelControllerSaturationEditField_2.Value)), ...
                    ];

            write(app.device,SendData,"uint8");

            app.MidlevelControllerSaturationEditField.Value = app.MidlevelControllerSaturationEditField_2.Value;

        end

        % Button pushed function: TotalVerification_StartButton
        function TotalVerification_StartButtonPushed(app, event)
            
            routines = uint8(app.ROUTINE_ID_MIDLEVEL_POSITION_SINE_REF);

            if(app.FeedforwardControllerCheckBox_2.Value == 1)
                routines = [routines, uint8(app.ROUTINE_ID_MIDLEVEL_FEEDFORWARD_FILTER)];
            end

            if(app.ImpedanceControllerCheckBox_2.Value == 1)
                routines = [routines, uint8(app.ROUTINE_ID_MIDLEVEL_IMPEDANCE_CONTROL)];
            elseif(app.PDControllerCheckBox_2.Value == 1)
                routines = [routines, uint8(app.ROUTINE_ID_MIDLEVEL_POSITION_CTRL)];
            end

            if(app.MechanicalImpedanceCompensatorCheckBox_2.Value == 1)
                routines = [routines, uint8(app.ROUTINE_ID_MIDLEVEL_IRC)];
            elseif(app.DisturbanceObserverCheckBox_2.Value == 1)
                routines = [routines, uint8(app.ROUTINE_ID_MIDLEVEL_DISTURBANCE_OBS)];
            end

            %%

            app.file_total_verification = fopen('total_verify.txt','w');

            SendData = [app.SDO_SEND,...                  %PDO
                    (app.node_id+16), ...
                    1,...
                    app.TASK_ID_MIDLEVEL, app.SDO_ID_MIDLEVEL_POSITION_PERIODIC_SIG_INFO, app.SDO_REQU, 2, ...
                    typecast(single(app.AmplituderadEditField_5.Value), 'uint8'), ...
                    typecast(single(app.FrequencyHzEditField_7.Value), 'uint8'), ...
                    ];

                write(app.device,SendData,"uint8");

                    % routine & state set
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, length(routines), routines), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 6, ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_LOOP_CNT), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_REF_POSITION), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_ACTUAL_POSITION), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_VELOCITY_ESTIMATED), ...
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_MID_CTRL_INPUT), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_DOB_DISTURABNCE), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_TOTAL_CTRL)), ...
                    ];

                write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");


        end

        % Button pushed function: TotalVerification_StopButton
        function TotalVerification_StopButtonPushed(app, event)
            
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

            try
                fclose(app.file_total_verification);
            catch
            end

            flush(app.device);

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            
            data = load('total_verify.txt');

            t_t = 0.001:0.001:length(data(:,1))/1000;

            cla(app.total_plot1); 
            hold(app.total_plot1,'on')

            if(app.ReferenceCheckBox.Value == 1)
                plot(app.total_plot1, t_t, data(:,2), 'k');
            end

            if(app.PositionCheckBox.Value == 1)
                plot(app.total_plot1, t_t, data(:,3), 'b');
            end

            if(app.VelocityCheckBox.Value == 1)
                plot(app.total_plot1, t_t, data(:,4), 'g');
            end

            if(app.ErrorCheckBox.Value == 1)
                plot(app.total_plot1, t_t, data(:,2) - data(:,3), 'r');
            end

            if(app.MidCtrlInputCheckBox.Value == 1)
                plot(app.total_plot1, t_t, data(:,5), 'm');
            end

            if(app.DisturbanceCheckBox.Value == 1)
                plot(app.total_plot1, t_t, data(:,6), 'y');
            end

            app.total_plot1.XGrid ="on";
            app.total_plot1.YGrid ="on";
            hold(app.total_plot1,'off')

            
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            
            data = load('total_verify.txt');

            t_t = 0.001:0.001:length(data(:,1))/1000;

            cla(app.total_plot2); 
            hold(app.total_plot2,'on')

            if(app.ReferenceCheckBox_2.Value == 1)
                plot(app.total_plot2, t_t, data(:,2), 'k');
            end

            if(app.PositionCheckBox_2.Value == 1)
                plot(app.total_plot2, t_t, data(:,3), 'b');
            end

            if(app.VelocityCheckBox_2.Value == 1)
                plot(app.total_plot2, t_t, data(:,4), 'g');
            end

            if(app.ErrorCheckBox_2.Value == 1)
                plot(app.total_plot2, t_t, data(:,2) - data(:,3), 'r');
            end

            if(app.MidCtrlInputCheckBox_2.Value == 1)
                plot(app.total_plot2, t_t, data(:,5), 'm');
            end

            if(app.DisturbanceCheckBox_2.Value == 1)
                plot(app.total_plot2, t_t, data(:,6), 'y');
            end

            app.total_plot2.XGrid ="on";
            app.total_plot2.YGrid ="on";
            hold(app.total_plot2,'off')

        end

        % Value changed function: MaxVel2radsEditField
        function MaxVel2radsEditFieldValueChanged(app, event)
            value = app.MaxVel2radsEditField.Value;
            
            if(value <= app.MaxVel1radsEditField.Value)
                f = msgbox("Error: The second maximum velocity must be larger than first one.","Error","error");
            end

            MaxVel = ((48 - app.PeakCurrentLimitEdit.Value*app.ResistanceOhmEditField.Value)/(app.VelocityConstantVradsEditField.Value)/(app.GearRatio.Value))*0.9;

            if(value > MaxVel)
                t_str = strcat("Error: The second maximum velocity must be smaller than ", num2str(MaxVel), ".")
                f = msgbox(t_str,"Error","error");
                app.MaxVel2radsEditField.Value = MaxVel;
            end

        end

        % Button pushed function: ApplyButton_4
        function ApplyButton_4Pushed(app, event)
                        
            SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...                      %identifier
                1,...
                app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_FRICTION_ID_INFO, app.SDO_REQU, 8, ...
                typecast(single(app.MaxVel1radsEditField.Value),'uint8'), ...
                typecast(single(app.MaxVel2radsEditField.Value),'uint8'), ...
                typecast(single(app.Vel1StepNumberEditField.Value),'uint8'), ...
                typecast(single(app.Vel2StepNumberEditField.Value),'uint8'), ...
                typecast(single(app.TimeperStepsecEditField.Value),'uint8'), ...
                typecast(single(app.CutTimesecEditField.Value),'uint8'), ...
                typecast(single(app.Pgain_Slider.Value),'uint8'), ...
                typecast(single(app.Igain_Slider.Value),'uint8'), ...
                ];

                write(app.device,SendData,"uint8");

        end

        % Button pushed function: IDStartButton
        function IDStartButtonPushed(app, event)
            app.friction_compensator_evluation = 0; 
            app.file_adv_friction_id = fopen('MECH_AdvFrictionID_FrictionModelID.txt','w');
   
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    2,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_ADVANCED_FRICTION_ID), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                    % State Enable
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    2,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");

                app.IDStartButton.Enable = 0;

        end

        % Button pushed function: IDStopButton
        function IDStopButtonPushed(app, event)
                                
            % State Enable
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                try
                    fclose(app.file_adv_friction_id);
                catch
                end

                flush(app.device);

                app.IDStartButton.Enable = 1;
     
        end

        % Value changed function: CutTimesecEditField
        function CutTimesecEditFieldValueChanged(app, event)
            value = app.CutTimesecEditField.Value;
            
            if(value >= app.TimeperStepsecEditField.Value)
                f = msgbox("Error: The 'cut time' must be smaller than 'Time per Step'.","Error","error");
                app.CutTimesecEditField.Value = app.TimeperStepsecEditField.Value/2;
            end

        end

        % Button pushed function: ApplyButton_5
        function ApplyButton_5Pushed(app, event)
            
            format long

            t_mdl = app.FitModelDropDown.Value;
            t_gain = app.GainEditField.Value;
            t_Fs = app.Fs_Field.Value;
            t_Fc = app.Fc_Field.Value;


            if     (t_mdl == "1: Coulomb+Viscous")
                mdl_id = 1;
            elseif (t_mdl == "2: Stribeck1")
                mdl_id = 2;
            elseif (t_mdl == "3: Stribeck2")
                mdl_id = 3;
            end

            MaxVel = ((48 - app.PeakCurrentLimitEdit.Value*app.ResistanceOhmEditField.Value)/(app.VelocityConstantVradsEditField.Value)/(app.GearRatio.Value))*0.9;
            results = design_friction_compensator('friction_compensator_designer.txt', 'MECH_AdvFrictionID_FrictionModelID.txt', app.EpsilonDeadZoneradsEditField.Value, t_Fs, t_Fc, MaxVel, app.DesiredDampingAsradEditField.Value, mdl_id, t_gain);

            data_act = load('MECH_AdvFrictionID_FrictionModelID.txt');
            data = load('friction_compensator_designer.txt');

            cla(app.adv_friction_id_plot); 

            hold(app.adv_friction_id_plot,'on')
            plot(app.adv_friction_id_plot, data_act(:,2), data_act(:,3), 'k.');
            plot(app.adv_friction_id_plot, data(:,1), data(:,2), 'Color', 'r');
            plot(app.adv_friction_id_plot, data(:,1), data(:,3), 'Color', 'g');   
            plot(app.adv_friction_id_plot, data(:,1), data(:,4), 'Color', 'b');
            app.adv_friction_id_plot.XMinorGrid = "on";
            app.adv_friction_id_plot.YMinorGrid = "on";
            hold(app.adv_friction_id_plot,'off')

            legend(app.adv_friction_id_plot, {'Experiment Result', 'Fitted Model', 'Desired Friction Model', 'Designed Compensator'});

            app.adv_friction_id_plot.Title.FontWeight = 'bold';
            app.adv_friction_id_plot.Title.FontSize = 14;

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_FRICTION_LUT_INFO, app.SDO_REQU, 9, ...
                    typecast(single(results(1)),'uint8'), ...
                    typecast(single(results(2)),'uint8'), ...
                    typecast(single(results(3)),'uint8'), ...
                    typecast(single(results(4)),'uint8'), ...
                    typecast(single(results(5)),'uint8'), ...
                    typecast(single(results(6)),'uint8'), ...
                    typecast(single(results(7)),'uint8'), ...
                    typecast(single(app.DesiredDampingAsradEditField.Value),'uint8'), ...
                    typecast(single(app.EpsilonDeadZoneradsEditField.Value),'uint8'), ...                    
                    ];

             write(app.device,SendData,"uint8");

        end

        % Button pushed function: FrictionCompensatorStartButton
        function FrictionCompensatorStartButtonPushed(app, event)
               
            app.file_friction_verification = fopen('friction_verification.txt','w');

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 2, ...
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_VELOCITY), ...
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_FRICTION_COMPENSATOR_INPUT), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.FRICTION_COMPENSATOR_VERIFICATION)), ...
                    ];

                write(app.device,SendData,"uint8");

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    2,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_ADV_FRICTION_COMPENSATION), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                    % State Enable
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    2,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");

                app.FrictionCompensatorStartButton.Enable = 0;

        end

        % Button pushed function: FrictionCompensatorStopButton
        function FrictionCompensatorStopButtonPushed(app, event)
            
                    % State Enable
             app.FrictionCompensatorStartButton.Enable = 1;

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    2,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

            try
                fclose(app.file_friction_verification);
            catch
            end

            flush(app.device);
            data = load('friction_verification.txt');


            hold(app.adv_friction_verify_plot,'on');

            len = length(data(:,1));
            t = 0:0.001:0.001*(len-1);
            yyaxis(app.adv_friction_verify_plot, 'left');         
            cla(app.adv_friction_verify_plot); 
            plot(app.adv_friction_verify_plot, t, data(:,1)*180/pi, 'b'); % velocity
            ylabel(app.adv_friction_verify_plot, 'Velocity (Deg/s)', 'Color', 'b');

            yyaxis(app.adv_friction_verify_plot, 'right');    
            cla(app.adv_friction_verify_plot); 
            plot(app.adv_friction_verify_plot, t, data(:,2), 'r');
            ylabel(app.adv_friction_verify_plot, 'Current (A)', 'Color', 'r');

            app.adv_friction_verify_plot.XGrid = "on";
            app.adv_friction_verify_plot.YGrid = "on";
         
            hold(app.adv_friction_verify_plot,'off');

            legend(app.adv_friction_verify_plot, {'Velocity (Deg/s)', 'Compensator Input (A)'});
            app.adv_friction_verify_plot.XLim = [0, 0.001*(len-1)];
            app.adv_friction_verify_plot.Title.FontWeight = 'bold';
            app.adv_friction_verify_plot.Title.FontSize = 14;


        end

        % Button pushed function: GaitPhase_Start
        function GaitPhase_StartPushed(app, event)
             app.file_WIDM_gait_phase = fopen('WIDM_gait_phase.txt','w');

                    % routine & state set
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...                    
                    app.pack_sdoUnit(app.TASK_ID_IMU,  app.SDO_ID_IMU_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_IMU_TOTAL_FUNCTION)), ...
%                     app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
%                     app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

             write(app.device,SendData,"uint8");

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 6, ...
                                app.TASK_ID_IMU, uint8(app.PDO_ID_IMU_GAIT_PHASE), ...
                                app.TASK_ID_IMU, uint8(app.PDO_ID_IMU_DEG_ACC), ...
                                app.TASK_ID_IMU, uint8(app.PDO_ID_IMU_DEG_GYR), ...
                                app.TASK_ID_IMU, uint8(app.PDO_ID_IMU_DEG_ACC_FILTERED), ...
                                app.TASK_ID_IMU, uint8(app.PDO_ID_IMU_DEG_GYR_FILTERED), ...
                                app.TASK_ID_IMU, uint8(app.PDO_ID_IMU_DEG_TVCF), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_GAIT_PHASE)), ...
                    ];

                write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...    
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");
        end

        % Button pushed function: GaitPhase_Stop
        function GaitPhase_StopButtonPushed(app, event)
            
                    % State Standby
                SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                fclose(app.file_WIDM_gait_phase);
                flush(app.device);

                data = load('WIDM_gait_phase.txt');

                t_t = 0.001:0.001:length(data(:,1))/1000;
    
                cla(app.GaitPhase_Plot);             
                hold(app.GaitPhase_Plot,'on')
                plot(app.GaitPhase_Plot, t_t, data(:,1), 'k');
                app.GaitPhase_Plot.XGrid = "on";
                app.GaitPhase_Plot.YGrid = "on";
                hold(app.GaitPhase_Plot,'off');

                cla(app.Degree_Plot);             
                hold(app.Degree_Plot,'on');
                if(app.DEG_ACCCheckBox.Value == 1)
                    plot(app.Degree_Plot, t_t, data(:,2), 'r');
                end

                if(app.DEG_GYRCheckBox.Value == 1)
                    plot(app.Degree_Plot, t_t, data(:,3), 'g');
                end

                if(app.DEG_ACC_FCheckBox.Value == 1)
                    plot(app.Degree_Plot, t_t, data(:,4), 'b');
                end

                if(app.DEG_GYR_FCheckBox.Value == 1)
                    plot(app.Degree_Plot, t_t, data(:,5), 'm');
                end

                if(app.DEG_TVCFCheckBox.Value == 1)
                    plot(app.Degree_Plot, t_t, data(:,6), 'c');
                end
               app.Degree_Plot.XGrid = "on";
               app.Degree_Plot.YGrid = "on";
               hold(app.Degree_Plot,'off');
  
        end

        % Button pushed function: Get_ACCGYR_Start
        function Get_ACCGYR_StartPushed(app, event)
             app.file_WIDM_acc_gyr = fopen('WIDM_acc_gyr.txt','w');

                    % routine & state set
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...                    
                    app.pack_sdoUnit(app.TASK_ID_IMU,  app.SDO_ID_IMU_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_IMU_6AXIS_GETVALUE)), ...
                    ];

             write(app.device,SendData,"uint8");

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 6, ...
                                app.TASK_ID_IMU, uint8(app.PDO_ID_IMU_ACC_X), ...
                                app.TASK_ID_IMU, uint8(app.PDO_ID_IMU_ACC_Y), ...
                                app.TASK_ID_IMU, uint8(app.PDO_ID_IMU_ACC_Z), ...
                                app.TASK_ID_IMU, uint8(app.PDO_ID_IMU_GYR_X), ...
                                app.TASK_ID_IMU, uint8(app.PDO_ID_IMU_GYR_Y), ...
                                app.TASK_ID_IMU, uint8(app.PDO_ID_IMU_GYR_Z), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_ACC_GYRO)), ...
                    ];

                write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    2,...    
                    app.pack_sdoUnit(app.TASK_ID_IMU,       app.SDO_ID_IMU_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");
        end

        % Button pushed function: Get_ACCGYR_Stop
        function Get_ACCGYR_StopPushed(app, event)
            
                    % State Standby
                SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    2,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_IMU,  app.SDO_ID_IMU_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                fclose(app.file_WIDM_acc_gyr);
                flush(app.device);

                data = load('WIDM_acc_gyr.txt');

                t_t = 0.001:0.001:length(data(:,1))/1000;
    
                cla(app.ACC_Plot);             
                hold(app.ACC_Plot,'on')
                plot(app.ACC_Plot, t_t, data(:,1), 'r');
                plot(app.ACC_Plot, t_t, data(:,2), 'g');
                plot(app.ACC_Plot, t_t, data(:,3), 'b');
                app.ACC_Plot.XGrid = "on";
                app.ACC_Plot.YGrid = "on";
                hold(app.ACC_Plot,'off');

                cla(app.GYR_Plot);             
                hold(app.GYR_Plot,'on')
                plot(app.GYR_Plot, t_t, data(:,4), 'r');
                plot(app.GYR_Plot, t_t, data(:,5), 'g');
                plot(app.GYR_Plot, t_t, data(:,6), 'b');
                app.GYR_Plot.XGrid = "on";
                app.GYR_Plot.YGrid = "on";
                hold(app.GYR_Plot,'off');
        end

        % Button pushed function: IDStartwithCompFBButton
        function IDStartwithCompFBButtonPushed(app, event)
            app.friction_compensator_evluation = 1; 
            
            app.file_adv_friction_id = fopen('adv_friction_id_with_compensator_FB.txt','w');
            
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    2,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 3, [uint8(app.ROUTINE_ID_LOWLEVEL_ADVANCED_FRICTION_ID), uint8(app.ROUTINE_ID_LOWLEVEL_ADV_FRICTION_COMPENSATION), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                    % State Enable
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");
                
        end

        % Button pushed function: IDStopButton_2
        function IDStopButton_2Pushed(app, event)
                        % State Enable
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                try
                    fclose(app.file_adv_friction_id);
                catch
                end

                flush(app.device);

        end

        % Button pushed function: PlotButton_3
        function PlotButton_3Pushed(app, event)
            
            format long  
            MaxVel = ((48 - app.PeakCurrentLimitEdit.Value*app.ResistanceOhmEditField.Value)/(app.VelocityConstantVradsEditField.Value)/(app.GearRatio.Value))*0.9;
            t_v = -MaxVel:(MaxVel/4096):MaxVel;
            t_c = app.DesiredDampingAsradEditField.Value*t_v;

            if (app.friction_compensator_evluation == 1)
                data = load('adv_friction_id_with_compensator_FB.txt');
            elseif (app.friction_compensator_evluation == 2)
                data = load('adv_friction_id_with_compensator_FF.txt');
            end

            data1 = load('MECH_AdvFrictionID_FrictionModelID.txt');

            cla(app.adv_friction_verify_plot_2); 
            hold(app.adv_friction_verify_plot_2,'on')
            plot(app.adv_friction_verify_plot_2, data1(:,2), data1(:,3), 'k.');
            plot(app.adv_friction_verify_plot_2, data(:,2), data(:,3), 'b.');
            plot(app.adv_friction_verify_plot_2, t_v, t_c, 'Color', 'r');
      
            legend(app.adv_friction_verify_plot_2, {'without FC', 'with FC', 'desired friction'});
            app.adv_friction_verify_plot_2.XMinorGrid = "on";
            app.adv_friction_verify_plot_2.YMinorGrid = "on";
            hold(app.adv_friction_verify_plot_2,'off')
        end

        % Callback function
        function TestStartButtonPushed(app, event)
                    SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...                      %identifier
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_SET_ROUTINE,      app.SDO_REQU, 1, uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_SET_STATE,        app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_SET_STATE,        app.SDO_REQU, 1, uint8(app.State_Enable)) ...
                    ];            
                write(app.device,SendData,"uint8");
        end

        % Callback function
        function TestStopButtonPushed(app, event)
                    SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...                      %identifier
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_SET_STATE,        app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];            
                write(app.device,SendData,"uint8");
        end

        % Button pushed function: ControlStartButton
        function ControlStartButtonPushed(app, event)
            flush(app.device);
            
            app.file_friction_id_vel_ctrl = fopen('friction_id_vel_ctrl_eval.txt', 'w');        

            SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...                      %identifier
                1,...
                app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_FRICTION_ID_INFO, app.SDO_REQU, 8, ...
                typecast(single(app.VelocityRefradsEditField.Value),'uint8'), ...
                typecast(single(app.MaxVel2radsEditField.Value),'uint8'), ...
                typecast(single(app.Vel1StepNumberEditField.Value),'uint8'), ...
                typecast(single(app.Vel2StepNumberEditField.Value),'uint8'), ...
                typecast(single(app.TimeperStepsecEditField.Value),'uint8'), ...
                typecast(single(app.CutTimesecEditField.Value),'uint8'), ...
                typecast(single(app.Pgain_Slider.Value),'uint8'), ...
                typecast(single(app.Igain_Slider.Value),'uint8'), ...
                ];

             write(app.device,SendData,"uint8");


            SendData = [app.SDO_SEND,...                  %SDO
                 (app.node_id+16), ...                      %identifier
                 4,...
                 app.pack_sdoUnit(app.TASK_ID_MSG, app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                 app.TASK_ID_MSG, app.SDO_ID_MSG_PDO_LIST, app.SDO_REQU, 3, ...                     
                     app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_ACTUAL_VELOCITY_RAW), ...
                     app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_VELOCITY_ESTIMATED), ...
                     app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_LOOP_CNT), ...                 
                 app.pack_sdoUnit(app.TASK_ID_MSG, app.SDO_ID_MSG_GUI_COMM_ONOFF,       app.SDO_REQU, 1, uint8(1)), ...
                 app.pack_sdoUnit(app.TASK_ID_MSG, app.SDO_ID_MSG_GUI_COMM_COMMAND,     app.SDO_REQU, 1, uint8(app.FRICTION_ID_VEL_CTRL_EVAL)), ...
                 ];
            
             write(app.device,SendData,"uint8");

            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ... 
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_ADV_FRICTION_ID_VEL_CTRL_EVAL), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];
            
                write(app.device,SendData,"uint8");

             SendData = [app.SDO_SEND,...                  %SDO
                 (app.node_id+16), ...                      %identifier
                 3,...
                 app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                 app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                 app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                 ];

                write(app.device,SendData,"uint8");                
        end

        % Button pushed function: ControlStopButton
        function ControlStopButtonPushed(app, event)
             % State Enable
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    2,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                try
                    fclose(app.file_friction_id_vel_ctrl);
                catch
                end

            data = load('friction_id_vel_ctrl_eval.txt');

            cla(app.friction_id_vel_ctrl_eval); 

            hold(app.friction_id_vel_ctrl_eval,'on')
            plot(app.friction_id_vel_ctrl_eval, data(:,2), 'r');
            %plot(app.friction_id_vel_ctrl_eval, data(:,3), 'k');
            plot(app.friction_id_vel_ctrl_eval, data(:,4), 'b');

            app.friction_id_vel_ctrl_eval.XMinorGrid = "on";
            app.friction_id_vel_ctrl_eval.YMinorGrid = "on";
            hold(app.friction_id_vel_ctrl_eval,'off');

            legend(app.friction_id_vel_ctrl_eval, {'Reference', 'Measurement'});


                flush(app.device);
        end

        % Button pushed function: IDStartwithCompFFButton
        function IDStartwithCompFFButtonPushed(app, event)
            app.friction_compensator_evluation = 2; 
            app.file_adv_friction_id = fopen('adv_friction_id_with_compensator_FF.txt','w');
            
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    2,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 3, [uint8(app.ROUTINE_ID_LOWLEVEL_ADVANCED_FRICTION_ID), uint8(app.ROUTINE_ID_LOWLEVEL_ADVANCED_FRICTION_COMPENSATION_FF), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                    % State Enable
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");
        end

        % Value changed function: ApplyButton_6
        function ApplyButton_6ValueChanged(app, event)
            
            t_J = single(app.custom_J.Value);
            t_B = single(app.custom_B.Value);

            app.JB_user_set = 1;

            t_a = -exp(-t_B*0.001/t_J);
            t_b = (1-exp(-t_B*0.001/t_J))/t_B;


            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_INERTIA,        app.SDO_REQU, 1, single(t_J)), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_DAMPING_COEF,   app.SDO_REQU, 1, single(t_B)), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_MECH_MODEL_A,   app.SDO_REQU, 1, single(t_a)), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_MECH_MODEL_B,   app.SDO_REQU, 1, single(t_b)), ...
                    ];
            
                write(app.device,SendData,"uint8");
            
        end

        % Button pushed function: dob_verification_start
        function dob_verification_startPushed(app, event)
             app.file_dob_evaluation = fopen('MECH_DOB_ControlData.txt','w');

            SendData = [app.SDO_SEND,...                  %PDO
                    (app.node_id+16), ...
                    1,...
                    app.TASK_ID_MIDLEVEL, app.SDO_ID_MIDLEVEL_CURRENT_PERIODIC_SIG_INFO, app.SDO_REQU, 2, ...
                    typecast(single(app.dob_verify_amp.Value), 'uint8'), ...
                    typecast(single(app.dob_verify_freq.Value), 'uint8'), ...
                    ];

                write(app.device,SendData,"uint8");
            
                if(app.ReferenceDropDown_2.Value == "SINE")

                    % routine & state set
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_ADV_FRICTION_COMPENSATION), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_MIDLEVEL_CURRENT_SINE_REF), uint8(app.ROUTINE_ID_MIDLEVEL_DISTURBANCE_OBS)]), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                elseif(app.ReferenceDropDown_2.Value == "RECT")

                    % routine & state set
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_ADV_FRICTION_COMPENSATION), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_MIDLEVEL_CURRENT_REC_REF), uint8(app.ROUTINE_ID_MIDLEVEL_DISTURBANCE_OBS)]), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                elseif(app.ReferenceDropDown_2.Value == "TANH")

                    % routine & state set
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_LOWLEVEL_ADV_FRICTION_COMPENSATION), uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_MIDLEVEL_CURRENT_TANH_REF), uint8(app.ROUTINE_ID_MIDLEVEL_DISTURBANCE_OBS)]), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                end

                write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 5, ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_LOOP_CNT), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_DOB_INPUT), ...                  % dob input
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_MID_ID_PROCESS_INPUT), ...       % rectangular current reference
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_FRICTION_COMPENSATOR_INPUT), ...  % friction compensator reference
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_VELOCITY_ESTIMATED), ...         % actual output
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_DOB_VERIFY)), ...
                    ];

                write(app.device,SendData,"uint8");


                    % State Enable
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");

                app.dob_verification_start.Enable = 0;

        end

        % Button pushed function: dob_verification_stop
        function dob_verification_stopButtonPushed(app, event)
            app.dob_verification_start.Enable = 1;
                    % State Standby
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                try
                    fclose(app.file_dob_evaluation);
                catch
                end

                flush(app.device);

                do_dob_verification(app.inertia_value, app.damp_coef_value, 'MECH_DOB_ControlData.txt');

                data = load('MECH_DOB_ModelFollowingEval.txt');

                t_t = 0.001:0.001:length(data(:,1))/1000;
    
                cla(app.dob_evaluation_plot); 
            
                hold(app.dob_evaluation_plot,'on')
                plot(app.dob_evaluation_plot, t_t, data(:,5), 'r');
                plot(app.dob_evaluation_plot, t_t, data(:,6), 'k');
                app.dob_evaluation_plot.XGrid = "on";
                app.dob_evaluation_plot.YGrid = "on";
                hold(app.dob_evaluation_plot,'off');

                
        end

        % Value changed function: Impedance_Setting
        function Impedance_SettingValueChanged(app, event)


            if (app.I_Vector_Checkbox1.Value == 1)
                
            SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...
                5,...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_EPSILON,  app.SDO_REQU, 1, uint8(10*app.HalfWidthdegEditField1.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_KP,       app.SDO_REQU, 1, uint8(2.55*app.KpEditField1.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_KD,       app.SDO_REQU, 1, uint8(2.55*app.KdEditField1.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_LAMBDA,   app.SDO_REQU, 1, uint8(100*app.lambdaEditField1.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_DURATION, app.SDO_REQU, 1, uint16(app.LmsEditField1.Value)), ...
                ];
            write(app.device,SendData,"uint8");

            end

            if (app.I_Vector_Checkbox2.Value == 1)
                
            SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...
                5,...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_EPSILON,  app.SDO_REQU, 1, uint8(10*app.HalfWidthdegEditField2.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_KP,       app.SDO_REQU, 1, uint8(2.55*app.KpEditField2.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_KD,       app.SDO_REQU, 1, uint8(2.55*app.KdEditField2.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_LAMBDA,   app.SDO_REQU, 1, uint8(100*app.lambdaEditField2.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_DURATION, app.SDO_REQU, 1, uint16(app.LmsEditField2.Value)), ...
                ];
            write(app.device,SendData,"uint8");

            end

            if (app.I_Vector_Checkbox3.Value == 1)
                
            SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...
                5,...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_EPSILON,  app.SDO_REQU, 1, uint8(10*app.HalfWidthdegEditField3.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_KP,       app.SDO_REQU, 1, uint8(2.55*app.KpEditField3.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_KD,       app.SDO_REQU, 1, uint8(2.55*app.KdEditField3.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_LAMBDA,   app.SDO_REQU, 1, uint8(100*app.lambdaEditField3.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_DURATION, app.SDO_REQU, 1, uint16(app.LmsEditField3.Value)), ...
                ];
            write(app.device,SendData,"uint8");

            end

            if (app.I_Vector_Checkbox4.Value == 1)
                
            SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...
                5,...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_EPSILON,  app.SDO_REQU, 1, uint8(10*app.HalfWidthdegEditField4.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_KP,       app.SDO_REQU, 1, uint8(2.55*app.KpEditField4.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_KD,       app.SDO_REQU, 1, uint8(2.55*app.KdEditField4.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_LAMBDA,   app.SDO_REQU, 1, uint8(100*app.lambdaEditField4.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_DURATION, app.SDO_REQU, 1, uint16(app.LmsEditField4.Value)), ...
                ];
            write(app.device,SendData,"uint8");

            end
            
            if (app.I_Vector_Checkbox5.Value == 1)
                
            SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...
                5,...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_EPSILON,  app.SDO_REQU, 1, uint8(10*app.HalfWidthdegEditField5.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_KP,       app.SDO_REQU, 1, uint8(2.55*app.KpEditField5.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_KD,       app.SDO_REQU, 1, uint8(2.55*app.KdEditField5.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_LAMBDA,   app.SDO_REQU, 1, uint8(100*app.lambdaEditField5.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_DURATION, app.SDO_REQU, 1, uint16(app.LmsEditField5.Value)), ...
                ];
            write(app.device,SendData,"uint8");

            end

            if (app.I_Vector_Checkbox6.Value == 1)
                
            SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...
                5,...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_EPSILON,  app.SDO_REQU, 1, uint8(10*app.HalfWidthdegEditField6.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_KP,       app.SDO_REQU, 1, uint8(2.55*app.KpEditField6.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_KD,       app.SDO_REQU, 1, uint8(2.55*app.KdEditField6.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_LAMBDA,   app.SDO_REQU, 1, uint8(100*app.lambdaEditField6.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_DURATION, app.SDO_REQU, 1, uint16(app.LmsEditField6.Value)), ...
                ];
            write(app.device,SendData,"uint8");

            end


        end

        % Button pushed function: Impedance_Ctrl_Start
        function Impedance_Ctrl_StartButtonPushed(app, event)
            app.file_pfi_evaluation = fopen("i_vector_evaluation.txt", 'w');

            % routine & state set
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_MIDLEVEL_IMPEDANCE_CONTROL), uint8(app.ROUTINE_ID_MIDLEVEL_P_VECTOR_DECODER)]), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];
             write(app.device,SendData,"uint8");

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 4, ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_LOOP_CNT), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_REF_POSITION), ...                  
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_ACTUAL_POSITION), ...       
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_TOTAL_CURRENT_INPUT), ...  
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_I_VECTOR_EVAL)), ...
                    ];
             write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];
             write(app.device,SendData,"uint8");
        end

        % Button pushed function: Impedance_Ctrl_Stop
        function Impedance_Ctrl_StopButtonPushed(app, event)
            
            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                fclose(app.file_pfi_evaluation);
                flush(app.device);

                data = load("i_vector_evaluation.txt");

                t_t = 0.001:0.001:length(data(:,1))/1000;
    
                cla(app.IvectorPlot);             
                hold(app.IvectorPlot,'on')
                plot(app.IvectorPlot, t_t, data(:,2), 'r');
                plot(app.IvectorPlot, t_t, data(:,3), 'k');
                plot(app.IvectorPlot, t_t, data(:,4), 'b');
                app.IvectorPlot.YGrid = "on";
                hold(app.IvectorPlot,'off');


        end

        % Button pushed function: Get_ACCGYR_Start_2
        function Get_ACCGYR_Start_2ButtonPushed(app, event)

            t_filename = sprintf('%s.txt', app.FileSaveNametxtEditField_3.Value);
            app.file_quaternion = fopen(t_filename,'w');      

            app.plot_cnt = 0;

            % routine & state set
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...                    
                    app.pack_sdoUnit(app.TASK_ID_IMU,  app.SDO_ID_IMU_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_IMU_QT_INT)), ...
                    ];

             write(app.device,SendData,"uint8");

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 3, ...
                                app.TASK_ID_IMU, uint8(app.PDO_ID_IMU_ACC_GYR_XYZ), ...
                                app.TASK_ID_IMU, uint8(app.PDO_ID_IMU_MAG_XYZ), ...
                                app.TASK_ID_IMU, uint8(app.PDO_ID_QUATERNION), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_QUATERNION)), ...
                    ];

                write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    2,...    
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_IMU,       app.SDO_ID_IMU_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");
        end

        % Button pushed function: ApplyButton_7
        function ApplyButton_7Pushed(app, event)
            t_filename = sprintf('%s.txt', app.FileSaveNametxtEditField_3.Value);
            app.file_quaternion = fopen(t_filename,'w');      
        end

        % Button pushed function: Get_ACCGYR_Stop_2
        function Get_ACCGYR_Stop_2ButtonPushed(app, event)
            
                    % State Standby
                SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    2,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_IMU,  app.SDO_ID_IMU_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                fclose(app.file_quaternion);
                flush(app.device);

                t_filename = sprintf('%s.txt', app.FileSaveNametxtEditField_3.Value);
                data = load(t_filename);

                t_t = 0.001:0.001:length(data(:,1))/1000;
    
                cla(app.Plot_Quaternion_Accel);             
                hold(app.Plot_Quaternion_Accel,'on')
                plot(app.Plot_Quaternion_Accel, t_t, data(:,1), 'r');
                plot(app.Plot_Quaternion_Accel, t_t, data(:,2), 'g');
                plot(app.Plot_Quaternion_Accel, t_t, data(:,3), 'b');
                app.Plot_Quaternion_Accel.XGrid = "on";
                app.Plot_Quaternion_Accel.YGrid = "on";
                hold(app.Plot_Quaternion_Accel,'off');
                legend(app.Plot_Quaternion_Accel, {'ACC_x', 'ACC_y', 'ACC_z'});
                app.Plot_Quaternion_Accel.Title.FontWeight = 'bold';
                app.Plot_Quaternion_Accel.Title.FontSize = 14;

                cla(app.Plot_Quaternion_Gyro);             
                hold(app.Plot_Quaternion_Gyro,'on')
                plot(app.Plot_Quaternion_Gyro, t_t, data(:,4), 'r');
                plot(app.Plot_Quaternion_Gyro, t_t, data(:,5), 'g');
                plot(app.Plot_Quaternion_Gyro, t_t, data(:,6), 'b');
                app.Plot_Quaternion_Gyro.XGrid = "on";
                app.Plot_Quaternion_Gyro.YGrid = "on";
                hold(app.Plot_Quaternion_Gyro,'off');
                legend(app.Plot_Quaternion_Gyro, {'GYR_x', 'GYR_y', 'GYR_z'});
                app.Plot_Quaternion_Gyro.Title.FontWeight = 'bold';
                app.Plot_Quaternion_Gyro.Title.FontSize = 14;

                cla(app.Plot_Quaternion_Mag);             
                hold(app.Plot_Quaternion_Mag,'on')
                plot(app.Plot_Quaternion_Mag, t_t, data(:,7), 'r');
                plot(app.Plot_Quaternion_Mag, t_t, data(:,8), 'g');
                plot(app.Plot_Quaternion_Mag, t_t, data(:,9), 'b');
                app.Plot_Quaternion_Gyro.XGrid = "on";
                app.Plot_Quaternion_Mag.YGrid = "on";
                hold(app.Plot_Quaternion_Mag,'off');                                    
                legend(app.Plot_Quaternion_Mag, {'MAG_x', 'MAG_y', 'MAG_z'});
                app.Plot_Quaternion_Mag.Title.FontWeight = 'bold';
                app.Plot_Quaternion_Mag.Title.FontSize = 14;

                cla(app.Plot_Quaternion);             
                hold(app.Plot_Quaternion,'on')
                plot(app.Plot_Quaternion, t_t, data(:,10),  'r');
                plot(app.Plot_Quaternion, t_t, data(:,11),  'g');
                plot(app.Plot_Quaternion, t_t, data(:,12),  'b');
                plot(app.Plot_Quaternion, t_t, data(:,13), 'k');
                app.Plot_Quaternion.XGrid = "on";
                app.Plot_Quaternion.YGrid = "on";
                hold(app.Plot_Quaternion,'off');
                legend(app.Plot_Quaternion, {'Q_x', 'Q_y', 'Q_z', 'Q_w'});
                app.Plot_Quaternion.Title.FontWeight = 'bold';
                app.Plot_Quaternion.Title.FontSize = 14;
        end

        % Button pushed function: pos_sine_start_2
        function pos_sine_start_2ButtonPushed(app, event)
            
            app.file_ctrl_verification = fopen('elec_angle_estimation_ev.txt','w');

            SendData = [app.SDO_SEND,...                  %PDO
                    (app.node_id+16), ...
                    1,...
                    app.TASK_ID_MIDLEVEL, app.SDO_ID_MIDLEVEL_POSITION_PERIODIC_SIG_INFO, app.SDO_REQU, 2, ...
                    typecast(single(app.AmplituderadEditField_6.Value), 'uint8'), ...
                    typecast(single(app.FrequencyHzEditField_8.Value), 'uint8'), ...
                    ];

                write(app.device,SendData,"uint8");

                    % routine & state set
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 2, [uint8(app.ROUTINE_ID_MIDLEVEL_POSITION_SINE_REF), uint8(app.ROUTINE_ID_MIDLEVEL_POSITION_CTRL)]), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 9, ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_LOOP_CNT), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_REF_POSITION), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_ACTUAL_POSITION), ...
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_HALL_SENSOR_LOGIC), ...
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_ELEC_ANGLE), ...
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_HALL_ELEC_ANGLE), ...
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_RAW_CURRENT), ...
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_3PHASES_CURRENT_RAW), ...
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_ELEC_ANGLE_HOMING_DONE), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_ELEC_ANGLE_EST_EV)), ...
                    ];

                write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");
        end

        % Button pushed function: pos_sine_stop_2
        function pos_sine_stop_2ButtonPushed(app, event)
            
                    % State Standby
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                fclose(app.file_ctrl_verification);
                flush(app.device);
% 
%                 data = load('control.txt');
% 
%                 t_t = 0.001:0.001:length(data(:,1))/1000;
%     
%                 cla(app.pos_ctrl_plot); 
%             
%                 hold(app.pos_ctrl_plot,'on')
%                 plot(app.pos_ctrl_plot, t_t, data(:,2), 'b');
%                 plot(app.pos_ctrl_plot, t_t, data(:,3), 'r');
%                 app.pos_ctrl_plot.XGrid = "on";
%                 app.pos_ctrl_plot.YGrid = "on";
%                 hold(app.pos_ctrl_plot,'off')
                
        end

        % Button pushed function: pos_sine_start_3
        function pos_sine_start_3ButtonPushed(app, event)
             app.file_ctrl_verification = fopen('hall_vs_inc_encoder.txt','w');

             SendData = [app.SDO_SEND,...                  %SDO
                 (app.node_id+16), ...                      %identifier
                 1,...              
                 app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_SET_ROUTINE,        app.SDO_REQU, 1, uint8(app.ROUTINE_ID_LOWLEVEL_HALL_INCENC_RELIABILITY_EVAL)), ... 
                 ];

                write(app.device,SendData,"uint8");

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 1, ...
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_HALL_INCENC_RELIABILITY_EVAL), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_HALL_VS_INCENC_DATA)), ...
                    ];

                write(app.device,SendData,"uint8");

             SendData = [app.SDO_SEND,...                  %SDO
                 (app.node_id+16), ...                      %identifier
                 2,...
                 app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_SET_STATE,          app.SDO_REQU, 1, uint8(app.State_Enable)),...
                 app.pack_sdoUnit(app.TASK_ID_MSG,      app.SDO_ID_MSG_SET_STATE,          app.SDO_REQU, 1, uint8(app.State_Enable)),...
                 ];

             write(app.device,SendData,"uint8");

             
        end

        % Button pushed function: pos_sine_stop_3
        function pos_sine_stop_3ButtonPushed(app, event)
                                % State Standby
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    2,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                fclose(app.file_ctrl_verification);
                flush(app.device);
        end

        % Value changed function: F_Vector_Send
        function F_Vector_SendValueChanged(app, event)

    
            if (app.CheckBox.Value == 1)
                
                SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...
                3,...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_F_VECTOR_MODE_IDX,   app.SDO_REQU, 1, uint8(app.ModeIDXEditField.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_F_VECTOR_TMAX,       app.SDO_REQU, 1, int16(100*app.MaxTorqueAEditField.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_F_VECTOR_DELAY,      app.SDO_REQU, 1, uint16(app.DelaymsEditField.Value)), ...
                ];
                write(app.device,SendData,"uint8");

            end

            if (app.CheckBox_2.Value == 1)
                
                SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...
                3,...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_F_VECTOR_MODE_IDX,   app.SDO_REQU, 1, uint8(app.ModeIDXEditField_2.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_F_VECTOR_TMAX,       app.SDO_REQU, 1, int16(100*app.MaxTorqueAEditField_2.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_F_VECTOR_DELAY,      app.SDO_REQU, 1, uint16(app.DelayEditField_2.Value)), ...
                ];
                write(app.device,SendData,"uint8");

            end

            if (app.CheckBox_3.Value == 1)
                
                SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...
                3,...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_F_VECTOR_MODE_IDX,   app.SDO_REQU, 1, uint8(app.ModeIDXEditField_3.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_F_VECTOR_TMAX,       app.SDO_REQU, 1, int16(100*app.MaxTorqueAEditField_3.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_F_VECTOR_DELAY,      app.SDO_REQU, 1, uint16(app.DelayEditField_3.Value)), ...
                ];
                write(app.device,SendData,"uint8");

            end

            if (app.CheckBox_4.Value == 1)
                
                SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...
                3,...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_F_VECTOR_MODE_IDX,   app.SDO_REQU, 1, uint8(app.ModeIDXEditField_4.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_F_VECTOR_TMAX,       app.SDO_REQU, 1, int16(100*app.MaxTorqueAEditField_4.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_F_VECTOR_DELAY,      app.SDO_REQU, 1, uint16(app.DelayEditField_4.Value)), ...
                ];
                write(app.device,SendData,"uint8");

            end

            if (app.CheckBox_5.Value == 1)
                
                SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...
                3,...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_F_VECTOR_MODE_IDX,   app.SDO_REQU, 1, uint8(app.ModeIDXEditField_5.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_F_VECTOR_TMAX,       app.SDO_REQU, 1, int16(100*app.MaxTorqueAEditField_5.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_F_VECTOR_DELAY,      app.SDO_REQU, 1, uint16(app.DelayEditField_5.Value)), ...
                ];
                write(app.device,SendData,"uint8");

            end

            if (app.CheckBox_6.Value == 1)
                
                SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...
                3,...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_F_VECTOR_MODE_IDX,   app.SDO_REQU, 1, uint8(app.ModeIDXEditField_6.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_F_VECTOR_TMAX,       app.SDO_REQU, 1, int16(100*app.MaxTorqueAEditField_6.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_F_VECTOR_DELAY,      app.SDO_REQU, 1, uint16(app.DelayEditField_6.Value)), ...
                ];
                write(app.device,SendData,"uint8");

            end

        end

        % Button pushed function: F_Vector_Ev_Start
        function F_Vector_Ev_StartButtonPushed(app, event)
            app.file_pfi_evaluation = fopen("f_vector_evaluation.txt", 'w');
            
            % routine & state set
            if (app.F_Vector_Ev_Controller_ON.Value == 0) % Just monitoring reference
               
                SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MIDLEVEL_F_VECTOR_DECODER)), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];


            elseif (app.F_Vector_Ev_Controller_ON.Value == 1)
                SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MIDLEVEL_F_VECTOR_DECODER)), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

            end

                write(app.device,SendData,"uint8");
            

              SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 3, ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_LOOP_CNT), ...
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_F_VECTOR_INPUT), ...
                                app.TASK_ID_LOWLEVEL, uint8(app.PDO_ID_LOWLEVEL_CURRENT_OUTPUT), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_F_VECTOR_EVAL)), ...
                    ];

                write(app.device,SendData,"uint8");

            if (app.F_Vector_Ev_Controller_ON.Value == 0) % Just monitoring reference
                 SendData = [app.SDO_SEND,...                  %SDO
                        (app.node_id+16), ...
                        2,...
                        app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                        app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                        ];
            elseif (app.F_Vector_Ev_Controller_ON.Value == 1)
                 SendData = [app.SDO_SEND,...                  %SDO
                        (app.node_id+16), ...
                        3,...
                        app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                        app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                        app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                        ];
            end
            
            write(app.device,SendData,"uint8");

            app.F_Vector_Ev_Start.Enable = "off";
        end

        % Button pushed function: F_Vector_Ev_Stop
        function F_Vector_Ev_StopButtonPushed(app, event)
            app.F_Vector_Ev_Start.Enable = "on";

            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                fclose(app.file_pfi_evaluation);
                flush(app.device);

                data = load("f_vector_evaluation.txt");

                t_t = 0.001:0.001:length(data(:,1))/1000;
    
                cla(app.FvectorPlot);             
                hold(app.FvectorPlot,'on');
                plot(app.FvectorPlot, t_t, data(:,3), 'k');
                plot(app.FvectorPlot, t_t, data(:,2), 'r');

                app.FvectorPlot.YGrid = "on";
                hold(app.FvectorPlot,'off');

                legend(app.FvectorPlot, {'Measurement', 'Reference'});
                app.FvectorPlot.Title.FontWeight = 'bold';
                app.FvectorPlot.Title.FontSize = 14;

                
        end

        % Button down function: Plot_Quaternion
        function Plot_QuaternionButtonDown(app, event)
            
        end

        % Value changed function: P_Vector_Send
        function P_Vector_SendValueChanged(app, event)
                    
            if (app.P_Vector_Checkbox1.Value == 1)
                
                SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...
                4,...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_P_VECTOR_YD,   app.SDO_REQU, 1, int16(app.P_Vector_Yd1.Value*(500*pi/180))), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_P_VECTOR_L,    app.SDO_REQU, 1, uint16(app.P_Vector_L1.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_P_VECTOR_S0,   app.SDO_REQU, 1, uint8(app.P_Vector_S01.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_P_VECTOR_SD,   app.SDO_REQU, 1, uint8(app.P_Vector_Sd1.Value)), ...
                ];
                write(app.device,SendData,"uint8");

            end

            if (app.P_Vector_Checkbox2.Value == 1)
                
                SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...
                4,...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_P_VECTOR_YD,   app.SDO_REQU, 1, int16(app.P_Vector_Yd2.Value*(500*pi/180))), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_P_VECTOR_L,    app.SDO_REQU, 1, uint16(app.P_Vector_L2.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_P_VECTOR_S0,   app.SDO_REQU, 1, uint8(app.P_Vector_S02.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_P_VECTOR_SD,   app.SDO_REQU, 1, uint8(app.P_Vector_Sd2.Value)), ...
                ];
                write(app.device,SendData,"uint8");

            end

            if (app.P_Vector_Checkbox3.Value == 1)
                
                SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...
                4,...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_P_VECTOR_YD,   app.SDO_REQU, 1, int16(app.P_Vector_Yd3.Value*(500*pi/180))), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_P_VECTOR_L,    app.SDO_REQU, 1, uint16(app.P_Vector_L3.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_P_VECTOR_S0,   app.SDO_REQU, 1, uint8(app.P_Vector_S03.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_P_VECTOR_SD,   app.SDO_REQU, 1, uint8(app.P_Vector_Sd3.Value)), ...
                ];
                write(app.device,SendData,"uint8");

            end

            if (app.P_Vector_Checkbox4.Value == 1)
                
                SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...
                4,...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_P_VECTOR_YD,   app.SDO_REQU, 1, int16(app.P_Vector_Yd4.Value*(500*pi/180))), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_P_VECTOR_L,    app.SDO_REQU, 1, uint16(app.P_Vector_L4.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_P_VECTOR_S0,   app.SDO_REQU, 1, uint8(app.P_Vector_S04.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_P_VECTOR_SD,   app.SDO_REQU, 1, uint8(app.P_Vector_Sd4.Value)), ...
                ];
                write(app.device,SendData,"uint8");

            end

            if (app.P_Vector_Checkbox5.Value == 1)
                
                SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...
                4,...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_P_VECTOR_YD,   app.SDO_REQU, 1, int16(app.P_Vector_Yd5.Value*(500*pi/180))), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_P_VECTOR_L,    app.SDO_REQU, 1, uint16(app.P_Vector_L5.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_P_VECTOR_S0,   app.SDO_REQU, 1, uint8(app.P_Vector_S05.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_P_VECTOR_SD,   app.SDO_REQU, 1, uint8(app.P_Vector_Sd5.Value)), ...
                ];
                write(app.device,SendData,"uint8");

            end

            if (app.P_Vector_Checkbox6.Value == 1)
                
                SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...
                4,...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_P_VECTOR_YD,   app.SDO_REQU, 1, int16(app.P_Vector_Yd6.Value*(500*pi/180))), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_P_VECTOR_L,    app.SDO_REQU, 1, uint16(app.P_Vector_L6.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_P_VECTOR_S0,   app.SDO_REQU, 1, uint8(app.P_Vector_S06.Value)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_P_VECTOR_SD,   app.SDO_REQU, 1, uint8(app.P_Vector_Sd6.Value)), ...
                ];
                write(app.device,SendData,"uint8");

            end
        end

        % Button pushed function: P_Vector_Ev_Start
        function P_Vector_Ev_StartButtonPushed(app, event)
            app.file_pfi_evaluation = fopen("p_vector_evaluation.txt", 'w');
            
            % routine & state set

            if (app.P_Vector_Ev_Controller_ON.Value == 0) % Just monitoring reference
                SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MIDLEVEL_P_VECTOR_DECODER)), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

            elseif (app.P_Vector_Ev_Controller_ON.Value == 1)
                SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    4,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_LOWLEVEL_CURRENT_CTRL)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 3, [uint8(app.ROUTINE_ID_MIDLEVEL_P_VECTOR_DECODER), uint8(app.ROUTINE_ID_MIDLEVEL_POSITION_CTRL), uint8(app.ROUTINE_ID_MIDLEVEL_DISTURBANCE_OBS)]), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

            end


                write(app.device,SendData,"uint8");
            

              SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 3, ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_LOOP_CNT), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_REF_POSITION), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_ACTUAL_POSITION), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_P_VECTOR_EVAL)), ...
                    ];

                write(app.device,SendData,"uint8");

            if (app.P_Vector_Ev_Controller_ON.Value == 1) % Just monitoring reference

                 SendData = [app.SDO_SEND,...                  %SDO
                        (app.node_id+16), ...
                        3,...
                        app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                        app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                        app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                        ];
            elseif (app.P_Vector_Ev_Controller_ON.Value == 0) 
              
                SendData = [app.SDO_SEND,...                  %SDO
                        (app.node_id+16), ...
                        2,...
                        app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                        app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                        ];

            end

            write(app.device,SendData,"uint8");

            app.P_Vector_Ev_Start.Enable = "off";
        end

        % Button pushed function: P_Vector_Ev_Stop
        function P_Vector_Ev_StopButtonPushed(app, event)
               app.P_Vector_Ev_Start.Enable = "on";

                SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MIDLEVEL_P_VECTOR_DECODER)), ...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];
            
            
                SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                fclose(app.file_pfi_evaluation);
                flush(app.device);

                data = load("p_vector_evaluation.txt");

                t_t = 0.001:0.001:length(data(:,1))/1000;
    
                cla(app.PvectorPlot);             
                hold(app.PvectorPlot,'on');
                plot(app.PvectorPlot, t_t, data(:,3), 'k');
                plot(app.PvectorPlot, t_t, data(:,2), 'r');

                app.PvectorPlot.YGrid = "on";
                hold(app.PvectorPlot,'off');

                legend(app.PvectorPlot, {'Measurement', 'Reference'});
                             
                app.PvectorPlot.Title.FontWeight = 'bold';
                app.PvectorPlot.Title.FontSize = 14;

                
        end

        % Button pushed function: pos_sine_start_4
        function pos_sine_start_4ButtonPushed(app, event)
            
            app.file_abs_encoder = fopen('link_converter.txt','w');

               % routine & state set
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    2,...                    
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];
             write(app.device,SendData,"uint8");

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    5,...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_SET_ROUTINE,   app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MSG_PDO_SEND)), ...
                    app.TASK_ID_MSG,  app.SDO_ID_MSG_PDO_LIST,   app.SDO_REQU, 3, ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_LOOP_CNT), ...
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_ACTUAL_POSITION), ...                  
                                app.TASK_ID_MIDLEVEL, uint8(app.PDO_ID_MIDLEVEL_ABS_POSITION), ...       
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_ONOFF,   app.SDO_REQU, 1, uint8(1)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,  app.SDO_ID_MSG_GUI_COMM_COMMAND,   app.SDO_REQU, 1, uint8(app.GET_LINK_LUT)), ...
                    ];
             write(app.device,SendData,"uint8");


             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];
             write(app.device,SendData,"uint8");
        end

        % Button pushed function: pos_sine_stop_4
        function pos_sine_stop_4ButtonPushed(app, event)
                    SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    3,...
                    app.pack_sdoUnit(app.TASK_ID_LOWLEVEL,  app.SDO_ID_LOWLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    app.pack_sdoUnit(app.TASK_ID_MSG,       app.SDO_ID_MSG_SET_STATE,      app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

                fclose(app.file_abs_encoder);
                flush(app.device);

                data = load('link_converter.txt');
    
                cla(app.Link_Conversion_Plot); 
            
                hold(app.Link_Conversion_Plot,'on')
                plot(app.Link_Conversion_Plot, data(:,2), data(:,3), 'k.');
                app.Link_Conversion_Plot.XGrid = "on";
                app.Link_Conversion_Plot.YGrid = "on";
                hold(app.Link_Conversion_Plot,'off')
        end

        % Value changed function: mag_calib_apply
        function mag_calib_applyValueChanged(app, event)

            A_inv = [app.A11.Value, app.A12.Value, app.A13.Value, app.A21.Value, app.A21.Value, app.A13.Value];

            SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...                     %identifier
                1,...
                app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_DOB_GQ_NUM,   app.SDO_REQU, GQ_num_length+1, typecast(single(GQ_num),'uint8'), ...
                ];

            write(app.device,SendData,"uint8");

        end

        % Button pushed function: AbsoluteEncoderCH1StartReadButton
        function AbsoluteEncoderCH1StartReadButtonPushed(app, event)
            app.file_abs_encoder = fopen('Setting_AbsEncoder_CH1.txt','w');

            SendData = [app.SDO_SEND,...                 %SDO
                    (app.node_id+16), ...
                    2,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MIDLEVEL_GET_ABSENCODER1_VALUE)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");

                app.AbsoluteEncoderCH1StartReadButton.Enable = 0;
        end

        % Button pushed function: AbsoluteEncoderCH2StartReadButton
        function AbsoluteEncoderCH2StartReadButtonPushed(app, event)
    
            app.file_abs_encoder = fopen('Setting_AbsEncoder_CH2.txt','w');

            SendData = [app.SDO_SEND,...                 %SDO
                    (app.node_id+16), ...
                    2,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MIDLEVEL_GET_ABSENCODER2_VALUE)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

             write(app.device,SendData,"uint8");

             app.AbsoluteEncoderCH2StartReadButton.Enable = 0;
        end

        % Button pushed function: AbsoluteEncoderCH1StopReadButton
        function AbsoluteEncoderCH1StopReadButtonPushed(app, event)
                 
           app.AbsoluteEncoderCH1StartReadButton.Enable = 1;
           
           SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

            write(app.device,SendData,"uint8");
            flush(app.device);

            try
                fclose(app.file_abs_encoder);
            catch
            end

            data = load('Setting_AbsEncoder_CH1.txt');
            
            t_t = 0.001:0.001:length(data(:,1))/1000;
            
            cla(app.AbsoluteEncoderCH1Plot);             
            hold(app.AbsoluteEncoderCH1Plot,'on');
            plot(app.AbsoluteEncoderCH1Plot, t_t, data(:,2), 'k');
            app.AbsoluteEncoderCH1Plot.XGrid = "on";
            app.AbsoluteEncoderCH1Plot.YGrid = "on";
            hold(app.AbsoluteEncoderCH1Plot,'off');

                
        end

        % Button pushed function: AbsoluteEncoderCH2StopReadButton
        function AbsoluteEncoderCH2StopReadButtonPushed(app, event)
               
            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");
                flush(app.device);

                try
                    fclose(app.file_abs_encoder);
                catch
                end

                data = load('Setting_AbsEncoder_CH2.txt');
    
                t_t = 0.001:0.001:length(data(:,1))/1000;
    
                cla(app.AbsoluteEncoderCH2Plot);             
                hold(app.AbsoluteEncoderCH2Plot,'on');
                plot(app.AbsoluteEncoderCH2Plot, t_t, data(:,2), 'k');
                app.AbsoluteEncoderCH2Plot.XGrid = "on";
                app.AbsoluteEncoderCH2Plot.YGrid = "on";
                hold(app.AbsoluteEncoderCH2Plot,'off');

                app.AbsoluteEncoderCH2StartReadButton.Enable = 1;
        end

        % Button pushed function: AbsoluteEncoderCH1SetButton
        function AbsoluteEncoderCH1SetButtonPushed(app, event)
                            
            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_ABSENCODER1_SET_OFFSET, app.SDO_REQU, 1, single(app.AbsoluteEncoderCH1DesiredAngleDeg.Value)), ...
                    ];

            write(app.device,SendData,"uint8");
        end

        % Button pushed function: AbsoluteEncoderCH2SetButton
        function AbsoluteEncoderCH2SetButtonPushed(app, event)
                        SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_ABSENCODER2_SET_OFFSET, app.SDO_REQU, 1, single(app.AbsoluteEncoderCH2DesiredAngleDeg.Value)), ...
                    ];

            write(app.device,SendData,"uint8");
        end

        % Button pushed function: AbsoluteEncoderCH1DirectionChangeButton
        function AbsoluteEncoderCH1DirectionChangeButtonPushed(app, event)
                 
            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_ABSENCODER1_CHANGE_DIRECTION, app.SDO_REQU, 1, uint8(0)), ...
                    ];

            write(app.device,SendData,"uint8");
        end

        % Button pushed function: AbsoluteEncoderCH2DirectionChangeButton
        function AbsoluteEncoderCH2DirectionChangeButtonPushed(app, event)
         
            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_ABSENCODER2_CHANGE_DIRECTION, app.SDO_REQU, 1, uint8(0)), ...
                    ];

            write(app.device,SendData,"uint8");
        end

        % Button pushed function: MotorSetting_ApplyButton_2
        function MotorSetting_ApplyButton_2Pushed(app, event)
            app.incremental_encoder_usage = 0;
            app.absolute_encoder1_usage   = 0;
            app.absolute_encoder2_usage   = 0;           
            app.hall_sensor_usage         = 0;
            app.motor_temp_sensor_usage   = 0;
             
            t_commutation_sensor = 0;
            if (app.CommutationSensorDropDown.Value == "Incremental Encoder (default)")
                t_commutation_sensor = 0;
                app.incremental_encoder_usage = 1;
            elseif (app.CommutationSensorDropDown.Value == "Hall Sensor")
                t_commutation_sensor = 1;
                app.hall_sensor_usage = 1;
            elseif (app.CommutationSensorDropDown.Value == "Incremental Encoder + Hall Sensor")
                t_commutation_sensor = 2;
                app.incremental_encoder_usage = 1;
                app.hall_sensor_usage = 1;
            end
  
            t_position_feedback_sensor = 0;
            if (app.PositionFeedbackSensorDropDown.Value == "No Sensor (default)")
                t_position_feedback_sensor = 0;
            elseif (app.PositionFeedbackSensorDropDown.Value == "Incremental Encoder")
                t_position_feedback_sensor = 1;
                app.incremental_encoder_usage = 1;
            elseif (app.PositionFeedbackSensorDropDown.Value == "Absolute Encoder (CH1)")
                t_position_feedback_sensor = 2;
                app.absolute_encoder1_usage = 1;
            elseif (app.PositionFeedbackSensorDropDown.Value == "Absolute Encoder (CH2)")
                t_position_feedback_sensor = 3;
                app.absolute_encoder2_usage = 1;
            end

            t_e_angle_homing_sensor = 0;
            if (app.ElectricalAngleHomingSensorDropDown.Value == "No Sensor (default)")
                t_e_angle_homing_sensor = 0;
            elseif (app.ElectricalAngleHomingSensorDropDown.Value == "Hall Sensor")
                t_e_angle_homing_sensor = 1;
                app.hall_sensor_usage = 1;
            elseif (app.ElectricalAngleHomingSensorDropDown.Value == "Absolute Encoder (CH1)")
                t_e_angle_homing_sensor = 2;
                app.absolute_encoder1_usage = 1;
            elseif (app.ElectricalAngleHomingSensorDropDown.Value == "Absolute Encoder (CH2)")
                t_e_angle_homing_sensor = 3;
                app.absolute_encoder2_usage = 1;
            end

            t_m_angle_homing_sensor = 0;
            if (app.MechanicalAngleHomingSensorDropDown.Value == "Set Zero (default)")
                t_m_angle_homing_sensor = 0;
            elseif (app.MechanicalAngleHomingSensorDropDown.Value == "Absolute Encoder (CH1)")
                t_m_angle_homing_sensor = 1;
                app.absolute_encoder1_usage = 1;
            elseif (app.MechanicalAngleHomingSensorDropDown.Value == "Absolute Encoder (CH2)")
                t_m_angle_homing_sensor = 2;
                app.absolute_encoder2_usage = 1;
            elseif (app.MechanicalAngleHomingSensorDropDown.Value == "Self Setting")
                t_m_angle_homing_sensor = 3;
            elseif (app.MechanicalAngleHomingSensorDropDown.Value == "Master Setting")
                t_m_angle_homing_sensor = 4;
            end

            t_sensor_usage = zeros(1,3);
            if (app.MotorTemperatureSensorDropDown.Value == "Used")
                app.motor_temp_sensor_usage = 1;
                t_sensor_usage(1) = 1;
            else
                app.motor_temp_sensor_usage = 0;
            end
            if (app.IMU6AxisDropDown.Value == "Used")
                t_sensor_usage(2) = 1;
            end
            if (app.IMU3AxisDropDown.Value == "Used")
                t_sensor_usage(3) = 1;
            end


            SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...                      %identifier
                5,...
                app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_SET_COMMUTATION_SENSOR,      app.SDO_REQU, 1, uint8(t_commutation_sensor)), ...
                app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_SET_POS_FEEDBACK_SENSOR,     app.SDO_REQU, 1, uint8(t_position_feedback_sensor)), ...
                app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_SET_E_ANGLE_HOMING_SENSOR,   app.SDO_REQU, 1, uint8(t_e_angle_homing_sensor)), ...
                app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_SET_M_ANGLE_HOMING_SENSOR,   app.SDO_REQU, 1, uint8(t_m_angle_homing_sensor)), ...
                app.pack_sdoUnit(app.TASK_ID_LOWLEVEL, app.SDO_ID_LOWLEVEL_SET_SENSOR_USAGE,            app.SDO_REQU, 3, uint8(t_sensor_usage)), ...
                ];

            write(app.device,SendData,"uint8");   

            if (app.incremental_encoder_usage == 1)
                app.IncrementalEncoderLamp.Color = [0, 1, 0];
            else
                app.IncrementalEncoderLamp.Color = [0.8, 0.8, 0.8];    
            end
 
            if (app.absolute_encoder1_usage == 1)
                app.AbsoluteEncoderCH1Lamp.Color = [0, 1, 0];
            else
                app.AbsoluteEncoderCH1Lamp.Color = [0.8, 0.8, 0.8];    
            end 
            
            if (app.absolute_encoder2_usage == 1)
                app.AbsoluteEncoderCH2Lamp.Color = [0, 1, 0];
            else
                app.AbsoluteEncoderCH2Lamp.Color = [0.8, 0.8, 0.8];    
            end

            if (app.hall_sensor_usage == 1)
                app.HallSensorLamp.Color = [0, 1, 0];
            else
                app.HallSensorLamp.Color = [0.8, 0.8, 0.8];    
            end
 
            if (app.motor_temp_sensor_usage  == 1)
                app.MotorTemperatureSensorLamp.Color = [0, 1, 0];
            else
                app.MotorTemperatureSensorLamp.Color = [0.8, 0.8, 0.8];    
            end


        end

        % Button pushed function: HallSensorReadStartButton
        function HallSensorReadStartButtonPushed(app, event)
            app.file_hall_sensor = fopen('hall_sensor_values.txt','w');

            SendData = [app.SDO_SEND,...                 %SDO
                    (app.node_id+16), ...
                    2,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MIDLEVEL_GET_HALL_SENSOR_VALUE)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

                write(app.device,SendData,"uint8");

                app.HallSensorReadStartButton.Enable = 0;
        end

        % Button pushed function: HallSensorReadStopButton
        function HallSensorReadStopButtonPushed(app, event)
            
            SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");
                flush(app.device);

                try
                    fclose(app.file_hall_sensor);
                catch
                end

                data = load('hall_sensor_values.txt');
    
                t_t = 0.001:0.001:length(data(:,1))/1000;
    
                cla(app.HallSensorPlot);             
                hold(app.HallSensorPlot,'on');
                plot(app.HallSensorPlot, t_t, data(:,2), 'r');
                plot(app.HallSensorPlot, t_t, data(:,3), 'g');
                plot(app.HallSensorPlot, t_t, data(:,4), 'b');
                app.HallSensorPlot.XGrid = "on";
                app.HallSensorPlot.YGrid = "on";
                hold(app.HallSensorPlot,'off');

                
                cla(app.HallPatternPlot);             
                hold(app.HallPatternPlot,'on');
                plot(app.HallPatternPlot, t_t, data(:,5), 'k');
                app.HallPatternPlot.XGrid = "on";
                app.HallPatternPlot.YGrid = "on";
                hold(app.HallPatternPlot,'off');

                app.HallSensorReadStartButton.Enable = 1;


        end

        % Button pushed function: AbsoluteEncoderSettingApply
        function AbsoluteEncoderSettingApplyButtonPushed(app, event)
            
            t_abs_enc1_location = 0;
            t_abs_enc2_location = 0;  

            if (app.AbsoluteEncoderCH1DropDown.Value == "None (defaut)")
                t_abs_enc1_location = 0;
            elseif (app.AbsoluteEncoderCH1DropDown.Value == "Actuator Input")
                t_abs_enc1_location = 1;
            elseif (app.AbsoluteEncoderCH1DropDown.Value == "Actuator Output")
                t_abs_enc1_location = 2;
            elseif (app.AbsoluteEncoderCH1DropDown.Value == "Joint1")
                t_abs_enc1_location = 3;
            elseif (app.AbsoluteEncoderCH1DropDown.Value == "Joint2")
                t_abs_enc1_location = 4;
            end

            if (app.AbsoluteEncoderCH2DropDown.Value == "None (defaut)")
                t_abs_enc2_location = 0;
            elseif (app.AbsoluteEncoderCH2DropDown.Value == "Actuator Input")
                t_abs_enc2_location = 1;
            elseif (app.AbsoluteEncoderCH2DropDown.Value == "Actuator Output")
                t_abs_enc2_location = 2;
            elseif (app.AbsoluteEncoderCH2DropDown.Value == "Joint1")
                t_abs_enc2_location = 3;
            elseif (app.AbsoluteEncoderCH2DropDown.Value == "Joint2")
                t_abs_enc2_location = 4;
            end

            SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...                      %identifier
                2,...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL, app.SDO_ID_MIDLEVEL_ABSENCODER1_LOCATION,     app.SDO_REQU, 1, uint8(t_abs_enc1_location)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL, app.SDO_ID_MIDLEVEL_ABSENCODER2_LOCATION,     app.SDO_REQU, 1, uint8(t_abs_enc2_location)), ...
                ];

            write(app.device,SendData,"uint8");   
        end

        % Button pushed function: StartIncEncReadButton
        function StartIncEncReadButtonPushed2(app, event)
             
            app.file_inc_encoder = fopen('inc_encoder_values.txt','w');

             SendData = [app.SDO_SEND,...                 %SDO
                    (app.node_id+16), ...
                    2,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_ROUTINE, app.SDO_REQU, 1, uint8(app.ROUTINE_ID_MIDLEVEL_GET_INCENCODER_VALUE)), ...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE,   app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");

             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Enable)), ...
                    ];

             write(app.device,SendData,"uint8");

             app.StartIncEncReadButton.Enable = 0;
        end

        % Button pushed function: StopIncEncReadButton
        function StopIncEncReadButtonPushed2(app, event)
            
             SendData = [app.SDO_SEND,...                  %SDO
                    (app.node_id+16), ...
                    1,...
                    app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_SET_STATE, app.SDO_REQU, 1, uint8(app.State_Standby)), ...
                    ];

                write(app.device,SendData,"uint8");
                
                flush(app.device);

                try
                    fclose(app.file_inc_encoder);
                catch
                end

                data = load('inc_encoder_values.txt');
    
                t_t = 0.001:0.001:length(data(:,1))/1000;
    
                cla(app.IncEncPlot);             
                hold(app.IncEncPlot,'on');
                plot(app.IncEncPlot, t_t, data(:,2), 'k');
                app.IncEncPlot.XGrid = "on";
                app.IncEncPlot.YGrid = "on";
                hold(app.IncEncPlot,'off');

                app.StartIncEncReadButton.Enable = 1;
        end

        % Button pushed function: IncEncSetOriginButton
        function IncEncSetOriginButtonPushed2(app, event)
                
            SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...                      %identifier
                1,...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL, app.SDO_ID_MIDLEVEL_INCENCODER_SET_OFFSET,       app.SDO_REQU, 1, uint8(0)), ...
                ];

                write(app.device,SendData,"uint8");      
        end

        % Button pushed function: ImpedanceControllerApplyButton_2
        function ImpedanceControllerApplyButton_2Pushed(app, event)
            
            t_imp_ctrl_mode = 0;
            if (app.ImpedanceControlModeDropDown.Value == "Variable Parameter")
                t_imp_ctrl_mode = 0;
            elseif (app.ImpedanceControlModeDropDown.Value == "Constant Parameter")
                t_imp_ctrl_mode = 1;
            end
            
            SendData = [app.SDO_SEND,...                  %SDO
                (app.node_id+16), ...
                3,...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_OPTION,   app.SDO_REQU, 1, uint8(t_imp_ctrl_mode)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_KP_MAX,   app.SDO_REQU, 1, single(app.ActualKp100ADegEditField.Value * 180/pi)), ...
                app.pack_sdoUnit(app.TASK_ID_MIDLEVEL,  app.SDO_ID_MIDLEVEL_I_VECTOR_KD_MAX,   app.SDO_REQU, 1, single(app.ActualKd100ADegsEditField.Value * 180/pi)), ...
               
                ];
            write(app.device,SendData,"uint8");
        end

        % Size changed function: AdvFrictionIDTab
        function AdvFrictionIDTabSizeChanged(app, event)
            position = app.AdvFrictionIDTab.Position;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.adv_friction_verify_plot_3 = uiaxes(app.UIFigure);
            title(app.adv_friction_verify_plot_3, 'Title')
            xlabel(app.adv_friction_verify_plot_3, 'X')
            ylabel(app.adv_friction_verify_plot_3, 'Y')
            zlabel(app.adv_friction_verify_plot_3, 'Z')
            app.system_id_verify_plot_2 = uiaxes(app.UIFigure);
            title(app.system_id_verify_plot_2, 'Model Evaluation')
            xlabel(app.system_id_verify_plot_2, 'Time (s)')
            ylabel(app.system_id_verify_plot_2, 'Velocity (rad/s)')
            zlabel(app.system_id_verify_plot_2, 'Z')
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.Image11 = uiimage(app.GridLayout5);
            app.Image11.ImageSource = fullfile(pathToMLAPP, 'Images', 'MD_GUI_FIGURE_AGR_LOGO3.png');
            app.Image12 = uiimage(app.GridLayout5);
            app.Image12.ImageSource = fullfile(pathToMLAPP, 'Images', 'MD_GUI_FIGURE_EXOLAB_LOGO.png');
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.PORTDropDown_2Label = uilabel(app.GridLayout2);
            app.PORTDropDown_2Label.Text = 'PORT';
            app.CommunicationPort = uidropdown(app.GridLayout2);
            app.CommunicationPort.Items = {'COM'};
            app.CommunicationPort.DropDownOpeningFcn = createCallbackFcn(app, @CommunicationPortOpening, true);
            app.CommunicationPort.Tag = 'dropdown_comm_port';
            app.CommunicationPort.Value = 'COM';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.Visible = 'off';
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SettingTab = uitab(app.TabGroup);
            app.SettingTab.Title = 'Setting';
            app.GridLayout25 = uigridlayout(app.SettingTab);
            app.TabGroup2 = uitabgroup(app.GridLayout25);
            app.BasicSettingTab = uitab(app.TabGroup2);
            app.BasicSettingTab.Title = '1. Basic Setting';
            app.GridLayout20 = uigridlayout(app.BasicSettingTab);
            app.GridLayout19 = uigridlayout(app.GridLayout20);
            app.GridLayout18 = uigridlayout(app.GridLayout19);
            app.GridLayout16 = uigridlayout(app.GridLayout18);
            app.NamePanel = uipanel(app.GridLayout16);
            app.NamePanel.Title = 'Name';
            app.GridLayout29 = uigridlayout(app.NamePanel);
            app.MotorName = uieditfield(app.GridLayout29, 'text');
            app.MotorName.Value = 'Hello, Motor !';
            app.MotorModelNameLabel = uilabel(app.GridLayout29);
            app.MotorModelNameLabel.Text = 'Motor Model Name';
            app.MotorName_ApplyButton = uibutton(app.GridLayout29, 'push');
            app.MotorName_ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @MotorName_ApplyButtonPushed, true);
            app.MotorName_ApplyButton.Text = 'Apply';
            app.MotorPropertiesPanel = uipanel(app.GridLayout16);
            app.MotorPropertiesPanel.Title = 'Motor Properties';
            app.GridLayout17_4 = uigridlayout(app.MotorPropertiesPanel);
            app.NumberofPolePairLabel = uilabel(app.GridLayout17_4);
            app.NumberofPolePairLabel.Text = 'Number of Pole Pair';
            app.IncrementalEncoderResolutionLabel = uilabel(app.GridLayout17_4);
            app.IncrementalEncoderResolutionLabel.Text = 'Incremental Encoder Resolution';
            app.PolePair = uieditfield(app.GridLayout17_4, 'numeric');
            app.PolePair.Limits = [1 37];
            app.PolePair.ValueDisplayFormat = '%.0f';
            app.PolePair.Value = 8;
            app.EncResolution = uieditfield(app.GridLayout17_4, 'numeric');
            app.EncResolution.Limits = [10 500000];
            app.EncResolution.ValueDisplayFormat = '%.0f';
            app.EncResolution.Value = 8192;
            app.GearRatio = uieditfield(app.GridLayout17_4, 'numeric');
            app.GearRatio.Limits = [0.0001 65535];
            app.GearRatio.ValueDisplayFormat = '%.4f';
            app.GearRatio.Value = 20.25;
            app.MotorSetting_ApplyButton = uibutton(app.GridLayout17_4, 'push');
            app.MotorSetting_ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @MotorSetting_ApplyButtonPushed, true);
            app.MotorSetting_ApplyButton.Text = 'Apply';
            app.GearRatioOutputInputLabel = uilabel(app.GridLayout17_4);
            app.GearRatioOutputInputLabel.Text = 'Gear Ratio (Output / Input)';
            app.PeakCurrentALabel = uilabel(app.GridLayout17_4);
            app.PeakCurrentALabel.Text = 'Peak Current (A)';
            app.PeakCurrentLimitEdit = uieditfield(app.GridLayout17_4, 'numeric');
            app.PeakCurrentLimitEdit.Limits = [0.2 14.5];
            app.PeakCurrentLimitEdit.ValueDisplayFormat = '%.4f';
            app.PeakCurrentLimitEdit.Value = 12;
            app.ContinuousCurrentALabel = uilabel(app.GridLayout17_4);
            app.ContinuousCurrentALabel.Text = 'Continuous Current (A)';
            app.ContinuousCurrentLimitEdit = uieditfield(app.GridLayout17_4, 'numeric');
            app.ContinuousCurrentLimitEdit.Limits = [0.1 14.5];
            app.ContinuousCurrentLimitEdit.ValueDisplayFormat = '%.4f';
            app.ContinuousCurrentLimitEdit.Value = 10;
            app.MaximumVelocityrpmBeforegoingthoughgearEditFieldLabel = uilabel(app.GridLayout17_4);
            app.MaximumVelocityrpmBeforegoingthoughgearEditFieldLabel.Text = {'Maximum Velocity (rpm)'; '(Before going though gear)'};
            app.MaxVelocity = uieditfield(app.GridLayout17_4, 'numeric');
            app.MaxVelocity.Limits = [1 10000];
            app.MaxVelocity.Value = 4200;
            app.TorqueConstantNmAEditFieldLabel = uilabel(app.GridLayout17_4);
            app.TorqueConstantNmAEditFieldLabel.Text = 'Torque Constant (Nm/A)';
            app.TorqueConstantNmAEditField = uieditfield(app.GridLayout17_4, 'numeric');
            app.TorqueConstantNmAEditField.Limits = [1e-05 100];
            app.TorqueConstantNmAEditField.ValueDisplayFormat = '%.6f';
            app.TorqueConstantNmAEditField.Value = 0.1;
            app.VelocityConstantVradsEditFieldLabel = uilabel(app.GridLayout17_4);
            app.VelocityConstantVradsEditFieldLabel.Text = 'Velocity Constant (V/rad/s)';
            app.VelocityConstantVradsEditField = uieditfield(app.GridLayout17_4, 'numeric');
            app.VelocityConstantVradsEditField.Limits = [1e-05 100];
            app.VelocityConstantVradsEditField.ValueDisplayFormat = '%.6f';
            app.VelocityConstantVradsEditField.Value = 0.1;
            app.SensorSettingPanel = uipanel(app.GridLayout16);
            app.SensorSettingPanel.Title = 'Sensor Setting';
            app.GridLayout29_2 = uigridlayout(app.SensorSettingPanel);
            app.GridLayout172 = uigridlayout(app.GridLayout29_2);
            app.CommutationSensorDropDownLabel = uilabel(app.GridLayout172);
            app.CommutationSensorDropDownLabel.Text = 'Commutation Sensor';
            app.CommutationSensorDropDown = uidropdown(app.GridLayout172);
            app.CommutationSensorDropDown.Items = {'Incremental Encoder (default)', 'Hall Sensor', 'Incremental Encoder + Hall Sensor'};
            app.CommutationSensorDropDown.Value = 'Incremental Encoder (default)';
            app.PositionFeedbackSensorDropDownLabel = uilabel(app.GridLayout172);
            app.PositionFeedbackSensorDropDownLabel.Text = 'Position Feedback Sensor';
            app.PositionFeedbackSensorDropDown = uidropdown(app.GridLayout172);
            app.PositionFeedbackSensorDropDown.Items = {'No Sensor (default)', 'Incremental Encoder', 'Absolute Encoder (CH1)', 'Absolute Encoder (CH2)'};
            app.PositionFeedbackSensorDropDown.Value = 'No Sensor (default)';
            app.MotorTemperatureSensorDropDownLabel = uilabel(app.GridLayout172);
            app.MotorTemperatureSensorDropDownLabel.Text = 'Motor Temperature Sensor';
            app.MotorTemperatureSensorDropDown = uidropdown(app.GridLayout172);
            app.MotorTemperatureSensorDropDown.Items = {'Not Used (default)', 'Used'};
            app.MotorTemperatureSensorDropDown.Value = 'Not Used (default)';
            app.ElectricalAngleHomingSensorDropDownLabel = uilabel(app.GridLayout172);
            app.ElectricalAngleHomingSensorDropDownLabel.Text = 'Electrical Angle Homing Sensor';
            app.ElectricalAngleHomingSensorDropDown = uidropdown(app.GridLayout172);
            app.ElectricalAngleHomingSensorDropDown.Items = {'No Sensor (default)', 'Hall Sensor', 'Absolute Encoder (CH1)', 'Absolute Encoder (CH2)'};
            app.ElectricalAngleHomingSensorDropDown.Value = 'No Sensor (default)';
            app.MechanicalAngleHomingSensorDropDownLabel = uilabel(app.GridLayout172);
            app.MechanicalAngleHomingSensorDropDownLabel.Text = 'Mechanical Angle Homing Sensor';
            app.MechanicalAngleHomingSensorDropDown = uidropdown(app.GridLayout172);
            app.MechanicalAngleHomingSensorDropDown.Items = {'Set Zero (default)', 'Absolute Encoder (CH1)', 'Absolute Encoder (CH2)', 'Self Setting', 'Master Setting'};
            app.MechanicalAngleHomingSensorDropDown.Value = 'Set Zero (default)';
            app.IMU6AxisDropDownLabel = uilabel(app.GridLayout172);
            app.IMU6AxisDropDownLabel.Text = 'IMU(6-Axis)';
            app.IMU6AxisDropDown = uidropdown(app.GridLayout172);
            app.IMU6AxisDropDown.Items = {'Not Used (default)', 'Used'};
            app.IMU6AxisDropDown.Value = 'Not Used (default)';
            app.IMU3AxisDropDownLabel = uilabel(app.GridLayout172);
            app.IMU3AxisDropDownLabel.Text = 'IMU(3-Axis)';
            app.IMU3AxisDropDown = uidropdown(app.GridLayout172);
            app.IMU3AxisDropDown.Items = {'Not Used (default)', 'Used'};
            app.IMU3AxisDropDown.Value = 'Not Used (default)';
            app.AngleHomingSensorsLabel = uilabel(app.GridLayout172);
            app.AngleHomingSensorsLabel.Text = 'Angle Homing Sensors';
            app.ControllerFeedbackSensorsLabel = uilabel(app.GridLayout172);
            app.ControllerFeedbackSensorsLabel.Text = 'Controller Feedback Sensors';
            app.OtherSensorsLabel = uilabel(app.GridLayout172);
            app.OtherSensorsLabel.Text = 'Other Sensors';
            app.MotorSetting_ApplyButton_2 = uibutton(app.GridLayout172, 'push');
            app.MotorSetting_ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @MotorSetting_ApplyButton_2Pushed, true);
            app.MotorSetting_ApplyButton_2.Text = 'Apply';
            app.Label = uilabel(app.GridLayout172);
            app.Label.Text = 'Absolute Encoder Channel is defined at Left Tab, [4. Absolute Encoder Setting]';
            app.SensorRequirementLabel = uilabel(app.GridLayout172);
            app.SensorRequirementLabel.Text = 'Sensor Requirement';
            app.IncrementalEncoderLampLabel = uilabel(app.GridLayout172);
            app.IncrementalEncoderLampLabel.Text = 'Incremental Encoder';
            app.IncrementalEncoderLamp = uilamp(app.GridLayout172);
            app.AbsoluteEncoderCH1LampLabel = uilabel(app.GridLayout172);
            app.AbsoluteEncoderCH1LampLabel.Text = 'Absolute Encoder (CH1)';
            app.AbsoluteEncoderCH1Lamp = uilamp(app.GridLayout172);
            app.AbsoluteEncoderCH2LampLabel = uilabel(app.GridLayout172);
            app.AbsoluteEncoderCH2LampLabel.Text = 'Absolute Encoder (CH2)';
            app.AbsoluteEncoderCH2Lamp = uilamp(app.GridLayout172);
            app.HallSensorLampLabel = uilabel(app.GridLayout172);
            app.HallSensorLampLabel.Text = 'Hall Sensor';
            app.HallSensorLamp = uilamp(app.GridLayout172);
            app.MotorTemperatureSensorLampLabel = uilabel(app.GridLayout172);
            app.MotorTemperatureSensorLampLabel.Text = 'Motor Temperature Sensor';
            app.MotorTemperatureSensorLamp = uilamp(app.GridLayout172);
            app.HallSensorTab = uitab(app.TabGroup2);
            app.HallSensorTab.Title = '2. Hall Sensor';
            app.GridLayout174 = uigridlayout(app.HallSensorTab);
            app.HallSensorSignalPanel = uipanel(app.GridLayout174);
            app.HallSensorSignalPanel.Title = 'Hall Sensor Signal';
            app.GridLayout64_10 = uigridlayout(app.HallSensorSignalPanel);
            app.HallSensorPlot = uiaxes(app.GridLayout64_10);
            title(app.HallSensorPlot, 'Hall Sensor')
            xlabel(app.HallSensorPlot, 'Time (s)')
            ylabel(app.HallSensorPlot, 'A/B/C Hall Signal')
            zlabel(app.HallSensorPlot, 'Z')
            app.HallPatternPlot = uiaxes(app.GridLayout64_10);
            title(app.HallPatternPlot, 'Hall Pattern')
            xlabel(app.HallPatternPlot, 'Time (s)')
            ylabel(app.HallPatternPlot, 'Hall Pattern')
            zlabel(app.HallPatternPlot, 'Z')
            app.HallSensorCEditField = uieditfield(app.GridLayout64_10, 'numeric');
            app.HallSensorCEditField.ValueDisplayFormat = '%d';
            app.HallSensorCEditField.Editable = 'off';
            app.HALLLOGICLabel = uilabel(app.GridLayout64_10);
            app.HALLLOGICLabel.Text = 'HALL LOGIC';
            app.HallSensorLogicEditField = uieditfield(app.GridLayout64_10, 'numeric');
            app.HallSensorLogicEditField.ValueDisplayFormat = '%d';
            app.HallSensorLogicEditField.Editable = 'off';
            app.HallSensorC = uilabel(app.GridLayout64_10);
            app.HallSensorC.Text = 'HALL C';
            app.HallSensorReadStartButton = uibutton(app.GridLayout64_10, 'push');
            app.HallSensorReadStartButton.ButtonPushedFcn = createCallbackFcn(app, @HallSensorReadStartButtonPushed, true);
            app.HallSensorReadStartButton.Text = 'Start Read';
            app.HallSensorReadStopButton = uibutton(app.GridLayout64_10, 'push');
            app.HallSensorReadStopButton.ButtonPushedFcn = createCallbackFcn(app, @HallSensorReadStopButtonPushed, true);
            app.HallSensorReadStopButton.Text = 'Stop Read';
            app.HallSensorB = uilabel(app.GridLayout64_10);
            app.HallSensorB.Text = 'HALL B';
            app.HallSensorA = uilabel(app.GridLayout64_10);
            app.HallSensorA.Text = 'HALL A';
            app.HallSensorBEditField = uieditfield(app.GridLayout64_10, 'numeric');
            app.HallSensorBEditField.ValueDisplayFormat = '%d';
            app.HallSensorBEditField.Editable = 'off';
            app.HallSensorAEditField = uieditfield(app.GridLayout64_10, 'numeric');
            app.HallSensorAEditField.ValueDisplayFormat = '%d';
            app.HallSensorAEditField.Editable = 'off';
            app.HallALabel = uilabel(app.GridLayout64_10);
            app.HallALabel.Text = 'Hall A';
            app.HallBLabel = uilabel(app.GridLayout64_10);
            app.HallBLabel.Text = 'Hall B';
            app.HallCLabel = uilabel(app.GridLayout64_10);
            app.HallCLabel.Text = 'Hall C';
            app.IncrementalEncoderTab = uitab(app.TabGroup2);
            app.IncrementalEncoderTab.Title = '3. Incremental Encoder';
            app.GridLayout173 = uigridlayout(app.IncrementalEncoderTab);
            app.IncrementalEncoderPanel_2 = uipanel(app.GridLayout173);
            app.IncrementalEncoderPanel_2.Title = 'Incremental Encoder';
            app.GridLayout64_9 = uigridlayout(app.IncrementalEncoderPanel_2);
            app.IncEncPlot = uiaxes(app.GridLayout64_9);
            title(app.IncEncPlot, 'Incremental Encoder')
            xlabel(app.IncEncPlot, 'Time')
            ylabel(app.IncEncPlot, 'Counter (-)')
            zlabel(app.IncEncPlot, 'Z')
            app.IncEncCounterEditField = uieditfield(app.GridLayout64_9, 'numeric');
            app.IncEncCounterEditField.ValueDisplayFormat = '%.0f';
            app.IncEncCounterEditField.Editable = 'off';
            app.AngleDegLabel = uilabel(app.GridLayout64_9);
            app.AngleDegLabel.Text = 'Angle (Deg)';
            app.IncEncAnglerDegEditField = uieditfield(app.GridLayout64_9, 'numeric');
            app.IncEncAnglerDegEditField.Editable = 'off';
            app.IncEncCounterLabel = uilabel(app.GridLayout64_9);
            app.IncEncCounterLabel.Text = 'Counter';
            app.IncEncSetOriginButton = uibutton(app.GridLayout64_9, 'push');
            app.IncEncSetOriginButton.ButtonPushedFcn = createCallbackFcn(app, @IncEncSetOriginButtonPushed2, true);
            app.IncEncSetOriginButton.Text = 'Set Origin';
            app.StartIncEncReadButton = uibutton(app.GridLayout64_9, 'push');
            app.StartIncEncReadButton.ButtonPushedFcn = createCallbackFcn(app, @StartIncEncReadButtonPushed2, true);
            app.StartIncEncReadButton.Text = 'Start Read';
            app.StopIncEncReadButton = uibutton(app.GridLayout64_9, 'push');
            app.StopIncEncReadButton.ButtonPushedFcn = createCallbackFcn(app, @StopIncEncReadButtonPushed2, true);
            app.StopIncEncReadButton.Text = 'Stop Read';
            app.AbsoluteEncoderTab = uitab(app.TabGroup2);
            app.AbsoluteEncoderTab.Title = '4. Absolute Encoder';
            app.GridLayout168 = uigridlayout(app.AbsoluteEncoderTab);
            app.AbsoluteEncoderChannelSettingPanel = uipanel(app.GridLayout168);
            app.AbsoluteEncoderChannelSettingPanel.Title = '1. Absolute Encoder Channel Setting';
            app.GridLayout169 = uigridlayout(app.AbsoluteEncoderChannelSettingPanel);
            app.AbsoluteEncoderCH1DropDownLabel = uilabel(app.GridLayout169);
            app.AbsoluteEncoderCH1DropDownLabel.Text = 'Absolute Encoder CH1';
            app.AbsoluteEncoderCH1DropDown = uidropdown(app.GridLayout169);
            app.AbsoluteEncoderCH1DropDown.Items = {'None (defaut)', 'Actuator Input', 'Actuator Output', 'Joint1', 'Joint2'};
            app.AbsoluteEncoderCH1DropDown.Value = 'None (defaut)';
            app.AbsoluteEncoderCH2DropDownLabel = uilabel(app.GridLayout169);
            app.AbsoluteEncoderCH2DropDownLabel.Text = 'Absolute Encoder CH2';
            app.AbsoluteEncoderCH2DropDown = uidropdown(app.GridLayout169);
            app.AbsoluteEncoderCH2DropDown.Items = {'None (defaut)', 'Actuator Input', 'Actuator Output', 'Joint1', 'Joint2'};
            app.AbsoluteEncoderCH2DropDown.Value = 'None (defaut)';
            app.AbsoluteEncoderChannelLabel = uilabel(app.GridLayout169);
            app.AbsoluteEncoderChannelLabel.Text = 'Absolute Encoder Channel';
            app.MountingLocationLabel = uilabel(app.GridLayout169);
            app.MountingLocationLabel.Text = 'Mounting Location';
            app.ResolutionLabel = uilabel(app.GridLayout169);
            app.ResolutionLabel.Text = 'Resolution';
            app.AbsoluteEncoderCH1Resolution = uieditfield(app.GridLayout169, 'numeric');
            app.AbsoluteEncoderCH1Resolution.Limits = [8191 8192];
            app.AbsoluteEncoderCH1Resolution.ValueDisplayFormat = '%.0f';
            app.AbsoluteEncoderCH1Resolution.Value = 8192;
            app.AbsoluteEncoderCH2Resolution = uieditfield(app.GridLayout169, 'numeric');
            app.AbsoluteEncoderCH2Resolution.Limits = [8191 8192];
            app.AbsoluteEncoderCH2Resolution.ValueDisplayFormat = '%.0f';
            app.AbsoluteEncoderCH2Resolution.Value = 8192;
            app.AbsoluteEncoderChannelDefinitionImage = uiimage(app.GridLayout169);
            app.AbsoluteEncoderChannelDefinitionImage.ImageSource = fullfile(pathToMLAPP, 'Images', 'MD_GUI_FIGURE_ABS_ENCODER_CHANNEL.png');
            app.AbsoluteEncoderMountingLocationDefinitionImage = uiimage(app.GridLayout169);
            app.AbsoluteEncoderMountingLocationDefinitionImage.ImageSource = fullfile(pathToMLAPP, 'Images', 'MD_GUI_FIGURE_ABS_ENCODER_LOCATION.png');
            app.AbsoluteEncoderChannelDefinitionLabel = uilabel(app.GridLayout169);
            app.AbsoluteEncoderChannelDefinitionLabel.Text = '[Absolute Encoder Channel Definition]';
            app.MountingLocationDefinitionLabel = uilabel(app.GridLayout169);
            app.MountingLocationDefinitionLabel.Text = '[Mounting Location Definition]';
            app.AbsoluteEncoderSettingApply = uibutton(app.GridLayout169, 'push');
            app.AbsoluteEncoderSettingApply.ButtonPushedFcn = createCallbackFcn(app, @AbsoluteEncoderSettingApplyButtonPushed, true);
            app.AbsoluteEncoderSettingApply.Text = 'Apply';
            app.AbsoluteEncoderCH1DirectionOffsetSettingPanel = uipanel(app.GridLayout168);
            app.AbsoluteEncoderCH1DirectionOffsetSettingPanel.Title = '2. Absolute Encoder CH1 Direction & Offset Setting';
            app.GridLayout170 = uigridlayout(app.AbsoluteEncoderCH1DirectionOffsetSettingPanel);
            app.AbsoluteEncoderCH1Plot = uiaxes(app.GridLayout170);
            title(app.AbsoluteEncoderCH1Plot, 'Absolut Encoder CH1')
            xlabel(app.AbsoluteEncoderCH1Plot, 'Time (s)')
            ylabel(app.AbsoluteEncoderCH1Plot, 'Angle (Deg)')
            zlabel(app.AbsoluteEncoderCH1Plot, 'Z')
            app.AbsoluteEncoderCH1SetButton = uibutton(app.GridLayout170, 'push');
            app.AbsoluteEncoderCH1SetButton.ButtonPushedFcn = createCallbackFcn(app, @AbsoluteEncoderCH1SetButtonPushed, true);
            app.AbsoluteEncoderCH1SetButton.Text = 'Set';
            app.AbsoluteEncoderCH1StartReadButton = uibutton(app.GridLayout170, 'push');
            app.AbsoluteEncoderCH1StartReadButton.ButtonPushedFcn = createCallbackFcn(app, @AbsoluteEncoderCH1StartReadButtonPushed, true);
            app.AbsoluteEncoderCH1StartReadButton.Text = 'Start Read';
            app.AbsoluteEncoderCH1StopReadButton = uibutton(app.GridLayout170, 'push');
            app.AbsoluteEncoderCH1StopReadButton.ButtonPushedFcn = createCallbackFcn(app, @AbsoluteEncoderCH1StopReadButtonPushed, true);
            app.AbsoluteEncoderCH1StopReadButton.Text = 'Stop Read';
            app.AbsoluteEncoderCH1DirectionChangeButton = uibutton(app.GridLayout170, 'push');
            app.AbsoluteEncoderCH1DirectionChangeButton.ButtonPushedFcn = createCallbackFcn(app, @AbsoluteEncoderCH1DirectionChangeButtonPushed, true);
            app.AbsoluteEncoderCH1DirectionChangeButton.Text = 'Direction Change';
            app.AngledegLabel_6 = uilabel(app.GridLayout170);
            app.AngledegLabel_6.Text = 'Current Angle (deg)';
            app.AbsoluteEncoderCH1CurrentAngleDeg = uieditfield(app.GridLayout170, 'numeric');
            app.AbsoluteEncoderCH1CurrentAngleDeg.Editable = 'off';
            app.AngledegLabel_7 = uilabel(app.GridLayout170);
            app.AngledegLabel_7.Text = 'Desired Angle (Deg)';
            app.AbsoluteEncoderCH1DesiredAngleDeg = uieditfield(app.GridLayout170, 'numeric');
            app.AbsoluteEncoderCH2DirectionOffsetSettingPanel = uipanel(app.GridLayout168);
            app.AbsoluteEncoderCH2DirectionOffsetSettingPanel.Title = '3. Absolute Encoder CH2 Direction & Offset Setting';
            app.GridLayout171 = uigridlayout(app.AbsoluteEncoderCH2DirectionOffsetSettingPanel);
            app.AbsoluteEncoderCH2Plot = uiaxes(app.GridLayout171);
            title(app.AbsoluteEncoderCH2Plot, 'Absolut Encoder CH2')
            xlabel(app.AbsoluteEncoderCH2Plot, 'Time (s)')
            ylabel(app.AbsoluteEncoderCH2Plot, 'Angle (Deg)')
            zlabel(app.AbsoluteEncoderCH2Plot, 'Z')
            app.AngledegLabel_8 = uilabel(app.GridLayout171);
            app.AngledegLabel_8.Text = 'Current Angle (deg)';
            app.AbsoluteEncoderCH2CurrentAngleDeg = uieditfield(app.GridLayout171, 'numeric');
            app.AbsoluteEncoderCH2CurrentAngleDeg.Editable = 'off';
            app.AngledegLabel_9 = uilabel(app.GridLayout171);
            app.AngledegLabel_9.Text = 'Desired Angle (Deg)';
            app.AbsoluteEncoderCH2DesiredAngleDeg = uieditfield(app.GridLayout171, 'numeric');
            app.AbsoluteEncoderCH2DesiredAngleDeg.Editable = 'off';
            app.AbsoluteEncoderCH2StartReadButton = uibutton(app.GridLayout171, 'push');
            app.AbsoluteEncoderCH2StartReadButton.ButtonPushedFcn = createCallbackFcn(app, @AbsoluteEncoderCH2StartReadButtonPushed, true);
            app.AbsoluteEncoderCH2StartReadButton.Text = 'Start Read';
            app.AbsoluteEncoderCH2StopReadButton = uibutton(app.GridLayout171, 'push');
            app.AbsoluteEncoderCH2StopReadButton.ButtonPushedFcn = createCallbackFcn(app, @AbsoluteEncoderCH2StopReadButtonPushed, true);
            app.AbsoluteEncoderCH2StopReadButton.Text = 'Stop Read';
            app.AbsoluteEncoderCH2DirectionChangeButton = uibutton(app.GridLayout171, 'push');
            app.AbsoluteEncoderCH2DirectionChangeButton.ButtonPushedFcn = createCallbackFcn(app, @AbsoluteEncoderCH2DirectionChangeButtonPushed, true);
            app.AbsoluteEncoderCH2DirectionChangeButton.Text = 'Direction Change';
            app.AbsoluteEncoderCH2SetButton = uibutton(app.GridLayout171, 'push');
            app.AbsoluteEncoderCH2SetButton.ButtonPushedFcn = createCallbackFcn(app, @AbsoluteEncoderCH2SetButtonPushed, true);
            app.AbsoluteEncoderCH2SetButton.Text = 'Set';
            app.CommutationSettingTab = uitab(app.TabGroup2);
            app.CommutationSettingTab.Title = '5. Commutation Setting';
            app.CommutationSettingPanel = uipanel(app.CommutationSettingTab);
            app.CommutationSettingPanel.Title = 'Commutation Setting';
            app.GridLayout31 = uigridlayout(app.CommutationSettingPanel);
            app.direction_set_enc_plot = uiaxes(app.GridLayout31);
            title(app.direction_set_enc_plot, 'Actual Position')
            xlabel(app.direction_set_enc_plot, 'Time (ms)')
            ylabel(app.direction_set_enc_plot, 'rad')
            zlabel(app.direction_set_enc_plot, 'Z')
            app.direction_set_step_plot = uiaxes(app.GridLayout31);
            title(app.direction_set_step_plot, 'Commutation Step')
            xlabel(app.direction_set_step_plot, 'Time (ms)')
            ylabel(app.direction_set_step_plot, 'Step')
            zlabel(app.direction_set_step_plot, 'Z')
            app.direction_set_hall_step_plot = uiaxes(app.GridLayout31);
            title(app.direction_set_hall_step_plot, 'Hall Sensor Step')
            xlabel(app.direction_set_hall_step_plot, 'Time (ms)')
            ylabel(app.direction_set_hall_step_plot, 'Hall Step')
            zlabel(app.direction_set_hall_step_plot, 'Z')
            app.ForcedVoltageVLabel = uilabel(app.GridLayout31);
            app.ForcedVoltageVLabel.Text = 'Forced Voltage (V)';
            app.FocedVoltage_Edit = uieditfield(app.GridLayout31, 'numeric');
            app.FocedVoltage_Edit.Limits = [0 20];
            app.FocedVoltage_Edit.Value = 1;
            app.StartCommutationSettingButton = uibutton(app.GridLayout31, 'push');
            app.StartCommutationSettingButton.ButtonPushedFcn = createCallbackFcn(app, @StartCommutationSettingButtonPushed, true);
            app.StartCommutationSettingButton.Text = 'Start Commutation Setting';
            app.ELECTab = uitab(app.TabGroup);
            app.ELECTab.Title = 'ELEC';
            app.GridLayout25_2 = uigridlayout(app.ELECTab);
            app.TabGroup2_2 = uitabgroup(app.GridLayout25_2);
            app.ElecSysIDTab = uitab(app.TabGroup2_2);
            app.ElecSysIDTab.Title = 'Elec. Sys ID';
            app.GridLayout20_2 = uigridlayout(app.ElecSysIDTab);
            app.GridLayout19_2 = uigridlayout(app.GridLayout20_2);
            app.GridLayout18_2 = uigridlayout(app.GridLayout19_2);
            app.GridLayout33 = uigridlayout(app.GridLayout18_2);
            app.BEMFConstantinDiscretizedModelPanel = uipanel(app.GridLayout33);
            app.BEMFConstantinDiscretizedModelPanel.Title = 'BEMF Constant in Discretized Model';
            app.GridLayout53 = uigridlayout(app.BEMFConstantinDiscretizedModelPanel);
            app.BEMFConstantIDStartButton = uibutton(app.GridLayout53, 'push');
            app.BEMFConstantIDStartButton.ButtonPushedFcn = createCallbackFcn(app, @BEMFConstantIDStartButtonPushed, true);
            app.BEMFConstantIDStartButton.Text = {'BEMF Constant ID'; 'Start'};
            app.AngularVelocityradsMultipliedbygearratioEditFieldLabel = uilabel(app.GridLayout53);
            app.AngularVelocityradsMultipliedbygearratioEditFieldLabel.Text = {'Angular Velocity (rad/s)'; '(Multiplied by gear ratio)'};
            app.AngularVelocity = uieditfield(app.GridLayout53, 'numeric');
            app.AngularVelocity.Limits = [0 90];
            app.AngularVelocity.ValueDisplayFormat = '%.4f';
            app.AngularVelocity.Value = 4;
            app.BemfLabel = uilabel(app.GridLayout53);
            app.BemfLabel.Text = 'c = ';
            app.VelocityControllerGainLabel = uilabel(app.GridLayout53);
            app.VelocityControllerGainLabel.Text = 'Velocity Controller Gain (%)';
            app.VelocityControllerGainSlider = uislider(app.GridLayout53);
            app.VelocityControllerGainSlider.Limits = [10 100];
            app.VelocityControllerGainSlider.Tooltip = {'100% = Continuous Current Limit/10'; '10% = Continuous Current Limit/100'};
            app.VelocityControllerGainSlider.Value = 10;
            app.ElectricalSystemIDPanel = uipanel(app.GridLayout33);
            app.ElectricalSystemIDPanel.Title = 'Electrical System ID.';
            app.GridLayout38 = uigridlayout(app.ElectricalSystemIDPanel);
            app.ElecBode = uiaxes(app.GridLayout38);
            title(app.ElecBode, 'Electrical System Identification Result')
            xlabel(app.ElecBode, 'Frequency (rad/s)')
            ylabel(app.ElecBode, 'Magnitude (dB)')
            zlabel(app.ElecBode, 'Z')
            app.InputVoltageVEditFieldLabel = uilabel(app.GridLayout38);
            app.InputVoltageVEditFieldLabel.Text = 'Input Voltage (V)';
            app.InputVoltageVEditField = uieditfield(app.GridLayout38, 'numeric');
            app.InputVoltageVEditField.Value = 5;
            app.ResistanceOhmEditFieldLabel = uilabel(app.GridLayout38);
            app.ResistanceOhmEditFieldLabel.Text = 'Resistance (Ohm)';
            app.ResistanceOhmEditField = uieditfield(app.GridLayout38, 'numeric');
            app.ResistanceOhmEditField.Limits = [0 Inf];
            app.ResistanceOhmEditField.Editable = 'off';
            app.InductanceHEditFieldLabel = uilabel(app.GridLayout38);
            app.InductanceHEditFieldLabel.Text = 'Inductance (H)';
            app.InductanceHEditField = uieditfield(app.GridLayout38, 'numeric');
            app.InductanceHEditField.Limits = [0 Inf];
            app.InductanceHEditField.Editable = 'off';
            app.ElectricalSystemIDStartButton = uibutton(app.GridLayout38, 'push');
            app.ElectricalSystemIDStartButton.ButtonPushedFcn = createCallbackFcn(app, @ElectricalSystemIDStartButtonPushed, true);
            app.ElectricalSystemIDStartButton.Text = {'Electrical System ID '; 'Start'};
            app.WaitforPlotLabel_3 = uilabel(app.GridLayout38);
            app.WaitforPlotLabel_3.Visible = 'off';
            app.WaitforPlotLabel_3.Text = '   Wait for Plot...';
            app.CurrentControllerDesignTab = uitab(app.TabGroup2_2);
            app.CurrentControllerDesignTab.Title = 'Current Controller Design';
            app.CurrentControllerDesignVerificationPanel = uipanel(app.CurrentControllerDesignTab);
            app.CurrentControllerDesignVerificationPanel.Title = 'Current Controller Design & Verification';
            app.GridLayout99 = uigridlayout(app.CurrentControllerDesignVerificationPanel);
            app.BW_PLOT = uiaxes(app.GridLayout99);
            title(app.BW_PLOT, 'Current Controller Tracking Performance')
            xlabel(app.BW_PLOT, 'Time (s)')
            ylabel(app.BW_PLOT, 'Current (A)')
            zlabel(app.BW_PLOT, 'Z')
            app.WaitforPlotLabel_4 = uilabel(app.GridLayout99);
            app.WaitforPlotLabel_4.Visible = 'off';
            app.WaitforPlotLabel_4.Text = '   Wait for Plot...';
            app.CurrentControllerBandwidthCheckButton = uibutton(app.GridLayout99, 'push');
            app.CurrentControllerBandwidthCheckButton.ButtonPushedFcn = createCallbackFcn(app, @CurrentControllerBandwidthCheckButtonPushed, true);
            app.CurrentControllerBandwidthCheckButton.Text = {'Current Controller'; 'Bandwidth Check'};
            app.DesiredCurrnetControllerBandwidthHzEditFieldLabel = uilabel(app.GridLayout99);
            app.DesiredCurrnetControllerBandwidthHzEditFieldLabel.Text = {'Desired Currnet '; 'Controller Bandwidth (Hz)'};
            app.DesiredBW2 = uieditfield(app.GridLayout99, 'numeric');
            app.DesiredBW2.Limits = [800 5000];
            app.DesiredBW2.Value = 1000;
            app.ReferenceLabel_3 = uilabel(app.GridLayout99);
            app.ReferenceLabel_3.Text = 'Reference';
            app.MeasurementLabel = uilabel(app.GridLayout99);
            app.MeasurementLabel.Text = 'Measurement';
            app.CurrentControllerFrequencyResponsePanel = uipanel(app.CurrentControllerDesignTab);
            app.CurrentControllerFrequencyResponsePanel.Title = 'Current Controller Frequency Response';
            app.GridLayout99_2 = uigridlayout(app.CurrentControllerFrequencyResponsePanel);
            app.CurrentControllerBodeMag = uiaxes(app.GridLayout99_2);
            title(app.CurrentControllerBodeMag, 'Magnitude')
            xlabel(app.CurrentControllerBodeMag, 'Frequency (Hz)')
            ylabel(app.CurrentControllerBodeMag, 'Magnitude (dB)')
            zlabel(app.CurrentControllerBodeMag, 'Z')
            app.CurrentControllerBodePhase = uiaxes(app.GridLayout99_2);
            title(app.CurrentControllerBodePhase, 'Phase')
            xlabel(app.CurrentControllerBodePhase, 'Frequency (Hz)')
            ylabel(app.CurrentControllerBodePhase, 'Phase (Deg)')
            zlabel(app.CurrentControllerBodePhase, 'Z')
            app.MECHTab = uitab(app.TabGroup);
            app.MECHTab.Title = 'MECH';
            app.GridLayout25_4 = uigridlayout(app.MECHTab);
            app.TabGroup2_4 = uitabgroup(app.GridLayout25_4);
            app.OverviewTab = uitab(app.TabGroup2_4);
            app.OverviewTab.Title = 'Overview';
            app.GridLayout118 = uigridlayout(app.OverviewTab);
            app.Image9 = uiimage(app.GridLayout118);
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'Images', 'MD_GUI_FIGURE_CONTROLLER_OVERVIEW.png');
            app.FeedforwardControllerCheckBox = uicheckbox(app.GridLayout118);
            app.FeedforwardControllerCheckBox.Text = 'Feedforward Controller';
            app.ImpedanceControllerCheckBox = uicheckbox(app.GridLayout118);
            app.ImpedanceControllerCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImpedanceControllerCheckBoxValueChanged, true);
            app.ImpedanceControllerCheckBox.Text = 'Impedance Controller';
            app.MechanicalImpedanceCompensatorCheckBox = uicheckbox(app.GridLayout118);
            app.MechanicalImpedanceCompensatorCheckBox.ValueChangedFcn = createCallbackFcn(app, @MechanicalImpedanceCompensatorCheckBoxValueChanged, true);
            app.MechanicalImpedanceCompensatorCheckBox.Text = 'Mechanical Impedance Compensator';
            app.PDControllerCheckBox = uicheckbox(app.GridLayout118);
            app.PDControllerCheckBox.ValueChangedFcn = createCallbackFcn(app, @PDControllerCheckBoxValueChanged, true);
            app.PDControllerCheckBox.Text = 'PD Controller';
            app.DisturbanceObserverCheckBox = uicheckbox(app.GridLayout118);
            app.DisturbanceObserverCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisturbanceObserverCheckBoxValueChanged, true);
            app.DisturbanceObserverCheckBox.Text = 'Disturbance Observer';
            app.ApplyButton_2 = uibutton(app.GridLayout118, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_2.Text = 'Apply';
            app.MidlevelControllerSaturationEditFieldLabel = uilabel(app.GridLayout118);
            app.MidlevelControllerSaturationEditFieldLabel.Text = 'Mid level Controller Saturation';
            app.MidlevelControllerSaturationEditField = uieditfield(app.GridLayout118, 'numeric');
            app.MidlevelControllerSaturationEditField.Limits = [1 14.5];
            app.MidlevelControllerSaturationEditField.Value = 7;
            app.AdvFrictionIDTab = uitab(app.TabGroup2_4);
            app.AdvFrictionIDTab.SizeChangedFcn = createCallbackFcn(app, @AdvFrictionIDTabSizeChanged, true);
            app.AdvFrictionIDTab.Title = 'Adv Friction ID';
            app.GridLayout155 = uigridlayout(app.AdvFrictionIDTab);
            app.FrictionIDSettingPanel = uipanel(app.GridLayout155);
            app.FrictionIDSettingPanel.Title = 'Friction ID Setting';
            app.GridLayout156 = uigridlayout(app.FrictionIDSettingPanel);
            app.friction_id_vel_ctrl_eval = uiaxes(app.GridLayout156);
            title(app.friction_id_vel_ctrl_eval, 'Title')
            xlabel(app.friction_id_vel_ctrl_eval, 'Time')
            ylabel(app.friction_id_vel_ctrl_eval, 'Velocity (rad/s)')
            zlabel(app.friction_id_vel_ctrl_eval, 'Z')
            app.MaxVel1radsEditFieldLabel = uilabel(app.GridLayout156);
            app.MaxVel1radsEditFieldLabel.Text = 'Max Vel1 (rad/s)';
            app.MaxVel1radsEditField = uieditfield(app.GridLayout156, 'numeric');
            app.MaxVel1radsEditField.Value = 2;
            app.Vel1StepNumberEditFieldLabel = uilabel(app.GridLayout156);
            app.Vel1StepNumberEditFieldLabel.Text = 'Vel1 Step Number';
            app.Vel1StepNumberEditField = uieditfield(app.GridLayout156, 'numeric');
            app.Vel1StepNumberEditField.Value = 20;
            app.TimeperStepsecEditFieldLabel = uilabel(app.GridLayout156);
            app.TimeperStepsecEditFieldLabel.Text = 'Time per Step (sec)';
            app.TimeperStepsecEditField = uieditfield(app.GridLayout156, 'numeric');
            app.TimeperStepsecEditField.Value = 1;
            app.MaxVel2radsEditFieldLabel = uilabel(app.GridLayout156);
            app.MaxVel2radsEditFieldLabel.Text = 'Max Vel2 (rad/s)';
            app.MaxVel2radsEditField = uieditfield(app.GridLayout156, 'numeric');
            app.MaxVel2radsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxVel2radsEditFieldValueChanged, true);
            app.MaxVel2radsEditField.Value = 10;
            app.Vel2StepNumberEditFieldLabel = uilabel(app.GridLayout156);
            app.Vel2StepNumberEditFieldLabel.Text = 'Vel2 Step Number';
            app.Vel2StepNumberEditField = uieditfield(app.GridLayout156, 'numeric');
            app.Vel2StepNumberEditField.Value = 20;
            app.CutTimesecEditFieldLabel = uilabel(app.GridLayout156);
            app.CutTimesecEditFieldLabel.Text = 'Cut Time (sec)';
            app.CutTimesecEditField = uieditfield(app.GridLayout156, 'numeric');
            app.CutTimesecEditField.ValueChangedFcn = createCallbackFcn(app, @CutTimesecEditFieldValueChanged, true);
            app.CutTimesecEditField.Value = 0.5;
            app.PgainLabel = uilabel(app.GridLayout156);
            app.PgainLabel.Text = 'P gain Slider';
            app.Pgain_Slider = uislider(app.GridLayout156);
            app.Pgain_Slider.MajorTicks = [10 25 40 55 70 85 100];
            app.Pgain_Slider.MajorTickLabels = {'10', '25', '40', '55', '70', '85', '100'};
            app.Pgain_Slider.Tooltip = {'100% = Continuous Current Limit/10'; '10% = Continuous Current Limit/100'};
            app.Pgain_Slider.Value = 10;
            app.IDStartButton = uibutton(app.GridLayout156, 'push');
            app.IDStartButton.ButtonPushedFcn = createCallbackFcn(app, @IDStartButtonPushed, true);
            app.IDStartButton.Text = 'ID Start';
            app.IDStopButton = uibutton(app.GridLayout156, 'push');
            app.IDStopButton.ButtonPushedFcn = createCallbackFcn(app, @IDStopButtonPushed, true);
            app.IDStopButton.Text = 'ID Stop';
            app.ApplyButton_4 = uibutton(app.GridLayout156, 'push');
            app.ApplyButton_4.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_4Pushed, true);
            app.ApplyButton_4.Text = 'Apply';
            app.Igain_Slider = uislider(app.GridLayout156);
            app.Igain_Slider.MajorTicks = [10 25 40 55 70 85 100];
            app.Igain_Slider.MajorTickLabels = {'10', '25', '40', '55', '70', '85', '100'};
            app.Igain_Slider.Tooltip = {'100% = Continuous Current Limit/10'; '10% = Continuous Current Limit/100'};
            app.Igain_Slider.Value = 73.2346324116409;
            app.IgainSliderLabel = uilabel(app.GridLayout156);
            app.IgainSliderLabel.Text = 'I gain Slider';
            app.ControlStartButton = uibutton(app.GridLayout156, 'push');
            app.ControlStartButton.ButtonPushedFcn = createCallbackFcn(app, @ControlStartButtonPushed, true);
            app.ControlStartButton.Text = 'Control Start';
            app.ControlStopButton = uibutton(app.GridLayout156, 'push');
            app.ControlStopButton.ButtonPushedFcn = createCallbackFcn(app, @ControlStopButtonPushed, true);
            app.ControlStopButton.Text = 'Control Stop';
            app.VelocityRefradsEditFieldLabel = uilabel(app.GridLayout156);
            app.VelocityRefradsEditFieldLabel.Text = 'Velocity Ref (rad/s)';
            app.VelocityRefradsEditField = uieditfield(app.GridLayout156, 'numeric');
            app.FrictionIDResultPanel = uipanel(app.GridLayout155);
            app.FrictionIDResultPanel.Title = 'Friction ID Result';
            app.GridLayout157 = uigridlayout(app.FrictionIDResultPanel);
            app.adv_friction_id_plot = uiaxes(app.GridLayout157);
            title(app.adv_friction_id_plot, 'Friction Model')
            xlabel(app.adv_friction_id_plot, 'Velocity (rad/s)')
            ylabel(app.adv_friction_id_plot, 'Current (A)')
            zlabel(app.adv_friction_id_plot, 'Z')
            app.EpsilonDeadZoneradsEditFieldLabel = uilabel(app.GridLayout157);
            app.EpsilonDeadZoneradsEditFieldLabel.Text = 'Epsilon(Dead Zone) (rad/s)';
            app.EpsilonDeadZoneradsEditField = uieditfield(app.GridLayout157, 'numeric');
            app.EpsilonDeadZoneradsEditField.Limits = [0.001 Inf];
            app.EpsilonDeadZoneradsEditField.Value = 0.001;
            app.ApplyButton_5 = uibutton(app.GridLayout157, 'push');
            app.ApplyButton_5.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_5Pushed, true);
            app.ApplyButton_5.Text = 'Apply';
            app.DesiredDampingAsradEditFieldLabel = uilabel(app.GridLayout157);
            app.DesiredDampingAsradEditFieldLabel.Text = 'Desired Damping (As/rad)';
            app.DesiredDampingAsradEditField = uieditfield(app.GridLayout157, 'numeric');
            app.DesiredDampingAsradEditField.Limits = [0.001 Inf];
            app.DesiredDampingAsradEditField.Value = 0.001;
            app.FitModelDropDownLabel = uilabel(app.GridLayout157);
            app.FitModelDropDownLabel.Text = 'Fit Model';
            app.FitModelDropDown = uidropdown(app.GridLayout157);
            app.FitModelDropDown.Items = {'1: Coulomb+Viscous', '2: Stribeck1', '3: Stribeck2'};
            app.FitModelDropDown.Value = '1: Coulomb+Viscous';
            app.GainEditFieldLabel = uilabel(app.GridLayout157);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.GridLayout157, 'numeric');
            app.GainEditField.Limits = [0 1];
            app.GainEditField.Value = 0.99;
            app.Fc_Field = uieditfield(app.GridLayout157, 'numeric');
            app.Fc_Field.Limits = [0 1];
            app.Fc_Field.Value = 0.99;
            app.Fs_Field = uieditfield(app.GridLayout157, 'numeric');
            app.Fs_Field.Limits = [0 1];
            app.Fs_Field.Value = 0.99;
            app.FitModelDropDownLabel_2 = uilabel(app.GridLayout157);
            app.FitModelDropDownLabel_2.Text = 'Fs/Fc';
            app.FrictionCompensatorVerificationPanel = uipanel(app.GridLayout155);
            app.FrictionCompensatorVerificationPanel.Title = 'Friction Compensator Verification';
            app.GridLayout158 = uigridlayout(app.FrictionCompensatorVerificationPanel);
            app.adv_friction_verify_plot = uiaxes(app.GridLayout158);
            title(app.adv_friction_verify_plot, 'Friction Compensator Input')
            xlabel(app.adv_friction_verify_plot, 'TIme (s)')
            ylabel(app.adv_friction_verify_plot, 'Y')
            zlabel(app.adv_friction_verify_plot, 'Z')
            app.FrictionCompensatorStartButton = uibutton(app.GridLayout158, 'push');
            app.FrictionCompensatorStartButton.ButtonPushedFcn = createCallbackFcn(app, @FrictionCompensatorStartButtonPushed, true);
            app.FrictionCompensatorStartButton.Text = 'Friction Compensator Start';
            app.FrictionCompensatorStopButton = uibutton(app.GridLayout158, 'push');
            app.FrictionCompensatorStopButton.ButtonPushedFcn = createCallbackFcn(app, @FrictionCompensatorStopButtonPushed, true);
            app.FrictionCompensatorStopButton.Text = 'Friction Compensator Stop';
            app.FrictionCompensatorEvaluationPanel = uipanel(app.GridLayout155);
            app.FrictionCompensatorEvaluationPanel.Title = 'Friction Compensator Evaluation';
            app.GridLayout158_2 = uigridlayout(app.FrictionCompensatorEvaluationPanel);
            app.adv_friction_verify_plot_2 = uiaxes(app.GridLayout158_2);
            title(app.adv_friction_verify_plot_2, 'Friction Model')
            xlabel(app.adv_friction_verify_plot_2, 'Velocity (rad/s)')
            ylabel(app.adv_friction_verify_plot_2, 'Current (A)')
            zlabel(app.adv_friction_verify_plot_2, 'Z')
            app.IDStartwithCompFBButton = uibutton(app.GridLayout158_2, 'push');
            app.IDStartwithCompFBButton.ButtonPushedFcn = createCallbackFcn(app, @IDStartwithCompFBButtonPushed, true);
            app.IDStartwithCompFBButton.Text = {'ID Start with Comp'; '(FB)'};
            app.IDStopButton_2 = uibutton(app.GridLayout158_2, 'push');
            app.IDStopButton_2.ButtonPushedFcn = createCallbackFcn(app, @IDStopButton_2Pushed, true);
            app.IDStopButton_2.Text = 'ID Stop';
            app.PlotButton_3 = uibutton(app.GridLayout158_2, 'push');
            app.PlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_3Pushed, true);
            app.PlotButton_3.Text = 'Plot';
            app.IDStartwithCompFFButton = uibutton(app.GridLayout158_2, 'push');
            app.IDStartwithCompFFButton.ButtonPushedFcn = createCallbackFcn(app, @IDStartwithCompFFButtonPushed, true);
            app.IDStartwithCompFFButton.Text = {'ID Start with Comp'; '(FF)'};
            app.SystemIDTab = uitab(app.TabGroup2_4);
            app.SystemIDTab.Title = 'System ID';
            app.GridLayout90 = uigridlayout(app.SystemIDTab);
            app.FrequencySamplePropertiesSettingPanel = uipanel(app.GridLayout90);
            app.FrequencySamplePropertiesSettingPanel.Title = 'Frequency Sample Properties Setting';
            app.GridLayout91 = uigridlayout(app.FrequencySamplePropertiesSettingPanel);
            app.ApplyButton = uibutton(app.GridLayout91, 'state');
            app.ApplyButton.ValueChangedFcn = createCallbackFcn(app, @ApplyButtonValueChanged, true);
            app.ApplyButton.Text = 'Apply';
            app.InputOffsetAoffsetEditField_2Label = uilabel(app.GridLayout91);
            app.InputOffsetAoffsetEditField_2Label.Text = {'6. Input Offset (A)'; '    (offset)'};
            app.InputOffsetAoffsetEditField = uieditfield(app.GridLayout91, 'numeric');
            app.InputMagnitudeAMEditField_2Label = uilabel(app.GridLayout91);
            app.InputMagnitudeAMEditField_2Label.Text = {'5. Input Magnitude (A)'; '    (M)'};
            app.InputMagnitudeAMEditField = uieditfield(app.GridLayout91, 'numeric');
            app.InputMagnitudeAMEditField.Limits = [0 10];
            app.NumberofIterationsN_iterEditField_2Label = uilabel(app.GridLayout91);
            app.NumberofIterationsN_iterEditField_2Label.Text = {'4. Number of Iterations'; '    (N_iter)'};
            app.NumberofIterationsN_iterEditField = uieditfield(app.GridLayout91, 'numeric');
            app.NumberofIterationsN_iterEditField.Limits = [5 50];
            app.NumberofIterationsN_iterEditField.Value = 10;
            app.NumberofFrequencySamplesN_samplesEditField_2Label = uilabel(app.GridLayout91);
            app.NumberofFrequencySamplesN_samplesEditField_2Label.Text = {'3. Number of Frequency Samples'; '    (N_samples)'};
            app.NumberofFrequencySamplesN_samplesEditField = uieditfield(app.GridLayout91, 'numeric');
            app.NumberofFrequencySamplesN_samplesEditField.Limits = [10 100];
            app.NumberofFrequencySamplesN_samplesEditField.Value = 20;
            app.MaximumFrequencyHzf_maxEditField_2Label = uilabel(app.GridLayout91);
            app.MaximumFrequencyHzf_maxEditField_2Label.Text = {'2. Maximum Frequency (Hz)'; '    (f_max) '};
            app.MaximumFrequencyHzf_maxEditField = uieditfield(app.GridLayout91, 'numeric');
            app.MaximumFrequencyHzf_maxEditField.Limits = [1 200];
            app.MaximumFrequencyHzf_maxEditField.Value = 50;
            app.MinimumFrequencyHzf_minEditField_2Label = uilabel(app.GridLayout91);
            app.MinimumFrequencyHzf_minEditField_2Label.Text = {'1. Minimum Frequency (Hz)'; '    (f_min)'};
            app.MinimumFrequencyHzf_minEditField = uieditfield(app.GridLayout91, 'numeric');
            app.MinimumFrequencyHzf_minEditField.Limits = [1 200];
            app.MinimumFrequencyHzf_minEditField.Value = 1;
            app.FileSaveNametxtEditFieldLabel = uilabel(app.GridLayout91);
            app.FileSaveNametxtEditFieldLabel.Text = {'File Save Name'; '(.txt)'};
            app.FileSaveNametxtEditField = uieditfield(app.GridLayout91, 'text');
            app.FileSaveNametxtEditField.Value = 'mech_system_id';
            app.StartIDButton = uibutton(app.GridLayout91, 'push');
            app.StartIDButton.ButtonPushedFcn = createCallbackFcn(app, @StartIDButtonPushed, true);
            app.StartIDButton.Text = 'Start ID';
            app.StopIDButton = uibutton(app.GridLayout91, 'push');
            app.StopIDButton.ButtonPushedFcn = createCallbackFcn(app, @StopIDButtonPushed, true);
            app.StopIDButton.Text = 'Stop ID';
            app.AnalysisButton = uibutton(app.GridLayout91, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Text = 'Analysis';
            app.InertiaJunitAs2Label = uilabel(app.GridLayout91);
            app.InertiaJunitAs2Label.Text = 'Inertia (J) (unit: As^2)';
            app.DampingBunitAsLabel = uilabel(app.GridLayout91);
            app.DampingBunitAsLabel.Text = 'Damping (B) (unit: As)';
            app.MinimumFrequencyHzf_minEditField_2Label_2 = uilabel(app.GridLayout91);
            app.MinimumFrequencyHzf_minEditField_2Label_2.Text = 'Set J/B';
            app.custom_J = uieditfield(app.GridLayout91, 'numeric');
            app.custom_B = uieditfield(app.GridLayout91, 'numeric');
            app.ApplyButton_6 = uibutton(app.GridLayout91, 'state');
            app.ApplyButton_6.ValueChangedFcn = createCallbackFcn(app, @ApplyButton_6ValueChanged, true);
            app.ApplyButton_6.Text = 'Apply';
            app.VerificationPlotPanel_2 = uipanel(app.GridLayout90);
            app.VerificationPlotPanel_2.Title = 'Verification & Plot';
            app.GridLayout92 = uigridlayout(app.VerificationPlotPanel_2);
            app.system_id_verify_plot = uiaxes(app.GridLayout92);
            title(app.system_id_verify_plot, 'Model Evaluation')
            xlabel(app.system_id_verify_plot, 'Time (s)')
            ylabel(app.system_id_verify_plot, 'Velocity (Deg/s)')
            zlabel(app.system_id_verify_plot, 'Z')
            app.sys_id_verification_StartButton = uibutton(app.GridLayout92, 'push');
            app.sys_id_verification_StartButton.ButtonPushedFcn = createCallbackFcn(app, @sys_id_verification_StartButtonPushed, true);
            app.sys_id_verification_StartButton.Text = 'Start';
            app.sys_id_verification_StopButton = uibutton(app.GridLayout92, 'push');
            app.sys_id_verification_StopButton.ButtonPushedFcn = createCallbackFcn(app, @sys_id_verification_StopButtonPushed, true);
            app.sys_id_verification_StopButton.Text = 'Stop';
            app.MagnitudeALabel = uilabel(app.GridLayout92);
            app.MagnitudeALabel.Text = {'Magnitude'; '(A)'};
            app.Cur_Rec_Amp = uieditfield(app.GridLayout92, 'numeric');
            app.FrequencyHzLabel = uilabel(app.GridLayout92);
            app.FrequencyHzLabel.Text = {'Frequency'; '(Hz)'};
            app.Cur_Rec_Freq = uieditfield(app.GridLayout92, 'numeric');
            app.CurrentReferenceDropDownLabel = uilabel(app.GridLayout92);
            app.CurrentReferenceDropDownLabel.Text = 'Current Reference';
            app.CurrentReferenceDropDown = uidropdown(app.GridLayout92);
            app.CurrentReferenceDropDown.Items = {'SINE', 'TANH', 'RECT'};
            app.CurrentReferenceDropDown.Value = 'TANH';
            app.Image8 = uiimage(app.GridLayout90);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'Images', 'MD_GUI_FIGURE_SYSTEM_ID.png');
            app.SystemIDResultPanel = uipanel(app.GridLayout90);
            app.SystemIDResultPanel.Title = 'System ID Result';
            app.GridLayout149 = uigridlayout(app.SystemIDResultPanel);
            app.Bode_Magnitude = uiaxes(app.GridLayout149);
            title(app.Bode_Magnitude, 'Magnitude')
            xlabel(app.Bode_Magnitude, 'Frequency (Hz)')
            ylabel(app.Bode_Magnitude, 'Magnitude (dB)')
            zlabel(app.Bode_Magnitude, 'Z')
            app.Bode_Phase = uiaxes(app.GridLayout149);
            title(app.Bode_Phase, 'Phase')
            xlabel(app.Bode_Phase, 'Frequency (Hz)')
            ylabel(app.Bode_Phase, 'Phase (deg)')
            zlabel(app.Bode_Phase, 'Z')
            app.VelocityEstimatorTab = uitab(app.TabGroup2_4);
            app.VelocityEstimatorTab.Title = 'Velocity Estimator';
            app.GridLayout146 = uigridlayout(app.VelocityEstimatorTab);
            app.Panel3 = uipanel(app.GridLayout146);
            app.Panel3.Title = 'Panel3';
            app.GridLayout148 = uigridlayout(app.Panel3);
            app.EstimatorTypeDropDownLabel = uilabel(app.GridLayout148);
            app.EstimatorTypeDropDownLabel.Text = 'Estimator Type';
            app.EstimatorTypeDropDown = uidropdown(app.GridLayout148);
            app.EstimatorTypeDropDown.Items = {'Raw Value', 'LPF 300', 'LPF 400', 'LPF 500', 'Moving Average', 'Kalman Filter', 'Lead-Lag'};
            app.EstimatorTypeDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6'};
            app.EstimatorTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @EstimatorTypeDropDownValueChanged, true);
            app.EstimatorTypeDropDown.Value = '0';
            app.VE_apply = uibutton(app.GridLayout148, 'push');
            app.VE_apply.ButtonPushedFcn = createCallbackFcn(app, @VE_applyPushed, true);
            app.VE_apply.Text = 'Apply';
            app.MaskingSizeEditFieldLabel = uilabel(app.GridLayout148);
            app.MaskingSizeEditFieldLabel.Text = 'Masking Size';
            app.MaskingSizeEditField = uieditfield(app.GridLayout148, 'numeric');
            app.MaskingSizeEditField.Limits = [1 8];
            app.MaskingSizeEditField.Enable = 'off';
            app.MaskingSizeEditField.Value = 1;
            app.VE_StartButton = uibutton(app.GridLayout148, 'push');
            app.VE_StartButton.ButtonPushedFcn = createCallbackFcn(app, @VE_StartButtonPushed, true);
            app.VE_StartButton.Text = 'Start';
            app.VE_StopButton = uibutton(app.GridLayout148, 'push');
            app.VE_StopButton.ButtonPushedFcn = createCallbackFcn(app, @VE_StopButtonPushed, true);
            app.VE_StopButton.Text = 'Stop';
            app.aofLeadLagEditFieldLabel = uilabel(app.GridLayout148);
            app.aofLeadLagEditFieldLabel.Text = 'a of Lead Lag';
            app.aofLeadLagEditField = uieditfield(app.GridLayout148, 'numeric');
            app.aofLeadLagEditField.Enable = 'off';
            app.bofLeadLagEditFieldLabel = uilabel(app.GridLayout148);
            app.bofLeadLagEditFieldLabel.Text = 'b of Lead Lag';
            app.bofLeadLagEditField = uieditfield(app.GridLayout148, 'numeric');
            app.bofLeadLagEditField.Enable = 'off';
            app.Panel4 = uipanel(app.GridLayout146);
            app.Panel4.Title = 'Panel4';
            app.GridLayout147 = uigridlayout(app.Panel4);
            app.VE_result_plot = uiaxes(app.GridLayout147);
            title(app.VE_result_plot, 'Title')
            xlabel(app.VE_result_plot, 'X')
            ylabel(app.VE_result_plot, 'Y')
            zlabel(app.VE_result_plot, 'Z')
            app.IRCTab = uitab(app.TabGroup2_4);
            app.IRCTab.Title = 'IRC';
            app.GridLayout100 = uigridlayout(app.IRCTab);
            app.ImpedanceReductionControllerDesignPanel = uipanel(app.GridLayout100);
            app.ImpedanceReductionControllerDesignPanel.Title = 'Impedance Reduction Controller Design';
            app.GridLayout103 = uigridlayout(app.ImpedanceReductionControllerDesignPanel);
            app.GridLayout112 = uigridlayout(app.GridLayout103);
            app.IRC_Bode_Phase = uiaxes(app.GridLayout112);
            title(app.IRC_Bode_Phase, 'Phase Plot')
            xlabel(app.IRC_Bode_Phase, 'Frequency (rad/s)')
            ylabel(app.IRC_Bode_Phase, 'Phase (Feg)')
            zlabel(app.IRC_Bode_Phase, 'Z')
            app.IRC_Bode_Mag = uiaxes(app.GridLayout112);
            title(app.IRC_Bode_Mag, 'Magnitude Plot')
            xlabel(app.IRC_Bode_Mag, 'Frequency (rad/s)')
            ylabel(app.IRC_Bode_Mag, 'Magnitude (dB)')
            zlabel(app.IRC_Bode_Mag, 'Z')
            app.GsPlantInverseLabel = uilabel(app.GridLayout112);
            app.GsPlantInverseLabel.Text = '1/G(s) : Plant Inverse';
            app.FsIRCFilterLabel = uilabel(app.GridLayout112);
            app.FsIRCFilterLabel.Text = 'F(s) : IRC Filter';
            app.CsIRCControllerLabel = uilabel(app.GridLayout112);
            app.CsIRCControllerLabel.Text = 'C(s) : IRC Controller';
            app.GridLayout117 = uigridlayout(app.GridLayout103);
            app.QvalueEditFieldLabel = uilabel(app.GridLayout117);
            app.QvalueEditFieldLabel.Text = 'Q value';
            app.QvalueEditField = uieditfield(app.GridLayout117, 'numeric');
            app.QvalueEditField.Limits = [0 1];
            app.QvalueEditField.Value = 1;
            app.DampingBunitAsLabel_2 = uilabel(app.GridLayout117);
            app.DampingBunitAsLabel_2.Text = 'Damping (B) (unit: As)';
            app.InertiaJunitAs2Label_2 = uilabel(app.GridLayout117);
            app.InertiaJunitAs2Label_2.Text = 'Inertia (J) (unit: As^2)';
            app.ApproximationDropDownLabel = uilabel(app.GridLayout117);
            app.ApproximationDropDownLabel.Text = 'Approximation';
            app.ApproximationDropDown = uidropdown(app.GridLayout117);
            app.ApproximationDropDown.Items = {'zoh', 'tustin', 'matched'};
            app.ApproximationDropDown.Value = 'zoh';
            app.ConversionPlotButton = uibutton(app.GridLayout117, 'push');
            app.ConversionPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ConversionPlotButtonPushed, true);
            app.ConversionPlotButton.Text = 'Conversion & Plot';
            app.IRC_apply_button = uibutton(app.GridLayout117, 'push');
            app.IRC_apply_button.ButtonPushedFcn = createCallbackFcn(app, @IRC_apply_buttonPushed, true);
            app.IRC_apply_button.Text = 'Apply';
            app.MainFilterPanel = uipanel(app.GridLayout117);
            app.MainFilterPanel.Title = 'Main Filter';
            app.GridLayout121 = uigridlayout(app.MainFilterPanel);
            app.OrderDropDownLabel = uilabel(app.GridLayout121);
            app.OrderDropDownLabel.Text = 'Order';
            app.OrderDropDown = uidropdown(app.GridLayout121);
            app.OrderDropDown.Items = {'First', 'Second', 'Third'};
            app.OrderDropDown.ItemsData = {'1', '2', '3'};
            app.OrderDropDown.Value = '1';
            app.LowPassFilterTypeDropDownLabel = uilabel(app.GridLayout121);
            app.LowPassFilterTypeDropDownLabel.Text = 'Low Pass Filter Type';
            app.LowPassFilterTypeDropDown = uidropdown(app.GridLayout121);
            app.LowPassFilterTypeDropDown.Items = {'Basic', 'Butterworth', 'Chebyshev'};
            app.LowPassFilterTypeDropDown.Value = 'Basic';
            app.CutoffHzEditFieldLabel = uilabel(app.GridLayout121);
            app.CutoffHzEditFieldLabel.Text = 'Cutoff (Hz)';
            app.CutoffHzEditField = uieditfield(app.GridLayout121, 'numeric');
            app.SubFilter1storderlowpassPanel = uipanel(app.GridLayout117);
            app.SubFilter1storderlowpassPanel.Title = 'Sub Filter (1st order low pass)';
            app.GridLayout122 = uigridlayout(app.SubFilter1storderlowpassPanel);
            app.CutoffHzEditField_2Label = uilabel(app.GridLayout122);
            app.CutoffHzEditField_2Label.Text = 'Cutoff (Hz)';
            app.CutoffHzEditField_2 = uieditfield(app.GridLayout122, 'numeric');

            app.UsesubfilterCheckBox = uicheckbox(app.GridLayout122);
            app.UsesubfilterCheckBox.Text = 'Use sub filter';
            app.IRCSaturationAEditFieldLabel = uilabel(app.GridLayout117);
            app.IRCSaturationAEditFieldLabel.Text = 'IRC Saturation (A)';
            app.IRCSaturationAEditField = uieditfield(app.GridLayout117, 'numeric');
            app.IRCSaturationAEditField.Limits = [1 14.5];
            app.IRCSaturationAEditField.Value = 1;
            app.IRCFilterMainFilterSubFilterLabel = uilabel(app.GridLayout117);
            app.IRCFilterMainFilterSubFilterLabel.Text = 'IRC Filter = Main Filter * Sub Filter';
            app.ImpedanceReductionControllerVerificationPanel = uipanel(app.GridLayout100);
            app.ImpedanceReductionControllerVerificationPanel.Title = 'Impedance Reduction Controller Verification';
            app.GridLayout104 = uigridlayout(app.ImpedanceReductionControllerVerificationPanel);
            app.GridLayout106 = uigridlayout(app.GridLayout104);
            app.FileNametxtEditFieldLabel = uilabel(app.GridLayout106);
            app.FileNametxtEditFieldLabel.Text = 'File Name (.txt)';
            app.FileNametxtEditField = uieditfield(app.GridLayout106, 'text');
            app.FileNametxtEditField.Value = 'IRC_verification';
            app.IRCStartButton = uibutton(app.GridLayout106, 'push');
            app.IRCStartButton.ButtonPushedFcn = createCallbackFcn(app, @IRCStartButtonPushed, true);
            app.IRCStartButton.Text = 'IRC Start';
            app.IRCStopButton = uibutton(app.GridLayout106, 'push');
            app.IRCStopButton.ButtonPushedFcn = createCallbackFcn(app, @IRCStopButtonPushed, true);
            app.IRCStopButton.Text = 'IRC Stop';
            app.FrictionCompensatorONCheckBox = uicheckbox(app.GridLayout106);
            app.FrictionCompensatorONCheckBox.Text = 'Friction Compensator ON';
            app.GridLayout175 = uigridlayout(app.GridLayout104);
            app.IRC_Verification_Plot = uiaxes(app.GridLayout175);
            title(app.IRC_Verification_Plot, 'IRC Input')
            xlabel(app.IRC_Verification_Plot, 'Time (s)')
            ylabel(app.IRC_Verification_Plot, 'Current (A)')
            zlabel(app.IRC_Verification_Plot, 'Z')
            app.ImpedanceControllerTab = uitab(app.TabGroup2_4);
            app.ImpedanceControllerTab.Title = 'Impedance Controller';
            app.GridLayout123 = uigridlayout(app.ImpedanceControllerTab);
            app.ImpedanceControllerGainSettingPanel = uipanel(app.GridLayout123);
            app.ImpedanceControllerGainSettingPanel.Title = 'Impedance Controller Gain Setting';
            app.GridLayout124 = uigridlayout(app.ImpedanceControllerGainSettingPanel);
            app.VirtualStiffnessLabel = uilabel(app.GridLayout124);
            app.VirtualStiffnessLabel.Text = 'Virtual Stiffness (%)';
            app.VirtualStiffnessEditField = uieditfield(app.GridLayout124, 'numeric');
            app.VirtualStiffnessEditField.Limits = [0 Inf];
            app.VirtualDampingLabel = uilabel(app.GridLayout124);
            app.VirtualDampingLabel.Text = 'Virtual Damping  (%)';
            app.VirtualDampingEditField = uieditfield(app.GridLayout124, 'numeric');
            app.VirtualDampingEditField.Limits = [0 Inf];
            app.ImpedanceControllerApplyButton = uibutton(app.GridLayout124, 'push');
            app.ImpedanceControllerApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ImpedanceControllerApplyButtonPushed, true);
            app.ImpedanceControllerApplyButton.Text = 'Apply';
            app.CorridorHalfWidthDegEditFieldLabel = uilabel(app.GridLayout124);
            app.CorridorHalfWidthDegEditFieldLabel.Text = 'Corridor Half Width (Deg)';
            app.CorridorHalfWidthDegEditField = uieditfield(app.GridLayout124, 'numeric');
            app.CorridorHalfWidthDegEditField.Limits = [0 Inf];
            app.ImpedanceRatioinCorridor0255EditFieldLabel = uilabel(app.GridLayout124);
            app.ImpedanceRatioinCorridor0255EditFieldLabel.Text = 'Impedance Ratio in Corridor (0 ~ 2.55)';
            app.ImpedanceRatioinCorridor0255EditField = uieditfield(app.GridLayout124, 'numeric');
            app.ImpedanceRatioinCorridor0255EditField.Limits = [0 Inf];
            app.TransitionTimemsEditFieldLabel = uilabel(app.GridLayout124);
            app.TransitionTimemsEditFieldLabel.Text = 'Transition Time (ms)';
            app.TransitionTimemsEditField = uieditfield(app.GridLayout124, 'numeric');
            app.TransitionTimemsEditField.Limits = [0 Inf];
            app.ActualKp100ADegEditFieldLabel = uilabel(app.GridLayout124);
            app.ActualKp100ADegEditFieldLabel.Text = 'Actual Kp @ 100% (A/Deg)';
            app.ActualKp100ADegEditField = uieditfield(app.GridLayout124, 'numeric');
            app.ActualKp100ADegEditField.Limits = [0 Inf];
            app.ActualKd100ADegsEditFieldLabel = uilabel(app.GridLayout124);
            app.ActualKd100ADegsEditFieldLabel.Text = 'Actual Kd @ 100% (A/Deg/s)';
            app.ActualKd100ADegsEditField = uieditfield(app.GridLayout124, 'numeric');
            app.ActualKd100ADegsEditField.Limits = [0 Inf];
            app.ImpedanceControllerApplyButton_2 = uibutton(app.GridLayout124, 'push');
            app.ImpedanceControllerApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImpedanceControllerApplyButton_2Pushed, true);
            app.ImpedanceControllerApplyButton_2.Text = 'Apply';
            app.ImpedanceControlModeDropDownLabel = uilabel(app.GridLayout124);
            app.ImpedanceControlModeDropDownLabel.Text = 'Impedance Control Mode';
            app.ImpedanceControlModeDropDown = uidropdown(app.GridLayout124);
            app.ImpedanceControlModeDropDown.Items = {'Constant Parameter', 'Variable Parameter'};
            app.ImpedanceControlModeDropDown.Value = 'Variable Parameter';
            app.ImpedanceControllerVerificationPanel = uipanel(app.GridLayout123);
            app.ImpedanceControllerVerificationPanel.Title = 'Impedance Controller Verification';
            app.GridLayout127 = uigridlayout(app.ImpedanceControllerVerificationPanel);
            app.imp_verify_position_plot = uiaxes(app.GridLayout127);
            title(app.imp_verify_position_plot, 'Impedance Control Position')
            xlabel(app.imp_verify_position_plot, 'Time')
            ylabel(app.imp_verify_position_plot, 'Position (Deg)')
            zlabel(app.imp_verify_position_plot, 'Z')
            app.imp_verify_input_plot = uiaxes(app.GridLayout127);
            title(app.imp_verify_input_plot, 'Impedance Control Input')
            xlabel(app.imp_verify_input_plot, 'Time')
            ylabel(app.imp_verify_input_plot, 'Control Input (A)')
            zlabel(app.imp_verify_input_plot, 'Z')
            app.TabGroup9 = uitabgroup(app.GridLayout127);
            app.SinusoidalTab_3 = uitab(app.TabGroup9);
            app.SinusoidalTab_3.Title = 'Sinusoidal';
            app.GridLayout129 = uigridlayout(app.SinusoidalTab_3);
            app.MagnituderadEditFieldLabel = uilabel(app.GridLayout129);
            app.MagnituderadEditFieldLabel.Text = 'Magnitude (rad)';
            app.IMP_Sine_Magnitude = uieditfield(app.GridLayout129, 'numeric');
            app.FrequencyHzEditField_7Label = uilabel(app.GridLayout129);
            app.FrequencyHzEditField_7Label.Text = 'Frequency (Hz)';
            app.IMP_Sine_Frequency = uieditfield(app.GridLayout129, 'numeric');
            app.imp_verify_sine_start = uibutton(app.GridLayout129, 'push');
            app.imp_verify_sine_start.ButtonPushedFcn = createCallbackFcn(app, @imp_verify_sine_startPushed, true);
            app.imp_verify_sine_start.Text = 'Start';
            app.imp_verify_sine_stop = uibutton(app.GridLayout129, 'push');
            app.imp_verify_sine_stop.ButtonPushedFcn = createCallbackFcn(app, @imp_verify_sine_stopPushed, true);
            app.imp_verify_sine_stop.Text = 'Stop';
            app.FrictionCompensatorONCheckBox_2 = uicheckbox(app.GridLayout129);
            app.FrictionCompensatorONCheckBox_2.Text = 'Friction Compensator ON';
            app.ReferenceLabel_2 = uilabel(app.GridLayout127);
            app.ReferenceLabel_2.Text = 'Reference';
            app.ActualLabel_3 = uilabel(app.GridLayout127);
            app.ActualLabel_3.Text = 'Actual';
            app.ImpedanceParameterExplanationPanel = uipanel(app.GridLayout123);
            app.ImpedanceParameterExplanationPanel.Title = 'Impedance Parameter Explanation';
            app.GridLayout125 = uigridlayout(app.ImpedanceParameterExplanationPanel);
            app.Image13 = uiimage(app.GridLayout125);
            app.Image13.ImageSource = fullfile(pathToMLAPP, 'Images', 'MD_GUI_FIGURE_IMPEDANCE_PARAMETER.png');
            app.DOBTab = uitab(app.TabGroup2_4);
            app.DOBTab.Title = 'DOB';
            app.GridLayout89 = uigridlayout(app.DOBTab);
            app.DObDesignPanel = uipanel(app.GridLayout89);
            app.DObDesignPanel.Title = 'DOb Design';
            app.GridLayout131 = uigridlayout(app.DObDesignPanel);
            app.dob_evaluation_plot = uiaxes(app.GridLayout131);
            title(app.dob_evaluation_plot, 'DoB Performance Evaluation')
            xlabel(app.dob_evaluation_plot, 'Time (s)')
            ylabel(app.dob_evaluation_plot, 'Velocity (rad/s)')
            zlabel(app.dob_evaluation_plot, 'Z')
            app.GinvQ_mag_plot = uiaxes(app.GridLayout131);
            title(app.GinvQ_mag_plot, 'Title')
            xlabel(app.GinvQ_mag_plot, 'X')
            ylabel(app.GinvQ_mag_plot, 'Y')
            zlabel(app.GinvQ_mag_plot, 'Z')
            app.GinvQ_phase_plot = uiaxes(app.GridLayout131);
            title(app.GinvQ_phase_plot, 'Title')
            xlabel(app.GinvQ_phase_plot, 'X')
            ylabel(app.GinvQ_phase_plot, 'Y')
            zlabel(app.GinvQ_phase_plot, 'Z')
            app.InputOffsetAoffsetEditField_2Label_2 = uilabel(app.GridLayout131);
            app.InputOffsetAoffsetEditField_2Label_2.Text = 'Q Filter Cutoff (Hz)';
            app.QFilterCutoffHzEditField = uieditfield(app.GridLayout131, 'numeric');
            app.DoB_Qfilter_ApplyButton = uibutton(app.GridLayout131, 'push');
            app.DoB_Qfilter_ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @DoB_Qfilter_ApplyButtonPushed, true);
            app.DoB_Qfilter_ApplyButton.Text = 'Apply';
            app.DampingBunitAsLabel_3 = uilabel(app.GridLayout131);
            app.DampingBunitAsLabel_3.Text = 'Damping (B) (unit: As)';
            app.InertiaJunitAs2Label_3 = uilabel(app.GridLayout131);
            app.InertiaJunitAs2Label_3.Text = 'Inertia (J) (unit: As^2)';
            app.SaturationAEditField_3Label = uilabel(app.GridLayout131);
            app.SaturationAEditField_3Label.Text = 'Saturation (A)';
            app.SaturationAEditField_3 = uieditfield(app.GridLayout131, 'numeric');
            app.dob_verification_start = uibutton(app.GridLayout131, 'push');
            app.dob_verification_start.ButtonPushedFcn = createCallbackFcn(app, @dob_verification_startPushed, true);
            app.dob_verification_start.Text = 'Start';
            app.dob_verification_stop = uibutton(app.GridLayout131, 'push');
            app.dob_verification_stop.ButtonPushedFcn = createCallbackFcn(app, @dob_verification_stopButtonPushed, true);
            app.dob_verification_stop.Text = 'Stop';
            app.MagnitudeALabel_2 = uilabel(app.GridLayout131);
            app.MagnitudeALabel_2.Text = {'Magnitude'; '(A)'};
            app.dob_verify_amp = uieditfield(app.GridLayout131, 'numeric');
            app.FrequencyHzLabel_2 = uilabel(app.GridLayout131);
            app.FrequencyHzLabel_2.Text = {'Frequency'; '(Hz)'};
            app.dob_verify_freq = uieditfield(app.GridLayout131, 'numeric');
            app.ActualLabel_2 = uilabel(app.GridLayout131);
            app.ActualLabel_2.Text = 'Actual';
            app.EstimationLabel_2 = uilabel(app.GridLayout131);
            app.EstimationLabel_2.Text = 'Estimation';
            app.ReferenceDropDown_2Label = uilabel(app.GridLayout131);
            app.ReferenceDropDown_2Label.Text = 'Reference';
            app.ReferenceDropDown_2 = uidropdown(app.GridLayout131);
            app.ReferenceDropDown_2.Items = {'SINE', 'TANH', 'RECT'};
            app.ReferenceDropDown_2.Value = 'TANH';
            app.DObVerificationPanel = uipanel(app.GridLayout89);
            app.DObVerificationPanel.Title = 'DOb Verification';
            app.GridLayout133 = uigridlayout(app.DObVerificationPanel);
            app.DoB_test_plot2 = uiaxes(app.GridLayout133);
            title(app.DoB_test_plot2, 'Disturbance Estimation')
            xlabel(app.DoB_test_plot2, 'Time')
            ylabel(app.DoB_test_plot2, 'Disturbance (A)')
            zlabel(app.DoB_test_plot2, 'Z')
            app.DoB_test_plot1 = uiaxes(app.GridLayout133);
            title(app.DoB_test_plot1, 'Position Control Performance (Blue: ref, Red: act)')
            xlabel(app.DoB_test_plot1, 'Time')
            ylabel(app.DoB_test_plot1, 'Position (deg)')
            zlabel(app.DoB_test_plot1, 'Z')
            app.FileSaveNametxtEditField_2Label = uilabel(app.GridLayout133);
            app.FileSaveNametxtEditField_2Label.Text = {'File Save Name'; '(.txt)'};
            app.FileSaveNametxtEditField_2 = uieditfield(app.GridLayout133, 'text');
            app.FileSaveNametxtEditField_2.Value = 'DOb_verification';
            app.DoB_test_StartButton = uibutton(app.GridLayout133, 'push');
            app.DoB_test_StartButton.ButtonPushedFcn = createCallbackFcn(app, @DoB_test_StartButtonPushed, true);
            app.DoB_test_StartButton.Text = 'Start';
            app.DoB_test_StopButton = uibutton(app.GridLayout133, 'push');
            app.DoB_test_StopButton.ButtonPushedFcn = createCallbackFcn(app, @DoB_test_StopButtonPushed, true);
            app.DoB_test_StopButton.Text = 'Stop';
            app.FrequencyHzEditField_3Label_3 = uilabel(app.GridLayout133);
            app.FrequencyHzEditField_3Label_3.Text = 'Frequency (Hz)';
            app.DoB_test_FrequencyHzEditField = uieditfield(app.GridLayout133, 'numeric');
            app.AmplituderadLabel = uilabel(app.GridLayout133);
            app.AmplituderadLabel.Text = 'Amplitude (rad)';
            app.DoB_test_AmplitudedegEditField = uieditfield(app.GridLayout133, 'numeric');
            app.PgainImpedanceControllerEditFieldLabel = uilabel(app.GridLayout133);
            app.PgainImpedanceControllerEditFieldLabel.Text = {'P gain '; '(Impedance Controller)'};
            app.PgainDOb = uieditfield(app.GridLayout133, 'numeric');
            app.DgainImpedanceControllerEditFieldLabel = uilabel(app.GridLayout133);
            app.DgainImpedanceControllerEditFieldLabel.Text = {'D gain '; '(Impedance Controller)'};
            app.DgainDOb = uieditfield(app.GridLayout133, 'numeric');
            app.PDControllerTab = uitab(app.TabGroup2_4);
            app.PDControllerTab.Title = 'PD Controller';
            app.GridLayout58 = uigridlayout(app.PDControllerTab);
            app.FrequencyResponseofClosedloopSystemPanel_2 = uipanel(app.GridLayout58);
            app.FrequencyResponseofClosedloopSystemPanel_2.Title = 'Frequency Response of Closed-loop System';
            app.GridLayout130 = uigridlayout(app.FrequencyResponseofClosedloopSystemPanel_2);
            app.closed_loop_mag2 = uiaxes(app.GridLayout130);
            title(app.closed_loop_mag2, 'Magnitude    (Blue: Velocity | Red: Position)')
            xlabel(app.closed_loop_mag2, 'Frequency (rad/s)')
            ylabel(app.closed_loop_mag2, 'Magnitude (dB)')
            zlabel(app.closed_loop_mag2, 'Z')
            app.closed_loop_phase2 = uiaxes(app.GridLayout130);
            title(app.closed_loop_phase2, 'Phase    (Blue: Velocity | Red: Position)')
            xlabel(app.closed_loop_phase2, 'Frequency (rad/s)')
            ylabel(app.closed_loop_phase2, 'Phase (degrees)')
            zlabel(app.closed_loop_phase2, 'Z')
            app.GainTunnerPanel = uipanel(app.GridLayout58);
            app.GainTunnerPanel.Title = 'Gain Tunner';
            app.GridLayout60 = uigridlayout(app.GainTunnerPanel);
            app.GridLayout62 = uigridlayout(app.GridLayout60);
            app.GridLayout63 = uigridlayout(app.GridLayout62);
            app.GainTune = uibutton(app.GridLayout63, 'push');
            app.GainTune.ButtonPushedFcn = createCallbackFcn(app, @GainTuneButtonPushed, true);
            app.GainTune.Text = 'Gain Tunning';
            app.VelocityBandwidthHzEditFieldLabel = uilabel(app.GridLayout63);
            app.VelocityBandwidthHzEditFieldLabel.Text = 'Velocity Bandwidth (Hz)';
            app.VelocityBandwidthHzEditField = uieditfield(app.GridLayout63, 'numeric');
            app.VelocityBandwidthHzEditField.Limits = [0 1000000];
            app.VelocityBandwidthHzEditField.Value = 10;
            app.PositionInputPenaltyEditFieldLabel = uilabel(app.GridLayout63);
            app.PositionInputPenaltyEditFieldLabel.Text = 'Position Input Penalty';
            app.Pos_Input_Penalty = uieditfield(app.GridLayout63, 'numeric');
            app.Pos_Input_Penalty.Limits = [1e-05 10];
            app.Pos_Input_Penalty.Value = 0.001;
            app.VelocityControllerPIControllerPanel = uipanel(app.GridLayout62);
            app.VelocityControllerPIControllerPanel.Title = 'Velocity Controller (PI Controller)';
            app.GridLayout64_6 = uigridlayout(app.VelocityControllerPIControllerPanel);
            app.Vel_P_Gain = uilabel(app.GridLayout64_6);
            app.Vel_P_Gain.Text = 'P Gain = ';
            app.Vel_I_Gain = uilabel(app.GridLayout64_6);
            app.Vel_I_Gain.Text = 'I Gain = ';
            app.PositionControllerPDControllerPanel = uipanel(app.GridLayout62);
            app.PositionControllerPDControllerPanel.Title = 'Position Controller (PD Controller)';
            app.GridLayout65 = uigridlayout(app.PositionControllerPDControllerPanel);
            app.Pos_P_Gain = uilabel(app.GridLayout65);
            app.Pos_P_Gain.Text = 'P Gain = ';
            app.Pos_D_Gain = uilabel(app.GridLayout65);
            app.Pos_D_Gain.Text = 'D Gain = ';
            app.PositionControllerPDControllerExpertPanel = uipanel(app.GridLayout62);
            app.PositionControllerPDControllerExpertPanel.Title = 'Position Controller (PD Controller, Expert)';
            app.GridLayout65_2 = uigridlayout(app.PositionControllerPDControllerExpertPanel);
            app.PGainEditFieldLabel = uilabel(app.GridLayout65_2);
            app.PGainEditFieldLabel.Text = 'P Gain';
            app.PGainEditField = uieditfield(app.GridLayout65_2, 'numeric');
            app.DGainEditFieldLabel = uilabel(app.GridLayout65_2);
            app.DGainEditFieldLabel.Text = 'D Gain';
            app.DGainEditField = uieditfield(app.GridLayout65_2, 'numeric');
            app.SetGainButton = uibutton(app.GridLayout65_2, 'push');
            app.SetGainButton.ButtonPushedFcn = createCallbackFcn(app, @SetGainButtonPushed, true);
            app.SetGainButton.Text = 'Set';
            app.VelocityControlPanel = uipanel(app.GridLayout58);
            app.VelocityControlPanel.Title = 'Velocity Control';
            app.GridLayout67 = uigridlayout(app.VelocityControlPanel);
            app.vel_ctrl_plot = uiaxes(app.GridLayout67);
            title(app.vel_ctrl_plot, 'Blue - Referenece | Red - Actual')
            xlabel(app.vel_ctrl_plot, 'Time')
            ylabel(app.vel_ctrl_plot, 'Angular Velocity (rad/s)')
            zlabel(app.vel_ctrl_plot, 'Z')
            app.TabGroup4 = uitabgroup(app.GridLayout67);
            app.StepTab = uitab(app.TabGroup4);
            app.StepTab.Title = 'Step';
            app.GridLayout72 = uigridlayout(app.StepTab);
            app.StepReferenceLabel = uilabel(app.GridLayout72);
            app.StepReferenceLabel.Text = {'Target Velocity '; '(rad/s)'};
            app.vel_ref = uieditfield(app.GridLayout72, 'numeric');
            app.vel_step_stop = uibutton(app.GridLayout72, 'push');
            app.vel_step_stop.ButtonPushedFcn = createCallbackFcn(app, @vel_step_startPushed, true);
            app.vel_step_stop.Text = 'Start';
            app.vel_step_start = uibutton(app.GridLayout72, 'push');
            app.vel_step_start.ButtonPushedFcn = createCallbackFcn(app, @vel_step_stopPushed, true);
            app.vel_step_start.Text = 'Stop';
            app.SinusoidalTab = uitab(app.TabGroup4);
            app.SinusoidalTab.Title = 'Sinusoidal';
            app.GridLayout73 = uigridlayout(app.SinusoidalTab);
            app.vel_sine_start = uibutton(app.GridLayout73, 'push');
            app.vel_sine_start.ButtonPushedFcn = createCallbackFcn(app, @vel_sine_startPushed, true);
            app.vel_sine_start.Text = 'Start';
            app.vel_sine_stop = uibutton(app.GridLayout73, 'push');
            app.vel_sine_stop.ButtonPushedFcn = createCallbackFcn(app, @vel_sine_stopPushed, true);
            app.vel_sine_stop.Text = 'Stop';
            app.AmplituderadEditFieldLabel = uilabel(app.GridLayout73);
            app.AmplituderadEditFieldLabel.Text = 'Amplitude (rad)';
            app.AmplituderadEditField = uieditfield(app.GridLayout73, 'numeric');
            app.AmplituderadEditField.Limits = [0 100];
            app.FrequencyHzEditFieldLabel = uilabel(app.GridLayout73);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.GridLayout73, 'numeric');
            app.FrequencyHzEditField.Limits = [0 100];
            app.RectangleTab = uitab(app.TabGroup4);
            app.RectangleTab.Title = 'Rectangle';
            app.GridLayout73_2 = uigridlayout(app.RectangleTab);
            app.vel_rec_start = uibutton(app.GridLayout73_2, 'push');
            app.vel_rec_start.ButtonPushedFcn = createCallbackFcn(app, @vel_rec_startButtonPushed, true);
            app.vel_rec_start.Text = 'Start';
            app.vel_rec_stop = uibutton(app.GridLayout73_2, 'push');
            app.vel_rec_stop.ButtonPushedFcn = createCallbackFcn(app, @vel_rec_stopButtonPushed, true);
            app.vel_rec_stop.Text = 'Stop';
            app.AmplituderadEditField_3Label_3 = uilabel(app.GridLayout73_2);
            app.AmplituderadEditField_3Label_3.Text = 'Amplitude (rad)';
            app.RecAmplitude = uieditfield(app.GridLayout73_2, 'numeric');
            app.RecAmplitude.Limits = [0 100];
            app.FrequencyHzEditField_3Label_5 = uilabel(app.GridLayout73_2);
            app.FrequencyHzEditField_3Label_5.Text = 'Frequency (Hz)';
            app.RecFrequency = uieditfield(app.GridLayout73_2, 'numeric');
            app.RecFrequency.Limits = [0 100];
            app.PositionControlPanel = uipanel(app.GridLayout58);
            app.PositionControlPanel.Title = 'Position Control';
            app.GridLayout68 = uigridlayout(app.PositionControlPanel);
            app.pos_ctrl_plot = uiaxes(app.GridLayout68);
            title(app.pos_ctrl_plot, 'Blue - Referenece | Red - Actual')
            xlabel(app.pos_ctrl_plot, 'Time')
            ylabel(app.pos_ctrl_plot, 'Angular (rad)')
            zlabel(app.pos_ctrl_plot, 'Z')
            app.TabGroup5 = uitabgroup(app.GridLayout68);
            app.StepTab_2 = uitab(app.TabGroup5);
            app.StepTab_2.Title = 'Step';
            app.GridLayout75 = uigridlayout(app.StepTab_2);
            app.pos_step_start = uibutton(app.GridLayout75, 'push');
            app.pos_step_start.ButtonPushedFcn = createCallbackFcn(app, @pos_step_startPushed, true);
            app.pos_step_start.Text = 'Start';
            app.pos_step_stop = uibutton(app.GridLayout75, 'push');
            app.pos_step_stop.ButtonPushedFcn = createCallbackFcn(app, @pos_step_stopPushed, true);
            app.pos_step_stop.Text = 'Stop';
            app.ReferenceLabel = uilabel(app.GridLayout75);
            app.ReferenceLabel.Text = {'Target Position '; '(rad)'};
            app.pos_ref = uieditfield(app.GridLayout75, 'numeric');
            app.SinusoidalTab_2 = uitab(app.TabGroup5);
            app.SinusoidalTab_2.Title = 'Sinusoidal';
            app.GridLayout74 = uigridlayout(app.SinusoidalTab_2);
            app.pos_sine_start = uibutton(app.GridLayout74, 'push');
            app.pos_sine_start.ButtonPushedFcn = createCallbackFcn(app, @pos_sine_startButtonPushed, true);
            app.pos_sine_start.Text = 'Start';
            app.pos_sine_stop = uibutton(app.GridLayout74, 'push');
            app.pos_sine_stop.ButtonPushedFcn = createCallbackFcn(app, @pos_sine_stopPushed, true);
            app.pos_sine_stop.Text = 'Stop';
            app.AmplituderadEditField_4Label = uilabel(app.GridLayout74);
            app.AmplituderadEditField_4Label.Text = 'Amplitude (rad)';
            app.AmplituderadEditField_4 = uieditfield(app.GridLayout74, 'numeric');
            app.FrequencyHzEditField_6Label = uilabel(app.GridLayout74);
            app.FrequencyHzEditField_6Label.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField_6 = uieditfield(app.GridLayout74, 'numeric');
            app.RectangleTab_2 = uitab(app.TabGroup5);
            app.RectangleTab_2.Title = 'Rectangle';
            app.GridLayout74_2 = uigridlayout(app.RectangleTab_2);
            app.pos_rec_start = uibutton(app.GridLayout74_2, 'push');
            app.pos_rec_start.ButtonPushedFcn = createCallbackFcn(app, @pos_rec_startButtonPushed, true);
            app.pos_rec_start.Text = 'Start';
            app.pos_rec_stop = uibutton(app.GridLayout74_2, 'push');
            app.pos_rec_stop.ButtonPushedFcn = createCallbackFcn(app, @pos_rec_stopButtonPushed, true);
            app.pos_rec_stop.Text = 'Stop';
            app.AmplituderadEditField_4Label_2 = uilabel(app.GridLayout74_2);
            app.AmplituderadEditField_4Label_2.Text = 'Amplitude (rad)';
            app.Pos_Rec_Amp = uieditfield(app.GridLayout74_2, 'numeric');
            app.FrequencyHzEditField_4Label = uilabel(app.GridLayout74_2);
            app.FrequencyHzEditField_4Label.Text = 'Frequency (Hz)';
            app.Pos_Rec_Freq = uieditfield(app.GridLayout74_2, 'numeric');
            app.FeedforwardTab = uitab(app.TabGroup2_4);
            app.FeedforwardTab.Title = 'Feedforward';
            app.GridLayout141 = uigridlayout(app.FeedforwardTab);
            app.FeedforwardControllerPanel = uipanel(app.GridLayout141);
            app.FeedforwardControllerPanel.Title = 'Feedforward Controller';
            app.GridLayout143 = uigridlayout(app.FeedforwardControllerPanel);
            app.GridLayout145 = uigridlayout(app.GridLayout143);
            app.InertiaJunitAs2Label_5 = uilabel(app.GridLayout145);
            app.InertiaJunitAs2Label_5.Text = 'Inertia (J) (unit: As^2)';
            app.DampingBunitAsLabel_5 = uilabel(app.GridLayout145);
            app.DampingBunitAsLabel_5.Text = 'Damping (B) (unit: As)';
            app.FeedforwardDesignOnforImpedanceControllerButton = uibutton(app.GridLayout145, 'push');
            app.FeedforwardDesignOnforImpedanceControllerButton.ButtonPushedFcn = createCallbackFcn(app, @FeedforwardDesignOnforImpedanceControllerButtonPushed, true);
            app.FeedforwardDesignOnforImpedanceControllerButton.Text = {'Feedforward Design & On '; '(for Impedance Controller)'};
            app.GridLayout150 = uigridlayout(app.GridLayout143);
            app.ff_mag_plot = uiaxes(app.GridLayout150);
            title(app.ff_mag_plot, 'Title')
            xlabel(app.ff_mag_plot, 'X')
            ylabel(app.ff_mag_plot, 'Y')
            zlabel(app.ff_mag_plot, 'Z')
            app.ff_phase_plot = uiaxes(app.GridLayout150);
            title(app.ff_phase_plot, 'Title')
            xlabel(app.ff_phase_plot, 'X')
            ylabel(app.ff_phase_plot, 'Y')
            zlabel(app.ff_phase_plot, 'Z')
            app.FeedforwardforPDControllerPanel = uipanel(app.GridLayout141);
            app.FeedforwardforPDControllerPanel.Title = 'Feedforward for PD Controller';
            app.GridLayout142 = uigridlayout(app.FeedforwardforPDControllerPanel);
            app.GridLayout144 = uigridlayout(app.GridLayout142);
            app.ff_plot = uiaxes(app.GridLayout144);
            title(app.ff_plot, 'Title')
            xlabel(app.ff_plot, 'X')
            ylabel(app.ff_plot, 'Y')
            zlabel(app.ff_plot, 'Z')
            app.SineFrequencyEditFieldLabel = uilabel(app.GridLayout144);
            app.SineFrequencyEditFieldLabel.Text = 'Sine Frequency';
            app.SineFrequencyEditField = uieditfield(app.GridLayout144, 'numeric');
            app.SineMagnitudeEditFieldLabel = uilabel(app.GridLayout144);
            app.SineMagnitudeEditFieldLabel.Text = 'Sine Magnitude';
            app.SineMagnitudeEditField = uieditfield(app.GridLayout144, 'numeric');
            app.FF_StartButton = uibutton(app.GridLayout144, 'push');
            app.FF_StartButton.ButtonPushedFcn = createCallbackFcn(app, @FF_StartButtonPushed, true);
            app.FF_StartButton.Text = 'Start';
            app.FF_StopButton = uibutton(app.GridLayout144, 'push');
            app.FF_StopButton.ButtonPushedFcn = createCallbackFcn(app, @FF_StopButtonPushed, true);
            app.FF_StopButton.Text = 'Stop';
            app.VerificationTab = uitab(app.TabGroup2_4);
            app.VerificationTab.Title = 'Verification';
            app.GridLayout118_2 = uigridlayout(app.VerificationTab);
            app.Plot1Panel = uipanel(app.GridLayout118_2);
            app.Plot1Panel.Title = 'Plot 1';
            app.GridLayout152 = uigridlayout(app.Plot1Panel);
            app.total_plot1 = uiaxes(app.GridLayout152);
            title(app.total_plot1, 'Title')
            xlabel(app.total_plot1, 'X')
            ylabel(app.total_plot1, 'Y')
            zlabel(app.total_plot1, 'Z')
            app.PlotButton = uibutton(app.GridLayout152, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ReferenceCheckBox = uicheckbox(app.GridLayout152);
            app.ReferenceCheckBox.Text = 'Reference';
            app.PositionCheckBox = uicheckbox(app.GridLayout152);
            app.PositionCheckBox.Text = 'Position';
            app.VelocityCheckBox = uicheckbox(app.GridLayout152);
            app.VelocityCheckBox.Text = 'Velocity';
            app.ErrorCheckBox = uicheckbox(app.GridLayout152);
            app.ErrorCheckBox.Text = 'Error';
            app.MidCtrlInputCheckBox = uicheckbox(app.GridLayout152);
            app.MidCtrlInputCheckBox.Text = 'Mid Ctrl Input';
            app.DisturbanceCheckBox = uicheckbox(app.GridLayout152);
            app.DisturbanceCheckBox.Text = 'Disturbance';
            app.TotalVerification_StartButton = uibutton(app.GridLayout118_2, 'push');
            app.TotalVerification_StartButton.ButtonPushedFcn = createCallbackFcn(app, @TotalVerification_StartButtonPushed, true);
            app.TotalVerification_StartButton.Text = 'Start';
            app.TotalVerification_StopButton = uibutton(app.GridLayout118_2, 'push');
            app.TotalVerification_StopButton.ButtonPushedFcn = createCallbackFcn(app, @TotalVerification_StopButtonPushed, true);
            app.TotalVerification_StopButton.Text = 'Stop';
            app.Plot2Panel = uipanel(app.GridLayout118_2);
            app.Plot2Panel.Title = 'Plot 2';
            app.GridLayout153 = uigridlayout(app.Plot2Panel);
            app.total_plot2 = uiaxes(app.GridLayout153);
            title(app.total_plot2, 'Title')
            xlabel(app.total_plot2, 'X')
            ylabel(app.total_plot2, 'Y')
            zlabel(app.total_plot2, 'Z')
            app.PlotButton_2 = uibutton(app.GridLayout153, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.ReferenceCheckBox_2 = uicheckbox(app.GridLayout153);
            app.ReferenceCheckBox_2.Text = 'Reference';
            app.PositionCheckBox_2 = uicheckbox(app.GridLayout153);
            app.PositionCheckBox_2.Text = 'Position';
            app.VelocityCheckBox_2 = uicheckbox(app.GridLayout153);
            app.VelocityCheckBox_2.Text = 'Velocity';
            app.ErrorCheckBox_2 = uicheckbox(app.GridLayout153);
            app.ErrorCheckBox_2.Text = 'Error';
            app.MidCtrlInputCheckBox_2 = uicheckbox(app.GridLayout153);
            app.MidCtrlInputCheckBox_2.Text = 'Mid Ctrl Input';
            app.DisturbanceCheckBox_2 = uicheckbox(app.GridLayout153);
            app.DisturbanceCheckBox_2.Text = 'Disturbance';
            app.AmplituderadEditField_5Label = uilabel(app.GridLayout118_2);
            app.AmplituderadEditField_5Label.Text = 'Amplitude (rad)';
            app.AmplituderadEditField_5 = uieditfield(app.GridLayout118_2, 'numeric');
            app.FrequencyHzEditField_7Label_2 = uilabel(app.GridLayout118_2);
            app.FrequencyHzEditField_7Label_2.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField_7 = uieditfield(app.GridLayout118_2, 'numeric');
            app.Panel5 = uipanel(app.GridLayout118_2);
            app.Panel5.Title = 'Panel5';
            app.GridLayout154 = uigridlayout(app.Panel5);
            app.ApplyButton_3 = uibutton(app.GridLayout154, 'push');
            app.ApplyButton_3.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_3Pushed, true);
            app.ApplyButton_3.Text = 'Apply';
            app.MidlevelControllerSaturationEditField_2Label = uilabel(app.GridLayout154);
            app.MidlevelControllerSaturationEditField_2Label.Text = 'Mid level Controller Saturation';
            app.MidlevelControllerSaturationEditField_2 = uieditfield(app.GridLayout154, 'numeric');
            app.MidlevelControllerSaturationEditField_2.Limits = [1 14.5];
            app.MidlevelControllerSaturationEditField_2.Value = 7;
            app.Image9_2 = uiimage(app.GridLayout154);
            app.Image9_2.ImageSource = fullfile(pathToMLAPP, 'Images', 'MD_GUI_FIGURE_CONTROLLER_OVERVIEW.png');
            app.FeedforwardControllerCheckBox_2 = uicheckbox(app.GridLayout154);
            app.FeedforwardControllerCheckBox_2.Text = 'Feedforward Controller';
            app.MechanicalImpedanceCompensatorCheckBox_2 = uicheckbox(app.GridLayout154);
            app.MechanicalImpedanceCompensatorCheckBox_2.Text = 'Mechanical Impedance Compensator';
            app.DisturbanceObserverCheckBox_2 = uicheckbox(app.GridLayout154);
            app.DisturbanceObserverCheckBox_2.Text = 'Disturbance Observer';
            app.ImpedanceControllerCheckBox_2 = uicheckbox(app.GridLayout154);
            app.ImpedanceControllerCheckBox_2.Text = 'Impedance Controller';
            app.PDControllerCheckBox_2 = uicheckbox(app.GridLayout154);
            app.PDControllerCheckBox_2.Text = 'PD Controller';
            app.AdditionalFunctionTab = uitab(app.TabGroup);
            app.AdditionalFunctionTab.Title = 'Additional Function';
            app.GridLayout119 = uigridlayout(app.AdditionalFunctionTab);
            app.TabGroup7 = uitabgroup(app.GridLayout119);
            app.VSDTab = uitab(app.TabGroup7);
            app.VSDTab.Title = 'VSD';
            app.GridLayout137 = uigridlayout(app.VSDTab);
            app.VirtualSpringDamperParametersPanel = uipanel(app.GridLayout137);
            app.VirtualSpringDamperParametersPanel.Title = 'Virtual Spring-Damper Parameters';
            app.GridLayout139 = uigridlayout(app.VirtualSpringDamperParametersPanel);
            app.LowerStiffnessAdegEditFieldLabel = uilabel(app.GridLayout139);
            app.LowerStiffnessAdegEditFieldLabel.Text = 'Lower Stiffness (A/deg)';
            app.LowerStiffnessAdegEditField = uieditfield(app.GridLayout139, 'numeric');
            app.LowerStiffnessAdegEditField.Limits = [0 Inf];
            app.UpperStiffnessAdegEditFieldLabel = uilabel(app.GridLayout139);
            app.UpperStiffnessAdegEditFieldLabel.Text = 'Upper Stiffness (A/deg)';
            app.UpperStiffnessAdegEditField = uieditfield(app.GridLayout139, 'numeric');
            app.UpperStiffnessAdegEditField.Limits = [0 Inf];
            app.LowerDamperAsdegEditFieldLabel = uilabel(app.GridLayout139);
            app.LowerDamperAsdegEditFieldLabel.Text = 'Lower Damper (As/deg)';
            app.LowerDamperAsdegEditField = uieditfield(app.GridLayout139, 'numeric');
            app.LowerDamperAsdegEditField.Limits = [0 Inf];
            app.UpperDamperAsdegEditFieldLabel = uilabel(app.GridLayout139);
            app.UpperDamperAsdegEditFieldLabel.Text = 'Upper Damper (As/deg)';
            app.UpperDamperAsdegEditField = uieditfield(app.GridLayout139, 'numeric');
            app.UpperDamperAsdegEditField.Limits = [0 Inf];
            app.LowerDampedRangedegEditFieldLabel = uilabel(app.GridLayout139);
            app.LowerDampedRangedegEditFieldLabel.Text = 'Lower Damped Range (deg)';
            app.LowerDampedRangedegEditField = uieditfield(app.GridLayout139, 'numeric');
            app.LowerDampedRangedegEditField.Limits = [0 Inf];
            app.UpperDampedRangedegEditFieldLabel = uilabel(app.GridLayout139);
            app.UpperDampedRangedegEditFieldLabel.Text = 'Upper Damped Range (deg)';
            app.UpperDampedRangedegEditField = uieditfield(app.GridLayout139, 'numeric');
            app.UpperDampedRangedegEditField.Limits = [0 Inf];
            app.LowerLimitdegEditFieldLabel = uilabel(app.GridLayout139);
            app.LowerLimitdegEditFieldLabel.Text = 'Lower Limit (deg)';
            app.LowerLimitdegEditField = uieditfield(app.GridLayout139, 'numeric');
            app.LowerLimitdegEditField.Editable = 'off';
            app.UpperLimitdegEditFieldLabel = uilabel(app.GridLayout139);
            app.UpperLimitdegEditFieldLabel.Text = 'Upper Limit (deg)';
            app.UpperLimitdegEditField = uieditfield(app.GridLayout139, 'numeric');
            app.UpperLimitdegEditField.Editable = 'off';
            app.VSD_applyBtn = uibutton(app.GridLayout139, 'push');
            app.VSD_applyBtn.ButtonPushedFcn = createCallbackFcn(app, @VSD_applyBtnButtonPushed, true);
            app.VSD_applyBtn.Text = 'Apply';
            app.VSD_LowerLimit_SetBtn = uibutton(app.GridLayout139, 'push');
            app.VSD_LowerLimit_SetBtn.ButtonPushedFcn = createCallbackFcn(app, @VSD_LowerLimit_SetBtnPushed, true);
            app.VSD_LowerLimit_SetBtn.Text = 'Set';
            app.VSD_UpperLimit_SetBtn = uibutton(app.GridLayout139, 'push');
            app.VSD_UpperLimit_SetBtn.ButtonPushedFcn = createCallbackFcn(app, @VSD_UpperLimit_SetBtnButtonPushed, true);
            app.VSD_UpperLimit_SetBtn.Text = 'Set';
            app.VSD_StartBtn = uibutton(app.GridLayout139, 'push');
            app.VSD_StartBtn.ButtonPushedFcn = createCallbackFcn(app, @VSD_StartBtnButtonPushed, true);
            app.VSD_StartBtn.Text = 'Start';
            app.VSD_StopBtn = uibutton(app.GridLayout139, 'push');
            app.VSD_StopBtn.ButtonPushedFcn = createCallbackFcn(app, @VSD_StopBtnButtonPushed, true);
            app.VSD_StopBtn.Text = 'Stop';
            app.SaturationAEditField_2Label = uilabel(app.GridLayout139);
            app.SaturationAEditField_2Label.Text = 'Saturation (A)';
            app.SaturationAEditField_2 = uieditfield(app.GridLayout139, 'numeric');
            app.VSD_SaturationSetBtn = uibutton(app.GridLayout139, 'push');
            app.VSD_SaturationSetBtn.ButtonPushedFcn = createCallbackFcn(app, @VSD_SaturationSetBtnButtonPushed, true);
            app.VSD_SaturationSetBtn.Text = 'Set';
            app.LowerStiffRangedegEditFieldLabel = uilabel(app.GridLayout139);
            app.LowerStiffRangedegEditFieldLabel.Text = 'Lower Stiff Range (deg)';
            app.LowerStiffRangedegEditField = uieditfield(app.GridLayout139, 'numeric');
            app.LowerStiffRangedegEditField.Limits = [0 Inf];
            app.UpperStiffRangedegEditFieldLabel = uilabel(app.GridLayout139);
            app.UpperStiffRangedegEditFieldLabel.Text = 'Upper Stiff Range (deg)';
            app.UpperStiffRangedegEditField = uieditfield(app.GridLayout139, 'numeric');
            app.UpperStiffRangedegEditField.Limits = [0 Inf];
            app.VerificationPlotPanel = uipanel(app.GridLayout137);
            app.VerificationPlotPanel.Title = 'Verification Plot';
            app.GridLayout138 = uigridlayout(app.VerificationPlotPanel);
            app.vsd_abs_position_plot = uiaxes(app.GridLayout138);
            title(app.vsd_abs_position_plot, 'Title')
            xlabel(app.vsd_abs_position_plot, 'X')
            ylabel(app.vsd_abs_position_plot, 'Y')
            zlabel(app.vsd_abs_position_plot, 'Z')
            app.vsd_input_plot = uiaxes(app.GridLayout138);
            title(app.vsd_input_plot, 'Title')
            xlabel(app.vsd_input_plot, 'X')
            ylabel(app.vsd_input_plot, 'Y')
            zlabel(app.vsd_input_plot, 'Z')
            app.VirtualSpringDamperConceptPanel = uipanel(app.GridLayout137);
            app.VirtualSpringDamperConceptPanel.Title = 'Virtual Spring-Damper Concept';
            app.GridLayout140 = uigridlayout(app.VirtualSpringDamperConceptPanel);
            app.Image10 = uiimage(app.GridLayout140);
            app.Image10.ImageSource = fullfile(pathToMLAPP, 'Images', 'MD_GUI_FIGURE_VSD.png');
            app.BacklashTab = uitab(app.TabGroup7);
            app.BacklashTab.Title = 'Backlash';
            app.GridLayout120 = uigridlayout(app.BacklashTab);
            app.Image7 = uiimage(app.GridLayout120);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'Images', 'MD_GUI_FIGURE_BACKLASH.png');
            app.PositionSineReferenceSettingPanel = uipanel(app.GridLayout120);
            app.PositionSineReferenceSettingPanel.Title = 'Position Sine Reference Setting';
            app.GridLayout83_3 = uigridlayout(app.PositionSineReferenceSettingPanel);
            app.Backlash_test_plot = uiaxes(app.GridLayout83_3);
            title(app.Backlash_test_plot, 'Backlash Test Result')
            xlabel(app.Backlash_test_plot, 'Time')
            ylabel(app.Backlash_test_plot, 'Backlash (deg)')
            zlabel(app.Backlash_test_plot, 'Z')
            app.MaxBacklashdegLabel = uilabel(app.GridLayout83_3);
            app.MaxBacklashdegLabel.Text = 'Max Backlash (deg)';
            app.MaxBacklashdegEditField = uieditfield(app.GridLayout83_3, 'numeric');
            app.BacklashTest_StartButton = uibutton(app.GridLayout83_3, 'push');
            app.BacklashTest_StartButton.ButtonPushedFcn = createCallbackFcn(app, @BacklashTest_StartButtonPushed, true);
            app.BacklashTest_StartButton.Text = 'Start';
            app.BacklashTest_StopButton = uibutton(app.GridLayout83_3, 'push');
            app.BacklashTest_StopButton.ButtonPushedFcn = createCallbackFcn(app, @BacklashTest_StopButtonPushed, true);
            app.BacklashTest_StopButton.Text = 'Stop';
            app.PFITestTab = uitab(app.TabGroup7);
            app.PFITestTab.Title = 'P/F/I Test';
            app.GridLayout163 = uigridlayout(app.PFITestTab);
            app.GridLayout164 = uigridlayout(app.GridLayout163);
            app.SetIVectorPanel = uipanel(app.GridLayout164);
            app.SetIVectorPanel.Title = 'Set I Vector';
            app.GridLayout165 = uigridlayout(app.SetIVectorPanel);
            app.IvectorPlot = uiaxes(app.GridLayout165);
            title(app.IvectorPlot, 'Posion Reference')
            xlabel(app.IvectorPlot, 'Time (s)')
            ylabel(app.IvectorPlot, 'Position Reference (Deg)')
            zlabel(app.IvectorPlot, 'Z')
            app.CorridorHalfWidthdegLabel = uilabel(app.GridLayout165);
            app.CorridorHalfWidthdegLabel.Text = {'Half '; 'Width (deg)'};
            app.HalfWidthdegEditField1 = uieditfield(app.GridLayout165, 'numeric');
            app.KpLabel = uilabel(app.GridLayout165);
            app.KpLabel.Text = 'Kp (%)';
            app.KpEditField1 = uieditfield(app.GridLayout165, 'numeric');
            app.KdLabel = uilabel(app.GridLayout165);
            app.KdLabel.Text = 'Kd (%)';
            app.KdEditField1 = uieditfield(app.GridLayout165, 'numeric');
            app.lambdaEditFieldLabel = uilabel(app.GridLayout165);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField1 = uieditfield(app.GridLayout165, 'numeric');
            app.LmsEditFieldLabel = uilabel(app.GridLayout165);
            app.LmsEditFieldLabel.Text = 'L (ms)';
            app.LmsEditField1 = uieditfield(app.GridLayout165, 'numeric');
            app.Impedance_Setting = uibutton(app.GridLayout165, 'state');
            app.Impedance_Setting.ValueChangedFcn = createCallbackFcn(app, @Impedance_SettingValueChanged, true);
            app.Impedance_Setting.Text = 'Send';
            app.Impedance_Ctrl_Start = uibutton(app.GridLayout165, 'push');
            app.Impedance_Ctrl_Start.ButtonPushedFcn = createCallbackFcn(app, @Impedance_Ctrl_StartButtonPushed, true);
            app.Impedance_Ctrl_Start.Text = 'Start';
            app.Impedance_Ctrl_Stop = uibutton(app.GridLayout165, 'push');
            app.Impedance_Ctrl_Stop.ButtonPushedFcn = createCallbackFcn(app, @Impedance_Ctrl_StopButtonPushed, true);
            app.Impedance_Ctrl_Stop.Text = 'Stop';
            app.ModeIDXEditFieldLabel_14 = uilabel(app.GridLayout165);
            app.ModeIDXEditFieldLabel_14.Text = '#1';
            app.ModeIDXEditFieldLabel_15 = uilabel(app.GridLayout165);
            app.ModeIDXEditFieldLabel_15.Text = '#2';
            app.ModeIDXEditFieldLabel_16 = uilabel(app.GridLayout165);
            app.ModeIDXEditFieldLabel_16.Text = '#3';
            app.ModeIDXEditFieldLabel_17 = uilabel(app.GridLayout165);
            app.ModeIDXEditFieldLabel_17.Text = '#4';
            app.ModeIDXEditFieldLabel_18 = uilabel(app.GridLayout165);
            app.ModeIDXEditFieldLabel_18.Text = '#5';
            app.ModeIDXEditFieldLabel_19 = uilabel(app.GridLayout165);
            app.ModeIDXEditFieldLabel_19.Text = '#6';
            app.I_Vector_Checkbox1 = uicheckbox(app.GridLayout165);
            app.I_Vector_Checkbox1.Text = '';
            app.I_Vector_Checkbox2 = uicheckbox(app.GridLayout165);
            app.I_Vector_Checkbox2.Text = '';
            app.I_Vector_Checkbox3 = uicheckbox(app.GridLayout165);
            app.I_Vector_Checkbox3.Text = '';
            app.I_Vector_Checkbox4 = uicheckbox(app.GridLayout165);
            app.I_Vector_Checkbox4.Text = '';
            app.I_Vector_Checkbox5 = uicheckbox(app.GridLayout165);
            app.I_Vector_Checkbox5.Text = '';
            app.I_Vector_Checkbox6 = uicheckbox(app.GridLayout165);
            app.I_Vector_Checkbox6.Text = '';
            app.HalfWidthdegEditField2 = uieditfield(app.GridLayout165, 'numeric');
            app.KpEditField2 = uieditfield(app.GridLayout165, 'numeric');
            app.KdEditField2 = uieditfield(app.GridLayout165, 'numeric');
            app.lambdaEditField2 = uieditfield(app.GridLayout165, 'numeric');
            app.LmsEditField2 = uieditfield(app.GridLayout165, 'numeric');
            app.HalfWidthdegEditField3 = uieditfield(app.GridLayout165, 'numeric');
            app.KpEditField3 = uieditfield(app.GridLayout165, 'numeric');
            app.KdEditField3 = uieditfield(app.GridLayout165, 'numeric');
            app.lambdaEditField3 = uieditfield(app.GridLayout165, 'numeric');
            app.LmsEditField3 = uieditfield(app.GridLayout165, 'numeric');
            app.HalfWidthdegEditField4 = uieditfield(app.GridLayout165, 'numeric');
            app.KpEditField4 = uieditfield(app.GridLayout165, 'numeric');
            app.KdEditField4 = uieditfield(app.GridLayout165, 'numeric');
            app.lambdaEditField4 = uieditfield(app.GridLayout165, 'numeric');
            app.LmsEditField4 = uieditfield(app.GridLayout165, 'numeric');
            app.HalfWidthdegEditField5 = uieditfield(app.GridLayout165, 'numeric');
            app.KpEditField5 = uieditfield(app.GridLayout165, 'numeric');
            app.KdEditField5 = uieditfield(app.GridLayout165, 'numeric');
            app.lambdaEditField5 = uieditfield(app.GridLayout165, 'numeric');
            app.LmsEditField5 = uieditfield(app.GridLayout165, 'numeric');
            app.HalfWidthdegEditField6 = uieditfield(app.GridLayout165, 'numeric');
            app.KpEditField6 = uieditfield(app.GridLayout165, 'numeric');
            app.KdEditField6 = uieditfield(app.GridLayout165, 'numeric');
            app.lambdaEditField6 = uieditfield(app.GridLayout165, 'numeric');
            app.LmsEditField6 = uieditfield(app.GridLayout165, 'numeric');
            app.SetFVectorPanel = uipanel(app.GridLayout164);
            app.SetFVectorPanel.Title = 'Set F Vector';
            app.GridLayout165_2 = uigridlayout(app.SetFVectorPanel);
            app.FvectorPlot = uiaxes(app.GridLayout165_2);
            title(app.FvectorPlot, 'Current Reference')
            xlabel(app.FvectorPlot, 'Time (s)')
            ylabel(app.FvectorPlot, 'Current (A)')
            zlabel(app.FvectorPlot, 'Z')
            app.ModeIDXEditFieldLabel = uilabel(app.GridLayout165_2);
            app.ModeIDXEditFieldLabel.Text = 'Mode IDX';
            app.ModeIDXEditField = uieditfield(app.GridLayout165_2, 'numeric');
            app.MaxTorqueALabel = uilabel(app.GridLayout165_2);
            app.MaxTorqueALabel.Text = 'Max.Torque (A)';
            app.MaxTorqueAEditField = uieditfield(app.GridLayout165_2, 'numeric');
            app.DelaymsEditFieldLabel = uilabel(app.GridLayout165_2);
            app.DelaymsEditFieldLabel.Text = 'Delay (ms)';
            app.DelaymsEditField = uieditfield(app.GridLayout165_2, 'numeric');
            app.F_Vector_Ev_Start = uibutton(app.GridLayout165_2, 'push');
            app.F_Vector_Ev_Start.ButtonPushedFcn = createCallbackFcn(app, @F_Vector_Ev_StartButtonPushed, true);
            app.F_Vector_Ev_Start.Text = 'Start';
            app.F_Vector_Ev_Stop = uibutton(app.GridLayout165_2, 'push');
            app.F_Vector_Ev_Stop.ButtonPushedFcn = createCallbackFcn(app, @F_Vector_Ev_StopButtonPushed, true);
            app.F_Vector_Ev_Stop.Text = 'Stop';
            app.F_Vector_Send = uibutton(app.GridLayout165_2, 'state');
            app.F_Vector_Send.ValueChangedFcn = createCallbackFcn(app, @F_Vector_SendValueChanged, true);
            app.F_Vector_Send.Text = 'Send';
            app.ModeIDXEditFieldLabel_2 = uilabel(app.GridLayout165_2);
            app.ModeIDXEditFieldLabel_2.Text = '#1';
            app.ModeIDXEditFieldLabel_3 = uilabel(app.GridLayout165_2);
            app.ModeIDXEditFieldLabel_3.Text = '#2';
            app.CheckBox = uicheckbox(app.GridLayout165_2);
            app.CheckBox.Text = '';
            app.ModeIDXEditField_2 = uieditfield(app.GridLayout165_2, 'numeric');
            app.MaxTorqueAEditField_2 = uieditfield(app.GridLayout165_2, 'numeric');
            app.DelayEditField_2 = uieditfield(app.GridLayout165_2, 'numeric');
            app.ModeIDXEditField_3 = uieditfield(app.GridLayout165_2, 'numeric');
            app.MaxTorqueAEditField_3 = uieditfield(app.GridLayout165_2, 'numeric');
            app.DelayEditField_3 = uieditfield(app.GridLayout165_2, 'numeric');
            app.CheckBox_2 = uicheckbox(app.GridLayout165_2);
            app.CheckBox_2.Text = '';
            app.ModeIDXEditFieldLabel_4 = uilabel(app.GridLayout165_2);
            app.ModeIDXEditFieldLabel_4.Text = '#3';
            app.ModeIDXEditField_4 = uieditfield(app.GridLayout165_2, 'numeric');
            app.MaxTorqueAEditField_4 = uieditfield(app.GridLayout165_2, 'numeric');
            app.DelayEditField_4 = uieditfield(app.GridLayout165_2, 'numeric');
            app.ModeIDXEditField_5 = uieditfield(app.GridLayout165_2, 'numeric');
            app.MaxTorqueAEditField_5 = uieditfield(app.GridLayout165_2, 'numeric');
            app.DelayEditField_5 = uieditfield(app.GridLayout165_2, 'numeric');
            app.ModeIDXEditField_6 = uieditfield(app.GridLayout165_2, 'numeric');
            app.MaxTorqueAEditField_6 = uieditfield(app.GridLayout165_2, 'numeric');
            app.DelayEditField_6 = uieditfield(app.GridLayout165_2, 'numeric');
            app.CheckBox_3 = uicheckbox(app.GridLayout165_2);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.GridLayout165_2);
            app.CheckBox_4.Text = '';
            app.CheckBox_5 = uicheckbox(app.GridLayout165_2);
            app.CheckBox_5.Text = '';
            app.CheckBox_6 = uicheckbox(app.GridLayout165_2);
            app.CheckBox_6.Text = '';
            app.ModeIDXEditFieldLabel_5 = uilabel(app.GridLayout165_2);
            app.ModeIDXEditFieldLabel_5.Text = '#4';
            app.ModeIDXEditFieldLabel_6 = uilabel(app.GridLayout165_2);
            app.ModeIDXEditFieldLabel_6.Text = '#5';
            app.ModeIDXEditFieldLabel_7 = uilabel(app.GridLayout165_2);
            app.ModeIDXEditFieldLabel_7.Text = '#6';
            app.F_Vector_Ev_Controller_ON = uicheckbox(app.GridLayout165_2);
            app.F_Vector_Ev_Controller_ON.Text = 'Controll ON';
            app.SetPVectorPanel = uipanel(app.GridLayout164);
            app.SetPVectorPanel.Title = 'Set P Vector';
            app.GridLayout165_3 = uigridlayout(app.SetPVectorPanel);
            app.PvectorPlot = uiaxes(app.GridLayout165_3);
            title(app.PvectorPlot, 'Posion Reference')
            xlabel(app.PvectorPlot, 'Time (s)')
            ylabel(app.PvectorPlot, 'Position (Deg)')
            zlabel(app.PvectorPlot, 'Z')
            app.YddegEditFieldLabel = uilabel(app.GridLayout165_3);
            app.YddegEditFieldLabel.Text = 'Yd (deg)';
            app.P_Vector_Yd1 = uieditfield(app.GridLayout165_3, 'numeric');
            app.S0EditFieldLabel = uilabel(app.GridLayout165_3);
            app.S0EditFieldLabel.Text = 'S0';
            app.P_Vector_S01 = uieditfield(app.GridLayout165_3, 'numeric');
            app.SdEditFieldLabel = uilabel(app.GridLayout165_3);
            app.SdEditFieldLabel.Text = 'Sd';
            app.P_Vector_Sd1 = uieditfield(app.GridLayout165_3, 'numeric');
            app.P_Vector_Ev_Start = uibutton(app.GridLayout165_3, 'push');
            app.P_Vector_Ev_Start.ButtonPushedFcn = createCallbackFcn(app, @P_Vector_Ev_StartButtonPushed, true);
            app.P_Vector_Ev_Start.Text = 'Start';
            app.P_Vector_Ev_Stop = uibutton(app.GridLayout165_3, 'push');
            app.P_Vector_Ev_Stop.ButtonPushedFcn = createCallbackFcn(app, @P_Vector_Ev_StopButtonPushed, true);
            app.P_Vector_Ev_Stop.Text = 'Stop';
            app.P_Vector_Send = uibutton(app.GridLayout165_3, 'state');
            app.P_Vector_Send.ValueChangedFcn = createCallbackFcn(app, @P_Vector_SendValueChanged, true);
            app.P_Vector_Send.Text = 'Send';
            app.ModeIDXEditFieldLabel_8 = uilabel(app.GridLayout165_3);
            app.ModeIDXEditFieldLabel_8.Text = '#1';
            app.ModeIDXEditFieldLabel_9 = uilabel(app.GridLayout165_3);
            app.ModeIDXEditFieldLabel_9.Text = '#2';
            app.P_Vector_Checkbox1 = uicheckbox(app.GridLayout165_3);
            app.P_Vector_Checkbox1.Text = '';
            app.P_Vector_Yd2 = uieditfield(app.GridLayout165_3, 'numeric');
            app.P_Vector_S02 = uieditfield(app.GridLayout165_3, 'numeric');
            app.P_Vector_Sd2 = uieditfield(app.GridLayout165_3, 'numeric');
            app.P_Vector_Yd3 = uieditfield(app.GridLayout165_3, 'numeric');
            app.P_Vector_S03 = uieditfield(app.GridLayout165_3, 'numeric');
            app.P_Vector_Sd3 = uieditfield(app.GridLayout165_3, 'numeric');
            app.P_Vector_Checkbox2 = uicheckbox(app.GridLayout165_3);
            app.P_Vector_Checkbox2.Text = '';
            app.ModeIDXEditFieldLabel_10 = uilabel(app.GridLayout165_3);
            app.ModeIDXEditFieldLabel_10.Text = '#3';
            app.P_Vector_Yd4 = uieditfield(app.GridLayout165_3, 'numeric');
            app.P_Vector_S04 = uieditfield(app.GridLayout165_3, 'numeric');
            app.P_Vector_Sd4 = uieditfield(app.GridLayout165_3, 'numeric');
            app.P_Vector_Yd5 = uieditfield(app.GridLayout165_3, 'numeric');
            app.P_Vector_S05 = uieditfield(app.GridLayout165_3, 'numeric');
            app.P_Vector_Sd5 = uieditfield(app.GridLayout165_3, 'numeric');
            app.P_Vector_Yd6 = uieditfield(app.GridLayout165_3, 'numeric');
            app.P_Vector_S06 = uieditfield(app.GridLayout165_3, 'numeric');
            app.P_Vector_Sd6 = uieditfield(app.GridLayout165_3, 'numeric');
            app.P_Vector_Checkbox3 = uicheckbox(app.GridLayout165_3);
            app.P_Vector_Checkbox3.Text = '';
            app.P_Vector_Checkbox4 = uicheckbox(app.GridLayout165_3);
            app.P_Vector_Checkbox4.Text = '';
            app.P_Vector_Checkbox5 = uicheckbox(app.GridLayout165_3);
            app.P_Vector_Checkbox5.Text = '';
            app.P_Vector_Checkbox6 = uicheckbox(app.GridLayout165_3);
            app.P_Vector_Checkbox6.Text = '';
            app.ModeIDXEditFieldLabel_11 = uilabel(app.GridLayout165_3);
            app.ModeIDXEditFieldLabel_11.Text = '#4';
            app.ModeIDXEditFieldLabel_12 = uilabel(app.GridLayout165_3);
            app.ModeIDXEditFieldLabel_12.Text = '#5';
            app.ModeIDXEditFieldLabel_13 = uilabel(app.GridLayout165_3);
            app.ModeIDXEditFieldLabel_13.Text = '#6';
            app.P_Vector_Ev_Controller_ON = uicheckbox(app.GridLayout165_3);
            app.P_Vector_Ev_Controller_ON.Text = 'Controll ON';
            app.P_Vector_L1 = uieditfield(app.GridLayout165_3, 'numeric');
            app.P_Vector_L2 = uieditfield(app.GridLayout165_3, 'numeric');
            app.P_Vector_L3 = uieditfield(app.GridLayout165_3, 'numeric');
            app.P_Vector_L4 = uieditfield(app.GridLayout165_3, 'numeric');
            app.P_Vector_L5 = uieditfield(app.GridLayout165_3, 'numeric');
            app.P_Vector_L6 = uieditfield(app.GridLayout165_3, 'numeric');
            app.YddegEditFieldLabel_2 = uilabel(app.GridLayout165_3);
            app.YddegEditFieldLabel_2.Text = 'L (ms)';
            app.WIDMTab = uitab(app.TabGroup7);
            app.WIDMTab.Title = 'WIDM';
            app.GridLayout159 = uigridlayout(app.WIDMTab);
            app.GridLayout160 = uigridlayout(app.GridLayout159);
            app.GetACCGYROPanel = uipanel(app.GridLayout160);
            app.GetACCGYROPanel.Title = 'Get ACC & GYRO';
            app.GridLayout161 = uigridlayout(app.GetACCGYROPanel);
            app.ACC_Plot = uiaxes(app.GridLayout161);
            title(app.ACC_Plot, 'Acceleration (x: Red | Y: Green | Z: Blue)')
            xlabel(app.ACC_Plot, 'Time')
            ylabel(app.ACC_Plot, 'Acceleration (m/s2)')
            zlabel(app.ACC_Plot, 'Z')
            app.GYR_Plot = uiaxes(app.GridLayout161);
            title(app.GYR_Plot, 'Gyro (x: Red | Y: Green | Z: Blue)')
            xlabel(app.GYR_Plot, 'Time')
            ylabel(app.GYR_Plot, 'Velocity (m/s)')
            zlabel(app.GYR_Plot, 'Z')
            app.Get_ACCGYR_Start = uibutton(app.GridLayout161, 'push');
            app.Get_ACCGYR_Start.ButtonPushedFcn = createCallbackFcn(app, @Get_ACCGYR_StartPushed, true);
            app.Get_ACCGYR_Start.Text = 'Start';
            app.Get_ACCGYR_Stop = uibutton(app.GridLayout161, 'push');
            app.Get_ACCGYR_Stop.ButtonPushedFcn = createCallbackFcn(app, @Get_ACCGYR_StopPushed, true);
            app.Get_ACCGYR_Stop.Text = 'Plot';
            app.GetGaitPhasePanel = uipanel(app.GridLayout160);
            app.GetGaitPhasePanel.Title = 'Get Gait Phase';
            app.GridLayout162 = uigridlayout(app.GetGaitPhasePanel);
            app.GaitPhase_Plot = uiaxes(app.GridLayout162);
            title(app.GaitPhase_Plot, 'Gait Phase')
            xlabel(app.GaitPhase_Plot, 'Time')
            ylabel(app.GaitPhase_Plot, 'Gait Phase')
            zlabel(app.GaitPhase_Plot, 'Z')
            app.Degree_Plot = uiaxes(app.GridLayout162);
            title(app.Degree_Plot, 'Estimated Degree')
            xlabel(app.Degree_Plot, 'Time')
            ylabel(app.Degree_Plot, 'Degree (deg)')
            zlabel(app.Degree_Plot, 'Z')
            app.GaitPhase_Start = uibutton(app.GridLayout162, 'push');
            app.GaitPhase_Start.ButtonPushedFcn = createCallbackFcn(app, @GaitPhase_StartPushed, true);
            app.GaitPhase_Start.Text = 'Start';
            app.GaitPhase_Stop = uibutton(app.GridLayout162, 'push');
            app.GaitPhase_Stop.ButtonPushedFcn = createCallbackFcn(app, @GaitPhase_StopButtonPushed, true);
            app.GaitPhase_Stop.Text = 'Plot';
            app.DEG_ACCCheckBox = uicheckbox(app.GridLayout162);
            app.DEG_ACCCheckBox.Text = 'DEG_ACC';
            app.DEG_GYRCheckBox = uicheckbox(app.GridLayout162);
            app.DEG_GYRCheckBox.Text = 'DEG_GYR';
            app.DEG_ACC_FCheckBox = uicheckbox(app.GridLayout162);
            app.DEG_ACC_FCheckBox.Text = 'DEG_ACC_F';
            app.DEG_GYR_FCheckBox = uicheckbox(app.GridLayout162);
            app.DEG_GYR_FCheckBox.Text = {'DEG_GYR_F'; ''};
            app.DEG_TVCFCheckBox = uicheckbox(app.GridLayout162);
            app.DEG_TVCFCheckBox.Text = 'DEG_TVCF';
            app.QuaternionTab = uitab(app.TabGroup7);
            app.QuaternionTab.Title = 'Quaternion';
            app.GridLayout160_2 = uigridlayout(app.QuaternionTab);
            app.GetACCGYROMAGPanel = uipanel(app.GridLayout160_2);
            app.GetACCGYROMAGPanel.Title = 'Get ACC & GYRO & MAG';
            app.GridLayout161_2 = uigridlayout(app.GetACCGYROMAGPanel);
            app.Plot_Quaternion_Accel = uiaxes(app.GridLayout161_2);
            title(app.Plot_Quaternion_Accel, 'Accelerometer')
            xlabel(app.Plot_Quaternion_Accel, 'Time (s)')
            ylabel(app.Plot_Quaternion_Accel, 'ACC')
            zlabel(app.Plot_Quaternion_Accel, 'Z')
            app.Plot_Quaternion_Gyro = uiaxes(app.GridLayout161_2);
            title(app.Plot_Quaternion_Gyro, 'Gyroscope')
            xlabel(app.Plot_Quaternion_Gyro, 'Time (s)')
            ylabel(app.Plot_Quaternion_Gyro, 'GYR')
            zlabel(app.Plot_Quaternion_Gyro, 'Z')
            app.Plot_Quaternion = uiaxes(app.GridLayout161_2);
            title(app.Plot_Quaternion, 'Quaternion')
            xlabel(app.Plot_Quaternion, 'Time (s)')
            ylabel(app.Plot_Quaternion, 'quaternion')
            zlabel(app.Plot_Quaternion, 'Z')
            app.Plot_Quaternion.ButtonDownFcn = createCallbackFcn(app, @Plot_QuaternionButtonDown, true);
            app.Plot_Quaternion_Mag = uiaxes(app.GridLayout161_2);
            title(app.Plot_Quaternion_Mag, 'Magnetometer')
            xlabel(app.Plot_Quaternion_Mag, 'Time (s)')
            ylabel(app.Plot_Quaternion_Mag, 'MAG')
            zlabel(app.Plot_Quaternion_Mag, 'Z')
            app.Get_ACCGYR_Start_2 = uibutton(app.GridLayout161_2, 'push');
            app.Get_ACCGYR_Start_2.ButtonPushedFcn = createCallbackFcn(app, @Get_ACCGYR_Start_2ButtonPushed, true);
            app.Get_ACCGYR_Start_2.Text = 'Start';
            app.Get_ACCGYR_Stop_2 = uibutton(app.GridLayout161_2, 'push');
            app.Get_ACCGYR_Stop_2.ButtonPushedFcn = createCallbackFcn(app, @Get_ACCGYR_Stop_2ButtonPushed, true);
            app.Get_ACCGYR_Stop_2.Text = 'Plot';
            app.ApplyButton_7 = uibutton(app.GridLayout161_2, 'push');
            app.ApplyButton_7.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_7Pushed, true);
            app.ApplyButton_7.Text = 'Apply';
            app.FileSaveNametxtEditField_3Label = uilabel(app.GridLayout161_2);
            app.FileSaveNametxtEditField_3Label.Text = 'File Save Name (.txt)';
            app.FileSaveNametxtEditField_3 = uieditfield(app.GridLayout161_2, 'text');
            app.ElecAngleDebuggingTab = uitab(app.TabGroup7);
            app.ElecAngleDebuggingTab.Title = 'Elec Angle Debugging';
            app.pos_sine_start_2 = uibutton(app.ElecAngleDebuggingTab, 'push');
            app.pos_sine_start_2.ButtonPushedFcn = createCallbackFcn(app, @pos_sine_start_2ButtonPushed, true);
            app.pos_sine_start_2.Text = 'Start';
            app.pos_sine_stop_2 = uibutton(app.ElecAngleDebuggingTab, 'push');
            app.pos_sine_stop_2.ButtonPushedFcn = createCallbackFcn(app, @pos_sine_stop_2ButtonPushed, true);
            app.pos_sine_stop_2.Text = 'Stop';
            app.AmplituderadEditField_6Label = uilabel(app.ElecAngleDebuggingTab);
            app.AmplituderadEditField_6Label.Text = 'Amplitude (rad)';
            app.AmplituderadEditField_6 = uieditfield(app.ElecAngleDebuggingTab, 'numeric');
            app.FrequencyHzEditField_8Label = uilabel(app.ElecAngleDebuggingTab);
            app.FrequencyHzEditField_8Label.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField_8 = uieditfield(app.ElecAngleDebuggingTab, 'numeric');
            app.pos_sine_start_3 = uibutton(app.ElecAngleDebuggingTab, 'push');
            app.pos_sine_start_3.ButtonPushedFcn = createCallbackFcn(app, @pos_sine_start_3ButtonPushed, true);
            app.pos_sine_start_3.Text = 'Start';
            app.pos_sine_stop_3 = uibutton(app.ElecAngleDebuggingTab, 'push');
            app.pos_sine_stop_3.ButtonPushedFcn = createCallbackFcn(app, @pos_sine_stop_3ButtonPushed, true);
            app.pos_sine_stop_3.Text = 'Stop';
            app.HallSensorvsIncEncoderLabel = uilabel(app.ElecAngleDebuggingTab);
            app.HallSensorvsIncEncoderLabel.Text = 'Hall Sensor vs Inc Encoder';
            app.LinkLUTConverterTab = uitab(app.TabGroup7);
            app.LinkLUTConverterTab.Title = 'Link LUT Converter';
            app.Link_Conversion_Plot = uiaxes(app.LinkLUTConverterTab);
            title(app.Link_Conversion_Plot, 'Posion Reference')
            xlabel(app.Link_Conversion_Plot, 'incremental encoder (deg)')
            ylabel(app.Link_Conversion_Plot, 'absolute encoder (deg)')
            zlabel(app.Link_Conversion_Plot, 'Z')
            app.pos_sine_start_4 = uibutton(app.LinkLUTConverterTab, 'push');
            app.pos_sine_start_4.ButtonPushedFcn = createCallbackFcn(app, @pos_sine_start_4ButtonPushed, true);
            app.pos_sine_start_4.Text = 'Start';
            app.pos_sine_stop_4 = uibutton(app.LinkLUTConverterTab, 'push');
            app.pos_sine_stop_4.ButtonPushedFcn = createCallbackFcn(app, @pos_sine_stop_4ButtonPushed, true);
            app.pos_sine_stop_4.Text = 'Stop';
            app.MagnetometerCalibrationTab = uitab(app.TabGroup7);
            app.MagnetometerCalibrationTab.Title = 'Magnetometer Calibration';
            app.GridLayout166 = uigridlayout(app.MagnetometerCalibrationTab);
            app.SetMagnetometerMatrixPanel = uipanel(app.GridLayout166);
            app.SetMagnetometerMatrixPanel.Title = 'Set Magnetometer Matrix';
            app.GridLayout167 = uigridlayout(app.SetMagnetometerMatrixPanel);
            app.A11Label = uilabel(app.GridLayout167);
            app.A11Label.Text = 'Ainv';
            app.A11 = uieditfield(app.GridLayout167, 'numeric');
            app.A12 = uieditfield(app.GridLayout167, 'numeric');
            app.A21 = uieditfield(app.GridLayout167, 'numeric');
            app.A13 = uieditfield(app.GridLayout167, 'numeric');
            app.A22 = uieditfield(app.GridLayout167, 'numeric');
            app.A23 = uieditfield(app.GridLayout167, 'numeric');
            app.A32 = uieditfield(app.GridLayout167, 'numeric');
            app.A33 = uieditfield(app.GridLayout167, 'numeric');
            app.A31 = uieditfield(app.GridLayout167, 'numeric');
            app.B_matrix = uilabel(app.GridLayout167);
            app.B_matrix.Text = 'b';
            app.B3 = uieditfield(app.GridLayout167, 'numeric');
            app.B2 = uieditfield(app.GridLayout167, 'numeric');
            app.B1 = uieditfield(app.GridLayout167, 'numeric');
            app.mag_calib_apply = uibutton(app.GridLayout167, 'state');
            app.mag_calib_apply.ValueChangedFcn = createCallbackFcn(app, @mag_calib_applyValueChanged, true);
            app.mag_calib_apply.Text = 'Apply';
            app.GridLayout27 = uigridlayout(app.GridLayout);
            app.ConnectButton = uibutton(app.GridLayout27, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.GridLayout27, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.TextArea = uitextarea(app.GridLayout);
            app.GridLayout32 = uigridlayout(app.GridLayout);
            app.SaveButton = uibutton(app.GridLayout32, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ImportButton = uibutton(app.GridLayout32, 'push');
            app.ImportButton.Text = 'Import';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BBB_GUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end