classdef Commutation_DirectionSet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        SetButton                       matlab.ui.control.Button
        SignofcurrentdirectionDropDown  matlab.ui.control.DropDown
        SignofcurrentdirectionDropDownLabel  matlab.ui.control.Label
        CheckthedirectionofmotorSetitssignyouwantLabel  matlab.ui.control.Label
    end

  %% PUBLIC PROPERTIES (MOTOR)
  properties (Access = public)

    end

    properties (Access = private)
        CallingApp   % Main app object
        set
        device
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, device)
             % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp; 

            app.device = device;

             movegui(app.UIFigure,"center");
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
        
            value = app.SignofcurrentdirectionDropDown.Value;
            switch value
                case "Positive"
                    app.set = 1;
                case "Negative"
                    app.set = 2;
                otherwise
                    app.set = 0;
            end

            SendData = [2,...                  %SDO
                    0x14, ...                      %identifier
                    2,...
                    app.CallingApp.TASK_ID_LOWLEVEL,...
                    app.CallingApp.SDO_ID_LOWLEVEL_USER_DIRECTION, ...
                    app.CallingApp.SDO_REQU,...
                    1, ...
                    app.set];

            write(app.CallingApp.device, SendData, "uint8");

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CheckthedirectionofmotorSetitssignyouwantLabel = uilabel(app.GridLayout);
            app.CheckthedirectionofmotorSetitssignyouwantLabel.Text = {'Check the direction of motor,'; ''; 'Set its sign you want.'};
            app.SignofcurrentdirectionDropDownLabel = uilabel(app.GridLayout);
            app.SignofcurrentdirectionDropDownLabel.Text = {'Sign of '; 'current direction'};
            app.SignofcurrentdirectionDropDown = uidropdown(app.GridLayout);
            app.SignofcurrentdirectionDropDown.Items = {'Positive', 'Negative'};
            app.SignofcurrentdirectionDropDown.Value = 'Positive';
            app.SetButton = uibutton(app.GridLayout, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Commutation_DirectionSet(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end