classdef DataLoad < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        MotorModelLabel       matlab.ui.control.Label
        OKAY2Button           matlab.ui.control.Button
        NodeIDRegisterButton  matlab.ui.control.Button
        NodeIDEditField       matlab.ui.control.NumericEditField
        NodeIDEditFieldLabel  matlab.ui.control.Label
        NOButton              matlab.ui.control.Button
        OKAYButton            matlab.ui.control.Button
        DataLoadButton        matlab.ui.control.Button
    end

    
    properties (Access = public)
         %% MSG FNC CODE
        EMCY            = uint16(0x000);
        SYNC            = uint16(0x100);
        SDO             = uint16(0x200);
        PDO             = uint16(0x300);
        TIMEOUT         = uint16(0x400);
        GUI_SYNC   = uint16(0x500);

        %% MSG FNC CODE
        EMCY_SEND            = uint8(0x00);
        SYNC_SEND            = uint8(0x01);
        SDO_SEND             = uint8(0x02);
        PDO_SEND             = uint8(0x03);
        TIMEOUT_SEND         = uint8(0x04);
        GUI_SYNC_SEND     = uint8(0x05);

        %% Node ID
        NODE_ID_ALL	= uint8(0x00);
        NODE_ID_CM	= uint8(0x10);
        NODE_ID_LH	= uint8(0x20);
        NODE_ID_RH	= uint8(0x30);
        NODE_ID_LK	= uint8(0x40);
        NODE_ID_RK	= uint8(0x50);
        NODE_ID_LA	= uint8(0x60);
        NODE_ID_RA	= uint8(0x70);

        %% TASK ID
        TASK_ID_LOWLEVEL    = 0x00;
        TASK_ID_MIDLEVEL    = 0x01;
    	TASK_ID_MSG         = 0x02;
    	TASK_ID_IMU         = 0x03;
    	TASK_ID_SYSMNGT     = 0x04;
    	TASK_ID_EXTDEV      = 0x05;

        %% SDO ID
    	SDO_ID_LOWLEVEL_GET_STATE                   =0x00;
	    SDO_ID_LOWLEVEL_SET_STATE                   =0x01;
	    SDO_ID_LOWLEVEL_GET_ROUTINE                 =0x02;
	    SDO_ID_LOWLEVEL_SET_ROUTINE                 =0x03;
        SDO_ID_LOWLEVEL_CURRENT_CTRL_TYPE           =0x04;
	    SDO_ID_LOWLEVEL_USER_DIRECTION              =0x05;
	    SDO_ID_LOWLEVEL_TERMINAL_RESISTANCE         =0x06;
	    SDO_ID_LOWLEVEL_TERMINAL_INDUCTANCE         =0x07;
	    SDO_ID_LOWLEVEL_POLE_PAIR                   =0x08;
	    SDO_ID_LOWLEVEL_BACK_EMF_CONSTANT           =0x09;
	    SDO_ID_LOWLEVEL_TORQUE_CONSTANT             =0x0A;
	    SDO_ID_LOWLEVEL_PEAK_CURRENT_LIMIT_DR       =0x0B;
	    SDO_ID_LOWLEVEL_PEAK_CURRENT_LIMIT_MT       =0x0C;
	    SDO_ID_LOWLEVEL_CONTINUOUS_CURRENT_LIMIT_DR =0x0D;
	    SDO_ID_LOWLEVEL_CONTINUOUS_CURRENT_LIMIT_MT =0x0E;
	    SDO_ID_LOWLEVEL_CONTINUOUS_TIME_LIMIT       =0x0F;
	    SDO_ID_LOWLEVEL_ENCODER_RESOLUTION          =0x10;
	    SDO_ID_LOWLEVEL_NAME                        =0x11;
        SDO_ID_LOWLEVEL_COMMUTATION_DUTY            =0x12;
        SDO_ID_LOWLEVEL_CURRENT_CTRL_BW_RAD         =0x13;
        SDO_ID_LOWLEVEL_BEMF_ID_VELOCITY            =0x14;
        SDO_ID_LOWLEVEL_GEAR_RATIO                  =0x15;
	    SDO_ID_LOWLEVEL_INERTIA                     =0x16;
	    SDO_ID_LOWLEVEL_DAMPING_COEF                =0x17;
	    SDO_ID_LOWLEVEL_MECH_MODEL_A0               =0x18;
	    SDO_ID_LOWLEVEL_MECH_MODEL_A1               =0x19;
	    SDO_ID_LOWLEVEL_MECH_MODEL_B0               =0x1A;
	    SDO_ID_LOWLEVEL_MECH_MODEL_B1               =0x1B;
	    SDO_ID_LOWLEVEL_MECH_MODEL_C0               =0x1C;
	    SDO_ID_LOWLEVEL_MECH_MODEL_C1               =0x1D;
        SDO_ID_LOWLEVEL_VERIFY_REF_MAG              =0x1E;
        SDO_ID_LOWLEVEL_VERIFY_REF_FREQ             =0x1F;
        SDO_ID_LOWLEVEL_KALMAN_ONOFF                =0x20;
        SDO_ID_LOWLEVEL_FEEDBACK_SENSOR             =0x21;
        SDO_ID_LOWLEVEL_MODE_OF_OPERATION           =0x22;
	    SDO_ID_LOWLEVEL_INPUT_METHOD                =0x23;
	    SDO_ID_LOWLEVEL_INPUT_MIN_DUTY              =0x24;
	    SDO_ID_LOWLEVEL_INPUT_MAX_DUTY              =0x25;
	    SDO_ID_LOWLEVEL_INPUT_MIN                   =0x26;
	    SDO_ID_LOWLEVEL_INPUT_MAX                   =0x27;

    	SDO_ID_MIDLEVEL_GET_STATE                  =0x00;
	    SDO_ID_MIDLEVEL_SET_STATE                  =0x01;
	    SDO_ID_MIDLEVEL_GET_ROUTINE                =0x02;
	    SDO_ID_MIDLEVEL_SET_ROUTINE                =0x03;
        SDO_ID_MIDLEVEL_SYSTEM_ID_MAG              =0x04;
        SDO_ID_MIDLEVEL_STIFFNESS                  =0x05;
        SDO_ID_MIDLEVEL_DAMPER                     =0x06;
	    SDO_ID_MIDLEVEL_VELOCITY_P_GAIN            =0x07;
	    SDO_ID_MIDLEVEL_VELOCITY_I_GAIN            =0x08;
	    SDO_ID_MIDLEVEL_POSITION_P_GAIN            =0x09;
	    SDO_ID_MIDLEVEL_POSITION_D_GAIN            =0x0A;
	    SDO_ID_MIDLEVEL_VELOCITY_BW                =0x0B;
	    SDO_ID_MIDLEVEL_POSITION_BW                =0x0C;
        SDO_ID_MIDLEVEL_SET_ORIGIN                 =0x0D;
	    SDO_ID_MIDLEVEL_VELOCITY_PERIODIC_SIG_AMP  =0x0E;
	    SDO_ID_MIDLEVEL_VELOCITY_PERIODIC_SIG_FREQ =0x0F;
	    SDO_ID_MIDLEVEL_POSITION_PERIODIC_SIG_AMP  =0x10;
	    SDO_ID_MIDLEVEL_POSITION_PERIODIC_SIG_FREQ =0x11;

        SDO_ID_MSG_GET_STATE 		=0x00;      % request data size 1
        SDO_ID_MSG_SET_STATE		=0x01;      % request data size 1
        SDO_ID_MSG_GET_ROUTINE		=0x02;      % request data size 1
        SDO_ID_MSG_SET_ROUTINE		=0x03;      % request data size 1
        SDO_ID_MSG_PDO_LIST 	    =0x04;      % request data size 1
        SDO_ID_MSG_MS_ENUM 	    =0x05;      % request data size 1

    	SDO_ID_EXT_GET_STATE                    =0x00;
	    SDO_ID_EXT_SET_STATE                    =0x01;
	    SDO_ID_EXT_GET_ROUTINE                  =0x02;
	    SDO_ID_EXT_SET_ROUTINE                  =0x03;
        SDO_ID_EXT_DC_SET_LENGTH	            =0x04;     
        SDO_ID_EXT_DC_SET_DIRECT                =0x05;

        %% PDO ID
        PDO_ID_LOWLEVEL_REF_CURRENT         =0x00;
        PDO_ID_LOWLEVEL_ACTUAL_CURRENT 	    =0x01;
        PDO_ID_LOWLEVEL_PHASES_CURRENT      =0x02;
        PDO_ID_LOWLEVEL_PHASES_VOLTAGE      =0X03;
        PDO_ID_LOWLEVEL_POSITION            =0x04;
        PDO_ID_LOWLEVEL_VELOCITY		    =0x05;
        PDO_ID_LOWLEVEL_CLARKE_OUT	        =0x06;
        PDO_ID_LOWLEVEL_PARK_OUT            =0x07;
        PDO_ID_LOWLEVEL_VOLTAGE_IN          =0x08;
        PDO_ID_LOWLEVEL_ELEC_ANGLE          =0x09;
        PDO_ID_LOWLEVEL_PRBS_DATA           =0x0A;
        PDO_ID_LOWLEVEL_RAW_CURRENT         =0x0B;
        PDO_ID_LOWLEVEL_KF_CURRENT          =0x0C;

        PDO_ID_MIDLEVEL_REF_POSITION       =0x00;
        PDO_ID_MIDLEVEL_REF_VELOCITY       =0x01;
        PDO_ID_MIDLEVEL_ACTUAL_POSITION    =0x02;
        PDO_ID_MIDLEVEL_ACTUAL_VELOCITY    =0x03;
        PDO_ID_MIDLEVEL_ERROR_POSITION     =0x04;
        PDO_ID_MIDLEVEL_ERROR_VELOCITY     =0x05; 
        PDO_ID_MIDLEVEL_POSITION_DISTURBANCE	=0x06;
        PDO_ID_MIDLEVEL_VELOCITY_DISTURBANCE	=0x07;
        PDO_ID_MIDLEVEL_PRBS_SIGNAL            =0x08;
        PDO_ID_MIDLEVEL_TIME               =0x09;
        PDO_ID_MIDLEVEL_ENCODER_COUNTER    =0x0A;
        PDO_ID_MIDLEVEL_ABS_MOTOR_DEGREE	=0x0B;
        PDO_ID_MIDLEVEL_ABS_ANKLE_DEGREE	=0x0C;
        PDO_ID_MIDLEVEL_ABS_MOTOR_VELOCITY =0x0D;

        PDO_ID_CORE_LTC_VOLT =0x00;
        PDO_ID_CORE_LTC_CURR =0x01;
        PDO_ID_CORE_LTC_TEMP =0x02;

        PDO_ID_EXT_FSR          =0x00;
        PDO_ID_EXT_LP           =0x01;
        PDO_ID_EXT_DC_LENGTH    =0x02;
        PDO_ID_EXT_DC_DIRECTION =0x03;

        %% Routine ID
        ROUTINE_ID_LOWLEVEL_FRICTION_COMPENSATION  =0x00;
        ROUTINE_ID_LOWLEVEL_CURRENT_CTRL	       =0x01;
        ROUTINE_ID_LOWLEVEL_ELECTRICAL_SYS_ID      =0x02;
        ROUTINE_ID_LOWLEVEL_DIRECTION_SET          =0x03;
        ROUTINE_ID_LOWLEVEL_ELECTRICAL_BEMF        =0x04;
        ROUTINE_ID_LOWLEVEL_PERFORMANCE_VERIFICATION =0x05;
        ROUTINE_ID_LOWLEVEL_CURRENT_CTRL_BANDWIDTH_CHECK =0x06;
        ROUTINE_ID_LOWLEVEL_GET_ENCODER_VALUE       =0x07;

        ROUTINE_ID_MIDLEVEL_POSITION_CTRL              =0x00;
        ROUTINE_ID_MIDLEVEL_VELOCITY_CTRL	            =0x01;
        ROUTINE_ID_MIDLEVEL_SYSTEM_ID                  =0x02;
        ROUTINE_ID_MIDLEVEL_VELOCITY_SEND2GUI          =0x03;
	    ROUTINE_ID_MIDLEVEL_POSITION_SEND2GUI          =0x04;
        ROUTINE_ID_MIDLEVEL_VELOCITY_SINE_REF          =0x05;
	    ROUTINE_ID_MIDLEVEL_POSITION_SINE_REF          =0x06;
        ROUTINE_ID_MIDLEVEL_VELOCITY_REC_REF           =0x07;
	    ROUTINE_ID_MIDLEVEL_POSITION_REC_REF           =0x08;
        ROUTINE_ID_MIDLEVEL_REF_SEND2GUI               =0x09;
%         ROUTINE_ID_MIDLEVEL_SYSTEM_ID_VERIFICATION
%         ROUTINE_ID_MIDLEVEL_SYSTEM_ID_SINE_BY_SINE        
% 	    ROUTINE_ID_MIDLEVEL_IRC                        =0x06; 
%         ROUTINE_ID_MIDLEVEL_VIRTUAL_SPRING_DAMPER      =0x04;

        ROUTINE_ID_EXT_FOOT_ROUTINE              =0x00;
        ROUTINE_ID_DC_LENGTH_CMD_ROUTINE	     =0x01;
        ROUTINE_ID_DC_DIRECT_CMD_ROUTINE         =0x02;
        ROUTINE_ID_DC_SWITCH_ROUTINE             =0x03;

        %% SDO FNC
        SDO_IDLE  =2;
        SDO_REQU  =1;
        SDO_SUCC  =0;
        SDO_FAIL =255;

        %% STATE
        State_Off = 0;
        State_Standby = 1;
        State_Enable = 2;
        State_Error = 3;

        %% Main Sequence Enum
	    IDLE                        = 0;
	    UPLOAD_PROPERTIES           = 1;
	    SAVE_PROPERTIES             = 2;
        DOWNLOAD_PROPERTIES         = 3;
	    ELECTRICAL_SYSTEM_ID        = 4;
	    ELECTRICAL_SYSTEM_VERIFY    = 5;
	    MECHANICAL_SYSTEM_ID        = 6;
        CURRENT_BANDWIDTH_CHECK     = 7;
	    AUTO_TUNING                 = 8;

        %% UI UPDATE
        FIRST_USE					=0x01;
        SECOND_USE					=0x02;
        MEMORY_UI_SYNC1				=0x03;
        MEMORY_UI_SYNC2				=0x04;
        MEMORY_UI_SYNC3				=0x05;
        E_SYS_BATCH					=0x06;
        E_SYS_BEMF                  =0x07;
        M_SYS_DATA_COMM             =0x08;
        E_VERIFY_DATA_COMM			=0x09;
        BW_CHECK                    =0x0A;
        VSD_KIPYEONG                =0x0B;
        GAIN_TUNER                  =0x0C;
        GET_ENCODER                  =0x0D;
        GET_VELOCITY_CTRL           =0x0E;
        GET_POSITION_CTRL           =0x0F;
        GET_REF                     =0x10;

        %% Mode of Operation 
        CURRENT_CTRL_MODE = 0x00;
        VELOCITY_CTRL_MODE = 0x01;
        POSITION_CTRL_MODE = 0x02;

        %% Feedback Sensor
        INCREMENTAL_ENCODER = 0x00;
        HALL_SENSOR         = 0x01;

        %% Current CTRL Type
        FOC                 = 0x00;
        TRAPEZOIDAL         = 0x01;

        %% Input Method
    	M_COMMUNICATION =0x00;
	    M_PWM           =0x01;
	    M_ANALOG        =0x02;
        M_GUI           =0x03;

    end % Description
     
    properties (Access = private)
        CallingApp   % Main app object
        device
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, device)
            app.CallingApp = mainapp; 

            app.device = device;

             movegui(app.UIFigure,"center");
        end

        % Button pushed function: DataLoadButton
        function DataLoadButtonPushed(app, event)
            SendData = [2,...                  %SDO
                    0x14, ...                      %identifier
                    1,...
                    app.CallingApp.TASK_ID_MSG, ...
                    app.CallingApp.SDO_ID_MSG_MS_ENUM, ...
                    app.CallingApp.SDO_REQU, ...
                    1, ...
                    app.CallingApp.UPLOAD_PROPERTIES, ...
                    ];
            
            write(app.device,SendData,"uint8");

            pause(1);
            app.MotorModelLabel.Text = app.CallingApp.DataLoad; %%strcat('Model Name :  ', num2str(app.CallingApp.DataLoad));
            
            app.NOButton.Visible = "on";
            app.OKAYButton.Visible = "on";
        end

        % Button pushed function: OKAYButton
        function OKAYButtonPushed(app, event)
            
            SendData = [2,...                  %SDO
                0x14, ...                      %identifier
                1,...
                app.TASK_ID_MSG, ...
                app.SDO_ID_MSG_MS_ENUM, ...
                app.SDO_REQU, ...
                1, ...
                app.DOWNLOAD_PROPERTIES, ...
                ];

            write(app.device,SendData,"uint8");

            PropertySetChosen(app.CallingApp,2);
            delete(app);

        end

        % Button pushed function: NOButton
        function NOButtonPushed(app, event)
            PropertySetChosen(app.CallingApp,1);
            delete(app);

        end

        % Button pushed function: NodeIDRegisterButton
        function NodeIDRegisterButtonPushed(app, event)
           
            SetNodeID(app.CallingApp, uint8(app.NodeIDEditField.Value));
            app.DataLoadButton.Enable = "on"; 
        end

        % Button pushed function: OKAY2Button
        function OKAY2ButtonPushed(app, event)
            PropertySetChosen(app.CallingApp,1);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.DataLoadButton = uibutton(app.GridLayout, 'push');
            app.DataLoadButton.ButtonPushedFcn = createCallbackFcn(app, @DataLoadButtonPushed, true);
            app.DataLoadButton.Enable = 'off';
            app.DataLoadButton.Text = 'Data Load';
            app.OKAYButton = uibutton(app.GridLayout, 'push');
            app.OKAYButton.ButtonPushedFcn = createCallbackFcn(app, @OKAYButtonPushed, true);
            app.OKAYButton.Visible = 'off';
            app.OKAYButton.Text = 'OKAY';
            app.NOButton = uibutton(app.GridLayout, 'push');
            app.NOButton.ButtonPushedFcn = createCallbackFcn(app, @NOButtonPushed, true);
            app.NOButton.Visible = 'off';
            app.NOButton.Text = 'NO';
            app.NodeIDEditFieldLabel = uilabel(app.GridLayout);
            app.NodeIDEditFieldLabel.Text = 'Node ID';
            app.NodeIDEditField = uieditfield(app.GridLayout, 'numeric');
            app.NodeIDRegisterButton = uibutton(app.GridLayout, 'push');
            app.NodeIDRegisterButton.ButtonPushedFcn = createCallbackFcn(app, @NodeIDRegisterButtonPushed, true);
            app.NodeIDRegisterButton.Text = 'Node ID Register';
            app.OKAY2Button = uibutton(app.GridLayout, 'push');
            app.OKAY2Button.ButtonPushedFcn = createCallbackFcn(app, @OKAY2ButtonPushed, true);
            app.OKAY2Button.Enable = 'off';
            app.OKAY2Button.Visible = 'off';
            app.OKAY2Button.Text = 'OKAY';
            app.MotorModelLabel = uilabel(app.GridLayout);
            app.MotorModelLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataLoad(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end